classdef ConeFinderApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ConeFinderUIFigure              matlab.ui.Figure
        StartConeFinderButton           matlab.ui.control.StateButton
        VoronoiButton                   matlab.ui.control.Button
        SaveCurrentLocationsButton      matlab.ui.control.Button
        ExitButton                      matlab.ui.control.Button
        ImagePropertiesPanel            matlab.ui.container.Panel
        NextMosaicImageButton           matlab.ui.control.Button
        LogImageSwitchLabel             matlab.ui.control.Label
        LogImageSwitch                  matlab.ui.control.Switch
        ShowimageSwitchLabel            matlab.ui.control.Label
        ShowimageSwitch                 matlab.ui.control.Switch
        BrightnessPanel                 matlab.ui.container.Panel
        BrightnessLabel                 matlab.ui.control.Label
        BrightnessSlider                matlab.ui.control.Slider
        ResetBrightnessButton           matlab.ui.control.StateButton
        MarksPropertiesPanel            matlab.ui.container.Panel
        ConeMarksTypeButtonGroup        matlab.ui.container.ButtonGroup
        DotButton                       matlab.ui.control.RadioButton
        CircleButton                    matlab.ui.control.RadioButton
        CrossButton                     matlab.ui.control.RadioButton
        ShowMarksOptionsButtonGroup     matlab.ui.container.ButtonGroup
        AutoUserButton                  matlab.ui.control.RadioButton
        UserdetectButton                matlab.ui.control.RadioButton
        AutodetectButton                matlab.ui.control.RadioButton
        HighlightAutodetectedConesSwitchLabel  matlab.ui.control.Label
        HighlightAutodetectedConesSwitch  matlab.ui.control.Switch
        ShowMarkedLocationsSwitchLabel  matlab.ui.control.Label
        ShowMarkedLocationsSwitch       matlab.ui.control.Switch
        EditConeLocationsPanel          matlab.ui.container.Panel
        EditModeSwitchLabel             matlab.ui.control.Label
        EditModeSwitch                  matlab.ui.control.Switch
        ChangeBoxSizeButton             matlab.ui.control.Button
        DeletedSelectedConesButton      matlab.ui.control.Button
        AddConelocsOutsideBoxButton     matlab.ui.control.Button
        AddConelocsInBoxButton          matlab.ui.control.Button
        HelpTextAreaLabel               matlab.ui.control.Label
        HelpTextArea                    matlab.ui.control.TextArea
        GridPanel                       matlab.ui.container.Panel
        ShowGridSwitchLabel             matlab.ui.control.Label
        ShowGridSwitch                  matlab.ui.control.Switch
        ShowGirdNumbersSwitchLabel      matlab.ui.control.Label
        ShowGirdNumbersSwitch           matlab.ui.control.Switch
    end

    
    properties (Access = private)
        
    end
    
    properties (Access = public)
        % Files
        Fname;
        Pname;
        
        % Image
        I;
        Ttl;
        A1;
        ChangeMosaic;
        Mosaic = 0;
        MultipleMosaics = 0;
        
        % Voronoi
        VColorMap;
        VoronoiPlot = 1;
        
        % Cone Locations
        Conelocs = [];
        BoxPosition;
        TotalConeCount = 0;
        ConeCount = 0;
        Minlimx = 0;
        Minlimy = 0;
        Maxlimx = 0;
        Maxlimy = 0;
        
        % Mark properties
        ShowMarks = 'On';
        MarkerSize = 2;
        MarkType = '.y';
        ShowConesByDetectType = 0;
        
        % Image properties
        ShowImage = 'On';
        LogImage = 'Off';
        Brightness = 1.0;
        
        % Grid
        ShowGrid = 'Off';
        ShowGridNumbers = 'Off';
        
        % Handler of figure window
        Handler;
        IsClosing = 0;
    end
    
    methods (Access = public)
        
        % Marks cone on image
        function MarkCone(app, x, y)
            %         x = round(x); y = round(y);       % rounded until 10.09.2019
            %         x = x; y = y;                     % exact values from 11.09.2019 (also changes in next analysis steps)
            % CNN generates more exact cone
            % locations anyway
            
            if x < app.Minlimx || x > app.Maxlimx || y < app.Minlimy || y > app.Maxlimy
                app.Conelocs(app.TotalConeCount+1,1) = x; app.Conelocs(app.TotalConeCount+1,2) = y; app.Conelocs(app.TotalConeCount+1,3) = 3;
            else
                app.Conelocs(app.TotalConeCount+1,1) = x; app.Conelocs(app.TotalConeCount+1,2) = y; app.Conelocs(app.TotalConeCount+1,3) = 2;
            end
            
            app.Conelocs = unique(app.Conelocs, 'rows', 'stable');
            app.ConeCount = size(app.Conelocs(app.Conelocs(:,3)~=0,1),1);
            app.TotalConeCount = size(app.Conelocs,1);
            
            % plot(app.Conelocs(app.Conelocs(:,3)~=0,1), app.Conelocs(app.Conelocs(:,3)~=0,2), app.MarkType, 'MarkerSize', app.MarkerSize)
            UpdateImage(app);
            % app.ConeCount = app.ConeCount+1;
            [Inr,  Num_mosaics] = size(app.ChangeMosaic);
            app.Ttl.BackgroundColor = [1 0 0];
            title([app.Fname,'  ',num2str(size(app.Conelocs(app.Conelocs(:,3)~=0,1),1)),' cones', '  mosaic ', num2str(app.Mosaic), '/', num2str(Num_mosaics)],'Interpreter','none')
        end
        
        % Removes Mark from image
        function RemoveMark(app, x, y)
            % Clear marked cone nearest to click location
            if (size(app.Conelocs,1)>0)
                %         x = round(x); y = round(y);       % rounded until 10.09.2019 --> see button 1
                currentloc = [x y];
                %compute Euclidean distances:
                distances = sqrt(sum(bsxfun(@minus, app.Conelocs(:,1:2), currentloc).^2,2));
                %find the smallest distance and delete that index in app.Conelocs:
                app.Conelocs(distances==min(distances),:)=[];
                
                app.Conelocs = unique(app.Conelocs, 'rows', 'stable');
                app.ConeCount = size(app.Conelocs(app.Conelocs(:,3)~=0,1),1);
                app.TotalConeCount = size(app.Conelocs,1);
                
                % After deletion, plot current set of cones
                [Inr, Num_mosaics] = size(app.ChangeMosaic);
                UpdateImage(app);
                title([app.Fname,'  ',num2str(size(app.Conelocs(app.Conelocs(:,3)~=0,1),1)),' cones', '  mosaic ', num2str(app.Mosaic), '/', num2str(Num_mosaics)],'Interpreter','none');
            end
        end
        
        % Saves current cone locations to a .mat file
        function SaveCurrentLocations(app)
            if app.MultipleMosaics
                app.I = app.ChangeMosaic;
            end
            
            [pstr, nm, ext] = fileparts([app.Pname app.Fname]);
            
            if strcmp(ext,'.tiff')==1 || strcmp(ext,'.tif')==1
                name1 = [pstr filesep nm '_annotated.png'];
                name2 = [pstr filesep nm '_annotated.mat'];
            elseif strcmp(ext,'.mat')==1
                name1 = [pstr filesep nm '.png'];
                name2 = [app.Pname app.Fname];
            end
            
            if ~app.IsClosing
                app.Handler.figprint = gcf;
                print(app.Handler.figprint, name1, '-dpng');
            end
            
            % names of variables must be the same to names in 'save' function
            conecount = app.ConeCount;
            I = app.I;
            conelocs = app.Conelocs;
            boxposition = app.BoxPosition;
            multiple_mosaics = app.MultipleMosaics;
            save(name2, 'conecount', 'I', 'conelocs', 'boxposition', 'multiple_mosaics');
            
            app.Ttl.BackgroundColor = [0 1 0];
            
            if app.MultipleMosaics
                app.I = app.ChangeMosaic;
            end
        end
        
        % Zooms In image
        function ZoomIn(app)
            ax = axis;
            width=ax(2)-ax(1);
            height=ax(4)-ax(3);
            axis([ax(1)-width/3 ax(2)+width/3 ax(3)-height/3 ax(4)+height/3]);
            zoom(1/2);
        end
        
        % Zooms Out image
        function ZoomOut(app, x, y)
            ax = axis;
            width=ax(2)-ax(1);
            height=ax(4)-ax(3);
            axis([x-width/2 x+width/2 y-height/2 y+height/2]);
            zoom(2);
        end
        
        % Moves image to left
        function PanLeft(app)
            ax = axis;
            width=ax(2)-ax(1);
            height=ax(4)-ax(3);
            increment = round(width/20);
            axis([ax(1)-increment ax(2)-increment ax(3) ax(4)]);
        end
        
        % Moves image to right
        function PanRight(app)
            ax = axis;
            width=ax(2)-ax(1);
            height=ax(4)-ax(3);
            increment = round(width/20);
            axis([ax(1)+increment ax(2)+increment ax(3) ax(4)]);
        end
        
        % Moves image to up
        function PanUp(app)
            ax = axis;
            width=ax(2)-ax(1);
            height=ax(4)-ax(3);
            increment = round(height/20);
            axis([ax(1) ax(2) ax(3)-increment ax(4)-increment]);
        end
        
        % Moves image to left
        function PanDown(app)
            ax = axis;
            width=ax(2)-ax(1);
            height=ax(4)-ax(3);
            increment = round(height/20);
            axis([ax(1) ax(2) ax(3)+increment ax(4)+increment]);
        end
        
        % Updates Image ------------------------------------------------------------
        function UpdateImage(app)
            blank = zeros(size(app.I));

            if strcmp(app.ShowImage, 'Off')
                % Print blank image
                cla; app.A1 = imshow(blank); hold on;
            else
                % Get image with given brightness
                J = imadjust(app.I,[],[],app.Brightness);
                
                if strcmp(app.LogImage, 'On')
                    % Get log image
                    J = GetLogImage(app, J);
                end
                
                % Print image
                cla; app.A1 = imshow(J); hold on;
            end
            % Print cone locations
            PlotConelocs(app);
            
            if strcmp(app.ShowGrid, 'On')
                % Print Grid
                PrintGrid(app);
            end
        end
        
        % Gets log image
        function image = GetLogImage(app, Image)
            % fix/round/ceil/floor removes message 'Warning: Integer operands are required for colon operator when used as index'
            minlimy = round(app.Minlimy+20);
            maxliny = round(app.Maxlimy-20);
            minlimx = round(app.Minlimx+20);
            maxlimx = round(app.Maxlimx-20);
            I_INbox = log(double(Image(minlimy:maxliny, minlimx:maxlimx)));
            
            I_log = log(double(Image));
            I_log = I_log - min(I_INbox(:));
            I_log = I_log./max(I_log(:));
            
            image = I_log;
        end
        
        % Prints cone locations
        function PlotConelocs(app)
            rectangle('Position', app.BoxPosition,'EdgeColor',[1 0 1]);
            
            if strcmp(app.ShowMarks, 'On')
                switch app.ShowConesByDetectType
                    case 1
                        usercones = app.Conelocs(app.Conelocs(:,3)==2,1:2);
                        plot(usercones(:,1),usercones(:,2), app.MarkType, 'MarkerSize', app.MarkerSize);
                        
                    case 2
                        autocones = app.Conelocs(app.Conelocs(:,3)==1,1:2);
                        plot(autocones(:,1),autocones(:,2),'.m')
                        
                    otherwise
                        if strcmp(app.HighlightAutodetectedConesSwitch.Value, 'Off')
                            plot(app.Conelocs(app.Conelocs(:,3)~=0,1), app.Conelocs(app.Conelocs(:,3)~=0,2), app.MarkType, 'MarkerSize', app.MarkerSize);
                        else
                            usercones = app.Conelocs(app.Conelocs(:,3)==2,1:2);
                            autocones = app.Conelocs(app.Conelocs(:,3)==1,1:2);
                            plot(autocones(:,1),autocones(:,2),'.m');
                            plot(usercones(:,1),usercones(:,2), app.MarkType, 'MarkerSize', app.MarkerSize);
                        end
                end
            end
        end
        
        % Prints Grid
        function PrintGrid(app)
            % Get sizes
            [rows, columns, numberOfColorChannels] = size(app.I);
            
            rowsNumber = floor(rows / 115);
            stepX = floor(rows / rowsNumber);
            
            colsNumber = floor(rows / 115);
            stepY = floor(rows / colsNumber);
            
            % Get grid
            linesRows = [stepX:stepX:(rows - stepX); stepX:stepX:(rows - stepX)];
            linesCols = [stepY:stepY:(columns - stepY); stepY:stepY:(columns - stepY)];
            colsX = [zeros(size(linesCols,2),1)' + 1; zeros(size(linesCols,2),1)' + columns];
            colsY = [zeros(size(linesRows,2),1)' + 1; zeros(size(linesRows,2),1)' + rows];
            
            % Print verticals
            line(colsX, linesCols, 'Color', 'w');
            % Print horizontals
            line(linesRows, colsY, 'Color', 'w');
            
            if strcmp(app.ShowGridNumbers, 'On')
                startX = 20;
                x = startX : stepX : rows;
                
                startY = 20;
                y = startY : stepY : columns;
                sx = size(x, 2);
                sy = size(y, 2);
                
                y = repmat(y, 1, sx);
                x = repelem(x, sy);
                textArray = arrayfun(@(a)num2str(a), (x-startX)/stepX*sx+(y-startY)/stepY, 'uni', 0);
                
                % Print numbers in each cell
                text(x, y, textArray, 'Color', 'w');
            end
        end
        % Updates Image ------------------------------------------------------------
        
        % Updates selection box
        function UpdateBox(app, old_boxposition)
            app.Minlimx = app.BoxPosition(1);
            app.Minlimy = app.BoxPosition(2);
            app.Maxlimx = app.Minlimx + app.BoxPosition(3);
            app.Maxlimy = app.Minlimy + app.BoxPosition(4);
            
            minlimx_old = old_boxposition(1);
            minlimy_old = old_boxposition(2);
            maxlimx_old = minlimx_old + old_boxposition(3);
            maxlimy_old = minlimy_old + old_boxposition(4);
            
            app.Conelocs(app.Conelocs(:,1)>app.Minlimx & app.Conelocs(:,1)<=minlimx_old,3)= 1;
            app.Conelocs(app.Conelocs(:,1)<app.Maxlimx & app.Conelocs(:,1)>=maxlimx_old,3)= 1;
            app.Conelocs(app.Conelocs(:,2)>app.Minlimy & app.Conelocs(:,2)<=minlimy_old,3)= 1;
            app.Conelocs(app.Conelocs(:,2)<app.Maxlimy & app.Conelocs(:,2)>=maxlimy_old,3)= 1;
            
            app.Conelocs(app.Conelocs(:,1)<=app.Minlimx,3)= 0;
            app.Conelocs(app.Conelocs(:,1)>=app.Maxlimx,3)= 0;
            app.Conelocs(app.Conelocs(:,2)<=app.Minlimy,3)= 0;
            app.Conelocs(app.Conelocs(:,2)>=app.Maxlimy,3)= 0;
            
            app.Conelocs = unique(app.Conelocs, 'rows', 'stable');
            app.ConeCount = size(app.Conelocs(app.Conelocs(:,3)~=0,1),1);
            app.TotalConeCount = size(app.Conelocs,1);
            
            UpdateImage(app);
            
            ax = gca; app.Ttl = ax.Title; app.Ttl.FontSize = 14;
            app.Ttl.BackgroundColor = [1 0 0];
            [Inr,  Num_mosaics] = size(app.ChangeMosaic);
            title([app.Fname,'  ',num2str(size(app.Conelocs(app.Conelocs(:,3)~=0,1),1)),' cones', '  mosaic ', num2str(app.Mosaic), '/', num2str(Num_mosaics)],'Interpreter','none');
            
        end
        
        % Updates User Interface
        function UpdateUI(app)
            isDisabled = 'off';
            if app.VoronoiPlot
                isDisabled = 'on';
            end
            
            editPanelDisabled = isDisabled;
            mainButtonsDisabled = 'on';
            if strcmp(app.EditModeSwitch.Value,'On')
                editPanelDisabled = 'off';
                mainButtonsDisabled = 'off';
            end
            
            set(findall(app.EditConeLocationsPanel, '-property', 'Enable'), 'Enable', editPanelDisabled)
            set(findall(app.MarksPropertiesPanel, '-property', 'Enable'), 'Enable', isDisabled)
            set(findall(app.GridPanel, '-property', 'Enable'), 'Enable', isDisabled)
            set(findall(app.ImagePropertiesPanel, '-property', 'Enable'), 'Enable', isDisabled)

            app.StartConeFinderButton.Enable = mainButtonsDisabled;
            app.VoronoiButton.Enable = mainButtonsDisabled;
            app.ExitButton.Enable = mainButtonsDisabled;
            app.SaveCurrentLocationsButton.Enable = mainButtonsDisabled;
        end
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            set(findall(app.ConeFinderUIFigure, '-property', 'Enable'), 'Enable', 'off')
            app.StartConeFinderButton.Enable = 'on';
        end

        % Value changed function: StartConeFinderButton
        function StartConeFinderButtonValueChanged(app, event)
            %clear all, close all, clc;
            
            format shortg
            app.Conelocs = [];
            % Colormap for Voronoi patches
            % map = colormap('colorcube'); close(gcf);
            % numcolors = 15;  % ********* change from 15
            % idx = round(linspace(1,15,numcolors));
            % neighcolors = map(idx,:);
            % neighcolors = flipud(neighcolors);
            
            colormaps_mpl2019;
            % map = colormap(inferno); close(gcf);
            app.VColorMap = colormap(viridis); close(gcf);
            % adjust settings for colormap in VoronoiPatch function ~ line 660
            
            %% User input - Select files to analyse
            % Select tiff to analyze, or mat file in case revisiting already counted tiff
            
            start_path = ([cd filesep]);
            % start_path = (['C:\Users\Jenny\Documents\Projekte\foveal_structure_and_function\Probandendaten_STUDIE\foveal9_mosaics\MARKED_files (g1+cunefare CNN Training)' filesep]);
            % start_path = (['E:\ausgelagerte_Studiendaten\788_gradedImages' filesep]);
            
            % file selection GUI
            [fnames, app.Pname] = uigetfile('*.mat;*.tiff;*.tif;', 'Select file(s) to mark', start_path, 'MultiSelect', 'on');
            
            % checks if user cancelled
            if isequal(fnames,0) || isequal(app.Pname,0)
                % display a message in the commmand window
                disp(' ');disp(' ');disp(' ');disp(' ----> User cancelled script');disp(' ');disp(' ');
                % if cancelled exit script
                return
            end
            
            % determine number of selected filess
            sumnorms = cellstr(fnames);
            nfiles = size(fnames(:),1);
            
            if nfiles > 10
                nfiles = 1;
            end
            
            if nfiles > 1
                app.MultipleMosaics = 1;
                app.Fname = fnames{1};
                [pstr, nm, ext] = fileparts([app.Pname app.Fname]);
            else
                app.MultipleMosaics = 0;
                app.Fname = fnames;
                [pstr, nm, ext] = fileparts([app.Pname app.Fname]);
            end
            
            %% In case of Multiselect - prepare for changing the app.Mosaic image
            if app.MultipleMosaics
                
                ref_image=fnames{1};
                refframe = imread([app.Pname ref_image]);
                
                pad = 100;
                
                %     rect1 = [206,206,301,301];
                %     rect2 = [306,306,201,201];
                rect1 = [200,200,201,201];
                rect2 = [312,200,201,201];
                rect3 = [200,312,201,201];
                rect4 = [312,312,201,201];
                %     rect5 = [308,164,79,183];
                
                [fheight, fwidth] = size(refframe);
                % rect is [start x coordinate, y coordinate, width, height]
                refcrop1 = imcrop(refframe,rect1);
                refcrop2 = imcrop(refframe,rect2);
                refcrop3 = imcrop(refframe,rect3);
                refcrop4 = imcrop(refframe,rect4);
                %     refcrop5 = imcrop(refframe,rect5);
                
                %     figure, imshow(refframe)
                %     title('Reference Frame')
                
                sumframe=zeros(fheight+(pad*2),fwidth+(pad*2));
                sumframebinary=ones(fheight+(pad*2),fwidth+(pad*2));
                
                for nf = 1:nfiles
                    frame = imread([app.Pname fnames{nf}]);
                    % rect is [start x coordinate, y coordinate, width, height]
                    crop1 = imcrop(frame,rect1);
                    crop2 = imcrop(frame,rect2);
                    crop3 = imcrop(frame,rect3);
                    crop4 = imcrop(frame,rect4);
                    %         crop5 = imcrop(frame,rect5);
                    
                    [shift1, dump] = dftregistration(fft2(refcrop1),fft2(crop1),10);
                    [shift2, dump] = dftregistration(fft2(refcrop2),fft2(crop2),10);
                    [shift3, dump] = dftregistration(fft2(refcrop3),fft2(crop3),10);
                    [shift4, dump] = dftregistration(fft2(refcrop4),fft2(crop4),10);
                    %         [shift5, dump] = dftregistration(fft2(refcrop5),fft2(crop5),10);
                    
                    
                    shifts(:,:,nf) = [shift1(3:4);shift2(3:4);shift3(3:4);shift4(3:4);];
                    %         shifts(:,:,nf) = [shift1(3:4);shift2(3:4);];
                    meanshift(nf,:) = mean(shifts(:,:,nf));
                    deviation(nf) = mean(std(shifts(:,:,nf)));
                    
                    f = double(zeros(fheight+(pad*2),fwidth+(pad*2)));
                    f(pad+1:(fheight+pad),pad+1:(fwidth+pad))=frame;
                    
                    deltar = -meanshift(nf,1);
                    deltac = -meanshift(nf,2);
                    phase = 2;
                    [nr,nc]=size(f);
                    Nr = ifftshift((-fix(nr/2):ceil(nr/2)-1));
                    Nc = ifftshift((-fix(nc/2):ceil(nc/2)-1));
                    [Nc,Nr] = meshgrid(Nc,Nr);
                    g = abs(ifft2(fft2(f).*exp(1i*2*pi*(deltar*Nr/nr+deltac*Nc/nc))).*exp(-1i*phase));
                    g = g./max(g(:));
                    
                    app.ChangeMosaic{nf} = g(101:812,101:812);
                    
                    %         figure, imshow(g)
                    %         title(['Frame ', num2str(nf)])
                    
                    clear f deltar deltac Nc Nr nc nr g
                end
                
                %     figure, imshow(app.ChangeMosaic(:,:,1));
                %     imwrite(app.ChangeMosaic(:,:,1),'frame1.tif','tif','Compression','none');
                %     figure, imshow(app.ChangeMosaic(:,:,2));
                %     imwrite(app.ChangeMosaic(:,:,2),'frame2.tif','tif','Compression','none');
                %     figure, imshow(app.ChangeMosaic(:,:,3));
                %     imwrite(app.ChangeMosaic(:,:,3),'frame3.tif','tif','Compression','none');
                
            end
            
            %% Prepare file to start/continue cone marking
            if strcmp(ext,'.tiff')==1 || strcmp(ext,'.tif')==1
                
                if app.MultipleMosaics
                    app.Mosaic = 1;
                    app.I = app.ChangeMosaic{app.Mosaic};
                elseif nfiles == 1
                    app.I = imread([app.Pname app.Fname]);
                    app.ChangeMosaic{1} = app.I;
                end
                
                app.Handler.fig = figure;
                imshow(app.I), hold on
                
                box_size = questdlg('Select box size', 'Define window size for analysis', '200x200 pixel', ...
                    '512x512  pixel', '1024x1024  pixel', '1024x1024  pixel');
                if strcmp(box_size, '200x200 pixel')
                    hi = imrect(gca,[0 0 200 200]);
                elseif strcmp(box_size, '512x512  pixel')
                    hi = imrect(gca,[0 0 512 512]);
                elseif strcmp(box_size, '1024x1024  pixel')
                    hi = imrect(gca,[0 0 1024 1024]);
                end
                
                app.BoxPosition = wait(hi);
                %     app.BoxPosition = [295.8507 239.8978 200 200];    % defined, preselected positions for Box can be entered here, before starting the script
                %     app.BoxPosition = [107.2877 97.5342 512 512];    % defined, preselected positions for Box can be entered here, before starting the script
                app.Minlimx = app.BoxPosition(1);
                app.Minlimy = app.BoxPosition(2);
                app.Maxlimx = app.Minlimx + app.BoxPosition(3);
                app.Maxlimy = app.Minlimy + app.BoxPosition(4);
                
                cla; app.A1 = imshow(app.I);title(app.Fname,'Interpreter','none'), hold on;
                ax = gca; app.Ttl = ax.Title; app.Ttl.FontSize = 14;
                app.Ttl.BackgroundColor = [1 0 0];
                rectangle('Position', app.BoxPosition,'EdgeColor',[1 0 1]);
                
                % QUESTDLG for use of the FastPeakFinder
                ButtonName = questdlg(' Use automatic cone finding algorithm? ', ...
                    'Startup condition', ...
                    'Fast Peak Finder', 'CNN','No','No');
                switch ButtonName
                    case 'Fast Peak Finder'
                        p = FastPeakFind(app.I);
                        app.Conelocs(:,1) = p(1:2:end);
                        app.Conelocs(:,2) = p(2:2:end);
                        app.Conelocs(:,3) = 1;
                        
                        app.Conelocs(app.Conelocs(:,1)<=app.Minlimx,3)= 0;
                        app.Conelocs(app.Conelocs(:,1)>=app.Maxlimx,3)= 0;
                        app.Conelocs(app.Conelocs(:,2)<=app.Minlimy,3)= 0;
                        app.Conelocs(app.Conelocs(:,2)>=app.Maxlimy,3)= 0;
                        
                        app.Conelocs = unique(app.Conelocs, 'rows', 'stable');
                        
                        plot(app.Conelocs(app.Conelocs(:,3)~=0,1), app.Conelocs(app.Conelocs(:,3)~=0,2), app.MarkType, 'MarkerSize', app.MarkerSize)
                        app.ConeCount = size(app.Conelocs(app.Conelocs(:,3)~=0,1),1);
                        app.TotalConeCount = size(app.Conelocs,1);
                        
                    case 'CNN'
                        p = FastPeakFind(app.I);
                        
                        %             DataSet = '14111';  % dataSet specifies trained CNN (grader 1 trained network - 14(x16) training images and 1(x16) validation image)
                        DataSet = 'g1+cunefare';  % dataSet specifies trained CNN (grader 1 14(x16)training + 100 cunefare val images and 1(x16) validation g1 + 16 cunefare training images)
                        %             DataSet = '42';  % dataSet specifies trained CNN (grader 1 14(x16)training + 100 cunefare val images and 1(x16) validation g1 + 16 cunefare training images)
                        
                        ImageDir = [app.Pname app.Fname];
                        tic;
                        startTimeCNN = clock;
                        fprintf('startTimeCNN: %s \n', sprintf('%g ', startTimeCNN));
                        [app.Conelocs] = RunCNNnewSet_automatic(DataSet, ImageDir, app.BoxPosition, app.I);
                        endTimeCNN = clock;
                        fprintf('endTimeCNN: %s \n', sprintf('%g ', endTimeCNN));
                        toc;
                        disp(' ');
                        disp(' ');
                        
                        app.Conelocs = unique(app.Conelocs,'rows', 'stable');
                        
                        %             app.Conelocs(:,1) = p(1:2:end);
                        %             app.Conelocs(:,2) = p(2:2:end);
                        app.Conelocs(:,3) = 1;
                        
                        app.Conelocs(app.Conelocs(:,1)<=app.Minlimx,3)= 0;
                        app.Conelocs(app.Conelocs(:,1)>=app.Maxlimx,3)= 0;
                        app.Conelocs(app.Conelocs(:,2)<=app.Minlimy,3)= 0;
                        app.Conelocs(app.Conelocs(:,2)>=app.Maxlimy,3)= 0;
                        
                        app.Conelocs = unique(app.Conelocs, 'rows', 'stable');
                        
                        plot(app.Conelocs(app.Conelocs(:,3)~=0,1), app.Conelocs(app.Conelocs(:,3)~=0,2), app.MarkType, 'MarkerSize', app.MarkerSize)
                        app.ConeCount = size(app.Conelocs(app.Conelocs(:,3)~=0,1),1);
                        app.TotalConeCount = size(app.Conelocs,1);
                        
                        
                    case 'No'
                        app.ConeCount = 0;
                end % switch
                
                title([app.Fname,'  ',num2str(app.ConeCount),' cones'],'Interpreter','none')
                
                if app.MultipleMosaics == 1
                    app.ChangeMosaic{1} = app.I;
                    app.Mosaic = 1;
                    %         app.I = app.I(:,:, app.Mosaic);
                else
                    app.ChangeMosaic{1} = app.I;
                    app.Mosaic = 1;
                end
                
            elseif strcmp(ext,'.mat')==1
                % load data
                load([app.Pname app.Fname]);
                app.ConeCount = conecount;
                app.I = I;
                app.Conelocs = conelocs;
                app.BoxPosition = boxposition;
                app.MultipleMosaics = multiple_mosaics;
                
                app.Conelocs = unique(app.Conelocs, 'rows', 'stable');
                app.ConeCount = size(app.Conelocs(app.Conelocs(:,3)~=0,1),1);
                app.TotalConeCount = size(app.Conelocs,1);
                
                app.Minlimx = app.BoxPosition(1);
                app.Minlimy = app.BoxPosition(2);
                app.Maxlimx = app.Minlimx + app.BoxPosition(3);
                app.Maxlimy = app.Minlimy + app.BoxPosition(4);
                
                if app.MultipleMosaics == 1
                    app.ChangeMosaic = app.I;
                    app.Mosaic = 1;
                    app.I = app.ChangeMosaic{1};
                else
                    app.ChangeMosaic = app.I;
                    app.Mosaic = 1;
                end
                
                app.MarkerSize = 4;
                
                app.Handler.fig = figure; imshow(app.I), title([app.Fname,'  ',num2str(app.ConeCount),' cones'],'Interpreter','none'), hold on
                ax = gca;                    % get the current axis
                app.Ttl = ax.Title;              % get the title text object
                app.Ttl.FontSize = 14;
                app.Ttl.BackgroundColor = [1 0 0];
                
                UpdateImage(app);
                title([app.Fname,'  ',num2str(app.ConeCount),' cones'],'Interpreter','none');
            end
            
            UpdateUI(app);
        end

        % Button pushed function: VoronoiButton
        function VoronoiButtonPushed(app, event)
            
            map = app.VColorMap;
            
            numcolors = 25;  % ********* change from 15
            %         max_area_pixel = 150;        % basis for dividing cone Area through numcolors
            max_area_pixel = 80;        % basis for dividing cone Area through numcolors
            corr_factor = max_area_pixel/numcolors; % to represent whole colormap over values
            idx = round(linspace(1,256,numcolors));
            neighcolors = map(idx,:);
            neighcolors = flipud(neighcolors);
            
            min_neighbors = 4;
            max_neighbors = 9;
            numcolorsNN = max_neighbors-min_neighbors;  % ********* change from 15
            %         corr_factorNN = numcolorsNN/max_neighbours; % to represent whole colormap over values
            idxNN = round(linspace(1,256,numcolorsNN));
            neighcolorsNN = map(idxNN,:);
            neighcolorsNN = flipud(neighcolorsNN);
            
            if app.VoronoiPlot
                cla; app.A1 = imshow(app.I); hold on;
                voronoi_type = questdlg('Select coloring for Voronoi patches', 'Coloring Voronois', 'Cone Area', ...
                    'Number of Neighbors', 'Cone Area');
                % Calculate num neighbours from Voronoi diagram
                if size(app.Conelocs,1)>3
                    
                    vorocones = app.Conelocs(app.Conelocs(:,3)~=0,1:2);
                    vorocones = unique(vorocones, 'rows', 'stable');
                    
                    dt = delaunayTriangulation(vorocones(:,1),vorocones(:,2));
                    [V,C] = voronoiDiagram(dt);
                    [dump,G] = voronoin(vorocones(:,1:2));
                    for j = 1 : length ( G )
                        NumNeighbors(j,1) = size(G{j},2);
                        ConeArea(j,1) = polyarea(V(C{j},1),V(C{j},2));
                        idxMinMax = round(linspace(min(ConeArea),max_area_pixel,numcolors));
                    end
                end
                for j = 1:length(C)
                    if all(C{j}~=1)
                        
                        % VARIANTE 1: Falschfarben sind Anzahl Nachbarn
                        if strcmp(voronoi_type, 'Number of Neighbors')
                            if NumNeighbors(j)<= min_neighbors
                                h_p = patch(V(C{j},1),V(C{j},2),neighcolorsNN(1,:));
                            elseif NumNeighbors(j)> max_neighbors
                                h_p = patch(V(C{j},1),V(C{j},2),neighcolorsNN(end,:));
                            elseif NumNeighbors(j)> min_neighbors
                                % h_p = patch(V(C{j},1),V(C{j},2),neighcolors(NumNeighbors(j),:),'FaceAlpha',0.1);
                                h_p = patch(V(C{j},1),V(C{j},2),neighcolorsNN(NumNeighbors(j)-min_neighbors,:));
                            end
                            
                            % VARIANTE 2: Falschfarben sind PatchgrцЯe
                        elseif strcmp(voronoi_type, 'Cone Area')
                            if ConeArea(j)<=max_area_pixel
                                [val,pos]=min(abs(idxMinMax-ConeArea(j)));
                                % h_p = patch(V(C{j},1),V(C{j},2),neighcolors(round(ConeArea(j)/corr_factor),:));
                                h_p = patch(V(C{j},1),V(C{j},2),neighcolors(pos,:));
                                % if ConeArea(j)<=64
                                %   h_p = patch(V(C{j},1),V(C{j},2),neighcolors(round(ConeArea(j)),:));
                            else
                                h_p = patch(V(C{j},1),V(C{j},2),neighcolors(end,:));
                            end
                        end                                             % VARIANTEN Ende
                    end
                end
                app.VoronoiPlot = 0;
            else
                UpdateImage(app);
                app.VoronoiPlot = 1;
            end
            UpdateUI(app);
        end

        % Value changed function: EditModeSwitch
        function EditModeSwitchValueChanged(app, event)
            UpdateUI(app);
            while strcmp(app.EditModeSwitch.Value,'On')
                [x,y,button] = ginput(1);
                
                % Mark cone center by left clicking
                if button == 1
                    MarkCone(app, x, y)
                end
                
                % Clear marked cone nearest to click location
                if button == 3
                    RemoveMark(app, x, y)
                end
                
                % Zoom in and out with two keys "+", "-" on numpad
                if button == 45
                    ZoomIn(app)
                end
                if button == 43
                    ZoomOut(app, x, y)
                end
                
                % Pan image laterally with arrow keys
                if button == 28 % LEFT arrow
                    PanLeft(app)
                elseif button == 29 % RIGHT arrow
                    PanRight(app)
                elseif button == 30 % UP arrow
                    PanUp(app)
                elseif button == 31 % DOWN arrow
                    PanDown(app)
                end
                
                % Escape key to quit count routine
                if button == 27
                    app.EditModeSwitch.Value = 'Off';
                end
            end
            UpdateUI(app);
        end

        % Value changed function: ResetBrightnessButton
        function ResetBrightnessButtonValueChanged(app, event)
            app.BrightnessSlider.Value = 1.0;
            app.Brightness = 1.0;
            UpdateImage(app);
        end

        % Value changed function: LogImageSwitch
        function LogImageSwitchValueChanged(app, event)
            % log_image = app.LogImageSwitch.Value
            % default = Off
            app.LogImage = app.LogImageSwitch.Value;
            UpdateImage(app);
        end

        % Value changed function: ShowMarkedLocationsSwitch
        function ShowMarkedLocationsSwitchValueChanged(app, event)
            % markson = app.ShowMarkedLocationsSwitch.Value
            % default = On
            app.ShowMarks = app.ShowMarkedLocationsSwitch.Value;
            UpdateImage(app);
        end

        % Value changed function: ShowimageSwitch
        function ShowimageSwitchValueChanged(app, event)
            % imageon = app.ShowimageSwitch.Value
            % default = On
            app.ShowImage = app.ShowimageSwitch.Value;
            UpdateImage(app);
        end

        % Value changing function: BrightnessSlider
        function BrightnessSliderValueChanging(app, event)
            app.Brightness = event.Value;
            UpdateImage(app);
        end

        % Selection changed function: ConeMarksTypeButtonGroup
        function ConeMarksTypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.ConeMarksTypeButtonGroup.SelectedObject;
            
            switch selectedButton.Text
                case 'Circle'
                    app.MarkType = 'oy';
                    app.MarkerSize = 5;
                    
                case 'Cross'
                    app.MarkType = 'xy';
                    app.MarkerSize = 5;
                    
                otherwise
                    app.MarkType = '.y';
                    app.MarkerSize = 2;
            end
            
            UpdateImage(app);
        end

        % Selection changed function: ShowMarksOptionsButtonGroup
        function ShowMarksOptionsButtonGroupSelectionChanged(app, event)
            selectedButton = app.ShowMarksOptionsButtonGroup.SelectedObject;
            
            switch selectedButton.Text
                case 'Userdetect'
                    app.ShowConesByDetectType = 1;
                    
                case 'Autodetect'
                    app.ShowConesByDetectType = 2;
                    
                otherwise
                    app.ShowConesByDetectType = 0;
            end
            UpdateImage(app);
        end

        % Value changed function: HighlightAutodetectedConesSwitch
        function HighlightAutodetectedConesSwitchValueChanged(app, event)
            UpdateImage(app);
        end

        % Button pushed function: SaveCurrentLocationsButton
        function SaveCurrentLocationsButtonPushed(app, event)
            SaveCurrentLocations(app);
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            close_dialog = questdlg('Do you want to save before quitting?',...
                'Close analysis window', 'Yes', 'No', 'Yes');
            
            if strcmp(close_dialog, 'Yes')
                SaveCurrentLocations(app);
            end
            
            close(app.ConeFinderUIFigure);
        end

        % Button pushed function: ChangeBoxSizeButton
        function ChangeBoxSizeButtonPushed(app, event)
            change_box = questdlg('Select new Box parameters', 'Change Size of Box/Rectangle', 'Keep current box', ...
                'Select new 500x500p box', 'Enter new box-parameters', 'Keep current box');
            
            if strcmp(change_box, 'Keep current box')
                % do nothing
            elseif strcmp(change_box, 'Select new 500x500p box')
                old_boxposition = app.BoxPosition;
                hi = imrect(gca,[0 0 500 500]);
                app.BoxPosition = wait(hi);
                UpdateBox(app, old_boxposition);
            elseif strcmp(change_box, 'Enter new box-parameters')
                old_boxposition = app.BoxPosition;
                app.BoxPosition = input('New box-parameters ([x y x-range y-range]: ');
                UpdateBox(app, old_boxposition);
            end
        end

        % Button pushed function: DeletedSelectedConesButton
        function DeletedSelectedConesButtonPushed(app, event)
            delBox = imrect(gca);
            box_delCones = wait(delBox);
            
            mindelx = box_delCones(1);
            mindely = box_delCones(2);
            maxdelx = mindelx + box_delCones(3);
            maxdely = mindely + box_delCones(4);
            
            app.Conelocs((app.Conelocs(:,1)>=mindelx & app.Conelocs(:,1)<=maxdelx & app.Conelocs(:,2)>=mindely & app.Conelocs(:,2)<=maxdely),:)= [];
            %         app.Conelocs(app.Conelocs(:,1)<=maxdelx,:)= [];
            %         app.Conelocs(app.Conelocs(:,2)>=mindely & app.Conelocs(:,2)<=maxdely,:)= [];
            %         app.Conelocs(app.Conelocs(:,2)<=maxdely,:)= [];
            
            UpdateImage(app);
            title([app.Fname,'  ',num2str(size(app.Conelocs(app.Conelocs(:,3)~=0,1),1)),' cones'],'Interpreter','none')
        end

        % Button pushed function: AddConelocsOutsideBoxButton
        function AddConelocsOutsideBoxButtonPushed(app, event)
            add_conelocs = [];
            
            fPFBox = imrect(gca);
            box_addCones = wait(fPFBox);
            
            minaddx = box_addCones(1);
            minaddy = box_addCones(2);
            maxaddx = minaddx + box_addCones(3);
            maxaddy = minaddy + box_addCones(4);
            
            %         app.Conelocs(app.Conelocs(:,1)>=minaddx&app.Conelocs(:,1)<=maxaddx&app.Conelocs(:,2)>=minaddy&app.Conelocs(:,2)<=maxaddy,3)= 3;
            %         app.Conelocs(app.Conelocs(:,1)<=maxaddx,3)= 3;
            %         app.Conelocs(app.Conelocs(:,2)>=minaddy,3)= 3;
            %         app.Conelocs(app.Conelocs(:,2)<=maxaddy,3)= 3;
            
            p = FastPeakFind(app.I(minaddy:maxaddy, minaddx:maxaddx));
            add_conelocs(:,1) = p(1:2:end)+minaddx-1;
            add_conelocs(:,2) = p(2:2:end)+minaddy-1;
            add_conelocs(:,3) = 3;
            
            app.Conelocs = [app.Conelocs; add_conelocs];
            app.Conelocs = unique(app.Conelocs, 'rows', 'stable');
            
            UpdateImage(app);
            title([app.Fname,'  ',num2str(size(app.Conelocs(app.Conelocs(:,3)~=0,1),1)),' cones'],'Interpreter','none');
        end

        % Button pushed function: AddConelocsInBoxButton
        function AddConelocsInBoxButtonPushed(app, event)
            fPFBox = imrect(gca);
            box_addCones = wait(fPFBox);
            
            minaddx = box_addCones(1);
            minaddy = box_addCones(2);
            maxaddx = minaddx + box_addCones(3);
            maxaddy = minaddy + box_addCones(4);
            
            app.Conelocs(app.Conelocs(:,1)>=minaddx&app.Conelocs(:,1)<=maxaddx&app.Conelocs(:,2)>=minaddy&app.Conelocs(:,2)<=maxaddy,3)= 3;
            %         app.Conelocs(app.Conelocs(:,1)<=maxaddx,3)= 3;
            %         app.Conelocs(app.Conelocs(:,2)>=minaddy,3)= 3;
            %         app.Conelocs(app.Conelocs(:,2)<=maxaddy,3)= 3;
            
            %
            %         DataSet = 'g1+cunefare';  % dataSet specifies trained CNN (grader 1 14(x16)training + 100 cunefare val images and 1(x16) validation g1 + 16 cunefare training images)
            %         ImageDir = [pname fname];
            %         [add_conelocs] = RunCNNnewSet_automatic(DataSet, ImageDir, boxposition, I);
            %         add_conelocs(:,3) = 3;
            
            %         p = FastPeakFind(I(minaddy:maxaddy,minaddx:maxaddx));
            %         add_conelocs(:,1) = p(1:2:end)+minaddx-1;
            %         add_conelocs(:,2) = p(2:2:end)+minaddy-1;
            %         add_conelocs(:,3) = 3;
            
            %         app.Conelocs = [app.Conelocs; add_conelocs];
            
            app.Conelocs = unique(app.Conelocs, 'rows', 'stable');
            UpdateImage(app);
            title([app.Fname,'  ',num2str(size(app.Conelocs(app.Conelocs(:,3)~=0,1),1)),' cones'],'Interpreter','none');
        end

        % Button pushed function: NextMosaicImageButton
        function NextMosaicImageButtonPushed(app, event)
            if app.MultipleMosaics
                [Inr,  Num_mosaics] = size(app.ChangeMosaic);
                if app.Mosaic < Num_mosaics
                    app.Mosaic = app.Mosaic+1;
                    app.I = app.ChangeMosaic{app.Mosaic};
                    
                    UpdateImage(app);
                    title([app.Fname,'  ',num2str(size(app.Conelocs(app.Conelocs(:,3)~=0,1),1)),' cones', '  mosaic ', num2str(app.Mosaic), '/', num2str(Num_mosaics)],'Interpreter','none');
                    
                elseif app.Mosaic == Num_mosaics
                    app.Mosaic = 1;
                    app.I = app.ChangeMosaic{app.Mosaic};
                    
                    UpdateImage(app);
                    title([app.Fname,'  ',num2str(size(app.Conelocs(app.Conelocs(:,3)~=0,1),1)),' cones', '  mosaic ', num2str(app.Mosaic), '/', num2str(Num_mosaics)],'Interpreter','none');
                end
            end
        end

        % Close request function: ConeFinderUIFigure
        function ConeFinderUIFigureCloseRequest(app, event)
            app.IsClosing = 1;
            try
                close(app.Handler.fig);
            catch
            end
            delete(app);
        end

        % Value changed function: ShowGridSwitch
        function ShowGridSwitchValueChanged(app, event)
            app.ShowGrid = app.ShowGridSwitch.Value;
            UpdateImage(app);
        end

        % Value changed function: ShowGirdNumbersSwitch
        function ShowGirdNumbersSwitchValueChanged(app, event)
            app.ShowGridNumbers = app.ShowGirdNumbersSwitch.Value;
            UpdateImage(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ConeFinderUIFigure = uifigure;
            app.ConeFinderUIFigure.Name = 'Cone Finder';
            app.ConeFinderUIFigure.CloseRequestFcn = createCallbackFcn(app, @ConeFinderUIFigureCloseRequest, true);
            setAutoResize(app, app.ConeFinderUIFigure, true)
            app.StartConeFinderButton = uibutton(app.ConeFinderUIFigure, 'state');
            app.StartConeFinderButton.ValueChangedFcn = createCallbackFcn(app, @StartConeFinderButtonValueChanged, true);
            app.StartConeFinderButton.Text = 'Start ConeFinder';
            app.VoronoiButton = uibutton(app.ConeFinderUIFigure, 'push');
            app.VoronoiButton.ButtonPushedFcn = createCallbackFcn(app, @VoronoiButtonPushed, true);
            app.VoronoiButton.Text = 'Voronoi';
            app.SaveCurrentLocationsButton = uibutton(app.ConeFinderUIFigure, 'push');
            app.SaveCurrentLocationsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveCurrentLocationsButtonPushed, true);
            app.SaveCurrentLocationsButton.Text = 'Save Current Locations';
            app.ExitButton = uibutton(app.ConeFinderUIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.ImagePropertiesPanel = uipanel(app.ConeFinderUIFigure);
            app.ImagePropertiesPanel.Title = 'Image Properties';
            app.NextMosaicImageButton = uibutton(app.ImagePropertiesPanel, 'push');
            app.NextMosaicImageButton.ButtonPushedFcn = createCallbackFcn(app, @NextMosaicImageButtonPushed, true);
            app.NextMosaicImageButton.Text = 'Next Mosaic Image';
            app.LogImageSwitchLabel = uilabel(app.ImagePropertiesPanel);
            app.LogImageSwitchLabel.Text = 'Log Image';
            app.LogImageSwitch = uiswitch(app.ImagePropertiesPanel, 'slider');
            app.LogImageSwitch.ValueChangedFcn = createCallbackFcn(app, @LogImageSwitchValueChanged, true);
            app.ShowimageSwitchLabel = uilabel(app.ImagePropertiesPanel);
            app.ShowimageSwitchLabel.Text = 'Show image';
            app.ShowimageSwitch = uiswitch(app.ImagePropertiesPanel, 'slider');
            app.ShowimageSwitch.ValueChangedFcn = createCallbackFcn(app, @ShowimageSwitchValueChanged, true);
            app.ShowimageSwitch.Value = 'On';
            app.BrightnessPanel = uipanel(app.ImagePropertiesPanel);
            app.BrightnessPanel.Title = 'Brightness';
            app.BrightnessLabel = uilabel(app.BrightnessPanel);
            app.BrightnessLabel.Text = 'Brightness';
            app.BrightnessSlider = uislider(app.BrightnessPanel);
            app.BrightnessSlider.Limits = [0 2];
            app.BrightnessSlider.MajorTicks = [0 0.5 1 1.5 2];
            app.BrightnessSlider.ValueChangingFcn = createCallbackFcn(app, @BrightnessSliderValueChanging, true);
            app.BrightnessSlider.Value = 1;
            app.ResetBrightnessButton = uibutton(app.BrightnessPanel, 'state');
            app.ResetBrightnessButton.ValueChangedFcn = createCallbackFcn(app, @ResetBrightnessButtonValueChanged, true);
            app.ResetBrightnessButton.Text = 'Reset Brightness';
            app.MarksPropertiesPanel = uipanel(app.ConeFinderUIFigure);
            app.MarksPropertiesPanel.Title = 'Marks Properties';
            app.ConeMarksTypeButtonGroup = uibuttongroup(app.MarksPropertiesPanel);
            app.ConeMarksTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ConeMarksTypeButtonGroupSelectionChanged, true);
            app.ConeMarksTypeButtonGroup.Title = 'Cone Marks Type';
            app.DotButton = uiradiobutton(app.ConeMarksTypeButtonGroup);
            app.DotButton.Text = 'Dot';
            app.DotButton.Value = true;
            app.CircleButton = uiradiobutton(app.ConeMarksTypeButtonGroup);
            app.CircleButton.Text = 'Circle';
            app.CrossButton = uiradiobutton(app.ConeMarksTypeButtonGroup);
            app.CrossButton.Text = 'Cross';
            app.ShowMarksOptionsButtonGroup = uibuttongroup(app.MarksPropertiesPanel);
            app.ShowMarksOptionsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ShowMarksOptionsButtonGroupSelectionChanged, true);
            app.ShowMarksOptionsButtonGroup.Title = 'Show Marks Options';
            app.AutoUserButton = uiradiobutton(app.ShowMarksOptionsButtonGroup);
            app.AutoUserButton.Text = 'Auto + User';
            app.AutoUserButton.Value = true;
            app.UserdetectButton = uiradiobutton(app.ShowMarksOptionsButtonGroup);
            app.UserdetectButton.Text = 'Userdetect';
            app.AutodetectButton = uiradiobutton(app.ShowMarksOptionsButtonGroup);
            app.AutodetectButton.Text = 'Autodetect';
            app.HighlightAutodetectedConesSwitchLabel = uilabel(app.MarksPropertiesPanel);
            app.HighlightAutodetectedConesSwitchLabel.Text = 'Highlight Autodetected Cones';
            app.HighlightAutodetectedConesSwitch = uiswitch(app.MarksPropertiesPanel, 'slider');
            app.HighlightAutodetectedConesSwitch.ValueChangedFcn = createCallbackFcn(app, @HighlightAutodetectedConesSwitchValueChanged, true);
            app.ShowMarkedLocationsSwitchLabel = uilabel(app.MarksPropertiesPanel);
            app.ShowMarkedLocationsSwitchLabel.Text = 'Show Marked Locations';
            app.ShowMarkedLocationsSwitch = uiswitch(app.MarksPropertiesPanel, 'slider');
            app.ShowMarkedLocationsSwitch.ValueChangedFcn = createCallbackFcn(app, @ShowMarkedLocationsSwitchValueChanged, true);
            app.ShowMarkedLocationsSwitch.Value = 'On';
            app.EditConeLocationsPanel = uipanel(app.ConeFinderUIFigure);
            app.EditConeLocationsPanel.Title = 'Edit Cone Locations';
            app.EditModeSwitchLabel = uilabel(app.EditConeLocationsPanel);
            app.EditModeSwitchLabel.Text = 'Edit Mode';
            app.EditModeSwitch = uiswitch(app.EditConeLocationsPanel, 'slider');
            app.EditModeSwitch.ValueChangedFcn = createCallbackFcn(app, @EditModeSwitchValueChanged, true);
            app.ChangeBoxSizeButton = uibutton(app.EditConeLocationsPanel, 'push');
            app.ChangeBoxSizeButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeBoxSizeButtonPushed, true);
            app.ChangeBoxSizeButton.Text = 'Change Box Size';
            app.DeletedSelectedConesButton = uibutton(app.EditConeLocationsPanel, 'push');
            app.DeletedSelectedConesButton.ButtonPushedFcn = createCallbackFcn(app, @DeletedSelectedConesButtonPushed, true);
            app.DeletedSelectedConesButton.Text = 'Deleted Selected Cones';
            app.AddConelocsOutsideBoxButton = uibutton(app.EditConeLocationsPanel, 'push');
            app.AddConelocsOutsideBoxButton.ButtonPushedFcn = createCallbackFcn(app, @AddConelocsOutsideBoxButtonPushed, true);
            app.AddConelocsOutsideBoxButton.Text = 'Add Conelocs Outside Box';
            app.AddConelocsInBoxButton = uibutton(app.EditConeLocationsPanel, 'push');
            app.AddConelocsInBoxButton.ButtonPushedFcn = createCallbackFcn(app, @AddConelocsInBoxButtonPushed, true);
            app.AddConelocsInBoxButton.Text = 'Add Conelocs In Box';
            app.HelpTextAreaLabel = uilabel(app.EditConeLocationsPanel);
            app.HelpTextAreaLabel.Text = 'Help';
            app.HelpTextArea = uitextarea(app.EditConeLocationsPanel);
            app.HelpTextArea.Editable = 'off';
            app.HelpTextArea.Value = {'HOTKEYS WORKS ONLY IN EDIT MODE!!!!!'; ''; 'Mouse Left Click - place Mark'; 'Mouse Right Click - remove Mark'; '''+'' - zoom in'; '''-'' - zoom out'; '↓ ↑  ← → - pan image'; 'Esc - turn off Edit Mode'};
            app.GridPanel = uipanel(app.ConeFinderUIFigure);
            app.GridPanel.Title = 'Grid';
            app.ShowGridSwitchLabel = uilabel(app.GridPanel);
            app.ShowGridSwitchLabel.Text = 'Show Grid';
            app.ShowGridSwitch = uiswitch(app.GridPanel, 'slider');
            app.ShowGridSwitch.ValueChangedFcn = createCallbackFcn(app, @ShowGridSwitchValueChanged, true);
            app.ShowGirdNumbersSwitchLabel = uilabel(app.GridPanel);
            app.ShowGirdNumbersSwitchLabel.Text = 'Show Gird Numbers';
            app.ShowGirdNumbersSwitch = uiswitch(app.GridPanel, 'slider');
            app.ShowGirdNumbersSwitch.ValueChangedFcn = createCallbackFcn(app, @ShowGirdNumbersSwitchValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = ConeFinderApp()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ConeFinderUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ConeFinderUIFigure)
        end
    end
end