classdef ConeFinderApp_v2020b_old_backup < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ConeFinderUIFigure              matlab.ui.Figure
        ComparewithButton               matlab.ui.control.Button
        ImproveCNNResultsPanel          matlab.ui.container.Panel
        ApplyButton                     matlab.ui.control.Button
        CorrectConeLocs2INDEVELOPButton  matlab.ui.control.Button
        ShowLastCorrectionResultSwitch  matlab.ui.control.Switch
        ShowLastCorrectionResultSwitchLabel  matlab.ui.control.Label
        DelDoublesButton                matlab.ui.control.Button
        DelOutsideImageButton           matlab.ui.control.Button
        VoronoiPanel                    matlab.ui.container.Panel
        UseCorretedConesCheckBox        matlab.ui.control.CheckBox
        ShowConeLocationsCheckBox       matlab.ui.control.CheckBox
        VoronoiVNButton                 matlab.ui.control.Button
        StartConeFinderButton           matlab.ui.control.Button
        EditConeLocationsPanel          matlab.ui.container.Panel
        EditCorrectedConesCheckBox      matlab.ui.control.CheckBox
        HelpTextArea                    matlab.ui.control.TextArea
        HelpTextAreaLabel               matlab.ui.control.Label
        AddConelocsOutsideBoxCNNButton  matlab.ui.control.Button
        AddConelocsbyfastpeakfindButton  matlab.ui.control.Button
        DeletedSelectedConesDelButton   matlab.ui.control.Button
        ChangeBoxSizeButton             matlab.ui.control.Button
        EditModeSwitch                  matlab.ui.control.Switch
        EditModeSwitchLabel             matlab.ui.control.Label
        MarksPropertiesPanel            matlab.ui.container.Panel
        ShowMarkedLocationsTSwitch      matlab.ui.control.Switch
        ShowMarkedLocationsTSwitchLabel  matlab.ui.control.Label
        HighlightAutodetectedCones0Switch  matlab.ui.control.Switch
        HighlightAutodetectedCones0SwitchLabel  matlab.ui.control.Label
        ShowMarksOptionsButtonGroup     matlab.ui.container.ButtonGroup
        Autodetect1Button               matlab.ui.control.RadioButton
        Userdetect2Button               matlab.ui.control.RadioButton
        AutoUser3Button                 matlab.ui.control.RadioButton
        ConeMarksTypeButtonGroup        matlab.ui.container.ButtonGroup
        CrossXButton                    matlab.ui.control.RadioButton
        CircleCButton                   matlab.ui.control.RadioButton
        DotZButton                      matlab.ui.control.RadioButton
        ImagePropertiesPanel            matlab.ui.container.Panel
        LapOfGausSwitch                 matlab.ui.control.Switch
        LapOfGausLabel                  matlab.ui.control.Label
        AddMosaicImageButton            matlab.ui.control.Button
        GridPanel                       matlab.ui.container.Panel
        ShowGirdNumbersESwitch          matlab.ui.control.Switch
        ShowGirdNumbersESwitchLabel     matlab.ui.control.Label
        ShowGridQSwitch                 matlab.ui.control.Switch
        ShowGridQSwitchLabel            matlab.ui.control.Label
        BrightnessPanel                 matlab.ui.container.Panel
        ResetBrightnessRButton          matlab.ui.control.Button
        BrightnessDBSlider              matlab.ui.control.Slider
        BrightnessDBSliderLabel         matlab.ui.control.Label
        ShowimageISwitch                matlab.ui.control.Switch
        ShowimageISwitchLabel           matlab.ui.control.Label
        LogImageLSwitch                 matlab.ui.control.Switch
        LogImageLSwitchLabel            matlab.ui.control.Label
        NextMosaicImageButton           matlab.ui.control.Button
        ExitButton                      matlab.ui.control.Button
        SaveCurrentLocationsSPACEButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
         
        % Zoom configuration options
        Magnify = 1.1;
        XMagnify = 1.0;
        YMagnify = 1.0;
        ChangeMagnify = 1.1;
        IncreaseChange = 1.1;
        MinValue = 1.1;
        MaxZoomScrollCount = 30;
        ImgWidth = 0;
        ImgHeight = 0;
        ZoomScrollCount = 0;
        XLim=[];
        YLim=[];
        
        % Shows whether app is Busy
        IsBusy = 0;
    end
    
    properties (Access = public)
        % Files
        Fname;
        Pname;
        
        % Image
        Image;
        Ttl;
        ChangeMosaic;
        Mosaic = 0;
        MultipleMosaics = 0;
        
        % Second Image
        Image2;
        
        % Voronoi
        VColorMap;
        VoronoiPlot = 1;
        PrintConesOnVoronoi = 0;
        UseCorrectedPointsInVoronoi = 0;
        
        % Cone Locations
        Conelocs = [];
        BoxPosition;
        TotalConeCount = 0;
        ConeCount = 0;
        Minlimx = 0;
        Minlimy = 0;
        Maxlimx = 0;
        Maxlimy = 0;
        
        % Second Cone Locations
        Conelocs2 = [];
        BoxPosition2;
        TotalConeCount2 = 0;
        ConeCount2 = 0;
        Minlimx2 = 0;
        Minlimy2 = 0;
        Maxlimx2 = 0;
        Maxlimy2 = 0;
        
        % Mark properties
        ShowMarks = 'On';
        MarkerSize = 2;
        MarkType = '.y';
        ShowConesByDetectType = 0;
        
        % Image properties
        ShowImage = 'On';
        LogImage = 'Off';
        LapOfGaus = 'Off';
        Brightness = 1.0;
        
        % Grid
        ShowGrid = 'Off';
        ShowGridNumbers = 'Off';
        
        
        % Handler of figure window
        FigureHandler;
        ImageShowHandler;
        ScrollPanelHandler;
        MagBoxHandler;
        ScrollPanelApi;
        IsClosing = 0;
        
        % Cone locs correction
        CorrectionRegionOfInterest = [-1 -1];
        CorrectedPoints = [];
        
        % Compare images
        IsComparing = 0;
        
        % Edit Mode
        EditCorrectedCones = 0;
    end
    
    methods (Access = public)
        
        % Marks cone on image
        function MarkCone(app, x, y)
            % CNN generates more exact cone
            % locations anyway
            app.Conelocs(app.TotalConeCount+1,1) = x;
            app.Conelocs(app.TotalConeCount+1,2) = y;
            if x < app.Minlimx || x > app.Maxlimx || y < app.Minlimy || y > app.Maxlimy
                app.Conelocs(app.TotalConeCount+1,3) = 3;
            else
                app.Conelocs(app.TotalConeCount+1,3) = 2;
            end
            
            app.Conelocs = unique(app.Conelocs, 'rows', 'stable');
            app.ConeCount = size(app.Conelocs(app.Conelocs(:,3)~=0,1),1);
            app.TotalConeCount = size(app.Conelocs,1);
            
            UpdateImage(app);
            UpdateLegacy(app);
        end
        
        % Removes Mark from image
        function RemoveMark(app, x, y)
            % Clear marked cone nearest to click location
            if (size(app.Conelocs,1)>0)
                currentloc = [x y];
                % compute Euclidean distances:
                distances = sqrt(sum(bsxfun(@minus, app.Conelocs(:,1:2), currentloc).^2,2));
                
                correctedDistances = [];
                correctedConesIsEditable = app.EditCorrectedCones && size(app.CorrectedPoints,2) == 2;
                if correctedConesIsEditable
                    correctedDistances = sqrt(sum(bsxfun(@minus, app.CorrectedPoints, currentloc).^2,2));
                end
                
                % find the smallest distance and delete that index in app.Conelocs:
                if correctedConesIsEditable && ...
                   min(distances) > min(correctedDistances)
                    app.CorrectedPoints(correctedDistances==min(correctedDistances),:)=[];
                else
                    app.Conelocs(distances==min(distances),:)=[];
                    app.Conelocs = unique(app.Conelocs, 'rows', 'stable');
                    app.ConeCount = size(app.Conelocs(app.Conelocs(:,3)~=0,1),1);
                    app.TotalConeCount = size(app.Conelocs,1);
                end
                
                % After deletion, plot current set of cones
                UpdateImage(app);
                UpdateLegacy(app);
            end
        end
        
        % Saves current cone locations to a .mat file
        function SaveCurrentLocations(app)
            if app.MultipleMosaics
                app.Image = app.ChangeMosaic;
            end
            
            [pstr, nm, ext] = fileparts([app.Pname app.Fname]);
            
            if strcmp(ext,'.tiff')==1 || strcmp(ext,'.tif')==1
                name1 = [pstr filesep nm '_annotated.png'];
                name2 = [pstr filesep nm '_annotated.mat'];
            elseif strcmp(ext,'.mat')==1
                name1 = [pstr filesep nm '.png'];
                name2 = [app.Pname app.Fname];
            end
            
            if ~app.IsClosing
                print(app.FigureHandler, name1, '-dpng');
            end
            
            % names of variables must be the same to names in 'save' function
            conecount = app.ConeCount;
            I = app.Image;
            conelocs = app.Conelocs;
            boxposition = app.BoxPosition;
            multiple_mosaics = app.MultipleMosaics;
            save(name2, 'conecount', 'I', 'conelocs', 'boxposition', 'multiple_mosaics');
            
            app.Ttl.BackgroundColor = [0 1 0];
            
            if app.MultipleMosaics
                app.Image = app.ChangeMosaic{1};
            end
        end
        
        % Zooms In image
        function ZoomIn(~)
            ax = axis;
            width=ax(2)-ax(1);
            height=ax(4)-ax(3);
            axis([ax(1)-width/3 ax(2)+width/3 ax(3)-height/3 ax(4)+height/3]);
            zoom(1/2);
        end
        
        % Zooms Out image
        function ZoomOut(~, x, y)
            ax = axis;
            width=ax(2)-ax(1);
            height=ax(4)-ax(3);
            axis([x-width/2 x+width/2 y-height/2 y+height/2]);
            zoom(2);
        end
        
        % Moves image to left
        function PanLeft(~)
            ax = axis;
            width=ax(2)-ax(1);
            increment = round(width/20);
            axis([ax(1)-increment ax(2)-increment ax(3) ax(4)]);
        end
        
        % Moves image to right
        function PanRight(~)
            ax = axis;
            width=ax(2)-ax(1);
            increment = round(width/20);
            axis([ax(1)+increment ax(2)+increment ax(3) ax(4)]);
        end
        
        % Moves image to up
        function PanUp(~)
            ax = axis;
            height=ax(4)-ax(3);
            increment = round(height/20);
            axis([ax(1) ax(2) ax(3)-increment ax(4)-increment]);
        end
        
        % Moves image to left
        function PanDown(~)
            ax = axis;
            height=ax(4)-ax(3);
            increment = round(height/20);
            axis([ax(1) ax(2) ax(3)+increment ax(4)+increment]);
        end
        
        % Updates Image ------------------------------------------------------------
        function UpdateImage(app)
            % Clean all objects from Axes, except Image
            delete(findobj(gca, '-not', 'Type', 'Image', '-and', '-not', 'Type', 'Axes'));
            
            % -------------------------------
            if ~isempty(app.Image2)
                subplot(1,2,1);
            end
            % -------------------------------
            
            PrintImage(app, app.Image);
            
            % Print cone locations
            PlotConelocs(app, app.BoxPosition, app.Conelocs);
            
            if strcmp(app.ShowGrid, 'On')
                % Print Grid
                PrintGrid(app, app.Image);
            end
            
            if strcmp(app.ShowLastCorrectionResultSwitch.Value, 'On')
                PrintCorrectionResult(app);
            end
            % -------------------------------
            if ~isempty(app.Image2)
                subplot(1,2,2);
                
                PrintImage(app, app.Image2);
                
                % Print cone locations
                PlotConelocs(app, app.BoxPosition2, app.Conelocs2);
                
                if strcmp(app.ShowGrid, 'On')
                    % Print Grid
                    PrintGrid(app, app.Image2);
                end
                
                UpdateLegacy(app);
            end
            % -------------------------------
            
            CreateScrollMinimap(app);
        end
        
        % Prints image
        function PrintImage(app, image)
            blank = zeros(size(image));
            
            if strcmp(app.ShowImage, 'Off')
                % Print blank image
                if isempty(app.ImageShowHandler)
                    cla; app.ImageShowHandler = imshow(blank); hold on;
                else
                    app.ImageShowHandler.CData = blank;
                end
            else
                % Get image with given brightness
                J = imadjust(image,[],[],app.Brightness);
                
                if strcmp(app.LogImage, 'On')
                    % Get log image
                    J = GetLogImage(app, J);
                end
                
                if strcmp(app.LapOfGaus, 'On')
                    % Get Laplacian of Gaussian filter
                    J = GetLapOfGausImage(app, J);
                end
                
                % Print image
                if isempty(app.ImageShowHandler)
                    cla; app.ImageShowHandler = imshow(J); hold on;
                else
                    app.ImageShowHandler.CData = J;
                end
            end
        end
        
        % Gets log10 image
        function image = GetLogImage(~, Image)
            R = max(max(Image));
            c = 255 / (log10(double(1 + R)));
            Image = c * log10(double(Image + 1));
            image = uint8(Image);
        end
        
        % Gets Laplacian of Gaussian image
        function image = GetLapOfGausImage(~, Image)
            filter = fspecial('log',5, 0.35);
            image = imfilter(Image, filter);
        end
        
        % Prints last correction result
        function PrintCorrectionResult(app)
            patch('XData', app.CorrectionRegionOfInterest(:, 1), 'YData', app.CorrectionRegionOfInterest(:, 2), ...
                'EdgeColor','green','FaceColor','none','LineWidth',2);
            if ~isempty(app.CorrectedPoints)
                plot(app.CorrectedPoints(:,1),app.CorrectedPoints(:,2), 'or', 'MarkerSize', app.MarkerSize);
            end
        end
        
        % Prints cone locations
        function PlotConelocs(app, boxPosition, conelocs)
            rectangle('Position', boxPosition,'EdgeColor',[1 0 1]);
            
            if strcmp(app.ShowMarks, 'On')
                switch app.ShowConesByDetectType
                    case 1
                        usercones = conelocs(conelocs(:,3)==2,1:2);
                        plot(usercones(:,1),usercones(:,2), app.MarkType, 'MarkerSize', app.MarkerSize);
                        
                    case 2
                        autocones = conelocs(conelocs(:,3)==1,1:2);
                        plot(autocones(:,1),autocones(:,2),'.m')
                        
                    otherwise
                        if strcmp(app.HighlightAutodetectedCones0Switch.Value, 'Off')
                            plot(conelocs(conelocs(:,3)~=0,1), conelocs(conelocs(:,3)~=0,2), app.MarkType, 'MarkerSize', app.MarkerSize);
                        else
                            usercones = conelocs(conelocs(:,3)==2,1:2);
                            autocones = conelocs(conelocs(:,3)==1,1:2);
                            plot(autocones(:,1),autocones(:,2),'.m');
                            plot(usercones(:,1),usercones(:,2), app.MarkType, 'MarkerSize', app.MarkerSize);
                        end
                end
            end
        end
        
        % Prints Grid
        function PrintGrid(app, image)
            % Get sizes
            [originalSizeY, originalSizeX, ~] = size(image);
            
            % Calculation like during CNN cone recogtion
            newSize = 150;
            
            % calculate minimum numer of cutouts to produce
            cutoutsINrowX = ceil(originalSizeX/newSize); 
            cutoutsINrowY = ceil(originalSizeY/newSize);

            offsetX = floor(cutoutsINrowX*newSize-originalSizeX)/(cutoutsINrowX-1);

            while offsetX < 20
                cutoutsINrowX = cutoutsINrowX+1;
                % offset which will be applied for a fix number of cutouts in a row/column
                offsetX = round((cutoutsINrowX*newSize-originalSizeX)/(cutoutsINrowX-1));
            end
            
            offsetY = floor(cutoutsINrowY*newSize-originalSizeY)/(cutoutsINrowY-1);

            while offsetY < 20
                cutoutsINrowY = cutoutsINrowY+1;
                % offset which will be applied for a fix number of cutouts in a row/column
                offsetY = round((cutoutsINrowY*newSize-originalSizeY)/(cutoutsINrowY-1));
            end

            cutoutsINrowX = cutoutsINrowX + 1;
            cutoutsINrowY = cutoutsINrowY + 1;
            stepX = newSize-1-offsetX;
            linesCols = [1 newSize-offsetX : stepX : ((newSize-1)*cutoutsINrowX-offsetX*cutoutsINrowX)];

            
%             positions_endX = linesCols +(newSize-1);
%             positions_endX = [positions_endX;positions_endX];
            linesCols = [linesCols;linesCols];
            stepY = newSize-1-offsetY;
            linesRows = [1 newSize-offsetY : stepY : ((newSize-1)*cutoutsINrowY-offsetY*cutoutsINrowY)];
%             positions_endY = linesRows +(newSize-1);
%             positions_endY  = [positions_endY ;positions_endY ];
            linesRows = [linesRows;linesRows];

            % End of calculation like in CNN recognition
            
            
%             rowsNumber = floor(rows / 115);
%             stepX = floor(rows / rowsNumber);
%             
%             colsNumber = floor(columns / 115);
%             stepY = floor(columns / colsNumber);
%             
%             % Get grid
%             linesRows1 = [stepX:stepX:(rows - stepX); stepX:stepX:(rows - stepX)];
%             linesCols1 = [stepY:stepY:(columns - stepY); stepY:stepY:(columns - stepY)];
            colsX = [zeros(size(linesRows,2),1)' + 1; zeros(size(linesRows,2),1)' + originalSizeX];
            colsY = [zeros(size(linesCols,2),1)' + 1; zeros(size(linesCols,2),1)' + originalSizeY];

            % Print verticals
            line(colsX, linesRows, 'Color', 'w');
            % Print horizontals
            line(linesCols, colsY, 'Color', 'w');
            
            
%             line(colsX, positions_endY, 'Color', 'w');
%             line(positions_endX, colsY, 'Color', 'w');
            
            if strcmp(app.ShowGridNumbers, 'On')
                startX = 20;
                x = startX : stepX : originalSizeX - newSize / 2;
                
                startY = 20;
                y = startY : stepY : originalSizeY - newSize / 2;
                sx = size(x, 2);
                sy = size(y, 2);
                
                y = repmat(y, 1, sx);
                x = repelem(x, sy);
                textArray = arrayfun(@(a)num2str(a), (y-startY)/stepY*sx + (x-startX)/stepX, 'uni', 0);
                
                % Print numbers in each cell
                textGridNumbers = text(x, y, textArray, 'Color', 'w');
                set (textGridNumbers, 'Clipping', 'on');
            end
        end
        
        function UpdateLegacy(app)
            if ~isempty(app.Image2)
                % plot images
                subplot(1,2,1);
                ttl1 = title({'Source image', [num2str(app.ConeCount),' cones']}, 'Interpreter','none');
                ttl1.BackgroundColor = [1 0 0];
                
                subplot(1,2,2);
                ttl2 = title({'Image to compare'; [num2str(app.ConeCount2),' cones']}, 'Interpreter','none');
                ttl2.BackgroundColor = [1 0 0];
            else
                [~,  Num_mosaics] = size(app.ChangeMosaic);
                app.Ttl.BackgroundColor = [1 0 0];
                title([app.Fname,'  ',num2str(size(app.Conelocs(app.Conelocs(:,3)~=0,1),1)),' cones', '  mosaic ', num2str(app.Mosaic), '/', num2str(Num_mosaics)],'Interpreter','none');
            end
        end
        % Updates Image ------------------------------------------------------------
        
        % Updates selection box
        function UpdateBox(app, old_boxposition)
            SetBox(app);
            
            minlimx_old = old_boxposition(1);
            minlimy_old = old_boxposition(2);
            maxlimx_old = minlimx_old + old_boxposition(3);
            maxlimy_old = minlimy_old + old_boxposition(4);
            
            app.Conelocs(app.Conelocs(:,1)>app.Minlimx & app.Conelocs(:,1)<=minlimx_old,3)= 1;
            app.Conelocs(app.Conelocs(:,1)<app.Maxlimx & app.Conelocs(:,1)>=maxlimx_old,3)= 1;
            app.Conelocs(app.Conelocs(:,2)>app.Minlimy & app.Conelocs(:,2)<=minlimy_old,3)= 1;
            app.Conelocs(app.Conelocs(:,2)<app.Maxlimy & app.Conelocs(:,2)>=maxlimy_old,3)= 1;
            
            app.Conelocs(app.Conelocs(:,1)<=app.Minlimx,3)= 0;
            app.Conelocs(app.Conelocs(:,1)>=app.Maxlimx,3)= 0;
            app.Conelocs(app.Conelocs(:,2)<=app.Minlimy,3)= 0;
            app.Conelocs(app.Conelocs(:,2)>=app.Maxlimy,3)= 0;
            
            app.Conelocs = unique(app.Conelocs, 'rows', 'stable');
            app.ConeCount = size(app.Conelocs(app.Conelocs(:,3)~=0,1),1);
            app.TotalConeCount = size(app.Conelocs,1);
            
            UpdateImage(app);
            
            ax = gca; app.Ttl = ax.Title; app.Ttl.FontSize = 14;
            UpdateLegacy(app);
        end
        
        % Updates User Interface
        function UpdateUI(app)
            isDisabled = 'off';
            if app.VoronoiPlot
                isDisabled = 'on';
            end
            
            editPanelDisabled = isDisabled;
            mainButtonsDisabled = 'on';
            if strcmp(app.EditModeSwitch.Value,'On')
                editPanelDisabled = 'off';
                mainButtonsDisabled = 'off';
            end
            
            if app.IsComparing
                editPanelDisabled = 'off';
            end
            
            correctionResultDisabled = isDisabled;
            if app.EditCorrectedCones
                app.UseCorrectedPointsInVoronoi = 1;
                app.UseCorretedConesCheckBox.Value = 1;
                app.ShowLastCorrectionResultSwitch.Value = 'On';
                correctionResultDisabled = 'off';
            end
            
            
            set(findall(app.EditConeLocationsPanel, '-property', 'Enable'), 'Enable', editPanelDisabled);
            set(findall(app.MarksPropertiesPanel, '-property', 'Enable'), 'Enable', isDisabled);
            set(findall(app.GridPanel, '-property', 'Enable'), 'Enable', isDisabled);
            set(findall(app.ImagePropertiesPanel, '-property', 'Enable'), 'Enable', isDisabled);
            set(findall(app.ImproveCNNResultsPanel, '-property', 'Enable'), 'Enable', editPanelDisabled);
            set(findall(app.VoronoiPanel, '-property', 'Enable'), 'Enable', mainButtonsDisabled);
            
            app.StartConeFinderButton.Enable = mainButtonsDisabled;
            app.ShowConeLocationsCheckBox.Enable = isDisabled;
            
            app.UseCorretedConesCheckBox.Enable = correctionResultDisabled;
            app.ShowLastCorrectionResultSwitch.Enable = correctionResultDisabled;
            
            app.VoronoiVNButton.Enable = mainButtonsDisabled;
            app.ExitButton.Enable = mainButtonsDisabled;
            app.SaveCurrentLocationsSPACEButton.Enable = mainButtonsDisabled;
            app.ComparewithButton.Enable = mainButtonsDisabled;
            
            app.NextMosaicImageButton.Enable = editPanelDisabled;
            app.AddMosaicImageButton.Enable = editPanelDisabled;
        end
        
        % ----------------------- Voronoi Diagram -------------------------------------
        % Get color for poygon by Number of Neighbours
        function color = GetColorNN(~, logicC, numNeighborsJ, minNeighbors, maxNeighbors, neighcolors)
            if logicC
                if numNeighborsJ <= minNeighbors
                    color = neighcolors(1,:);
                elseif numNeighborsJ > maxNeighbors
                    color = neighcolors(end,:);
                elseif numNeighborsJ > minNeighbors
                    color = neighcolors(numNeighborsJ - minNeighbors,:);
                end
            else
                color = [];
            end
        end
        
        % Get color for poygon by Cone Area
        function color = GetColorCA(~, logicC, coneAreaJ, max_area_pixel, idxMinMax, neighcolors)
            if logicC
                if coneAreaJ <= max_area_pixel
                    [~, pos] = min(abs(idxMinMax - coneAreaJ));
                    color = neighcolors(pos,:);
                else
                    color = neighcolors(end,:);
                end
            else
                color = [];
            end
        end
        
        % Get coordinate if it is between min/max
        function coord = GetCoord(~, coordinate, min, max)
            if coordinate > max || coordinate < min
                coord = NaN;
            else
                coord = coordinate;
            end
        end
        
        % Print Voronoi diagram
        function PrintVoronoiByType(app, voronoiType, conelocs, correctedConelocs)
            numberOfClosedPolygons = 0;
            vorocones = conelocs(conelocs(:,3)~=0,1:2);
            vorocones = unique(vorocones, 'rows', 'stable');
            vorocones = [vorocones; correctedConelocs];
            if size(vorocones,1)>3
                %%%%%%%%%%%%%% PREPARE THE DATA %%%%%%%%%%%%%%
                % vorocones = conelocs(conelocs(:,3)~=0,1:2);
                % vorocones = unique(vorocones, 'rows', 'stable');
                
                dt = delaunayTriangulation(vorocones);
                
                if isempty(dt)
                    return;
                end
                
                % V - verticies in polygon, C - the polygon around V
                [V,C] = voronoiDiagram(dt);
                % vorocones(:,1:2) - equal to vorocones, because vorocones n*2, where columns is x and y coordinate, rows is verticies
                
                if strcmp(voronoiType, 'Number of Neighbors')
                    min_neighbors = 4;
                    max_neighbors = 9;
                    numcolorsNN = max_neighbors - min_neighbors;  % ********* change from 15
                    
                    % corr_factorNN = numcolorsNN/max_neighbours; % to represent whole colormap over values
                    
                    idxNN = round(linspace(1, 256, numcolorsNN));
                    neighcolorsNN = app.VColorMap(idxNN,:);
                    neighcolorsNN = flipud(neighcolorsNN);
                    
                    NumNeighbors = arrayfun(@(a) size(C{a},2), 1 : length (C));
                    
                elseif strcmp(voronoiType, 'Cone Area') % VARIANTE 2: Falschfarben sind PatchgrцЯe
                    numcolors = 25;  % ********* change from 15
                    max_area_pixel = 80;        % basis for dividing cone Area through numcolors
                    
                    idx = round(linspace(1,256,numcolors));
                    neighcolors = app.VColorMap(idx,:);
                    neighcolors = flipud(neighcolors);
                    
                    ConeArea = arrayfun(@(a) polyarea(V(C{a},1),V(C{a},2)), 1 : length (C));
                    idxMinMax = round(linspace(min(ConeArea) ,max_area_pixel, numcolors));
                end
                
                % Get min and max coordinates of cone locations
                minX = min(vorocones(:,1));
                minY = min(vorocones(:,2));
                maxX = max(vorocones(:,1));
                maxY = max(vorocones(:,2));
                
                % Remove 'peek' coordinates of voronoi
                V(:,1) = arrayfun(@(a) app.GetCoord(a, minX, maxX), V(:,1));
                V(:,2) = arrayfun(@(a) app.GetCoord(a, minY, maxY), V(:,2));
                
                %%%%%%%%%%%%%% PRINT THE DIAGRAM %%%%%%%%%%%%%%
                % Find closed polygons in C (0 - open polygon, 1 - closed polygon)
                logicC = cellfun(@(a) all(a~=1), C, 'UniformOutput', true);
                
                % Find colors for closed polygones by voronoiType
                % VARIANTE 1: Falschfarben sind Anzahl Nachbarn
                if strcmp(voronoiType, 'Number of Neighbors')
                    colors = arrayfun(@(a) app.GetColorNN(logicC(a), NumNeighbors(a), min_neighbors, max_neighbors, neighcolorsNN), 1 : length(C), 'UniformOutput', false);
                % VARIANTE 2: Falschfarben sind PatchgrцЯe
                elseif strcmp(voronoiType, 'Cone Area')
                    colors = arrayfun(@(a) app.GetColorCA(logicC(a), ConeArea(a), max_area_pixel, idxMinMax, neighcolors), 1 : length(C), 'UniformOutput', false);
                end
                % Remove empty colors
                testColors = cellfun(@(a) ~isempty(a), colors, 'UniformOutput', true);
                colors = colors(testColors);
                % Make matrix from cell array of vectors
                colorsTest = vertcat(colors{:});
                
                % Remove open polygones
                C = C(logicC);
                numberOfClosedPolygons = length(C);
                % Create a matrix from list of Verice inedexes (empty values filled by NaN)
                lengthesC = cellfun('length',C);
                cellLengthesC = num2cell(lengthesC);
                maxLengthesC = max(lengthesC);
                batchC = cellfun(@(v,n) [v, nan(1, maxLengthesC-n)], C, cellLengthesC, 'UniformOutput', false);
                matrixC = vertcat(batchC{:});
                
                % Plot the diagram
                patch('Faces', matrixC,'Vertices', V, 'FaceVertexCData', colorsTest, 'FaceColor', 'flat');
                
            end
            
            % Print Basic Voronoi Diagram if there are no closed polygons
            if numberOfClosedPolygons == 0
                % vorocones = conelocs(conelocs(:,3)~=0,1:2);
                % vorocones = unique(vorocones, 'rows', 'stable');
                x = vorocones(:,1);
                y = vorocones(:,2);
                [vx, vy] = voronoi(x, y);
                plot(x,y,'r+',vx,vy,'b-');
            end
            
            if app.PrintConesOnVoronoi
                plot(vorocones(:, 1), vorocones(:, 2), '.r', 'MarkerSize', 3);
            end
            
            if app.UseCorrectedPointsInVoronoi
                patch('XData', app.CorrectionRegionOfInterest(:, 1), 'YData', app.CorrectionRegionOfInterest(:, 2), ...
                'EdgeColor','red','FaceColor','none','LineWidth',2);
            end
        end
        
        % ----------------------------- Mosaic ----------------------------
        % Load mosaic imagesapp.CorrectionRegionOfInterest
        function LoadMosaic(app, refImageName, fnames, startFileNumber, endFileNumber)
            refframe = imread([app.Pname refImageName]);
            
            pad = 100;
            
            rect1 = [200,200,201,201];
            rect2 = [312,200,201,201];
            rect3 = [200,312,201,201];
            rect4 = [312,312,201,201];
            
            [fheight, fwidth] = size(refframe);
            % rect is [start x coordinate, y coordinate, width, height]
            refcrop1 = imcrop(refframe,rect1);
            refcrop2 = imcrop(refframe,rect2);
            refcrop3 = imcrop(refframe,rect3);
            refcrop4 = imcrop(refframe,rect4);
           
            for nf = startFileNumber:endFileNumber
                index = nf - startFileNumber + 1;
                frame = imread([app.Pname fnames{index}]);
                % rect is [start x coordinate, y coordinate, width, height]
                crop1 = imcrop(frame,rect1);
                crop2 = imcrop(frame,rect2);
                crop3 = imcrop(frame,rect3);
                crop4 = imcrop(frame,rect4);
                
                [shift1, ~] = dftregistration(fft2(refcrop1),fft2(crop1),10);
                [shift2, ~] = dftregistration(fft2(refcrop2),fft2(crop2),10);
                [shift3, ~] = dftregistration(fft2(refcrop3),fft2(crop3),10);
                [shift4, ~] = dftregistration(fft2(refcrop4),fft2(crop4),10);
                
                shifts(:,:,index) = [shift1(3:4);shift2(3:4);shift3(3:4);shift4(3:4);];
                meanshift(index,:) = mean(shifts(:,:,index));
                deviation(index) = mean(std(shifts(:,:,index)));
                
                f = double(zeros(fheight+(pad*2),fwidth+(pad*2)));
                f(pad+1:(fheight+pad),pad+1:(fwidth+pad))=frame;
                
                deltar = -meanshift(index,1);
                deltac = -meanshift(index,2);
                phase = 2;
                [nr,nc]=size(f);
                Nr = ifftshift((-fix(nr/2):ceil(nr/2)-1));
                Nc = ifftshift((-fix(nc/2):ceil(nc/2)-1));
                [Nc,Nr] = meshgrid(Nc,Nr);
                g = abs(ifft2(fft2(f).*exp(1i*2*pi*(deltar*Nr/nr+deltac*Nc/nc))).*exp(-1i*phase));
                g = g./max(g(:));
                
                app.ChangeMosaic{nf} = g(101:812,101:812);
                
                clear f deltar deltac Nc Nr nc nr g
            end
        end
        
        % ------------------------- Find Cones ------------------------------
        % Finds cone locations by FastPeakFind algorithm
        function FastPeakFinder(app)
            p = FastPeakFind(app.Image);
            app.Conelocs(:,1) = p(1:2:end);
            app.Conelocs(:,2) = p(2:2:end);
            app.Conelocs(:,3) = 1;
            
            app.Conelocs(app.Conelocs(:,1)<=app.Minlimx,3)= 0;
            app.Conelocs(app.Conelocs(:,1)>=app.Maxlimx,3)= 0;
            app.Conelocs(app.Conelocs(:,2)<=app.Minlimy,3)= 0;
            app.Conelocs(app.Conelocs(:,2)>=app.Maxlimy,3)= 0;
            
            app.Conelocs = unique(app.Conelocs, 'rows', 'stable');
            
            plot(app.Conelocs(app.Conelocs(:,3)~=0,1), app.Conelocs(app.Conelocs(:,3)~=0,2), app.MarkType, 'MarkerSize', app.MarkerSize)
            app.ConeCount = size(app.Conelocs(app.Conelocs(:,3)~=0,1),1);
            app.TotalConeCount = size(app.Conelocs,1);
        end
        
        % Find cone locations by CNN
        function CnnFinder(app, cnnFloderName, cnnCalcType)
            % DataSet = '14111';  % dataSet specifies trained CNN (grader 1 trained network - 14(x16) training images and 1(x16) validation image)
            % DataSet = '42';  % dataSet specifies trained CNN (grader 1 14(x16)training + 100 cunefare val images and 1(x16) validation g1 + 16 cunefare training images)
           
            fn = {'g1+cunefare', 'alex training set'};
            [indx,tf] = listdlg('Name', 'Cone Finder Data Sets', 'PromptString', {'Select a cone finder data set.'},...
                'SelectionMode','single','ListString', fn);
            
            % if user clicked 'Cancel'
            if ~tf
                DataSet = 'alex training set';
            else
                switch indx
                    case 1 % 'g1+cunefare'
                        DataSet = 'g1+cunefare';
                        
                    case 2 % 'alex training set'
                        DataSet = 'alex training set';
                end % switch
            end
            
            ImageDir = [app.Pname app.Fname];
            tic;
            startTimeCNN = clock;
            fprintf('startTimeCNN: %s \n', sprintf('%g ', startTimeCNN));
            [app.Conelocs] = RunCNNnewSet_automatic(DataSet, ImageDir, app.BoxPosition, app.Image, cnnCalcType, cnnFloderName);
            endTimeCNN = clock;
            fprintf('endTimeCNN: %s \n', sprintf('%g ', endTimeCNN));
            toc;
            disp(' ');
            disp(' ');
            
            app.Conelocs = unique(app.Conelocs,'rows', 'stable');
            app.Conelocs(:,3) = 1;
            
            app.Conelocs(app.Conelocs(:,1)<=app.Minlimx,3)= 0;
            app.Conelocs(app.Conelocs(:,1)>=app.Maxlimx,3)= 0;
            app.Conelocs(app.Conelocs(:,2)<=app.Minlimy,3)= 0;
            app.Conelocs(app.Conelocs(:,2)>=app.Maxlimy,3)= 0;
            
            app.Conelocs = unique(app.Conelocs, 'rows', 'stable');
            
            plot(app.Conelocs(app.Conelocs(:,3)~=0,1), app.Conelocs(app.Conelocs(:,3)~=0,2), app.MarkType, 'MarkerSize', app.MarkerSize)
            app.ConeCount = size(app.Conelocs(app.Conelocs(:,3)~=0,1),1);
            app.TotalConeCount = size(app.Conelocs,1);
        end
        
        % Loads Data
        function LoadData(app)
            load([app.Pname app.Fname], 'I', 'conelocs', 'boxposition', 'multiple_mosaics');
            app.Image = I;
            app.Conelocs = conelocs;
            app.BoxPosition = boxposition;
            app.MultipleMosaics = multiple_mosaics;
            
            SetBox(app);
            
            app.Conelocs = unique(app.Conelocs, 'rows', 'stable');
            
            if size(app.Conelocs, 2) < 3
                app.Conelocs(:, 3) = 1;
                app.Conelocs(app.Conelocs(:,1)<=app.Minlimx,3)= 0;
                app.Conelocs(app.Conelocs(:,1)>=app.Maxlimx,3)= 0;
                app.Conelocs(app.Conelocs(:,2)<=app.Minlimy,3)= 0;
                app.Conelocs(app.Conelocs(:,2)>=app.Maxlimy,3)= 0;
            end
            
            app.ConeCount = size(app.Conelocs(app.Conelocs(:,3)~=0,1),1);
            app.TotalConeCount = size(app.Conelocs,1);
            
            if app.MultipleMosaics == 1
                app.ChangeMosaic = app.Image;
                app.Mosaic = 1;
                app.Image = app.ChangeMosaic{1};
            else
                app.ChangeMosaic = app.Image;
                app.Mosaic = 1;
            end
            
            app.MarkerSize = 4;
            
            CreateFigure(app);

            title([app.Fname,'  ',num2str(app.ConeCount),' cones'],'Interpreter','none'), hold on
            ax = gca;                    % get the current axis
            app.Ttl = ax.Title;              % get the title text object
            app.Ttl.FontSize = 14;
            app.Ttl.BackgroundColor = [1 0 0];
        end
        
        function SetBox(app)
            app.Minlimx = app.BoxPosition(1);
            if app.Minlimx < 0
                app.Minlimx = 0;
            end
            
            app.Minlimy = app.BoxPosition(2);
            if app.Minlimy < 0
                app.Minlimy = 0;
            end
            
            [h, w, ~] = size(app.Image);
            app.Maxlimx = app.Minlimx + app.BoxPosition(3);
            if app.Maxlimx > w
                app.Maxlimx = w;
            end
            
            app.Maxlimy = app.Minlimy + app.BoxPosition(4);
            if app.Maxlimy > h
                app.Maxlimy = h;
            end
        end
        
        % Removes Cones outside fovea image (on black boards)
        function RemoveBlackCones(app)
            conelocs = app.Conelocs(:, 1:2);
            image = app.Image;
            sizeImage = size(image);
            conelocsY = int64(conelocs(:,1));
            conelocsX = int64(conelocs(:,2));
            values = app.Image(sub2ind(sizeImage, conelocsX', conelocsY'));
            app.Conelocs = app.Conelocs(values > 1, :);
            app.ConeCount = size(app.Conelocs(app.Conelocs(:,3)~=0,1),1);
            app.TotalConeCount = size(app.Conelocs,1);
            UpdateLegacy(app);
        end
        
        function RemoveDoubleCones(app)
%             offset = 10;
%             [rows, columns, ~] = size(app.Image);
%             x = 0;
%             y = 0;
%             while x <= columns
%                 x = x + 150;
%                 y = 0;
%                 while y <= rows
%                     y = y + 150;
%                     conelocsY = app.Conelocs(:,1);
%                     conelocsX = app.Conelocs(:,2);
%                 end
%             end
            conelocsY = app.Conelocs(:,1);
            conelocsX = app.Conelocs(:,2);
            R_all = [conelocsX, conelocsY];
            Df = 1;
            
            % calculate distance matrix for every point pair
            dist = pdist2(R_all, R_all); % sqrt(sum((A-B).^2 , 3));
            
            % mask the distance to the point itself and to all previous points
            dist(1:(1+size(dist,1)):end) = inf;
            dist(logical(triu(ones(size(dist))))) = inf;
            % example
            % 0 0 0 0
            % d 0 0 0
            % d d 0 0
            % d d d 0
            
            L = dist<1.5*Df;
            L = any(L,1);
            
            app.Conelocs(L,:) = [];
            app.ConeCount = size(app.Conelocs(app.Conelocs(:,3)~=0,1),1);
            app.TotalConeCount = size(app.Conelocs,1);
            
            UpdateLegacy(app);
        end
        
        function CreateFigure(app)
           app.FigureHandler = figure;
           app.ImageShowHandler = imshow(app.Image);
        end
        
        function CreateScrollMinimap(app)
             if isempty(app.ScrollPanelHandler)
               app.ScrollPanelHandler = imscrollpanel(app.ImageShowHandler.Parent.Parent, app.ImageShowHandler); % Handle to scroll panel.
               set(app.ScrollPanelHandler,'Units','normalized',...
                   'Position',[0 .1 1 .9])
            
               % Add a Magnification Box and an Overview tool.
               app.MagBoxHandler = immagbox(app.ImageShowHandler.Parent.Parent, app.ImageShowHandler);
               pos = get(app.MagBoxHandler,'Position');
               set(app.MagBoxHandler,'Position',[0 0 pos(3) pos(4)]);
               imoverview(app.ImageShowHandler);
            
               % Get the scroll panel API to programmatically control the view.
               app.ScrollPanelApi = iptgetapi(app.ScrollPanelHandler); 
               
               figure(app.FigureHandler);
             end
        end
    end
    
    methods (Access = private)
        function SubscribeEvents(app)
            [h, w, ~] = size(app.Image);
            app.ImgWidth = w;
            app.ImgHeight = h;
            
            set(app.FigureHandler, 'WindowScrollWheelFcn', @app.ZoomMouseWheelCallback);
            set(app.FigureHandler, 'WindowButtonDownFcn', @app.MouseButtonDownCallback);
            set(app.FigureHandler, 'WindowButtonUpFcn', @app.MouseButtonUpCallback);
            set(app.FigureHandler, 'WindowKeyPressFcn', @app.KeyPressCallback);
            set(app.FigureHandler, 'WindowKeyReleaseFcn', @app.KeyReleaseCallback);
            set(app.FigureHandler, 'CloseRequestFcn', @app.FigCloseRequestFcn);
        end
        
        function UnsubscribeEvents(app)
            set(app.FigureHandler, 'WindowScrollWheelFcn', []);
            set(app.FigureHandler, 'WindowButtonDownFcn', []);
            set(app.FigureHandler, 'WindowButtonUpFcn', []);
            set(app.FigureHandler, 'WindowKeyPressFcn', []);
            set(app.FigureHandler, 'WindowKeyReleaseFcn', []);
        end
        
        % ===================== CONE POSITION CORRECTION ================
        % Solves system of equations and gets the closest point to correctionPoint
        % (x-x1)^2 + (y-y1)^2 == dist
        % (x-x2)^2 + (y-y2)^2 == dist
        function results = SolveEuklidSystem(~, point1, point2, correctionPoint, dist)
            dist = dist * dist;
            x1 = point1(1,1); y1 = point1(1,2);
            x2 = point2(1,1); y2 = point2(1,2);
            x3 = correctionPoint(1,1); y3 = correctionPoint(1,2);
            
            coef1 = x1 - x2;
            coef2 = y1 - y2;
            a = 4 * (coef1^2 + coef2^2);
            b = 4 * (coef1^2 + coef2^2) * coef2;
            c = (coef1^2 + coef2^2)^2 - 4*coef1*coef1*dist;
            
            z31 = (-b + sqrt(b^2 - 4 * a * c)) / (2 * a);
            z32 = (-b - sqrt(b^2 - 4 * a * c)) / (2 * a);
            
            y31 = z31 + y1;
            y32 = z32 + y1;
            
            x31 = x1 + sqrt(dist - (z31)^2);
            x32 = x1 - sqrt(dist - (z32)^2);
            x33 = x1 - sqrt(dist - (z31)^2);
            x34 = x1 + sqrt(dist - (z32)^2);
            
            if ismembertol((x33-x2)^2 + (y31-y2)^2, dist)
                x31 = x33;
            end
            
            if ismembertol((x34-x2)^2 + (y32-y2)^2, dist)
                x32 = x34;
            end
            
            % TODO: catch complex solutions
            % return the closest point to correction point
            if pdist([[x3 y3];[x31 y31]],'euclidean')  < pdist([[x3 y3];[x32 y32]],'euclidean')
                results = [x31 y31];
            else
                results = [x32 y32];
            end
        end
        
        % Solves system of equations and gets the closest point to edgePoint
        % (y-y1)/(y2-y1) == (x-x1)/(x2-x1)
        % (x-x1)^2 + (y-y1)^2 == dist
        function point = SolveLinearAndCircle(~, centerPoint, edgePoint, dist)
            dist = dist * dist;
            x1 = centerPoint(1,1); y1 = centerPoint(1,2);
            x2 = edgePoint(1,1); y2 = edgePoint(1,2);
            
            coef1 = (x1 - x2)/(y1 - y2);
            coef2 = (x2*y1 - x1*y2)/(y1 - y2) - x1;
            a = 1 + coef1*coef1;
            b = 2*(coef1*coef2 - y1);
            c = coef2*coef2 + y1*y1 - dist;
            yNew1 = (-b + sqrt(b*b - 4*a*c))/(2*a);
            yNew2 = (-b - sqrt(b*b - 4*a*c))/(2*a);
            xNew1 = coef2 + x1 + coef1*yNew1;
            xNew2 = coef2 + x1 + coef1*yNew2;
            
            if pdist([[x2 y2];[xNew1 yNew1]],'euclidean')  < pdist([[x2 y2];[xNew2 yNew2]],'euclidean')
                point = [xNew1 yNew1];
            else
                point = [xNew2 yNew2];
            end
        end
        
        function correctedPoints = FixateBrightCones(app, roiPoints)
            numOfPoints = size(roiPoints, 1);
            roiPoints = int64(roiPoints);
            
            for pointIndex = 1:numOfPoints
                point = roiPoints(pointIndex, :);
                values3x3 = app.Image(point(:,2) - 1 : point(:,2) + 1, point(:,1) - 1 : point(:,1) + 1);
                values3x3 = values3x3';
                maximum = max(max(values3x3));
                [x, y] = find(values3x3 == maximum);
                newPoint = [x(1,1) + point(:,1) - 2, y(1,1) + point(:,2) - 2];
                roiPoints(pointIndex, :) = newPoint;
            end
            
            correctedPoints = roiPoints;
        end
        
        % Gets the adjacency matrix for voronoi vertecies
        % https://people.sc.fsu.edu/~jburkardt/presentations/voronoi_neighbors.pdf
        % [V, C] = voronoin(points);
        % voronoiCells = C; - the voronoi cells returned by voronoin function
        function results = GetVoronoiAdjacency(~, n, voronoiCells)
            %
            % V contains the Voronoi vertices,
            % C contains indices of Voronoi vertices that form the (finite sides of the)
            % Voronoi cells.
            %
            % Two nodes are neighbors if they share an edge, that is, two Voronoi
            % vertices.
            %
            vn = sparse ( n, n );
            try
                for i = 1 : n
                    for j = i + 1 : n
                        s = size ( intersect ( voronoiCells{i}, voronoiCells{j} ) );
                        if ( 1 < s(2) )
                            vn(i,j) = 1;
                            vn(j,i) = 1;
                        end
                    end
                end
            catch
                disp(n);
            end
            
            results = vn;
        end
        
        % Gets the adjacency matrix for voronoi vertecies by Delanau Triangulation
        function res = GetDelanauAdjacency(~, points)
            % Perform Delaunay triangulation
            dt = delaunayTriangulation(points(:,1), points(:,2));
            tri = dt.ConnectivityList;
            NP = size(points, 1); % Number of points
            % Calculate adjacency matrix (this part of code is taken from mr. Akira
            % Agata
            % https://www.mathworks.com/matlabcentral/answers/369143-how-to-do-delaunay-triangulation-and-return-an-adjacency-matrix
            AdjMat = false(NP);
            for kk = 1:size(tri, 1)
                AdjMat(tri(kk,1), tri(kk,2)) = true;
                AdjMat(tri(kk,2), tri(kk,3)) = true;
                AdjMat(tri(kk,3), tri(kk,1)) = true;
            end
            res = AdjMat | AdjMat';
        end
        
        % Sorts points in clockwise order
        % https://stackoverflow.com/questions/13935324/sorting-clockwise-polygon-points-in-matlab
        function sortedPoints = SortPoints(~, points)
            x = points(:, 1);
            y = points(:, 2);
            cx = mean(x);
            cy = mean(y);
            a = atan2(y - cy, x - cx);
            [~, order] = sort(a);
            sortedPoints = points(order, :);
        end
        
        % Inserts edge points between newPoint and nextPoint if possible.
        %
        % Parameters
        % centerPoint - centerPoint of pattern
        % newPoint - start edge point
        % nextPoint - end edge point
        % roi - region of interest
        % points - source set of points
        % queue - source queue
        %
        % Returns
        % q - updated queue
        % returnPoints - updated set of points
        % lastPoint - last inserted point
        function [q, returnPoints, lastPoint] = InsertNewPoints(app, centerPoint, newPoint, nextPoint, distToCenter, roi, points, queue)
            %distToCenter = (pdist([newPoint(:, 1:2); centerPoint(:, 1:2)],'euclidean') + pdist([nextPoint(:, 1:2); centerPoint(:, 1:2)],'euclidean')) / 2;
            
            while pdist([newPoint(:, 1:2); nextPoint(:, 1:2)],'euclidean') > distToCenter
                resultPoint = app.SolveEuklidSystem(centerPoint(:, 1:2), newPoint(:, 1:2), nextPoint(:, 1:2), distToCenter);
                
                [in, ~] = inpolygon(resultPoint(:, 1), resultPoint(:, 2), roi(:, 1), roi(:, 2));
                % if we still inside region of interest
                if in == resultPoint
                    newIndex = size(points, 1) + 1;
                    points(newIndex, :) = [resultPoint 1 newIndex];
                    % enqueue point
                    queue.enqueue(newIndex);
                end
                
                distToCenter = (pdist([newPoint(:, 1:2); centerPoint(:, 1:2)],'euclidean') + pdist([nextPoint(:, 1:2); centerPoint(:, 1:2)],'euclidean')) / 2;
                newPoint = resultPoint;
            end
            q = queue;
            returnPoints = points;
            lastPoint = newPoint;
        end
        
        % Checks whether distance between points inside [dmin, dmax] range.
        % If point is not inside the range, then new coords of the point will be calculated
        % Parameters
        % centerPoint - centerPoint of pattern
        % prevPoint - previous tested point
        % nextPoint - current testing point
        % points - source set of points
        % queue - source queue
        % dmin - min distance between points
        % dmax - max distance between points
        %
        % Returns
        % q - updated queue
        % returnPoints - updated set of points
        % nextPoint - current tested point
        function [q, returnPoints, nextPoint] = CheckPoints(~, centerPoint, prevPoint, nextPoint, points, queue, dmin, dmax)
            distances3 = pdist([centerPoint(:, 1:2); prevPoint(:, 1:2); nextPoint(:, 1:2)],'euclidean');
            % if all distances is inside [dmin; dmax]
            if all(distances3(:, 2:3) >= dmin) && all(distances3(:, 2:3) <= dmax)
                % if point is not corrected
                if (points(nextPoint(1, 4), 3) ~= 1)
                    % enqueue point
                    queue.enqueue(nextPoint(1, 4));
                    % mark point as "corrected"
                    points(nextPoint(1, 4), 3) = 1;
                end
            else
                if (points(nextPoint(1, 4), 3) ~= 1)
%                     % calc new coords
%                     if ~all(distances3(:, 2:3) >= dmin)
%                         resultPoint = app.SolveEuklidSystem(centerPoint(:, 1:2), prevPoint(:, 1:2), nextPoint(:, 1:2), max(distances3(:, 2:3)));
%                     else
%                         resultPoint = app.SolveEuklidSystem(centerPoint(:, 1:2), prevPoint(:, 1:2), nextPoint(:, 1:2), dmax);
%                     end
%                     
%                     
                    % enqueue point
                    queue.enqueue(nextPoint(1, 4));
                    % mark point as "corrected"
                    points(nextPoint(1, 4), 3) = 1;
%                     
%                     % set new coords
%                     points(nextPoint(1, 4), 1) = resultPoint(1, 1);
%                     points(nextPoint(1, 4), 2) = resultPoint(1, 2);
%                     nextPoint(1,1) = resultPoint(1, 1);
%                     nextPoint(1,2) = resultPoint(1, 2);
                end
            end
            
            returnPoints = points;
            q = queue;
        end
        
        % Corrects cone locations
        function resultPoints = CorrectPoints(app, points, roi, dmin, dmax)
            % add column which indicates "corrected" points
            n = size(points,1);
            indices = 1:n;
            corrected = zeros(n,1);
            % points have next columns: x-coord | y-coord | 0/1(corrected) | sourceIndex
            points = [double(points) corrected indices'];
            
            x = points(:, 1);
            y = points(:, 2);
            % calc distances to (0,0) point
            distances = sqrt((x).^2 + (y).^2);
            % find the minimum distance index (the most left-top point in set)
            [~, index] = min(distances);
            
            points(index, 3) = 1;
            
            % create the queue
            q = queue(size(app.Conelocs, 1));
            q.enqueue(index);
            
            % while queue is not empty
            while q.Depth > 0
                % get next point
                nextIndex = q.dequeue;
                nextCenterPoint = points(nextIndex, :);
                
                % create voronoi diagram, to get neighbour points
                % TODO: can we optimize this step if new points is not added?
                vorAdj = app.GetDelanauAdjacency(points);
                % get neighbour points (all around)
                neighbourIndices = logical(vorAdj(nextIndex, :))';
                
                neighbours = points(neighbourIndices, :);
                neighbours = app.SortPoints(neighbours);
                % get current number of neighbours
                numOfNeighbours = size(neighbours, 1);
                
                if(size(neighbours, 1) == 0)
                    continue;
                end
                
                % get first point from neighbour set
                try
                    previousPoint = neighbours(1, :);
                catch
                    disp(neighbours);
                end
                
                % if point is not corrected
                if (points(previousPoint(1, 4), 3) ~= 1)
%                     resultPoint = previousPoint(:, 1:2);
%                     
%                     % if distance between center and edge point not in [dmin, dmax]
%                     % calc new coords
%                     if pdist([nextCenterPoint(:, 1:2); previousPoint(:, 1:2)],'euclidean') < dmin
%                         resultPoint = app.SolveLinearAndCircle(nextCenterPoint, previousPoint, dmin);
%                     elseif pdist([nextCenterPoint(:, 1:2); previousPoint(:, 1:2)],'euclidean') > dmax
%                         resultPoint = app.SolveLinearAndCircle(nextCenterPoint, previousPoint, dmax);
%                     end
%                     
%                     % if new point is calculated
%                     if resultPoint ~= previousPoint(:, 1:2)
%                         points(previousPoint(1, 4), 1) = resultPoint(1, 1);
%                         points(previousPoint(1, 4), 2) = resultPoint(1, 2);
%                         previousPoint(1,1) = resultPoint(1, 1);
%                         previousPoint(1,2) = resultPoint(1, 2);
%                     end
                    
                    % enqueue point
                    q.enqueue(previousPoint(1, 4));
                    % mark point as "corrected"
                    points(previousPoint(1, 4), 3) = 1;
                end
                
                % for each neighbour except first
                for ind = 2:numOfNeighbours
                    % get neighbour
                    nextPoint = neighbours(ind, :);
                    
%                     distBetweenEdgePoints = pdist([previousPoint(:, 1:2); nextPoint(:, 1:2)],'euclidean');
%                     distToCenter = pdist([previousPoint(:, 1:2); nextCenterPoint(:, 1:2)],'euclidean');
                    distances3 = pdist([nextCenterPoint(:, 1:2); previousPoint(:, 1:2); nextPoint(:, 1:2)],'euclidean');
                    
                    % CHITO ETO TAKOE??????
                    % if not all distances is inside [dmin; dmax]
                    % and distance between edge points is twice greater then distance from previous point to center point
                    if ~(all(distances3(:, 2:3) >= dmin) && all(distances3(:, 2:3) <= dmax))% && distBetweenEdgePoints > 2*distToCenter
                        [q, points, lastPoint] = InsertNewPoints(app, nextCenterPoint, previousPoint, nextPoint, dmin, roi, points, q);
                        [q, points, nextPoint] = CheckPoints(app, nextCenterPoint, lastPoint, nextPoint, points, q, dmin, dmax);
                    else
                        [q, points, nextPoint] = CheckPoints(app, nextCenterPoint, previousPoint, nextPoint, points, q, dmin, dmax);
                    end
                    previousPoint = nextPoint;
                end
                
                % add new point if possible
                firstPoint = neighbours(1, :);
                
                distBetweenEdgePoints = pdist([nextPoint(:, 1:2); firstPoint(:, 1:2)],'euclidean');
                distToCenter = pdist([previousPoint(:, 1:2); nextCenterPoint(:, 1:2)],'euclidean');
                
                if distBetweenEdgePoints >= 2*distToCenter
                    [q, points, ~] = InsertNewPoints(app, nextCenterPoint, nextPoint, firstPoint, roi, points, q);
                end
            end
            
            resultPoints = points;
        end
        
        function points = CorrectPoints2(app, roi, dmin, dmax, maxNumOfNeighbours, minBrightness) 
            minX = min(roi(:, 1));
            minY = min(roi(:, 2));
            maxX = max(roi(:, 1));
            maxY = max(roi(:, 2));
            newPoints = [0 0];
            % TODO: add new restrictions (image brightness/num of neighbours)
            % maybe im_brightness_threshold = f(distance)
            
            h = waitbar(0,'Detecting unrecognized points...');
            maxCount = (maxY - minY) * (maxX - minX);
            
            multiplyer = 1;
            if app.Image < 1
               multiplyer = 255;
            end
            
            for x = minX:maxX
                for y = minY:maxY
                    rangesMin = rangesearch([app.Conelocs(:, 1:2); newPoints], [x y], dmin);
                    rangesMax = rangesearch([app.Conelocs(:, 1:2); newPoints], [x y], dmax);
                    pointBrighness = app.Image(int32(y), int32(x));
                    if isempty(rangesMin{1}) && ...
                       length(rangesMax) < maxNumOfNeighbours && ...
                       (multiplyer * pointBrighness) >= minBrightness
                        newPoints = [newPoints; x y];
                    end
                    waitbar(((maxY - minY) * (x - minX + 1) + (y - minY + 1)) / maxCount);
                end
            end
            close(h);
            points = newPoints;
        end
        % ===================== CONE POSITION CORRECTION END ================
        
        % Applies zoom
        function ZoomMouseWheelCallback(app, ~, cbdata)
            scrollChange = cbdata.VerticalScrollCount; % -1: zoomIn, 1: zoomOut
            currentMag = app.ScrollPanelApi.getMagnification();
            app.ScrollPanelApi.setMagnification(currentMag - 0.1 * scrollChange);
        end
        
        
        function MouseButtonDownCallback(app, hObj, event)
            if app.IsBusy
                return;
            end
            
            clickType = event.Source.SelectionType;
            isAltPressed = any(ismember(get(hObj,'CurrentModifier'), 'alt'));

            % If 'Alt' is pressed
            if isAltPressed && ~isempty(app.ImageShowHandler) && isvalid(app.ImageShowHandler)
                seedPt = get(app.ImageShowHandler.Parent, 'CurrentPoint'); 
                seedPt = seedPt(1, :);
                x = seedPt(1);
                y = seedPt(2);
            
                % Mouse Left Click
                if strcmp(clickType, 'normal')
                    MarkCone(app, x, y);
                end
                
                % Mouse Right Click (or Ctrl + Left Click)
                if strcmp(clickType, 'alt')
                    RemoveMark(app, x, y);
                end
            end
            
            % if clicked mouse button 3 (wheel)
            if (strcmp(clickType, 'extend'))

                guiArea = hittest(hObj);
                parentAxes = ancestor(guiArea, 'axes');

                % if the mouse is over the desired axis, trigger the pan fcn
                if ~isempty(parentAxes)
                    app.StartPan(hObj)
                else
                    setptr(event.Source, 'forbidden')
                end
            end
        end
                
        function MouseButtonUpCallback(app, ~, ~)
            if app.IsBusy
                return;
            end
            
            app.StopPan();
        end
        
            
        function KeyPressCallback(app, ~, event)
            if app.IsBusy
                return;
            end
            
            if any(ismember(event.Modifier, 'alt'))
                setptr(gcf, 'cross');
            end
            button = event.Key;
        
            switch button
                % Darken image "D"
                case 'd'
                    if app.VoronoiPlot == 1
                        if (app.BrightnessDBSlider.Value < 2.0)
                            if (app.BrightnessDBSlider.Value + 0.1 <= 2.0)
                                app.BrightnessDBSlider.Value = app.BrightnessDBSlider.Value + 0.1;
                            else
                                app.BrightnessDBSlider.Value = 2.0;
                            end
                        end
                        newEvent.Value = app.BrightnessDBSlider.Value;
                        BrightnessDBSliderValueChanging(app, newEvent);
                    end
                    
                % Brighten image "B"
                case 'b'
                    if app.VoronoiPlot == 1
                        if (app.BrightnessDBSlider.Value > 0.0)
                            if (app.BrightnessDBSlider.Value - 0.1 >= 0.0)
                                app.BrightnessDBSlider.Value = app.BrightnessDBSlider.Value - 0.1;
                            else
                                app.BrightnessDBSlider.Value = 0.0;
                            end
                        end
                        newEvent.Value = app.BrightnessDBSlider.Value;
                        BrightnessDBSliderValueChanging(app, newEvent);
                    end
                    
                % Reset image brightness "R"
                case 'r'
                    if app.VoronoiPlot == 1
                        app.BrightnessDBSlider.Value = 1.0;
                        ResetBrightnessRButtonValueChanged(app, event);
                    end
                                        
                % Log filter image "L"
                case 'l'
                    if app.VoronoiPlot == 1
                        if strcmp(app.LogImageLSwitch.Value, 'Off')
                            app.LogImageLSwitch.Value = 'On';
                        else
                            app.LogImageLSwitch.Value = 'Off';
                        end
                        LogImageLSwitchValueChanged(app, event);
                    end
                    
                % Show image "I"
                case 'i'
                    if app.VoronoiPlot == 1
                        if strcmp(app.ShowimageISwitch.Value, 'Off')
                            app.ShowimageISwitch.Value = 'On';
                        else
                            app.ShowimageISwitch.Value = 'Off';
                        end
                        ShowimageISwitchValueChanged(app, event);
                    end
                    
                % Show Marks "T"
                case 't'
                    if app.VoronoiPlot == 1
                        if strcmp(app.ShowMarkedLocationsTSwitch.Value, 'Off')
                            app.ShowMarkedLocationsTSwitch.Value = 'On';
                        else
                            app.ShowMarkedLocationsTSwitch.Value = 'Off';
                        end
                        ShowMarkedLocationsTSwitchValueChanged(app, event);
                    end
                    
                % Circle marks "C"
                case 'c'
                    if app.VoronoiPlot == 1
                        app.CircleCButton.Value = ~app.CircleCButton.Value;
                        ConeMarksTypeButtonGroupSelectionChanged(app, event);
                    end
                    
                % Cross marks "X"
                case 'x'
                    if app.VoronoiPlot == 1
                        app.CrossXButton.Value = ~app.CrossXButton.Value;
                        ConeMarksTypeButtonGroupSelectionChanged(app, event);
                    end
                    
                % Dot marks "Z"
                case 'z'
                    if app.VoronoiPlot == 1
                        app.DotZButton.Value = ~app.DotZButton.Value;
                        ConeMarksTypeButtonGroupSelectionChanged(app, event);
                    end
                    
                % Autodetected marks "1"
                case '1'
                    if app.VoronoiPlot == 1
                        app.Autodetect1Button.Value = ~app.Autodetect1Button.Value;
                        ShowMarksOptionsButtonGroupSelectionChanged(app, event);
                    end
                    
                % Userdetected marks "2"
                case '2'
                    if app.VoronoiPlot == 1
                        app.Userdetect2Button.Value = ~app.Userdetect2Button.Value;
                        ShowMarksOptionsButtonGroupSelectionChanged(app, event);
                    end
                    
                % Auto + User detected marks "3"
                case '3'
                    if app.VoronoiPlot == 1
                        app.AutoUser3Button.Value = ~app.AutoUser3Button.Value;
                        ShowMarksOptionsButtonGroupSelectionChanged(app, event);
                    end
                    
                % Highlight autodetected marks "0"
                case '0'
                    if app.VoronoiPlot == 1
                        if strcmp(app.HighlightAutodetectedCones0Switch.Value, 'Off')
                            app.HighlightAutodetectedCones0Switch.Value = 'On';
                        else
                            app.HighlightAutodetectedCones0Switch.Value = 'Off';
                        end
                        HighlightAutodetectedCones0SwitchValueChanged(app, event);
                    end
                    
                % Show grid "Q"
                case 'q'
                    if app.VoronoiPlot == 1
                        if strcmp(app.ShowGridQSwitch.Value, 'Off')
                            app.ShowGridQSwitch.Value = 'On';
                        else
                            app.ShowGridQSwitch.Value = 'Off';
                        end
                        ShowGridQSwitchValueChanged(app, event);
                    end
                    
                % Show grid numbers "E"
                case 'e'
                    if app.VoronoiPlot == 1
                        if strcmp(app.ShowGirdNumbersESwitch.Value, 'Off')
                            app.ShowGirdNumbersESwitch.Value = 'On';
                        else
                            app.ShowGirdNumbersESwitch.Value = 'Off';
                        end
                        ShowGirdNumbersESwitchValueChanged(app, event);
                    end
                    
                % Save current locations "SPACE"
                case 'space'
                    SaveCurrentLocationsSPACEButtonPushed(app, event);
                    
                % Delete selected locations "Del" ("entf")
                case 'delete'
                    if app.VoronoiPlot == 1
                        DeletedSelectedConesDelButtonPushed(app, event);
                    end
                    
                % Plot voronoi digram for 'Cone Area', "V"
                case 'v'
                    if app.VoronoiPlot == 1
                        PrintImage(app, app.Image);
                        app.VoronoiPlot = 0;
                        UpdateUI(app);
                        PrintVoronoiByType(app, 'Cone Area', app.Conelocs, []);
                    else
                        app.VoronoiPlot = 1;
                        UpdateUI(app);
                        UpdateImage(app);
                    end
                    
                % Plot voronoi digram for 'Number of Neighbors', "N"
                case 'n'
                    if app.VoronoiPlot == 1
                        PrintImage(app, app.Image);
                        app.VoronoiPlot = 0;
                        UpdateUI(app);
                        PrintVoronoiByType(app, 'Number of Neighbors', app.Conelocs, []);
                    else
                        app.VoronoiPlot = 1;
                        UpdateUI(app);
                        UpdateImage(app);
                    end     
            end
               
            UpdateUI(app);
        end
    
        function KeyReleaseCallback(app, ~, event)
            if app.IsBusy
                return;
            end
            
            if ~any(ismember(event.Modifier, 'alt'))
                setptr(gcf, 'arrow');
            end
        end
        
        
        function FigCloseRequestFcn(app, hFig, ~)
            UnsubscribeEvents(app);
            delete(hFig);
            
            app.FigureHandler = [];
            app.ScrollPanelApi = [];
            app.ImageShowHandler = [];
            app.MagBoxHandler = [];
            app.ScrollPanelHandler = [];
        end
        
        %% AXIS PANNING FUNCTIONS
    
        % Call this Fcn in your 'WindowButtonDownFcn'
        % Take in desired Axis to pan
        % Get seed points & assign the Panning Fcn to top level Fig
        function StartPan(app, hFig)
            % Parent Fig
            %hFig = ancestor(hAx, 'Figure', 'toplevel');   
    
            % Get init mouse position
            seedPt = get(hFig, 'CurrentPoint'); 
            % Keep only 1st point
            seedPt = seedPt(1, :); 
    
            
            start_pos_im = app.ScrollPanelApi.getVisibleImageRect();
            start_pos_im(3:4) = [];

            screenPerImagePixels = app.ScrollPanelApi.getMagnification();

            set(hFig,'WindowButtonMotionFcn', {@app.PanningCallback, screenPerImagePixels, seedPt, start_pos_im});
            % Assign 'Panning' cursor
            setptr(hFig, 'hand');
        end
    
    
        % Call this Fcn in your 'WindowButtonUpFcn'
        function StopPan(~)
            set(gcbf,'WindowButtonMotionFcn',[]);
            setptr(gcbf,'arrow');
        end
    
    
        % Controls the real-time panning on the desired axis
        function PanningCallback(app, ~, ~, screenPerImagePixels, seedPt, start_pos_im)            
            % The closedhand pointer is not provided by the HG figure window. Since we
            % are using setptr, disable the pointer manager to prevent manager
            % from caching hand cursors.
            iptPointerManager(app.FigureHandler,'Disable');
            setptr(app.FigureHandler,'closedhand')

            % Note: we must use the figure CurrentPoint property to make sure
            % the calculation of the deltas is invariant to the image being dragged.
            new_point = get(app.FigureHandler, 'CurrentPoint');
            cp = new_point(1,1:2);
            delta_x_screen = cp(1) - seedPt(1);
            delta_y_screen = cp(2) - seedPt(2);
        
            new_pos_im =  start_pos_im - ...
                [delta_x_screen -delta_y_screen]/screenPerImagePixels;

            new_pos_im = app.ConstrainDrag(new_pos_im);
            app.ScrollPanelApi.setVisibleLocation(new_pos_im(1),new_pos_im(2));
        end
        
        function pos = ConstrainDrag(app, proposedPos)
    
            x = proposedPos(1);
            y = proposedPos(2);
    
            r = app.ScrollPanelApi.getVisibleImageRect();
            w = r(3);
            h = r(4);
            
            % get image model and cdata sizes
            imModel = getimagemodel(app.ImageShowHandler);
            imW = getImageWidth(imModel);
            imH = getImageHeight(imModel);
            
            % get spatial span of data
            xdata = app.getXData(app.ImageShowHandler);
            ydata = app.getYData(app.ImageShowHandler);
            
            % compute pixel span in each direction
            dx = images.internal.getDeltaOnePixel(xdata,imW);
            dy = images.internal.getDeltaOnePixel(ydata,imH);
            
            left_edge   = xdata(1) - dx/2;
            right_edge  = xdata(2) + dx/2;
            top_edge    = ydata(1) - dy/2;
            bottom_edge = ydata(2) + dy/2;
            
            x = min(right_edge  - w, max(x,left_edge) );
            y = min(bottom_edge - h, max(y,top_edge) );
     
            pos = [x y];
    
        end
            
        function xdata = getXData(~, handle)
            
            xdata = get(handle,'XData');
            
            if isscalar(xdata)
                xdata = [xdata xdata];
            end
        end
        
        function ydata = getYData(~, handle)
            
            ydata = get(handle,'YData');
            
            if isscalar(ydata)
                ydata = [ydata ydata];
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            set(findall(app.ConeFinderUIFigure, '-property', 'Enable'), 'Enable', 'off')
            app.StartConeFinderButton.Enable = 'on';
        end

        % Button pushed function: StartConeFinderButton
        function StartConeFinderButtonValueChanged(app, event)
            format shortg
            app.Conelocs = [];
           
            % add folder with CNN_Code to matlab search for current session
            addpath(fullfile(fileparts(mfilename('fullpath')), 'CNN_Code'));
            % add folder with Utilities to matlab search for current session
            addpath(fullfile(fileparts(mfilename('fullpath')), 'Utilities'));
            
            % Colormap for Voronoi patches
            colormaps_mpl2019;
            app.VColorMap = colormap(viridis); 
            try 
                close(gcf);
                app.FigureHandler = [];
            catch 
            end
            
            % User input - Select files to analyse
            % Select tiff to analyze, or mat file in case revisiting already counted tiff
            start_path = ([cd filesep]);
            
            % file selection GUI
            [fnames, app.Pname] = uigetfile('*.mat;*.tiff;*.tif;', 'Select file(s) to mark', start_path, 'MultiSelect', 'on');
            
            % checks if user cancelled
            if isequal(fnames,0) || isequal(app.Pname,0)
                % display a message in the commmand window
                disp(' ');disp(' ');disp(' ');disp(' ----> User cancelled script');disp(' ');disp(' ');
                % if cancelled exit script
                return
            end
            
            % determine number of selected files
            if ischar(fnames)
                nfiles = 1;
            else
                nfiles = size(fnames(:),1);
            end
            
            if nfiles > 1
                app.MultipleMosaics = 1;
                app.Fname = fnames{1};
                [~, ~, ext] = fileparts([app.Pname app.Fname]);
            else
                app.MultipleMosaics = 0;
                app.Fname = fnames;
                [~, ~, ext] = fileparts([app.Pname app.Fname]);
            end
            
            % In case of Multiselect - prepare for changing the app.Mosaic image
            if app.MultipleMosaics
                LoadMosaic(app, fnames{1}, fnames, 1, nfiles);
            end
            
            % Prepare file to start/continue cone marking
            if strcmp(ext,'.tiff')==1 || strcmp(ext,'.tif')==1
                
                if app.MultipleMosaics
                    app.Mosaic = 1;
                    app.Image = app.ChangeMosaic{app.Mosaic};
                elseif nfiles == 1
                    app.Image = imread([app.Pname app.Fname]);
                    app.ChangeMosaic{1} = app.Image;
                end
                
                CreateFigure(app);
                hold on;
                
                box_size = questdlg('Select box size', 'Define window size for analysis', '200x200 pixel', ...
                    '512x512  pixel', '1024x1024  pixel', '1024x1024  pixel');
                if strcmp(box_size, '200x200 pixel')
                    hi = drawrectangle('Position',[0 0 200 200]); %imrect(gca,[0 0 200 200]);
                elseif strcmp(box_size, '512x512  pixel')
                    hi = drawrectangle('Position',[0 0 512 512]); %hi = imrect(gca,[0 0 512 512]);
                elseif strcmp(box_size, '1024x1024  pixel')
                    hi = drawrectangle('Position',[0 0 1024 1024]); %hi = imrect(gca,[0 0 1024 1024]);
                end
                
                wait(hi);
                app.BoxPosition = hi.Position;
                delete(hi);
                
                SetBox(app);
                                
                PrintImage(app, app.Image);
                title(app.Fname,'Interpreter','none');
                ax = gca; 
                app.Ttl = ax.Title; 
                app.Ttl.FontSize = 14;
                app.Ttl.BackgroundColor = [1 0 0];
                rectangle('Position', app.BoxPosition,'EdgeColor',[1 0 1]);
                
                % QUESTDLG for use of the FastPeakFinder
                fn = {'Fast Peak Finder', 'CNN v23 (gpu)', 'CNN v23 (cpu)', 'CNN v25 (gpu)', 'CNN v25 (cpu)'};
                [indx,tf] = listdlg('Name', 'Cone Finder Algroithms', 'PromptString', {'Select a cone finder algorithm.'},...
                    'SelectionMode','single','ListString', fn);
                
                % if user clicked 'Cancel'
                if ~tf
                    app.ConeCount = 0;
                else
                    switch indx
                        case 1 % 'Fast Peak Finder'
                            FastPeakFinder(app);
                            
                        case 2 % 'CNN v23 (gpu)'
                            CnnFinder(app, 'matconvnet-1.0-beta23', 'gpu');
                            
                        case 3 % 'CNN v23 (cpu)'
                            CnnFinder(app, 'matconvnet-1.0-beta23cpu', 'cpu');
                            
                        case 4 % 'CNN v25 (gpu)'
                            CnnFinder(app, 'matconvnet-1.0-beta25', 'gpu');
                            
                        case 5 % 'CNN v25 (cpu)'
                            CnnFinder(app, 'matconvnet-1.0-beta25cpu', 'cpu');
                    end % switch
                end
                title([app.Fname,'  ',num2str(app.ConeCount),' cones'], 'Interpreter','none');
                
                app.ChangeMosaic{1} = app.Image;
                app.Mosaic = 1;
                
            elseif strcmp(ext,'.mat')==1
                % load data
                LoadData(app);    
            end
            
            UpdateImage(app);
            title([app.Fname,'  ',num2str(app.ConeCount),' cones'],'Interpreter','none');
                
            SubscribeEvents(app);
            UpdateUI(app);
        end

        % Button pushed function: VoronoiVNButton
        function VoronoiVNButtonPushed(app, event)
            if app.VoronoiPlot
                % Get voronoi type
                voronoi_type = questdlg('Select coloring for Voronoi patches', 'Coloring Voronois', 'Cone Area', ...
                    'Number of Neighbors', 'Cone Area');
                % Update user interface
                app.VoronoiPlot = 0;
                UpdateUI(app);
                
                % Print image
                if ~isempty(app.Image2)
                    subplot(1,2,1);
                end
                PrintImage(app, app.Image);
                
                additionalCones = [];
                if app.UseCorrectedPointsInVoronoi
                    additionalCones = app.CorrectedPoints;
                end
                
                % Print voronoi diagram
                PrintVoronoiByType(app, voronoi_type, app.Conelocs, additionalCones);
                
                if ~isempty(app.Image2)
                    subplot(1,2,2);
                    PrintImage(app, app.Image2);
                    PrintVoronoiByType(app, voronoi_type, app.Conelocs2, []);
                end
            else
                % Print image
                UpdateImage(app);
                % Update user interface
                app.VoronoiPlot = 1;
                UpdateUI(app);
            end
        end

        % Value changed function: EditModeSwitch
        function EditModeSwitchValueChanged(app, event)
            UpdateUI(app);
            while strcmp(app.EditModeSwitch.Value,'On')
                [x,y,button] = ginput(1);
                
                % sometimes matlab return buttom as not scalar or string
                if ~isscalar(button)
                    disp(strcat('is not scalar! value: ', button));
                    continue;
                end
                
                switch button
                    % Mark cone center by left clicking
                    case 1
                        if app.VoronoiPlot == 1
                            MarkCone(app, x, y)
                        end
                        
                        % Clear marked cone nearest to click location
                    case 3
                        if app.VoronoiPlot == 1
                            RemoveMark(app, x, y)
                        end
                        
                        % Zoom in and out with two keys "+", "-" on numpad
                    case 45
                        ZoomIn(app)
                    case 43
                        ZoomOut(app, x, y)
                        
                        % Pan image laterally with arrow keys
                    case  28 % LEFT arrow
                        PanLeft(app)
                    case 29 % RIGHT arrow
                        PanRight(app)
                    case 30 % UP arrow
                        PanUp(app)
                    case 31 % DOWN arrow
                        PanDown(app)
                        
                        % Escape key to quit count routine
                    case 27
                        app.EditModeSwitch.Value = 'Off';
                        
                        % Darken image "D"
                    case 100
                        if app.VoronoiPlot == 1
                            if (app.BrightnessDBSlider.Value < 2.0)
                                if (app.BrightnessDBSlider.Value + 0.1 <= 2.0)
                                    app.BrightnessDBSlider.Value = app.BrightnessDBSlider.Value + 0.1;
                                else
                                    app.BrightnessDBSlider.Value = 2.0;
                                end
                            end
                            newEvent.Value = app.BrightnessDBSlider.Value;
                            BrightnessDBSliderValueChanging(app, newEvent);
                        end
                        
                        % Brighten image "B"
                    case 98
                        if app.VoronoiPlot == 1
                            if (app.BrightnessDBSlider.Value > 0.0)
                                if (app.BrightnessDBSlider.Value - 0.1 >= 0.0)
                                    app.BrightnessDBSlider.Value = app.BrightnessDBSlider.Value - 0.1;
                                else
                                    app.BrightnessDBSlider.Value = 0.0;
                                end
                            end
                            newEvent.Value = app.BrightnessDBSlider.Value;
                            BrightnessDBSliderValueChanging(app, newEvent);
                        end
                        
                        % Reset image brightness "R"
                    case 114
                        if app.VoronoiPlot == 1
                            app.BrightnessDBSlider.Value = 1.0;
                            ResetBrightnessRButtonValueChanged(app, event);
                        end
                        
                        % Activate Pan/Zoom during Edit Mode "P"
                    case 112
                        while true
                            if waitforbuttonpress
                                key = get(gcf,'currentcharacter');
                                switch key
                                    case 112 % 112 is the "P" key
                                        break % break out of the while loop
                                    case 27 % 27 is the escape key
                                        break % break out of the while loop
                                    case 13 % 13 is the return key
                                        break
                                end
                            end
                        end
                        
                        % Log filter image "L"
                    case 108
                        if app.VoronoiPlot == 1
                            if strcmp(app.LogImageLSwitch.Value, 'Off')
                                app.LogImageLSwitch.Value = 'On';
                            else
                                app.LogImageLSwitch.Value = 'Off';
                            end
                            LogImageLSwitchValueChanged(app, event);
                        end
                        
                        % Show image "I"
                    case 105
                        if app.VoronoiPlot == 1
                            if strcmp(app.ShowimageISwitch.Value, 'Off')
                                app.ShowimageISwitch.Value = 'On';
                            else
                                app.ShowimageISwitch.Value = 'Off';
                            end
                            ShowimageISwitchValueChanged(app, event);
                        end
                        
                        % Show Marks "T"
                    case 116
                        if app.VoronoiPlot == 1
                            if strcmp(app.ShowMarkedLocationsTSwitch.Value, 'Off')
                                app.ShowMarkedLocationsTSwitch.Value = 'On';
                            else
                                app.ShowMarkedLocationsTSwitch.Value = 'Off';
                            end
                            ShowMarkedLocationsTSwitchValueChanged(app, event);
                        end
                        
                        % Circle marks "C"
                    case 99
                        if app.VoronoiPlot == 1
                            app.CircleCButton.Value = ~app.CircleCButton.Value;
                            ConeMarksTypeButtonGroupSelectionChanged(app, event);
                        end
                        
                        % Cross marks "X"
                    case 120
                        if app.VoronoiPlot == 1
                            app.CrossXButton.Value = ~app.CrossXButton.Value;
                            ConeMarksTypeButtonGroupSelectionChanged(app, event);
                        end
                        
                        % Dot marks "Z"
                    case 122
                        if app.VoronoiPlot == 1
                            app.DotZButton.Value = ~app.DotZButton.Value;
                            ConeMarksTypeButtonGroupSelectionChanged(app, event);
                        end
                        
                        % Autodetected marks "1"
                    case 49
                        if app.VoronoiPlot == 1
                            app.Autodetect1Button.Value = ~app.Autodetect1Button.Value;
                            ShowMarksOptionsButtonGroupSelectionChanged(app, event);
                        end
                        
                        % Userdetected marks "2"
                    case 50
                        if app.VoronoiPlot == 1
                            app.Userdetect2Button.Value = ~app.Userdetect2Button.Value;
                            ShowMarksOptionsButtonGroupSelectionChanged(app, event);
                        end
                        
                        % Auto + User detected marks "3"
                    case 51
                        if app.VoronoiPlot == 1
                            app.AutoUser3Button.Value = ~app.AutoUser3Button.Value;
                            ShowMarksOptionsButtonGroupSelectionChanged(app, event);
                        end
                        
                        % Highlight autodetected marks "0"
                    case 48
                        if app.VoronoiPlot == 1
                            if strcmp(app.HighlightAutodetectedCones0Switch.Value, 'Off')
                                app.HighlightAutodetectedCones0Switch.Value = 'On';
                            else
                                app.HighlightAutodetectedCones0Switch.Value = 'Off';
                            end
                            HighlightAutodetectedCones0SwitchValueChanged(app, event);
                        end
                        
                        % Show grid "Q"
                    case 113
                        if app.VoronoiPlot == 1
                            if strcmp(app.ShowGridQSwitch.Value, 'Off')
                                app.ShowGridQSwitch.Value = 'On';
                            else
                                app.ShowGridQSwitch.Value = 'Off';
                            end
                            ShowGridQSwitchValueChanged(app, event);
                        end
                        
                        % Show grid numbers "E"
                    case 101
                        if app.VoronoiPlot == 1
                            if strcmp(app.ShowGirdNumbersESwitch.Value, 'Off')
                                app.ShowGirdNumbersESwitch.Value = 'On';
                            else
                                app.ShowGirdNumbersESwitch.Value = 'Off';
                            end
                            ShowGirdNumbersESwitchValueChanged(app, event);
                        end
                        
                        % Save current locations "SPACE"
                    case 32
                        SaveCurrentLocationsSPACEButtonPushed(app, event);
                        
                        % Delete selected locations "Del" ("entf")
                    case 127
                        if app.VoronoiPlot == 1
                            DeletedSelectedConesDelButtonPushed(app, event);
                        end
                end
                
                % Plot voronoi digram for 'Cone Area', "V"
                if button == 118
                    if app.VoronoiPlot == 1
                        PrintImage(app, app.Image);
                        app.VoronoiPlot = 0;
                        UpdateUI(app);
                        PrintVoronoiByType(app, 'Cone Area', app.Conelocs, []);
                    else
                        app.VoronoiPlot = 1;
                        UpdateUI(app);
                        UpdateImage(app);
                    end
                end
                
                % Plot voronoi digram for 'Number of Neighbors', "N"
                if button == 110
                    if app.VoronoiPlot == 1
                        PrintImage(app, app.Image);
                        app.VoronoiPlot = 0;
                        UpdateUI(app);
                        PrintVoronoiByType(app, 'Number of Neighbors', app.Conelocs, []);
                    else
                        app.VoronoiPlot = 1;
                        UpdateUI(app);
                        UpdateImage(app);
                    end
                end
                
            end
            UpdateUI(app);
        end

        % Button pushed function: ResetBrightnessRButton
        function ResetBrightnessRButtonValueChanged(app, event)
            app.BrightnessDBSlider.Value = 1.0;
            app.Brightness = 1.0;
            UpdateImage(app);
        end

        % Value changed function: LogImageLSwitch
        function LogImageLSwitchValueChanged(app, event)
            % default = Off
            app.LogImage = app.LogImageLSwitch.Value;
            UpdateImage(app);
        end

        % Value changed function: ShowMarkedLocationsTSwitch
        function ShowMarkedLocationsTSwitchValueChanged(app, event)
            % markson = app.ShowMarkedLocationsTSwitch.Value
            % default = On
            app.ShowMarks = app.ShowMarkedLocationsTSwitch.Value;
            UpdateImage(app);
        end

        % Value changed function: ShowimageISwitch
        function ShowimageISwitchValueChanged(app, event)
            % imageon = app.ShowimageISwitch.Value
            % default = On
            app.ShowImage = app.ShowimageISwitch.Value;
            UpdateImage(app);
        end

        % Value changing function: BrightnessDBSlider
        function BrightnessDBSliderValueChanging(app, event)
            app.Brightness = event.Value;
            UpdateImage(app);
        end

        % Selection changed function: ConeMarksTypeButtonGroup
        function ConeMarksTypeButtonGroupSelectionChanged(app, event)
            if app.CircleCButton.Value
                app.MarkType = 'oy';
                app.MarkerSize = 5;
            elseif app.CrossXButton.Value
                app.MarkType = 'xy';
                app.MarkerSize = 5;
            else
                app.MarkType = '.y';
                app.MarkerSize = 2;
            end
            
            UpdateImage(app);
        end

        % Selection changed function: ShowMarksOptionsButtonGroup
        function ShowMarksOptionsButtonGroupSelectionChanged(app, event)
            if app.Userdetect2Button.Value
                app.ShowConesByDetectType = 1;
            elseif app.Autodetect1Button.Value
                app.ShowConesByDetectType = 2;
            else
                app.ShowConesByDetectType = 0;
            end
            
            UpdateImage(app);
        end

        % Value changed function: HighlightAutodetectedCones0Switch
        function HighlightAutodetectedCones0SwitchValueChanged(app, event)
            UpdateImage(app);
        end

        % Button pushed function: SaveCurrentLocationsSPACEButton
        function SaveCurrentLocationsSPACEButtonPushed(app, event)
            SaveCurrentLocations(app);
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            close_dialog = questdlg('Do you want to save before quitting?',...
                'Close analysis window', 'Yes', 'No', 'Yes');
            
            if strcmp(close_dialog, 'Yes')
                SaveCurrentLocations(app);
            end
            
            close(app.ConeFinderUIFigure);
        end

        % Button pushed function: ChangeBoxSizeButton
        function ChangeBoxSizeButtonPushed(app, event)
            app.IsBusy = 1;
            
            change_box = questdlg('Select new Box parameters', 'Change Size of Box/Rectangle', 'Keep current box', ...
                'Select new 500x500p box', 'Enter new box-parameters', 'Keep current box');
            
            if strcmp(change_box, 'Select new 500x500p box')
                old_boxposition = app.BoxPosition;
                hi = drawrectangle('Position',[0 0 500 500]); % imrect(gca,[0 0 500 500]);
                wait(hi);
                app.BoxPosition = hi.Position;
                
                delete(hi);
                UpdateBox(app, old_boxposition);
            elseif strcmp(change_box, 'Enter new box-parameters')
                old_boxposition = app.BoxPosition;
                app.BoxPosition = input('New box-parameters ([x y x-range y-range]: ');
                UpdateBox(app, old_boxposition);
            end
            
            app.IsBusy = 0;
        end

        % Button pushed function: DeletedSelectedConesDelButton
        function DeletedSelectedConesDelButtonPushed(app, event)
            app.IsBusy = 1;
            
            delBox = drawrectangle(); % imrect(gca);
            wait(delBox);
            
            box_delCones = delBox.Position;
            if ~isempty(box_delCones)
                mindelx = box_delCones(1);
                mindely = box_delCones(2);
                maxdelx = mindelx + box_delCones(3);
                maxdely = mindely + box_delCones(4);
                
                app.Conelocs((app.Conelocs(:,1)>=mindelx & app.Conelocs(:,1)<=maxdelx & app.Conelocs(:,2)>=mindely & app.Conelocs(:,2)<=maxdely),:)= [];
                
                delete(delBox);
                
                UpdateImage(app);
                title([app.Fname,'  ',num2str(size(app.Conelocs(app.Conelocs(:,3)~=0,1),1)),' cones'],'Interpreter','none')
            end
            
            app.IsBusy = 0;
        end

        % Button pushed function: AddConelocsbyfastpeakfindButton
        function AddConelocsbyfastpeakfindButtonPushed(app, event)
            app.IsBusy = 1;
            
            add_conelocs = [];
            
            fPFBox = drawrectangle(); % imrect(gca);
            wait(fPFBox);
            
            box_addCones = fPFBox.Position;
            
            if ~isempty(box_addCones)
                [rows, cols] = size(app.Image);
                
                minaddx = floor(box_addCones(1));
                minaddy = floor(box_addCones(2));
                if minaddx < 1
                    minaddx = 1;
                end
                if minaddy < 1
                    minaddy = 1;
                end
                
                maxaddx = minaddx + ceil(box_addCones(3));
                maxaddy = minaddy + ceil(box_addCones(4));
                if maxaddx > cols
                    maxaddx = cols;
                end
                if maxaddy > rows
                    maxaddy = rows;
                end
                
                p = FastPeakFind(app.Image(minaddy:maxaddy, minaddx:maxaddx));
                add_conelocs(:,1) = p(1:2:end)+minaddx-1;
                add_conelocs(:,2) = p(2:2:end)+minaddy-1;
                add_conelocs(:,3) = 3;
                
                app.Conelocs = [app.Conelocs; add_conelocs];
                app.Conelocs = unique(app.Conelocs, 'rows', 'stable');
                
                delete(fPFBox);
                
                UpdateImage(app);
                title([app.Fname,'  ',num2str(size(app.Conelocs(app.Conelocs(:,3)~=0,1),1)),' cones'],'Interpreter','none');
            end
            
            app.IsBusy = 0;
        end

        % Button pushed function: AddConelocsOutsideBoxCNNButton
        function AddConelocsOutsideBoxCNNButtonPushed(app, event)
            app.IsBusy = 1;
            
            fPFBox = drawrectangle(); % imrect(gca);
            wait(fPFBox);
            
            box_addCones = fPFBox.Position;

            if ~isempty(box_addCones)
                minaddx = box_addCones(1);
                minaddy = box_addCones(2);
                maxaddx = minaddx + box_addCones(3);
                maxaddy = minaddy + box_addCones(4);
                
                app.Conelocs(app.Conelocs(:,1)>=minaddx & ...
                             app.Conelocs(:,1)<=maxaddx & ...
                             app.Conelocs(:,2)>=minaddy & ...
                             app.Conelocs(:,2)<=maxaddy,3)= 3;
                
                app.Conelocs = unique(app.Conelocs, 'rows', 'stable');
                
                delete(fPFBox);
                
                UpdateImage(app);
                title([app.Fname,'  ',num2str(size(app.Conelocs(app.Conelocs(:,3)~=0,1),1)),' cones'],'Interpreter','none');
            end
            
            app.IsBusy = 0;
        end

        % Button pushed function: NextMosaicImageButton
        function NextMosaicImageButtonPushed(app, event)
            if app.MultipleMosaics
                [~,  Num_mosaics] = size(app.ChangeMosaic);
                if app.Mosaic < Num_mosaics
                    app.Mosaic = app.Mosaic+1;
                elseif app.Mosaic == Num_mosaics
                    app.Mosaic = 1;
                end
                
                app.Image = app.ChangeMosaic{app.Mosaic};
                UpdateImage(app);
                title([app.Fname,'  ',num2str(size(app.Conelocs(app.Conelocs(:,3)~=0,1),1)),' cones', '  mosaic ', num2str(app.Mosaic), '/', num2str(Num_mosaics)],'Interpreter','none');
            end
        end

        % Close request function: ConeFinderUIFigure
        function ConeFinderUIFigureCloseRequest(app, event)
            app.IsClosing = 1;
            try
                close(app.FigureHandler);
            catch
            end
            delete(app);
        end

        % Value changed function: ShowGridQSwitch
        function ShowGridQSwitchValueChanged(app, event)
            app.ShowGrid = app.ShowGridQSwitch.Value;
            UpdateImage(app);
        end

        % Value changed function: ShowGirdNumbersESwitch
        function ShowGirdNumbersESwitchValueChanged(app, event)
            app.ShowGridNumbers = app.ShowGirdNumbersESwitch.Value;
            UpdateImage(app);
        end

        % Value changed function: ShowConeLocationsCheckBox
        function ShowConeLocationsCheckBoxValueChanged(app, event)
            app.PrintConesOnVoronoi = app.ShowConeLocationsCheckBox.Value;
        end

        % Button pushed function: DelOutsideImageButton
        function DelOutsideImageButtonPushed(app, event)
            app.RemoveBlackCones();
            UpdateImage(app);
        end

        % Button pushed function: DelDoublesButton
        function DelDoublesButtonPushed(app, event)
            app.RemoveDoubleCones();
            UpdateImage(app);
        end

        % Callback function
        function CorrectConeLocsINDEVELOPButtonPushed(app, event)
            % draw polygon of interest
            poly = impoly;
            roi = getPosition(poly);
            % get cone locations inside the polygon
            [in, ~] = inpolygon(app.Conelocs(:, 1), app.Conelocs(:, 2), roi(:, 1), roi(:, 2));
            roiPointsX = app.Conelocs(:, 1);
            roiPointsX = roiPointsX(in);
            roiPointsY = app.Conelocs(:, 2);
            roiPointsY = roiPointsY(in);
            roiPoints = [roiPointsX roiPointsY];
            
            AdjMat = GetDelanauAdjacency(app, roiPoints);
            % Calculate distance between all points
            D = pdist2(roiPoints, roiPoints);
            % Calculate distance between points conected with delaunay triangles
            Distance = AdjMat.*D; % All values between points which are not connected with delaunay tri are zeros
            
%             dt = delaunayTriangulation(roiPointsX, roiPointsY);
            % get number of neighbours for all points
%             [~,C] = voronoiDiagram(dt);
%             NumNeighbors = arrayfun(@(a) size(C{a},2), 1 : length (C));
            
            minDistance = min(min(Distance(Distance > 0)));
%             maxDistance = max(max(Distance));
            maxDistance = minDistance;
%             minNumNeighbors = min(min(NumNeighbors));
%             maxNumNeighbors = max(max(NumNeighbors));
            
            % correct position by bright pixel
            % correctedPoints = app.FixateBrightCones(roiPoints);
            % correct by main alg
            correctedPoints = CorrectPoints(app, roiPoints, roi, minDistance, maxDistance);
            
            % save last result
            app.CorrectionRegionOfInterest = roi;
            app.CorrectedPoints = correctedPoints(:, 1:2);
            
            delete(poly);
            
            UpdateImage(app);
            disp('Coerrection is finished');
            % plot(correctedPoints(:,1), correctedPoints(:,2), '.m', 'MarkerSize', app.MarkerSize);
        end

        % Value changed function: ShowLastCorrectionResultSwitch
        function ShowLastCorrectionResultSwitchValueChanged(app, event)
            UpdateImage(app);
        end

        % Button pushed function: AddMosaicImageButton
        function AddMosaicImageButtonPushed(app, event)
            start_path = ([cd filesep]);
            % file selection GUI
            [fnames, app.Pname] = uigetfile('*.mat;*.tiff;*.tif;', 'Select file(s) to mark', start_path, 'MultiSelect', 'on');
            
            % checks if user cancelled
            if isequal(fnames,0) || isequal(app.Pname,0)
                % if cancelled exit script
                return
            end
            
            % determine number of selected files
            if ischar(fnames)
                nfiles = 1;
                fnames = {fnames};
            else
                nfiles = size(fnames(:),1);
            end
            % get the current number of mosaic images
            [~,  numMosaics] = size(app.ChangeMosaic);
            % new number of images
            nfiles = nfiles + numMosaics;
            
            app.MultipleMosaics = 1;
            LoadMosaic(app, app.Fname, fnames, numMosaics + 1, nfiles);
        end

        % Button pushed function: ComparewithButton
        function ComparewithButtonPushed(app, event)
            if ~app.IsComparing
                app.IsComparing = 1;
                start_path = ([cd filesep]);
                
                % file selection GUI
                [fnames, pname] = uigetfile('*.mat;', 'Select file(s) to mark', start_path, 'MultiSelect', 'on');
                
                % checks if user cancelled
                if isequal(fnames,0) || isequal(pname,0)
                    % display a message in the commmand window
                    disp(' ');disp(' ');disp(' ');disp(' ----> User cancelled script');disp(' ');disp(' ');
                    app.IsComparing = 0;
                    % if cancelled exit script
                    return
                end
                
                [~, ~, ext] = fileparts([pname fnames]);
                
                if strcmp(ext,'.mat') == 1
                    % load data
                    load([pname fnames], 'I', 'conelocs', 'boxposition');
                    
                    % load second image
                    app.Image2 = I;
                    % load cone locations
                    app.Conelocs2 = conelocs;
                    
                    if size(app.Conelocs2, 2) < 3
                        app.Conelocs2(:, 3) = 1;
                    end
                    
                    app.Conelocs2 = unique(app.Conelocs2, 'rows', 'stable');
                    % load cone count
                    app.ConeCount2 = size(app.Conelocs2(app.Conelocs2(:,3)~=0,1),1);
                    app.TotalConeCount2 = size(app.Conelocs2,1);
                    % load box position
                    app.BoxPosition2 = boxposition;
                    app.Minlimx2 = app.BoxPosition2(1);
                    
                    if app.Minlimx2 < 0
                        app.Minlimx2 = 0;
                    end
                    
                    app.Minlimy2 = app.BoxPosition2(2);
                    if app.Minlimy2 < 0
                        app.Minlimy2 = 0;
                    end
                    
                    [h, w, ~] = size(app.Image2);
                    app.Maxlimx2 = app.Minlimx2 + app.BoxPosition2(3);
                    if app.Maxlimx2 > w
                        app.Maxlimx2 = w;
                    end
                    
                    app.Maxlimy2 = app.Minlimy2 + app.BoxPosition2(4);
                    if app.Maxlimy2 > h
                        app.Maxlimy2 = h;
                    end
                    
                    % plot images
                    ax1 = subplot(1,2,1);
                    imshow(app.Image);
                    ttl1 = title({'Source image', [num2str(app.ConeCount),' cones']}, 'Interpreter','none');
                    ttl1.BackgroundColor = [1 0 0];
                    
                    ax2 = subplot(1,2,2);
                    imshow(I);
                    ttl2 = title({'Image to compare'; [num2str(app.ConeCount2),' cones']}, 'Interpreter','none');
                    ttl2.BackgroundColor = [1 0 0];
                    
                    linkaxes([ax1 ax2]);
                    dragzoom();
                    UpdateImage(app);
                end
            else
                app.IsComparing = 0;
                app.VoronoiPlot = 1;
                dragzoom('off');
                app.Image2 = [];
                
                subplot(1, 1, 1);
                UpdateImage(app);
                
                title([app.Fname,'  ',num2str(app.ConeCount),' cones'],'Interpreter','none');
                ax = gca;                    % get the current axis
                app.Ttl = ax.Title;              % get the title text object
                app.Ttl.FontSize = 14;
                app.Ttl.BackgroundColor = [1 0 0];
            end
            UpdateUI(app);
        end

        % Value changed function: LapOfGausSwitch
        function LapOfGausSwitchValueChanged(app, event)
            app.LapOfGaus = app.LapOfGausSwitch.Value;
            UpdateImage(app);
        end

        % Button pushed function: CorrectConeLocs2INDEVELOPButton
        function CorrectConeLocs2INDEVELOPButtonPushed(app, event)
            % draw polygon of interest
            poly = imrect(gca);
            roi = wait(poly);
            roi = [roi(1), roi(2); 
                roi(1), roi(2) + roi(4); 
                roi(1) + roi(3), roi(2) + roi(4); 
                roi(1) + roi(3), roi(2)];
            % poly = impoly;
            % get cone locations inside the polygon
            [in, ~] = inpolygon(app.Conelocs(:, 1), app.Conelocs(:, 2), roi(:, 1), roi(:, 2));
            roiPointsX = app.Conelocs(:, 1);
            roiPointsX = roiPointsX(in);
            roiPointsY = app.Conelocs(:, 2);
            roiPointsY = roiPointsY(in);
            roiPoints = [roiPointsX roiPointsY];
            
            AdjMat = GetDelanauAdjacency(app, roiPoints);
            % Calculate distance between all points
            D = pdist2(roiPoints, roiPoints);
            % Calculate distance between points conected with delaunay triangles
            Distance = AdjMat.*D; % All values between points which are not connected with delaunay tri are zeros
            
            dt = delaunayTriangulation(roiPointsX, roiPointsY);
            % get number of neighbours for all points
            [~,C] = voronoiDiagram(dt);
            NumNeighbors = arrayfun(@(a) size(C{a},2), 1 : length (C));
            
            minDistance = min(min(Distance(Distance > 0)));
            maxDistance = max(max(Distance));
            avgDistance = mean(Distance(Distance > 0));
            % minNumNeighbors = min(min(NumNeighbors));
            maxNumNeighbors = max(max(NumNeighbors));
            
            prompt = {sprintf('min dist: %f / avg dist: %f', minDistance, avgDistance), ...
                sprintf('max dist: %f', maxDistance)};
            dlgtitle = 'Enter distance between cones';
            dims = [1 35];
            definput = {num2str(minDistance), num2str(maxDistance)};
            answer = inputdlg(prompt, dlgtitle, dims, definput);
            minDistance = str2double(answer{1});
            maxDistance = str2double(answer{2});
                      
            imageSize = size(app.Image);
            % I has transposed coordinates
            imageSize = imageSize(:,[2 1]);
            prompt = {sprintf('Statistical min brightness of the center: %f \nStatistical min brightness of the border: %f \nCenter box: TopLeft - [%d %d], BottomRight - [%d %d]', ...
                46, 36, int32(round(imageSize(1) * 0.36)), int32(round(imageSize(2) * 0.36)), ...
                int32(round(imageSize(1) * 0.64)), int32(round(imageSize(2) * 0.64)))};
            dlgtitle = 'Enter minimal brightness (0-255)';
            dims = 1;
            definput = {num2str(46)};
            answer = inputdlg(prompt, dlgtitle, dims, definput);
            minBrightness = str2double(answer{1});
            
            % disable all UI
            set(findall(app.ConeFinderUIFigure, '-property', 'Enable'), 'Enable', 'off');
            % correct by main alg
            correctedPoints = CorrectPoints2(app, roi, minDistance, maxDistance, maxNumNeighbors, minBrightness);
            
            % save last result
            app.CorrectionRegionOfInterest = roi;
            app.CorrectedPoints = correctedPoints(:, 1:2);
            
            delete(poly);
            app.ShowLastCorrectionResultSwitch.Value = 'On';
            
            UpdateImage(app);
            UpdateUI(app);
            disp('Coerrection is finished');
            
            % plot(correctedPoints(:,1), correctedPoints(:,2), '.m', 'MarkerSize', app.MarkerSize);
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            if ~isempty(app.CorrectedPoints)
                app.CorrectedPoints(:, 3) = 1;
                app.Conelocs = [app.Conelocs; app.CorrectedPoints];
                app.CorrectedPoints = [];
                UpdateImage(app);
            end
        end

        % Value changed function: UseCorretedConesCheckBox
        function UseCorretedConesCheckBoxValueChanged(app, event)
            app.UseCorrectedPointsInVoronoi = app.UseCorretedConesCheckBox.Value;
        end

        % Value changed function: EditCorrectedConesCheckBox
        function EditCorrectedConesCheckBoxValueChanged(app, event)
            app.EditCorrectedCones = app.EditCorrectedConesCheckBox.Value;
            UpdateUI(app);
            UpdateImage(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ConeFinderUIFigure = uifigure('Visible', 'off');
            app.ConeFinderUIFigure.Name = 'Cone Finder';
            app.ConeFinderUIFigure.CloseRequestFcn = createCallbackFcn(app, @ConeFinderUIFigureCloseRequest, true);
            app.SaveCurrentLocationsSPACEButton = uibutton(app.ConeFinderUIFigure, 'push');
            app.SaveCurrentLocationsSPACEButton.ButtonPushedFcn = createCallbackFcn(app, @SaveCurrentLocationsSPACEButtonPushed, true);
            app.SaveCurrentLocationsSPACEButton.Text = 'Save Current Locations [SPACE]';
            app.ExitButton = uibutton(app.ConeFinderUIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.ImagePropertiesPanel = uipanel(app.ConeFinderUIFigure);
            app.ImagePropertiesPanel.Title = 'Image Properties';
            app.NextMosaicImageButton = uibutton(app.ImagePropertiesPanel, 'push');
            app.NextMosaicImageButton.ButtonPushedFcn = createCallbackFcn(app, @NextMosaicImageButtonPushed, true);
            app.NextMosaicImageButton.Text = 'Next Mosaic Image';
            app.LogImageLSwitchLabel = uilabel(app.ImagePropertiesPanel);
            app.LogImageLSwitchLabel.Text = 'Log Image [L]';
            app.LogImageLSwitch = uiswitch(app.ImagePropertiesPanel, 'slider');
            app.LogImageLSwitch.ValueChangedFcn = createCallbackFcn(app, @LogImageLSwitchValueChanged, true);
            app.ShowimageISwitchLabel = uilabel(app.ImagePropertiesPanel);
            app.ShowimageISwitchLabel.Text = 'Show image [I]';
            app.ShowimageISwitch = uiswitch(app.ImagePropertiesPanel, 'slider');
            app.ShowimageISwitch.ValueChangedFcn = createCallbackFcn(app, @ShowimageISwitchValueChanged, true);
            app.ShowimageISwitch.Value = 'On';
            app.BrightnessPanel = uipanel(app.ImagePropertiesPanel);
            app.BrightnessPanel.Title = 'Brightness';
            app.BrightnessDBSliderLabel = uilabel(app.BrightnessPanel);
            app.BrightnessDBSliderLabel.Text = 'Brightness [D, B]';
            app.BrightnessDBSlider = uislider(app.BrightnessPanel);
            app.BrightnessDBSlider.Limits = [0 2];
            app.BrightnessDBSlider.MajorTicks = [0 0.5 1 1.5 2];
            app.BrightnessDBSlider.ValueChangingFcn = createCallbackFcn(app, @BrightnessDBSliderValueChanging, true);
            app.BrightnessDBSlider.Value = 1;
            app.ResetBrightnessRButton = uibutton(app.BrightnessPanel, 'push');
            app.ResetBrightnessRButton.ButtonPushedFcn = createCallbackFcn(app, @ResetBrightnessRButtonValueChanged, true);
            app.ResetBrightnessRButton.Text = 'Reset Brightness [R]';
            app.GridPanel = uipanel(app.ImagePropertiesPanel);
            app.GridPanel.Title = 'Grid';
            app.ShowGridQSwitchLabel = uilabel(app.GridPanel);
            app.ShowGridQSwitchLabel.Text = 'Show Grid [Q]';
            app.ShowGridQSwitch = uiswitch(app.GridPanel, 'slider');
            app.ShowGridQSwitch.ValueChangedFcn = createCallbackFcn(app, @ShowGridQSwitchValueChanged, true);
            app.ShowGirdNumbersESwitchLabel = uilabel(app.GridPanel);
            app.ShowGirdNumbersESwitchLabel.Text = 'Show Gird Numbers [E]';
            app.ShowGirdNumbersESwitch = uiswitch(app.GridPanel, 'slider');
            app.ShowGirdNumbersESwitch.ValueChangedFcn = createCallbackFcn(app, @ShowGirdNumbersESwitchValueChanged, true);
            app.AddMosaicImageButton = uibutton(app.ImagePropertiesPanel, 'push');
            app.AddMosaicImageButton.ButtonPushedFcn = createCallbackFcn(app, @AddMosaicImageButtonPushed, true);
            app.AddMosaicImageButton.Text = 'Add Mosaic Image';
            app.LapOfGausLabel = uilabel(app.ImagePropertiesPanel);
            app.LapOfGausLabel.Text = 'LapOfGaus';
            app.LapOfGausSwitch = uiswitch(app.ImagePropertiesPanel, 'slider');
            app.LapOfGausSwitch.ValueChangedFcn = createCallbackFcn(app, @LapOfGausSwitchValueChanged, true);
            app.MarksPropertiesPanel = uipanel(app.ConeFinderUIFigure);
            app.MarksPropertiesPanel.Title = 'Marks Properties';
            app.ConeMarksTypeButtonGroup = uibuttongroup(app.MarksPropertiesPanel);
            app.ConeMarksTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ConeMarksTypeButtonGroupSelectionChanged, true);
            app.ConeMarksTypeButtonGroup.Title = 'Cone Marks Type';
            app.DotZButton = uiradiobutton(app.ConeMarksTypeButtonGroup);
            app.DotZButton.Text = 'Dot [Z]';
            app.DotZButton.Value = true;
            app.CircleCButton = uiradiobutton(app.ConeMarksTypeButtonGroup);
            app.CircleCButton.Text = 'Circle [C]';
            app.CrossXButton = uiradiobutton(app.ConeMarksTypeButtonGroup);
            app.CrossXButton.Text = 'Cross [X]';
            app.ShowMarksOptionsButtonGroup = uibuttongroup(app.MarksPropertiesPanel);
            app.ShowMarksOptionsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ShowMarksOptionsButtonGroupSelectionChanged, true);
            app.ShowMarksOptionsButtonGroup.Title = 'Show Marks Options';
            app.AutoUser3Button = uiradiobutton(app.ShowMarksOptionsButtonGroup);
            app.AutoUser3Button.Text = 'Auto + User [3]';
            app.AutoUser3Button.Value = true;
            app.Userdetect2Button = uiradiobutton(app.ShowMarksOptionsButtonGroup);
            app.Userdetect2Button.Text = 'Userdetect [2]';
            app.Autodetect1Button = uiradiobutton(app.ShowMarksOptionsButtonGroup);
            app.Autodetect1Button.Text = 'Autodetect [1]';
            app.HighlightAutodetectedCones0SwitchLabel = uilabel(app.MarksPropertiesPanel);
            app.HighlightAutodetectedCones0SwitchLabel.Text = 'Highlight Autodetected Cones [0]';
            app.HighlightAutodetectedCones0Switch = uiswitch(app.MarksPropertiesPanel, 'slider');
            app.HighlightAutodetectedCones0Switch.ValueChangedFcn = createCallbackFcn(app, @HighlightAutodetectedCones0SwitchValueChanged, true);
            app.ShowMarkedLocationsTSwitchLabel = uilabel(app.MarksPropertiesPanel);
            app.ShowMarkedLocationsTSwitchLabel.Text = 'Show Marked Locations [T]';
            app.ShowMarkedLocationsTSwitch = uiswitch(app.MarksPropertiesPanel, 'slider');
            app.ShowMarkedLocationsTSwitch.ValueChangedFcn = createCallbackFcn(app, @ShowMarkedLocationsTSwitchValueChanged, true);
            app.ShowMarkedLocationsTSwitch.Value = 'On';
            app.EditConeLocationsPanel = uipanel(app.ConeFinderUIFigure);
            app.EditConeLocationsPanel.Title = 'Edit Cone Locations';
            app.EditModeSwitchLabel = uilabel(app.EditConeLocationsPanel);
            app.EditModeSwitchLabel.Visible = 'off';
            app.EditModeSwitchLabel.Text = 'Edit Mode';
            app.EditModeSwitch = uiswitch(app.EditConeLocationsPanel, 'slider');
            app.EditModeSwitch.ValueChangedFcn = createCallbackFcn(app, @EditModeSwitchValueChanged, true);
            app.EditModeSwitch.Visible = 'off';
            app.ChangeBoxSizeButton = uibutton(app.EditConeLocationsPanel, 'push');
            app.ChangeBoxSizeButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeBoxSizeButtonPushed, true);
            app.ChangeBoxSizeButton.Text = 'Change Box Size';
            app.DeletedSelectedConesDelButton = uibutton(app.EditConeLocationsPanel, 'push');
            app.DeletedSelectedConesDelButton.ButtonPushedFcn = createCallbackFcn(app, @DeletedSelectedConesDelButtonPushed, true);
            app.DeletedSelectedConesDelButton.Text = 'Deleted Selected Cones [Del]';
            app.AddConelocsbyfastpeakfindButton = uibutton(app.EditConeLocationsPanel, 'push');
            app.AddConelocsbyfastpeakfindButton.ButtonPushedFcn = createCallbackFcn(app, @AddConelocsbyfastpeakfindButtonPushed, true);
            app.AddConelocsbyfastpeakfindButton.Text = 'Add Conelocs by fast peak find';
            app.AddConelocsOutsideBoxCNNButton = uibutton(app.EditConeLocationsPanel, 'push');
            app.AddConelocsOutsideBoxCNNButton.ButtonPushedFcn = createCallbackFcn(app, @AddConelocsOutsideBoxCNNButtonPushed, true);
            app.AddConelocsOutsideBoxCNNButton.Text = 'Add Conelocs Outside Box (CNN)';
            app.HelpTextAreaLabel = uilabel(app.EditConeLocationsPanel);
            app.HelpTextAreaLabel.Text = 'Help';
            app.HelpTextArea = uitextarea(app.EditConeLocationsPanel);
            app.HelpTextArea.Editable = 'off';
            app.HelpTextArea.Value = {'HOTKEYS WORKS ONLY IN EDIT MODE!!!!!'; ''; 'Mouse Left Click - place Mark'; 'Mouse Right Click - remove Mark'; '''+'' - zoom in'; '''-'' - zoom out'; '↓ ↑  ← → - pan image'; 'Esc - turn off Edit Mode'};
            app.EditCorrectedConesCheckBox = uicheckbox(app.EditConeLocationsPanel);
            app.EditCorrectedConesCheckBox.ValueChangedFcn = createCallbackFcn(app, @EditCorrectedConesCheckBoxValueChanged, true);
            app.EditCorrectedConesCheckBox.Text = 'Edit Corrected Cones';
            app.StartConeFinderButton = uibutton(app.ConeFinderUIFigure, 'push');
            app.StartConeFinderButton.ButtonPushedFcn = createCallbackFcn(app, @StartConeFinderButtonValueChanged, true);
            app.StartConeFinderButton.Text = 'Start Cone Finder';
            app.VoronoiPanel = uipanel(app.ConeFinderUIFigure);
            app.VoronoiPanel.Title = 'Voronoi';
            app.VoronoiVNButton = uibutton(app.VoronoiPanel, 'push');
            app.VoronoiVNButton.ButtonPushedFcn = createCallbackFcn(app, @VoronoiVNButtonPushed, true);
            app.VoronoiVNButton.Text = 'Voronoi [V, N]';
            app.ShowConeLocationsCheckBox = uicheckbox(app.VoronoiPanel);
            app.ShowConeLocationsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowConeLocationsCheckBoxValueChanged, true);
            app.ShowConeLocationsCheckBox.Text = 'Show Cone Locations';
            app.UseCorretedConesCheckBox = uicheckbox(app.VoronoiPanel);
            app.UseCorretedConesCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseCorretedConesCheckBoxValueChanged, true);
            app.UseCorretedConesCheckBox.Text = 'Use Correted Cones';
            app.ImproveCNNResultsPanel = uipanel(app.ConeFinderUIFigure);
            app.ImproveCNNResultsPanel.Title = 'Improve CNN Results';
            app.DelOutsideImageButton = uibutton(app.ImproveCNNResultsPanel, 'push');
            app.DelOutsideImageButton.ButtonPushedFcn = createCallbackFcn(app, @DelOutsideImageButtonPushed, true);
            app.DelOutsideImageButton.Text = 'Del Outside Image';
            app.DelDoublesButton = uibutton(app.ImproveCNNResultsPanel, 'push');
            app.DelDoublesButton.ButtonPushedFcn = createCallbackFcn(app, @DelDoublesButtonPushed, true);
            app.DelDoublesButton.Text = 'Del Doubles';
            app.ShowLastCorrectionResultSwitchLabel = uilabel(app.ImproveCNNResultsPanel);
            app.ShowLastCorrectionResultSwitchLabel.Text = 'Show Last Correction Result';
            app.ShowLastCorrectionResultSwitch = uiswitch(app.ImproveCNNResultsPanel, 'slider');
            app.ShowLastCorrectionResultSwitch.ValueChangedFcn = createCallbackFcn(app, @ShowLastCorrectionResultSwitchValueChanged, true);
            app.CorrectConeLocs2INDEVELOPButton = uibutton(app.ImproveCNNResultsPanel, 'push');
            app.CorrectConeLocs2INDEVELOPButton.ButtonPushedFcn = createCallbackFcn(app, @CorrectConeLocs2INDEVELOPButtonPushed, true);
            app.CorrectConeLocs2INDEVELOPButton.Text = 'Correct Cone Locs2 [IN DEVELOP]';
            app.ApplyButton = uibutton(app.ImproveCNNResultsPanel, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.ComparewithButton = uibutton(app.ConeFinderUIFigure, 'push');
            app.ComparewithButton.ButtonPushedFcn = createCallbackFcn(app, @ComparewithButtonPushed, true);
            app.ComparewithButton.Visible = 'off';
            app.ComparewithButton.Text = 'Compare with';
            % Show the figure after all components are created
            app.ConeFinderUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ConeFinderApp_v2020b_old_backup

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ConeFinderUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ConeFinderUIFigure)
        end
    end
end