classdef ConeMapperApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CMUIFigure                     matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        NewrecognitionMenu             matlab.ui.container.Menu
        OpenrecognitionMenu            matlab.ui.container.Menu
        SaverecognitionMenu            matlab.ui.container.Menu
        SettingsMenu                   matlab.ui.container.Menu
        ExitMenu                       matlab.ui.container.Menu
        ImportExportMenu               matlab.ui.container.Menu
        ImportConeLocationsMenu        matlab.ui.container.Menu
        ExportConeLocationsMenu        matlab.ui.container.Menu
        MetricsMenu                    matlab.ui.container.Menu
        ShowStatsWindowMenu            matlab.ui.container.Menu
        EuclideanNConesMenu            matlab.ui.container.Menu
        NNDmeanMenu                    matlab.ui.container.Menu
        YellotsRingsMenu               matlab.ui.container.Menu
        HelpMenu                       matlab.ui.container.Menu
        ShowdocumentationMenu          matlab.ui.container.Menu
        AboutMenu                      matlab.ui.container.Menu
        ReceptorLabel                  matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        ViewTab                        matlab.ui.container.Tab
        DensitycontourPanel            matlab.ui.container.Panel
        DensContGaussFilterSigmaEditField  matlab.ui.control.NumericEditField
        Label_3                        matlab.ui.control.Label
        DensContGaussFilterCheckBox    matlab.ui.control.CheckBox
        MainLinesPercentageDifferenceEditField  matlab.ui.control.NumericEditField
        Label_2                        matlab.ui.control.Label
        SingleLinePercentageEditField  matlab.ui.control.NumericEditField
        Label                          matlab.ui.control.Label
        ShowPercentTextCheckBox        matlab.ui.control.CheckBox
        ShowMainPercentageLinesCheckBox  matlab.ui.control.CheckBox
        ShowSinglePercentageLineCheckBox  matlab.ui.control.CheckBox
        ContoursCheckBox               matlab.ui.control.CheckBox
        TextLabelColorPreviewAxes      matlab.ui.control.UIAxes
        SingleLineColorPreviewAxes     matlab.ui.control.UIAxes
        MainColorPreviewAxes           matlab.ui.control.UIAxes
        OtherPanel                     matlab.ui.container.Panel
        ScaleRingsCheckBox             matlab.ui.control.CheckBox
        ColorBarCheckBox               matlab.ui.control.CheckBox
        ScaleBarUnitsDropDown          matlab.ui.control.DropDown
        GridNumbersCheckBox            matlab.ui.control.CheckBox
        GridCheckBox                   matlab.ui.control.CheckBox
        ScaleBarCheckBox               matlab.ui.control.CheckBox
        ScaleBarColorPreviewAxes       matlab.ui.control.UIAxes
        DensitymapButtonGroup          matlab.ui.container.ButtonGroup
        zScoremapCheckBox              matlab.ui.control.CheckBox
        DensityReportUnitsLabel        matlab.ui.control.Label
        MaxLimEditField                matlab.ui.control.NumericEditField
        maxEditFieldLabel              matlab.ui.control.Label
        MinLimEditField                matlab.ui.control.NumericEditField
        minEditFieldLabel              matlab.ui.control.Label
        AutoLimCheckBox                matlab.ui.control.CheckBox
        ApproximationEdgeCheckBox      matlab.ui.control.CheckBox
        CMapDensityDropDown            matlab.ui.control.DropDown
        CMapDropDownLabel              matlab.ui.control.Label
        AlphaEditField                 matlab.ui.control.NumericEditField
        AlphaSlider                    matlab.ui.control.Slider
        AlphaSliderLabel               matlab.ui.control.Label
        CDCCheckBox                    matlab.ui.control.CheckBox
        PCDCheckBox                    matlab.ui.control.CheckBox
        ShowDMCheckBox                 matlab.ui.control.CheckBox
        YellotsRingsButton             matlab.ui.control.RadioButton
        NNDmeanButton                  matlab.ui.control.RadioButton
        EuclideanNConesButton          matlab.ui.control.RadioButton
        VoronoidiagramButtonGroup      matlab.ui.container.ButtonGroup
        CMapVoronoiDensityDropDown     matlab.ui.control.DropDown
        CMapDropDownLabel_2            matlab.ui.control.Label
        VoronoiDiagramCheckBox         matlab.ui.control.CheckBox
        NNeighbourButton               matlab.ui.control.RadioButton
        AreaButton                     matlab.ui.control.RadioButton
        VoidButton                     matlab.ui.control.RadioButton
        MarksPropertiesPanel           matlab.ui.container.Panel
        ShowMarkedLocationsTCheckBox   matlab.ui.control.CheckBox
        AutovsUserdetectCheckBox       matlab.ui.control.CheckBox
        ShowMarkedOptionsButtonGroup   matlab.ui.container.ButtonGroup
        Autodetect1Button              matlab.ui.control.RadioButton
        Userdetect2Button              matlab.ui.control.RadioButton
        AutoUserButton                 matlab.ui.control.RadioButton
        ConeMarksTypeButtonGroup       matlab.ui.container.ButtonGroup
        CrossButton                    matlab.ui.control.RadioButton
        CircleButton                   matlab.ui.control.RadioButton
        DotButton                      matlab.ui.control.RadioButton
        ImagePropertiesPanel           matlab.ui.container.Panel
        ResetButton                    matlab.ui.control.Button
        BrightnessSlider               matlab.ui.control.Slider
        BrightnessSliderLabel          matlab.ui.control.Label
        ShowImageICheckBox             matlab.ui.control.CheckBox
        LaplacianofGaussianfilterCheckBox  matlab.ui.control.CheckBox
        LogfilterCheckBox              matlab.ui.control.CheckBox
        EditTab                        matlab.ui.container.Tab
        ReceptorsTypeDropDown          matlab.ui.control.DropDown
        ReceptorstypeDropDownLabel     matlab.ui.control.Label
        HelpTextArea                   matlab.ui.control.TextArea
        HelpTextAreaLabel              matlab.ui.control.Label
        ImproveCNNResultsPanel         matlab.ui.container.Panel
        DeletedSelectedConesButton     matlab.ui.control.Button
        FindConesCheckBox              matlab.ui.control.CheckBox
        TrainingDropDown               matlab.ui.control.DropDown
        TrainingDropDownLabel          matlab.ui.control.Label
        AlgorithmDropDown              matlab.ui.control.DropDown
        AlgorithmDropDownLabel         matlab.ui.control.Label
        ApplySelectedButton            matlab.ui.control.Button
        DelDoublesCheckBox             matlab.ui.control.CheckBox
        DelOutsideImageCheckBox        matlab.ui.control.CheckBox
        MetricsTab                     matlab.ui.container.Tab
        EyetypeButtonGroup             matlab.ui.container.ButtonGroup
        NoneButton                     matlab.ui.control.RadioButton
        RightButton                    matlab.ui.control.RadioButton
        LeftButton                     matlab.ui.control.RadioButton
        UnitssettingsPanel             matlab.ui.container.Panel
        RMFUnitsLabel                  matlab.ui.control.Label
        IMFUnitsLabel                  matlab.ui.control.Label
        SaveRMFButton                  matlab.ui.control.Button
        LockRMFButton                  matlab.ui.control.Button
        SaveIMFButton                  matlab.ui.control.Button
        LockPPDButton                  matlab.ui.control.Button
        RMFEditField                   matlab.ui.control.NumericEditField
        RMFEditFieldLabel              matlab.ui.control.Label
        IMFEditField                   matlab.ui.control.NumericEditField
        IMFEditFieldLabel              matlab.ui.control.Label
        ReportunitsDropDown            matlab.ui.control.DropDown
        ReportunitsDropDownLabel       matlab.ui.control.Label
        DensitycalculationparametersPanel  matlab.ui.container.Panel
        YellotsROIsizeEditField        matlab.ui.control.NumericEditField
        YellotsROIsizeEditFieldLabel   matlab.ui.control.Label
        EuclideanNconesEditField       matlab.ui.control.NumericEditField
        EuclideanNconesLabel           matlab.ui.control.Label
        InfoLabel                      matlab.ui.control.Label
    end

    
    properties (Access = private)
         
        % Zoom configuration options
        Magnify = 1.1;
        XMagnify = 1.0;
        YMagnify = 1.0;
        ChangeMagnify = 1.1;
        IncreaseChange = 1.1;
        MinValue = 1.1;
        MaxZoomScrollCount = 30;
        ImgWidth = 0;
        ImgHeight = 0;
        ZoomScrollCount = 0;
        XLim=[];
        YLim=[];

        % PRL marker size. CDC and PCD sizes depends on that value (to make them look equal)
        MarkerSizePRL = 8;
        
        % Shows whether app is Busy
        IsBusy = 0;
        
        AutoSaveTimer = [];
        
        % Handler of figure window
        FigureHandler           matlab.ui.Figure;
        ScrollPanelHandler;
        AxisHandler             matlab.graphics.axis.Axes;
        ImageShowHandler        matlab.graphics.primitive.Image;
        MagBoxHandler;
        ScrollPanelApi;
        MiniMapHandler;
        AxisForColorbarHandler  matlab.graphics.axis.Axes;
        ColorbarHandler;
        ConeUserPlotHandler;
        ConeAutoPlotHandler;
        RodPlotHandler;
        VoronoiPatchHandler;
        GridHorLinesHandler;
        GridVertLinesHandler;
        GridTextHandler;
        ScaleRingsHandler = [];
        ScaleRingsTextHandler = [];
        ScaleRingsHorAxHandler = [];
        ScaleRingsVertAxHandler = [];
        MagnificationAndMoveCallbackIds = {0,0};
        IsClosing = 0;
        
        OldFormatLoaded = 0;
        
        SettingUIFigureHandler = [];
        
        % Metrics
        DensityMapImageHandler = [];
        DensityAlphaValue = 0.4;

        PCDandCDChandler = [];
        ScaleBar = [];
        
        DensityContoursHandler = {[], []; [], []};
        DensityContoursUISettingsHandler = [];
        DensityCalcSettingsHandler;
        DensityTextHandlers = [];
        
        CurrnetMatlabVersion = [];
        
        % temp values for IMF and RMF
        IMFpreviousValue = [];
        RMFpreviousValue = [];
    end
    
    properties (Access = public)
        % Files
        Fname;
        Pname;
        
        % Image
        Image;
        
        % Voronoi
        VColorMap;
        VoronoiType = 'Empty';
        % For livetime update
        VoronoiDelaunayTriang;
        VoronoiAlphaShape;
        VoronoiVerticies;
        
        VoronoiAreaColors;
        VoronoiConeAreaColorScale;
        VoronoiAreaColorIndexes;
        VoronoiNNColors;
        VoronoiNNColorScale;
        VoronoiMinNN = 4;
        VoronoiMaxNN = 9;
        VoronoiMinConeArea = 0;
        VoronoiMaxConeArea = 100;
        
        
        % Cone Locations
        Conelocs = [];
        TotalConeCount = 0;
        ConeCount = 0;

        % Rods Locations
        Rodlocs = [];
        TotalRodCount = 0;

        % Mark properties
        MarkerSize = 5;
        ShowConesByDetectType = 0;
        Color = [1 1 0];
        Marker = '.';
        
        
        % Image properties
        Brightness = 1.0;
        LapOfGausDeviation = 0.35;
        
        % Settings
        IsAutosaveOn = 1;
        StartFolder = [];
        
        DensColorMap = [];
        % Density contours lines
        DensityContoursMainLinesPercentageDistance = 0.1;
        DensityContoursMainLinesColor = [1 0 0];
        DensityContoursSingleLinePercentage = 0.2;
        DensityContoursSingleLinesColor = [1 1 1];
        DensityTextLabelColor = [1 1 0];

        ScaleBarColor = [1 1 1];

        StatisticsWindowUIFigureHandler = [];

        % Density handlers
        EuclideanNConesHandler = [];
        NNDmeanHandler = [];
        YellotsRingsHandler = [];
        ZScoreMapHandler = [];
        AvgDensityRadialDataStruct = [];

        ImageMagnificationFactorListener;
        RetinalMagnificationFactorListener;
        DensityMinimumLimitListener;
        DensityMaximumLimitListener;


        % For preserve settings when zScore is on
        ReportUnitsPrevious = 'pixel';
        DensityMinimumLimitManual = 0;
        DensityMaximumLimitManual = 1;
        DensityMinimumLimitManualZScore = -1;
        DensityMaximumLimitManualZScore = 1;
        AutoLimitOnOffDensity = 1;
        AutoLimitOnOffZScore = 1;
        DensityColorMapPrevious = 'parula';
    end

    properties (Access = public, SetObservable = true)
        % Units
        ReportUnits = 'pixel';
        ImageMagnificationFactor = 0;
        RetinalMagnificationFactor = 0;
        DensityMinimumLimit = 0;
        DensityMaximumLimit = 0;
    end
    
    methods (Access = public)
        function UpdateParameters(app)
            if app.IsAutosaveOn
                if ~isempty(app.AutoSaveTimer) && strcmp(app.AutoSaveTimer.Running, 'off')
                    app.AutoSaveTimer.start();
                end
            else
                app.AutoSaveTimer.stop();
            end
            
            SaveParameters(app);
        end
        
        % Marks cone on image
        function MarkCone(app, x, y)
            [rows, cols, ~] = size(app.Image);
            % if point outside of the image canvas
            if (x < 1) || (x > cols) || (y < 1) || (y > rows)
                % do nothing
                return;
            end
            
            if strcmp(app.ReceptorsTypeDropDown.Value, 'Cones')
                % CNN generates more exact cone
                % locations anyway
                app.Conelocs(end + 1, 1:3) = [x, y, 2];
                
                app.Conelocs = unique(app.Conelocs, 'rows', 'stable');
                app.ConeCount = size(app.Conelocs(app.Conelocs(:,3)~=0,1), 1);
                app.TotalConeCount = size(app.Conelocs, 1);
            elseif strcmp(app.ReceptorsTypeDropDown.Value, 'Rods')
                app.Rodlocs(end + 1, 1:3) = [x, y, 2];
                
                app.Rodlocs = unique(app.Rodlocs, 'rows', 'stable');
                app.TotalRodCount = size(app.Rodlocs, 1);
            end
                
            UpdateImage(app);
            UpdateLegacy(app);
        end
        
        % Removes Mark from image
        function RemoveMark(app, x, y)
            currentloc = [x y];
            % Clear marked cone nearest to click location
            if strcmp(app.ReceptorsTypeDropDown.Value, 'Cones') && (size(app.Conelocs, 1) > 0)
                % compute Euclidean distances:
                distances = sqrt(sum(bsxfun(@minus, app.Conelocs(:,1:2), currentloc).^2, 2));

                % if distance from click to nearest cone is less than 2 px
                % we need it for safety reasons
                if min(distances) < 2
                    % find the smallest distance and delete that index in app.Conelocs:
                    app.Conelocs(distances==min(distances), :) = [];

                    app.Conelocs = unique(app.Conelocs, 'rows', 'stable');
                    app.ConeCount = size(app.Conelocs(app.Conelocs(:,3)~=0,1), 1);
                    app.TotalConeCount = size(app.Conelocs, 1);
                end

            elseif strcmp(app.ReceptorsTypeDropDown.Value, 'Rods') && (size(app.Rodlocs, 1) > 0)
                % compute Euclidean distances:
                distances = sqrt(sum(bsxfun(@minus, app.Rodlocs(:,1:2), currentloc).^2, 2));

                % if distance from click to nearest rod is less than 2 px
                % we need it for safety reasons
                if min(distances) < 2
                    % find the smallest distance and delete that index in app.Conelocs:
                    app.Rodlocs(distances==min(distances), :) = [];
                    
                    app.Rodlocs = unique(app.Rodlocs, 'rows', 'stable');
                    app.TotalRodCount = size(app.Rodlocs, 1);
                end
            end
                
            % After deletion, plot current set of cones/rods
            UpdateImage(app);
            UpdateLegacy(app);
        end
        
        % Returns the filenames for saving
        function [name1, name2] = GetSaveFilename(app)
            [pstr, nm, ext] = fileparts([app.Pname app.Fname]);
            
            if strcmp(ext,'.tiff')==1 || strcmp(ext,'.tif')==1
                name1 = [pstr filesep nm '_annotated.png'];
                name2 = [pstr filesep nm '_annotated.mat'];
            elseif strcmp(ext,'.mat')==1
                saveOldFile = 'Rewrite old file';
                if app.OldFormatLoaded
                    app.OldFormatLoaded = 0;
                    saveOldFile = questdlg('Save old file or rewrite?', 'Saving...', ...
                        'Save old file', 'Rewrite old file', 'Save old file');
                end
                if strcmp(saveOldFile, 'Rewrite old file')
                    name1 = [pstr filesep nm '.png'];
                    name2 = [app.Pname app.Fname];
                else
                    name1 = [pstr filesep nm '_corrected.png'];
                    name2 = [pstr filesep nm '_corrected.mat'];
                    [~, nm, ext] = fileparts(name2);
                    app.Fname = [nm, ext];
                end
            end
        end

        % Saves current cone locations to a .mat file
        function SaveCurrentLocations(app)
            [name1, name2] = GetSaveFilename(app);
            
            if ~app.IsClosing
                exportgraphics(app.ImageShowHandler.Parent, name1);
            end
            
            % names of variables must be the same to names in 'save' function
            conecount = app.ConeCount;
            I = app.Image;
            conelocs = app.Conelocs;
            rodlocs = app.Rodlocs;
%             pixelPerDegree = app.ImageMagnificationFactor;
            
            euclideanNCones = [];
            if ~isempty(app.EuclideanNConesHandler) && isvalid(app.EuclideanNConesHandler)
                euclideanNCones = app.EuclideanNConesHandler.saveobj();
            end
            
            nnd_mean = [];
            if ~isempty(app.NNDmeanHandler) && isvalid(app.NNDmeanHandler)
                nnd_mean = app.NNDmeanHandler.saveobj();
            end
            
            yellotsRings = [];
            if ~isempty(app.YellotsRingsHandler) && isvalid(app.YellotsRingsHandler)
                yellotsRings = app.YellotsRingsHandler.saveobj();
            end

            eyeType = app.EyetypeButtonGroup.SelectedObject.Tag;
            
            if isfile(name2)
                save(name2, 'conecount', 'I', 'conelocs', 'rodlocs', 'euclideanNCones', 'nnd_mean', 'yellotsRings', 'eyeType', '-append'); %, 'pixelPerDegree'
            else
                save(name2, 'conecount', 'I', 'conelocs', 'rodlocs', 'euclideanNCones', 'nnd_mean', 'yellotsRings', 'eyeType'); %, 'pixelPerDegree'
            end
            
            
            if ~app.IsClosing
                app.InfoLabel.Text = 'Saved successfully';
            end
        end

        % Saves only fields from 'structToSave'
        function SaveOnlyGivenField(app, structToSave)
            [~, name2] = GetSaveFilename(app);

            if isfile(name2)
                save(name2, '-struct', 'structToSave', '-append');
            else
                save(name2, '-struct', 'structToSave');
            end
        end
        
        function densityHandler = GetDensityHandlerFromRadioButtons(app)

            if app.zScoremapCheckBox.Value
                densityHandler = app.ZScoreMapHandler;
                return;
            end

            switch app.DensitymapButtonGroup.SelectedObject.Tag
                case 'EuclideanNCones'
                    densityHandler = app.EuclideanNConesHandler;
                case 'NNDmean'
                    densityHandler = app.NNDmeanHandler;
                case 'YellotsRungs'
                    densityHandler = app.YellotsRingsHandler;
                otherwise
                    densityHandler = [];
            end
        end

        % Updates Image ------------------------------------------------------------
        function UpdateImage(app)
            densityHandler = GetDensityHandlerFromRadioButtons(app);

            PrintImage(app, app.Image);
            PrintDensity(app, densityHandler);
            PrintVoronoiByType(app, app.VoronoiType, app.Conelocs);
                        
            % Print cone locations
            PrintConelocs(app, app.Conelocs);
            PrintRodlocs(app, app.Rodlocs);

            PrintPCD(app, densityHandler);
            PrintCDC(app, densityHandler);
            PrintGoodPointsEdge(app, densityHandler);
            
            PrintDensityContourLines(app, densityHandler);

            % sort the stack of the graphical objects
            uistack(app.ImageShowHandler, 'top');
            if ~isempty(app.VoronoiPatchHandler) && isvalid(app.VoronoiPatchHandler)
                uistack(app.VoronoiPatchHandler, 'top');
            end
            if ~isempty(app.DensityMapImageHandler) && isvalid(app.DensityMapImageHandler)
                uistack(app.DensityMapImageHandler, 'top');
            end

            uistack(app.ConeUserPlotHandler, 'top');
            uistack(app.ConeAutoPlotHandler, 'top');
            uistack(app.RodPlotHandler, 'top');
            
            if ~isempty(app.DensityContoursHandler{1, 1}) && isvalid(app.DensityContoursHandler{1, 1})
                uistack(app.DensityContoursHandler{1, 1}, 'top');
            end
            
            if ~isempty(app.DensityContoursHandler{2, 1}) && isvalid(app.DensityContoursHandler{2, 1})
                uistack(app.DensityContoursHandler{2, 1}, 'top');
            end

            if ~isempty(app.PCDandCDChandler)
                if ishghandle(app.PCDandCDChandler(1)) && isvalid(app.PCDandCDChandler(1))
                    uistack(app.PCDandCDChandler(1), 'top');
                end
                if ishghandle(app.PCDandCDChandler(2)) && isvalid(app.PCDandCDChandler(2))
                    uistack(app.PCDandCDChandler(2), 'top');
                end
                if ishghandle(app.PCDandCDChandler(3)) && isvalid(app.PCDandCDChandler(3))
                    uistack(app.PCDandCDChandler(3), 'top');
                end
            end

            if ~isempty(app.ScaleBar)
                uistack(app.ScaleBar.hTextX, 'top');
                uistack(app.ScaleBar.hLineX, 'top');
            end

            % Print Grid
            PrintGrid(app, app.Image);

            PrintScaleRings(app, densityHandler);

            if ~isempty(app.ScaleRingsHandler)
                uistack(app.ScaleRingsHandler, 'top');
            end
            
            if ~isempty(app.ScaleRingsHorAxHandler) && isvalid(app.ScaleRingsHorAxHandler)
                uistack(app.ScaleRingsHorAxHandler, 'top');
            end

            if ~isempty(app.ScaleRingsVertAxHandler) && isvalid(app.ScaleRingsVertAxHandler)
                uistack(app.ScaleRingsVertAxHandler, 'top');
            end

            CreateScrollMinimap(app);
            PrintScaleBar(app);
            PrintColorbar(app);
        end
        
        % Prints image
        function PrintImage(app, image)
            if ~app.ShowImageICheckBox.Value
                blank = zeros(size(image));
                % Print blank image
                if isempty(app.ImageShowHandler)
                    cla; app.ImageShowHandler = imshow(blank, 'Parent', app.AxisHandler); hold on;
                else
                    app.ImageShowHandler.CData = blank;
                end
            else
                % Get image with given brightness
                J = imadjust(image,[],[],app.Brightness);
                
                if app.LogfilterCheckBox.Value
                    % Get log image
                    J = GetLogImage(app, J);
                end
                
                if app.LaplacianofGaussianfilterCheckBox.Value
                    % Get Laplacian of Gaussian filter
                    J = GetLapOfGausImage(app, J);
                end
                
                % Print image
                if isempty(app.ImageShowHandler)
                    cla; app.ImageShowHandler = imshow(J, 'Parent', app.AxisHandler); hold on;
                else
                    app.ImageShowHandler.CData = J;
                end
            end
        end
        
        % Gets log10 image
        function image = GetLogImage(~, Image)
            [imh, imw, ~] = size(Image);
            
            % getting log(image content) in the middle 100*100px square
            I_INbox = log(double(Image(round(imh/2)-50:round(imh/2)+50, round(imw/2)-50:round(imw/2)+50)));
            
            I_log = log(double(Image));
            % making substract: log(image) - min(log(central_100_square))
            I_log = I_log - min(I_INbox(~isinf(I_INbox)), [], "all");
            I_log = I_log./max(I_log(:));
            
            image = I_log;
            image(isinf(image)) = 0;
            image = uint8(255.*image);

%             R = max(max(Image));
%             c = 255 / (log10(double(1 + R)));
%             Image = c * log10(double(Image + 1));
%             image = uint8(Image);
        end
        
        % Gets Laplacian of Gaussian image
        function image = GetLapOfGausImage(app, Image)
            % size of filter - size of the window which filter uses. Here
            % it is 5x5 pixels
            sizeOfFilter = 5;
            filter = fspecial('log', sizeOfFilter, app.LapOfGausDeviation);
            image = imfilter(Image, filter);
        end
        
        % Shows scale bar
        function PrintScaleBar(app)
            if isempty(app.ScaleBar)
                app.ScaleBar = scalebar(app.ImageShowHandler.Parent);
                app.ScaleBar.Color = app.ScaleBarColor;
                app.ScaleBar.hLineX(1).LineWidth = 5;
                app.ScaleBar.hTextX(1).FontSize = 9;
                app.ScaleBar.XUnit = 'px';
                app.ScaleBar.XLen = 100;
                
                set(app.ScaleBar.hLineY, 'Visible', 'off');
                set(app.ScaleBar.hTextY, 'Visible', 'off');
                app.ScaleBar.hLineX(2).Visible = 'off';
            end
            
            if app.ScaleBarCheckBox.Value
                app.ScaleBar.Visible = 'on';
                UpdateScalebar(app);
            else
                app.ScaleBar.Visible = 'off';
            end
        end
        
        % Updates scale bar
        function UpdateScalebar(app, ~)
            mag = app.ScrollPanelApi.getMagnification();
            loc = app.ScrollPanelApi.getVisibleLocation();
            
            app.ScaleBar.XLen = round(100 / mag);
            app.ScaleBar.Position = [loc(1) + 20 / mag, loc(2) + 50 / mag];
            app.ScaleBar.hTextX_Pos = [app.ScaleBar.XLen / 2, -20 / mag ];
        end

        function UpdateScalebarUnits(app, ~, ~)
            if ~strcmp(app.ScaleBarUnitsDropDown.Value, 'pixel') && app.ImageMagnificationFactor == 0
                app.ScaleBarUnitsDropDown.Value = 'pixel';
                msgbox("Please, Enter the IMF value.",...
                      "Error","error");
                return;
            end

            if (strcmp(app.ScaleBarUnitsDropDown.Value, 'millimeter') || ...
                strcmp(app.ScaleBarUnitsDropDown.Value, 'micrometer')) && app.RetinalMagnificationFactor == 0
                app.ScaleBarUnitsDropDown.Value = 'pixel';
                msgbox("Please, Enter the RMF value.",...
                      "Error","error");
                return;
            end

            unitString = GetUnitsToPrint(app.ScaleBarUnitsDropDown.Value);
            app.ScaleBar.ImageMagnificationFactor = app.ImageMagnificationFactor;
            app.ScaleBar.RMF = app.RetinalMagnificationFactor;
            app.ScaleBar.XUnit = unitString;
            UpdateScalebar(app, []);
        end

        % Update Grid and Scale rings
        function UpdateGridAndScaleRingsText(app, ~)
            mag = app.ScrollPanelApi.getMagnification();
            
            if all(~isempty(app.GridTextHandler)) && all(isvalid(app.GridTextHandler))
                for iGT = 1:length(app.GridTextHandler)
                    app.GridTextHandler(iGT).Position(1:2) = app.GridTextHandler(iGT).UserData - 19 + 20 ./ mag;
                end
            end

            if all(~isempty(app.ScaleRingsTextHandler)) && all(isvalid(app.ScaleRingsTextHandler))
                for iSR = 1:length(app.ScaleRingsTextHandler)
                    app.ScaleRingsTextHandler(iSR).Position(2) = app.ScaleRingsTextHandler(iSR).UserData(2) - (app.ScaleRingsTextHandler(iSR).FontSize+1) ./ mag;
                    app.ScaleRingsTextHandler(iSR).Position(1) = app.ScaleRingsTextHandler(iSR).UserData(1) + 2 ./ mag;
                end
            end
        end

        % Shows Density lines
        function PrintDensityContourLines(app, densityHandler)
            
            if ~app.ContoursCheckBox.Value
                if ~isempty(app.DensityContoursHandler{1, 1}) && isvalid(app.DensityContoursHandler{1, 1})
                    app.DensityContoursHandler{1, 1}.Visible = 'off';
                end
                
                if ~isempty(app.DensityContoursHandler{2, 1}) && isvalid(app.DensityContoursHandler{2, 1})
                    app.DensityContoursHandler{2, 1}.Visible = 'off';
                end
                
                return;
            end

            % recreate the density contours, after text labels is off
            % to fix performance issues
            if ~app.ShowPercentTextCheckBox.Value
                for ind = 1:length(app.DensityContoursHandler)
                    if ~isempty(app.DensityContoursHandler{ind, 1}) && isvalid(app.DensityContoursHandler{ind, 1})...
                            && app.DensityContoursHandler{ind, 1}.ShowText
                        delete(app.DensityContoursHandler{ind, 1});
                        app.DensityContoursHandler{ind, 1} = [];
                    end
                end
            end

            if isempty(densityHandler) || ~isvalid(densityHandler)
                return;
            end
            
            % get data to plot
            PCD_cppa = densityHandler.PCD_cppa;

            if app.DensContGaussFilterCheckBox.Value
                densityMatrix = imgaussfilt(densityHandler.DensityMatrix, app.DensContGaussFilterSigmaEditField.Value);
            else
                densityMatrix = densityHandler.DensityMatrix;
            end

            % plot main areas
            if app.ShowMainPercentageLinesCheckBox.Value
                Perc20dens = (0:app.DensityContoursMainLinesPercentageDistance:1) * PCD_cppa;
            
                if isempty(app.DensityContoursHandler{1, 1}) || ~isvalid(app.DensityContoursHandler{1, 1})
                    [cont, objC] = contour(app.AxisHandler, densityMatrix, Perc20dens, 'LineColor', app.DensityContoursMainLinesColor);
                    app.DensityContoursHandler{1, 1} = objC;
                    app.DensityContoursHandler{1, 2} = cont;
                else
                    app.DensityContoursHandler{1, 1}.LevelList = Perc20dens;
                    app.DensityContoursHandler{1, 1}.LineColor = app.DensityContoursMainLinesColor;
                    [rows, cols, ~] = size(densityMatrix);
                    app.DensityContoursHandler{1, 1}.XData = 1:cols;
                    app.DensityContoursHandler{1, 1}.YData = 1:rows;
                    app.DensityContoursHandler{1, 1}.ZData = densityMatrix;
                    app.DensityContoursHandler{1, 1}.Visible = 'on';
                end
            else
                if ~isempty(app.DensityContoursHandler{1, 1}) && isvalid(app.DensityContoursHandler{1, 1})
                    app.DensityContoursHandler{1, 1}.Visible = 'off';
                end
            end
            
            % plot desired area
            if app.ShowSinglePercentageLineCheckBox.Value
                Perc20dens = (1 - app.DensityContoursSingleLinePercentage) * PCD_cppa;
            
                if isempty(app.DensityContoursHandler{2, 1}) || ~isvalid(app.DensityContoursHandler{2, 1})
                    [cont, objC] = contour(app.AxisHandler, densityMatrix, [Perc20dens, Perc20dens], 'LineColor', app.DensityContoursSingleLinesColor,...
                        'LineWidth', 1);
                    app.DensityContoursHandler{2, 1} = objC;
                    app.DensityContoursHandler{1, 2} = cont;
                else
                    app.DensityContoursHandler{2, 1}.LevelList = Perc20dens;
                    app.DensityContoursHandler{2, 1}.LineColor = app.DensityContoursSingleLinesColor;
                    [rows, cols, ~] = size(densityMatrix);
                    app.DensityContoursHandler{2, 1}.XData = 1:cols;
                    app.DensityContoursHandler{2, 1}.YData = 1:rows;
                    app.DensityContoursHandler{2, 1}.ZData = densityMatrix;
                    app.DensityContoursHandler{2, 1}.Visible = 'on';
                end
            else
                if ~isempty(app.DensityContoursHandler{2, 1}) && isvalid(app.DensityContoursHandler{2, 1})
                    app.DensityContoursHandler{2, 1}.Visible = 'off';
                end
            end
            
            % show text if needed
            for ind = 1:length(app.DensityContoursHandler)
                if ~isempty(app.DensityContoursHandler{ind, 1}) && isvalid(app.DensityContoursHandler{ind, 1})
                    % need to use clabel here!!!!!
                    app.DensityContoursHandler{ind, 1}.ShowText = app.ShowPercentTextCheckBox.Value;
                    app.DensityContoursHandler{ind, 1}.LabelSpacing = Inf;

                    if CheckVersion(app)
                        app.DensityContoursHandler{ind, 1}.LabelFormat = @app.DensityContourLabelFormat;
                    else
                        app.DensityContoursHandler{ind, 1}.LabelFormat = "%0.3f";
                    end

                    if app.ShowPercentTextCheckBox.Value
                        % the only way to setup the color for labels
                        clabel([], app.DensityContoursHandler{ind, 1}, 'Color', app.DensityTextLabelColor);
% 
%                         htexts = findall(app.DensityTextHandlers,'type','text');
%                         set(htexts,'Units','Normalized');
                    end
                end
            end
        end

        % Formats the density contour labels
        function labels = DensityContourLabelFormat(app, vals)
            if app.zScoremapCheckBox.Value
                scaleCoefficient = 1;
            else
                scaleCoefficient = GetScaleCoefficient(app.ReportUnits, app.ImageMagnificationFactor, app.RetinalMagnificationFactor);
            end
            unitString = GetUnitsToPrint(app.ReportUnits);
            roundingNumber = GetRoundingNumberByUnit(app, unitString);

            newVals = round(vals ./ (scaleCoefficient^2), roundingNumber);
            labels = string(newVals); % + " " + convertCharsToStrings(unitString);
        end

        % Returns specific format
        function roundingNumber = GetRoundingNumberByUnit(~, unit)
            switch unit
                case 'arcsec'
                    roundingNumber = 5;
        
                case 'arcmin'
                    roundingNumber = 2;
        
                case {'degree', 'deg'}
                    roundingNumber = 0;
        
                otherwise
                    roundingNumber = 3;
            end
        end
        
        % creates zScore map
        function success = GetZScore(app, densityHandler)
            success = 0;
            if ~app.zScoremapCheckBox.Value
                return;
            end

            if ~isempty(app.ZScoreMapHandler) && isvalid(app.ZScoreMapHandler) ...
                    && strcmp(app.ZScoreMapHandler.DensityName, app.DensitymapButtonGroup.SelectedObject.Tag) ...
                    && strcmp(app.ZScoreMapHandler.EyeOrientation, app.EyetypeButtonGroup.SelectedObject.Tag)
                success = 1;
                return;
            end
            delete(app.ZScoreMapHandler);

            if app.ImageMagnificationFactor == 0 || app.RetinalMagnificationFactor == 0
                msgbox("Please, setup the IMF and the RMF in Metrics tab.",...
                      "Error","error");
                return;
            end

            pathUtilities = fullfile(fileparts(mfilename('fullpath')), 'Utilities');
            switch app.ReportUnits
                case {'micrometer', 'µm', 'millimeter', 'mm'}
                    avgFilename = 'AverageDensityEuclideanNConesMap_RMF.mat';
                otherwise
                    avgFilename = 'AverageDensityEuclideanNConesMap.mat';
            end

            load(fullfile(pathUtilities, 'Metrics', avgFilename), ...
                'averageMapLeft', 'averageMapRight', 'stdMapLeft', 'stdMapRight', ...
                'imageMagnificationFactorLeft', 'imageMagnificationFactorRight', ...
                'retinalMagnificationFactorLeft', 'retinalMagnificationFactorRight');

            if strcmp(app.EyetypeButtonGroup.SelectedObject.Tag, 'right')
                averageMap = averageMapRight;
                stdMap = stdMapRight;
                imfAvg = imageMagnificationFactorRight;
                rmfAvg = retinalMagnificationFactorRight;
            elseif strcmp(app.EyetypeButtonGroup.SelectedObject.Tag, 'left')
                averageMap = averageMapLeft;
                stdMap = stdMapLeft;
                imfAvg = imageMagnificationFactorLeft;
                rmfAvg = retinalMagnificationFactorLeft;
            else 
                % if none
                msgbox("Please, setup the EyeType in Metrics tab.",...
                      "Error","error");
                return;
            end

            % convert map to different IMF if necessary
            if app.ImageMagnificationFactor ~= imfAvg || app.RetinalMagnificationFactor ~= rmfAvg
                [oldHeight, oldWidth, ~] = size(averageMap);

                switch app.ReportUnits
                    case {'micrometer', 'µm', 'millimeter', 'mm'}
                        newAverageMap = ChangeImageMagnificationFactorForDensityMap(averageMap, imfAvg, app.ImageMagnificationFactor);
                        newStdMap = ChangeImageMagnificationFactorForDensityMap(stdMap, imfAvg, app.ImageMagnificationFactor);

                        newAverageMap = ChangeRetinalMagnificationFactorForDensityMap(newAverageMap, app.ImageMagnificationFactor, rmfAvg, app.RetinalMagnificationFactor);
                        newStdMap = ChangeRetinalMagnificationFactorForDensityMap(newStdMap, app.ImageMagnificationFactor, rmfAvg, app.RetinalMagnificationFactor);
                    otherwise
                        newAverageMap = ChangeImageMagnificationFactorForDensityMap(averageMap, imfAvg, app.ImageMagnificationFactor);
                        newStdMap = ChangeImageMagnificationFactorForDensityMap(stdMap, imfAvg, app.ImageMagnificationFactor);
                end 

                [newHeight, newWidth, ~] = size(newAverageMap);
                if newHeight ~= oldHeight && newWidth ~= oldWidth
                    averageMap = nan(oldHeight, oldWidth);
                    stdMap = nan(oldHeight, oldWidth);
                    halfDiffH = round(abs(oldHeight - newHeight) / 2);
                    halfDiffW = round(abs(oldWidth - newWidth) / 2);
                    if newHeight < oldHeight
                        averageMap(halfDiffH:halfDiffH+newHeight-1, halfDiffW:halfDiffW+newWidth-1) = newAverageMap;
                        stdMap(halfDiffH:halfDiffH+newHeight-1, halfDiffW:halfDiffW+newWidth-1) = newStdMap;
                    else
                        averageMap = newAverageMap(halfDiffH:newHeight-halfDiffH-1, halfDiffW:newWidth-halfDiffW-1);
                        stdMap = newStdMap(halfDiffH:newHeight-halfDiffH-1, halfDiffW:newWidth-halfDiffW-1);
                    end
                end
            end

            zScoreMap = CreateZScoreMap(densityHandler.DensityMatrix, averageMap, stdMap, ... 
                [densityHandler.Stats2.WeightedCentroid(1), densityHandler.Stats2.WeightedCentroid(2)]);

            app.ZScoreMapHandler = ZScoreMap(zScoreMap, [densityHandler.Stats2.WeightedCentroid(1), densityHandler.Stats2.WeightedCentroid(2)],...
                densityHandler.Stats2, densityHandler.PCD_loc, app.DensitymapButtonGroup.SelectedObject.Tag, ...
                app.EyetypeButtonGroup.SelectedObject.Tag);
            
            success = 1;
        end

        % Prints density map according to current density
        function PrintDensity(app, densityHandler)
            if app.ShowDMCheckBox.Value
                if isempty(densityHandler) || ~isvalid(densityHandler)
                    return;
                end
                % get map to plot
                densityMapImage = densityHandler.DensityMatrix;
                
                densityMapImage(isnan(densityMapImage)) = 0;
                [densityMapImage, ~] =...
                    mat2im(densityMapImage, app.DensColorMap, [app.DensityMinimumLimit, app.DensityMaximumLimit]);

                % plot map
                if isempty(app.DensityMapImageHandler) || ~isvalid(app.DensityMapImageHandler)
                    app.DensityMapImageHandler = imshow(densityMapImage, 'Parent', app.AxisHandler);
                else
                    app.DensityMapImageHandler.CData = densityMapImage;
                end
                
                [dHeight, dWidth, ~] = size(densityHandler.DensityMatrix);
                app.DensityMapImageHandler.AlphaData = zeros(dHeight, dWidth);
                app.DensityMapImageHandler.AlphaData(~isnan(densityHandler.DensityMatrix)) = app.DensityAlphaValue;
                app.DensityMapImageHandler.Visible = 'on';
            else
                if ~isempty(app.DensityMapImageHandler) && isvalid(app.DensityMapImageHandler)
                    app.DensityMapImageHandler.Visible = 'off';
                end
            end
        end
        
        % Prints PCD location according to current density
        function PrintPCD(app, densityHandler)
            if isempty(densityHandler)
                if ~isempty(app.PCDandCDChandler)...
                        && ishghandle(app.PCDandCDChandler(1))...
                        && isvalid(app.PCDandCDChandler(1))
                    app.PCDandCDChandler(1).Visible = 'off';
                end
                
                return;
            end

            if app.PCDCheckBox.Value
                isVisible = 'on';
            else
                isVisible = 'off';
            end

            if isempty(app.PCDandCDChandler)
                app.PCDandCDChandler = gobjects(3);
            end
            
            if ~ishghandle(app.PCDandCDChandler(1))
                app.PCDandCDChandler(1) = plot(app.AxisHandler, ...
                    densityHandler.PCD_loc(1), densityHandler.PCD_loc(2), ...
                    'Marker', '^', ...
                    'MarkerEdgeColor', [1 1 1], ...
                    'MarkerFaceColor', [0 0 0], ...
                    'MarkerSize', app.MarkerSizePRL*0.8, ...
                    'LineWidth', app.MarkerSizePRL*0.8/1.618 * 0.618/2,...
                    'LineStyle', 'none');
            else
                set(app.PCDandCDChandler(1),...
                    'XData', densityHandler.PCD_loc(1),...
                    'YData', densityHandler.PCD_loc(2));
            end

            app.PCDandCDChandler(1).Visible = isVisible;
        end
        
        % Prints CDC location according to current density
        function PrintCDC(app, densityHandler)
            if isempty(densityHandler)
                if ~isempty(app.PCDandCDChandler)...
                        && ishghandle(app.PCDandCDChandler(2))...
                        && isvalid(app.PCDandCDChandler(2))
                    app.PCDandCDChandler(2).Visible = 'off';
                end
                
                return;
            end

            if app.CDCCheckBox.Value
                isVisible = 'on';
            else
                isVisible = 'off';
            end

            if isempty(app.PCDandCDChandler)
                app.PCDandCDChandler = gobjects(3);
            end
            
            if ~ishghandle(app.PCDandCDChandler(2))
                app.PCDandCDChandler(2) = plot(app.AxisHandler, ...
                    densityHandler.Stats2.WeightedCentroid(1), densityHandler.Stats2.WeightedCentroid(2), ...
                    'Marker', 'o', ...
                    'MarkerEdgeColor', [1 0 0], ... %[201/255 60/255 56/255], ...
                    'MarkerFaceColor', [1 1 1], ...
                    'MarkerSize', app.MarkerSizePRL*0.85, ...
                    'LineWidth', app.MarkerSizePRL*0.85/1.618 * 0.618/1.6);
            else
                set(app.PCDandCDChandler(2),...
                    'XData', densityHandler.Stats2.WeightedCentroid(1),...
                    'YData', densityHandler.Stats2.WeightedCentroid(2));
            end

            app.PCDandCDChandler(2).Visible = isVisible;
        end
        
        % TODO: Prints PRL location (Add to UpdateImage when ready)
        function PrintPRL(app)
            % TODO make checkbox
            if app.CDCCheckBox.Value
                isVisible = 'on';
            else
                isVisible = 'off';
            end

            % TODO update PCDandCDChandler sizes (everywhere)
            if isempty(app.PCDandCDChandler)
                app.PCDandCDChandler = gobjects(3);
            end
            
            % TODO create fields for PRL location
            if ~ishghandle(app.PCDandCDChandler(4))
                app.PCDandCDChandler(4) = plot(app.AxisHandler, ...
                    app.ImgWidth / 2, app.ImgHeight / 2,...
                    'Marker', 's', ...
                    'MarkerEdgeColor', [1 1 1], ...
                    'MarkerFaceColor', [1 0 1], ...
                    'MarkerSize', app.MarkerSizePRL, ...
                    'LineWidth', app.MarkerSizePRL/1.618 * 0.618/1.9,...
                    'LineStyle', 'none');
            else
                set(app.PCDandCDChandler(4),...
                    'XData', app.ImgWidth / 2,...
                    'YData', app.ImgHeight / 2);
            end

            app.PCDandCDChandler(4).Visible = isVisible;
        end

        % Prints non approximate edge. Only for EuclideanNCones
        function PrintGoodPointsEdge(app, densityHandler)
            if isempty(densityHandler) || ~isa(densityHandler, 'EuclideanNCones')
                if ~isempty(app.PCDandCDChandler)...
                        && ishghandle(app.PCDandCDChandler(3))...
                        && isvalid(app.PCDandCDChandler(3))
                        
                    app.PCDandCDChandler(3).Visible = 'off';
                end
                
                return;
            end

            if isempty(app.PCDandCDChandler)
                app.PCDandCDChandler = gobjects(3);
            end

            if ~ishghandle(app.PCDandCDChandler(3))
                app.PCDandCDChandler(3) = plot(app.AxisHandler, ...
                    densityHandler.GoodPointsEdge(:, 1), densityHandler.GoodPointsEdge(:, 2), '-m', ...
                    'LineWidth', 2.5);
            else
               set(app.PCDandCDChandler(3),...
                    'XData', densityHandler.GoodPointsEdge(:, 1),...
                    'YData', densityHandler.GoodPointsEdge(:, 2));
            end

            app.PCDandCDChandler(3).Visible = BoolToOnOff(app, app.ShowDMCheckBox.Value && app.ApproximationEdgeCheckBox.Value);
        end

        % Prints cone locations
        function PrintConelocs(app, conelocs)
            if app.ShowMarkedLocationsTCheckBox.Value
                markerSize = app.MarkerSize;
                usercones = conelocs(conelocs(:,3)==2,1:2);
                autocones = conelocs(conelocs(:,3)==1,1:2);
                switch app.ShowConesByDetectType
                    case 1
                        userMarker = app.Marker;
                        userColor = app.Color;
                        userVisability = 'on';
                        autoMarker = '.';
                        % magenta
                        autoColor = [1 0 1];
                        autoVisability = 'off';
                        
                    case 2
                        userMarker = app.Marker;
                        userColor = app.Color;
                        userVisability = 'off';
                        autoMarker = '.';
                        % magenta
                        autoColor = [1 0 1];
                        autoVisability = 'on';
                    otherwise
                        userVisability = 'on';
                        autoVisability = 'on';
                        if ~app.AutovsUserdetectCheckBox.Value
                            userMarker = app.Marker;
                            userColor = app.Color;
                            autoMarker = app.Marker;
                            autoColor = app.Color;
                        else
                            userMarker = app.Marker;
                            userColor = app.Color;
                            autoMarker = '.';
                            % magenta
                            autoColor = [1 0 1];
                        end
                end
                    
                % check where plots are created
                if isempty(app.ConeUserPlotHandler) || ~isvalid(app.ConeUserPlotHandler)
                    app.ConeUserPlotHandler = plot(app.AxisHandler, 1, 1, '.');
                end
                if isempty(app.ConeAutoPlotHandler) || ~isvalid(app.ConeAutoPlotHandler)
                    app.ConeAutoPlotHandler = plot(app.AxisHandler, 1, 1, '.');
                end
                
                % set plots porperties
                set(app.ConeUserPlotHandler, ...
                    'XData', usercones(:,1), 'YData', usercones(:,2), ...
                    'MarkerSize', markerSize, 'Marker', userMarker, ...
                    'Color', userColor, 'Visible', userVisability);
                set(app.ConeAutoPlotHandler, ...
                    'XData', autocones(:,1), 'YData', autocones(:,2), ...
                    'MarkerSize', markerSize, 'Marker', autoMarker, ...
                    'Color', autoColor, 'Visible', autoVisability);
            else  
                % check where plots are created
                if isempty(app.ConeUserPlotHandler) || ~isvalid(app.ConeUserPlotHandler)
                    app.ConeUserPlotHandler = plot(app.AxisHandler, 1, 1, '.');
                end
                if isempty(app.ConeAutoPlotHandler) || ~isvalid(app.ConeAutoPlotHandler)
                    app.ConeAutoPlotHandler = plot(app.AxisHandler, 1, 1, '.');
                end
                
                % check where plots are created
                if ~isempty(app.ConeUserPlotHandler) && isvalid(app.ConeUserPlotHandler)
                    set(app.ConeUserPlotHandler, 'Visible', 'off');
                end
                if ~isempty(app.ConeAutoPlotHandler) && isvalid(app.ConeAutoPlotHandler)
                    set(app.ConeAutoPlotHandler, 'Visible', 'off');
                end
            end
        end

        % Print rodlocs
        function PrintRodlocs(app, rodlocs)
            if app.ShowMarkedLocationsTCheckBox.Value
                markerSize = app.MarkerSize;
                rods = rodlocs(rodlocs(:,3)==2, 1:2);
                cyanColor = [0 1 1];
                   
                % check where plots are created
                if isempty(app.RodPlotHandler) || ~isvalid(app.RodPlotHandler)
                    app.RodPlotHandler = plot(app.AxisHandler, 1, 1, '.');
                end
                
                % set plots porperties
                set(app.RodPlotHandler, ...
                    'XData', rods(:,1), 'YData', rods(:,2), ...
                    'MarkerSize', markerSize, 'Marker', '.', ...
                    'Color', cyanColor, 'Visible', 'on');
            else  
                % check where plots are created
                if isempty(app.RodPlotHandler) || ~isvalid(app.RodPlotHandler)
                    app.RodPlotHandler = plot(app.AxisHandler, 1, 1, '.');
                end
                
                % check where plots are created
                if ~isempty(app.RodPlotHandler) && isvalid(app.RodPlotHandler)
                    set(app.RodPlotHandler, 'Visible', 'off');
                end
            end
        end
        
        % Prints Grid
        function PrintGrid(app, image)
            if ~isempty(app.GridVertLinesHandler) && all(isvalid(app.GridVertLinesHandler))
                delete(app.GridVertLinesHandler);
            end
            if ~isempty(app.GridHorLinesHandler) && all(isvalid(app.GridHorLinesHandler))
                delete(app.GridHorLinesHandler);
            end
            if ~isempty(app.GridTextHandler) && all(isvalid(app.GridTextHandler))
                delete(app.GridTextHandler);
            end
            
            
            if app.GridCheckBox.Value
                % Get sizes
                [originalSizeY, originalSizeX, ~] = size(image);
                
                % Calculation like during CNN cone recogtion
                newSize = 150;
                
                % calculate minimum numer of cutouts to produce
                cutoutsINrowX = ceil(originalSizeX/newSize); 
                cutoutsINrowY = ceil(originalSizeY/newSize);
    
                offsetX = floor(cutoutsINrowX*newSize-originalSizeX)/(cutoutsINrowX-1);
    
                while offsetX < 20
                    cutoutsINrowX = cutoutsINrowX+1;
                    % offset which will be applied for a fix number of cutouts in a row/column
                    offsetX = round((cutoutsINrowX*newSize-originalSizeX)/(cutoutsINrowX-1));
                end
                
                offsetY = floor(cutoutsINrowY*newSize-originalSizeY)/(cutoutsINrowY-1);
    
                while offsetY < 20
                    cutoutsINrowY = cutoutsINrowY+1;
                    % offset which will be applied for a fix number of cutouts in a row/column
                    offsetY = round((cutoutsINrowY*newSize-originalSizeY)/(cutoutsINrowY-1));
                end
    
                cutoutsINrowX = cutoutsINrowX + 1;
                cutoutsINrowY = cutoutsINrowY + 1;
                stepX = newSize-1-offsetX;
                linesCols = [1 newSize-offsetX : stepX : ((newSize-1)*cutoutsINrowX-offsetX*cutoutsINrowX)];
    
                linesCols = [linesCols;linesCols];
                stepY = newSize-1-offsetY;
                linesRows = [1 newSize-offsetY : stepY : ((newSize-1)*cutoutsINrowY-offsetY*cutoutsINrowY)];
                linesRows = [linesRows;linesRows];
    
                % End of calculation like in CNN recognition
                
                colsX = [zeros(size(linesRows,2),1)' + 1; zeros(size(linesRows,2),1)' + originalSizeX];
                colsY = [zeros(size(linesCols,2),1)' + 1; zeros(size(linesCols,2),1)' + originalSizeY];
    
                % Print verticals
                app.GridVertLinesHandler = line(app.AxisHandler, colsX, linesRows, 'Color', 'w');
                % Print horizontals
                app.GridHorLinesHandler = line(app.AxisHandler, linesCols, colsY, 'Color', 'w');
                if app.GridNumbersCheckBox.Value
                    startX = 20;
                    x = startX : stepX : originalSizeX - newSize / 2;
                    
                    startY = 20;
                    y = startY : stepY : originalSizeY - newSize / 2;
                    sx = size(x, 2);
                    sy = size(y, 2);
                    
                    y = repmat(y, 1, sx);
                    x = repelem(x, sy);
                    textArray = arrayfun(@(a)num2str(a), (y-startY)/stepY*sx + (x-startX)/stepX, 'uni', 0);
                    app.GridTextHandler = text(app.AxisHandler, x, y, textArray, 'Color', 'w');

                    % safe basic coordinates for zoom manipulations
                    for iGT = 1:length(app.GridTextHandler)
                        app.GridTextHandler(iGT).UserData = [x(iGT), y(iGT)];
                    end

                    set(app.GridTextHandler, 'Clipping', 'on');

                    UpdateGridAndScaleRingsText(app, []);
                end
            end                        
        end

        % Prints concentric circles from CDC point to the edges of the
        % image. If there is no DensityMap, then circles will be from
        % center of the image.
        function PrintScaleRings(app, densityHandler)
            if ~isempty(app.ScaleRingsTextHandler) && all(isvalid(app.ScaleRingsTextHandler))
                delete(app.ScaleRingsTextHandler);
            end

            if ~app.ScaleRingsCheckBox.Value
                if ~isempty(app.ScaleRingsHandler) && isvalid(app.ScaleRingsHandler)
                    app.ScaleRingsHandler.Visible = 'off';
                end

                if ~isempty(app.ScaleRingsHorAxHandler) && isvalid(app.ScaleRingsHorAxHandler)
                    app.ScaleRingsHorAxHandler.Visible = 'off';
                end
    
                if ~isempty(app.ScaleRingsVertAxHandler) && isvalid(app.ScaleRingsVertAxHandler)
                    app.ScaleRingsVertAxHandler.Visible = 'off';
                end

                return;
            end

            % pick center of circle
            [height, width, ~] = size(app.Image);
            if ~isempty(densityHandler) && isvalid(densityHandler)
                C = densityHandler.CDC20_loc;
                maxRadius = max([C, width - C(1), height - C(2)]);
            else
                C = round([width height]/2);
                maxRadius = max([height / 2, width / 2]);
            end

            % get scale coefficient
            scaleCoefficient = GetScaleCoefficient(app.ScaleBarUnitsDropDown.Value, app.ImageMagnificationFactor, app.RetinalMagnificationFactor);
            % pick size of the scale
            switch app.ScaleBarUnitsDropDown.Value
                case 'pixel'
                    circleStep =  50;
        
                case 'arcsec'
                    circleStep =  360 / scaleCoefficient;
        
                case 'arcmin'
                    circleStep =  6 / scaleCoefficient;
        
                case 'degree'
                    circleStep =  0.1 / scaleCoefficient;
        
                case 'millimeter'
                    circleStep =  0.03 / scaleCoefficient;
                    
                case 'micrometer'
                    circleStep =  30 / scaleCoefficient;
        
                otherwise
                    error(["Unknown unit: ", unit]);
            end

            % if there is not enough info (wront IMF or RMF)
            if circleStep <= 0 || isinf(circleStep)
                return;
            end

            % prepare data for plot scale
            th = linspace(0,2*pi);
            steps = circleStep:circleStep:maxRadius;
            x = zeros((length(th)+1) * length(steps), 1);
            y = zeros((length(th)+1) * length(steps), 1);
            xt = zeros(length(steps), 1);
            yt = zeros(length(steps), 1);
            for iStep = 1:length(steps)
                r = steps(iStep);
                leftLimit = (length(th)+1) * (iStep-1) + 1;
                rightLimit = (length(th)+1) * iStep;
                x(leftLimit : rightLimit) = [C(1)+r*cos(th), NaN];
                y(leftLimit : rightLimit) = [C(2)+r*sin(th), NaN];

                xt(iStep) = C(1)+r*cos(0);
                yt(iStep) = C(2)+r*sin(0);
            end

            % plot scale
            if isempty(app.ScaleRingsHandler) || ~isvalid(app.ScaleRingsHandler)
                app.ScaleRingsHandler = plot(app.AxisHandler , x,y, 'LineWidth', 1);
            end

            app.ScaleRingsHandler.Color = app.ScaleBarColor;
            app.ScaleRingsHandler.XData = x;
            app.ScaleRingsHandler.YData = y;
            app.ScaleRingsHandler.Visible = 'on';

            % plot axis
            if isempty(app.ScaleRingsHorAxHandler) || ~isvalid(app.ScaleRingsHorAxHandler)
                app.ScaleRingsHorAxHandler = line(app.AxisHandler, [1 width], [C(2) C(2)]);
            end
            app.ScaleRingsHorAxHandler.XData = [1 width];
            app.ScaleRingsHorAxHandler.YData = [C(2) C(2)];
            app.ScaleRingsHorAxHandler.Color = app.ScaleBarColor;
            app.ScaleRingsHorAxHandler.Visible = 'on';

            if isempty(app.ScaleRingsVertAxHandler) || ~isvalid(app.ScaleRingsVertAxHandler)
                app.ScaleRingsVertAxHandler = line(app.AxisHandler, [C(1) C(1)], [1 height]);
            end
            app.ScaleRingsVertAxHandler.XData = [C(1) C(1)];
            app.ScaleRingsVertAxHandler.YData = [1 height];
            app.ScaleRingsVertAxHandler.Color = app.ScaleBarColor;
            app.ScaleRingsVertAxHandler.Visible = 'on';

            % plot text
            unitString = GetUnitsToPrint(app.ScaleBarUnitsDropDown.Value);
            textArray = arrayfun(@(a) num2str(a), steps * scaleCoefficient, 'uni', 0);
            textArray{1} = [textArray{1}, ' ', unitString];
            app.ScaleRingsTextHandler = text(app.AxisHandler, xt, yt, textArray, 'Color', app.ScaleBarColor);
            set(app.ScaleRingsTextHandler, 'Clipping', 'on');

            for iSR = 1:length(app.ScaleRingsTextHandler)
                app.ScaleRingsTextHandler(iSR).UserData = [xt(iSR), yt(iSR)];
            end

            UpdateGridAndScaleRingsText(app, []);
        end

        % Prints colorbar. Colorbar is used for Voronoi (Cone area/ Num of Neighbors)
        % and for density maps. Density map has priority over Voronoi.
        function PrintColorbar(app)
            % create color bar
            if isempty(app.ColorbarHandler) || ~isvalid(app.ColorbarHandler)
                app.AxisForColorbarHandler = axes(app.ScrollPanelHandler);
                app.ColorbarHandler = colorbar(app.AxisForColorbarHandler);

                app.AxisForColorbarHandler.XColor = 'none';
                app.AxisForColorbarHandler.YColor = 'none';
                app.AxisForColorbarHandler.TickDir = 'out';
                app.AxisForColorbarHandler.XTickLabel = [];
                app.AxisForColorbarHandler.XTick = [];
                app.AxisForColorbarHandler.YTickLabel = [];
                app.AxisForColorbarHandler.YTick = [];
                app.AxisForColorbarHandler.Color = [1 1 1 .8];
                
                app.ColorbarHandler.Location = "south";
                app.ColorbarHandler.YAxisLocation = 'bottom';
                app.ColorbarHandler.Label.Units = 'normalized';
                app.ColorbarHandler.FontWeight = 'bold';
                app.ColorbarHandler.FontSize = 9;
            end

            % if colorbar turn on
            if app.ColorBarCheckBox.Value  ...
                    && ((app.VoronoiDiagramCheckBox.Value && (strcmp(app.VoronoiType, 'Number of Neighbors') || strcmp(app.VoronoiType, 'Cone Area'))) ...
                    || app.ShowDMCheckBox.Value)

                % turn it on;
                app.ColorbarHandler.Visible = 'on';
                app.AxisForColorbarHandler.Visible = 'on';
                app.ColorbarHandler.Label.Position = [0.5 4];
                
                % setup positions
                SetColorbarSize(app);

                % if it is for Voronoi
                if app.VoronoiDiagramCheckBox.Value                    
                    % if Number of Neighbors
                    if strcmp(app.VoronoiType, 'Number of Neighbors')
                        % setup colormap
                        app.AxisForColorbarHandler.Colormap = app.VoronoiNNColorScale;
                        % setup options
                        app.ColorbarHandler.Label.String = 'Number of neighbors';
                        app.ColorbarHandler.Ruler.TickLabelFormat = '%d';
                        app.ColorbarHandler.Ruler.Exponent = 0;
                        % setup limits
                        app.AxisForColorbarHandler.CLim = [app.VoronoiMinNN - 0.6,  app.VoronoiMaxNN + 0.6];
                        app.ColorbarHandler.Ticks = app.VoronoiMinNN:app.VoronoiMaxNN;
                    end

                    % if Cone Area
                    if strcmp(app.VoronoiType, 'Cone Area')
                        scaleCoefficient = GetScaleCoefficient(app.ReportUnits, app.ImageMagnificationFactor, app.RetinalMagnificationFactor);
                        unitString = GetUnitsToPrint(app.ReportUnits);

                        % setup colormap
                        app.AxisForColorbarHandler.Colormap = flipud(app.VColorMap);

                        % setup options
                        if strcmp('degree', app.ReportUnits)
                            format = '%.1f';
                            app.ColorbarHandler.Ruler.Exponent = -4;
                        elseif strcmp('arcsec', app.ReportUnits) || strcmp('pixel', app.ReportUnits)
                            format = '%d';
                            app.ColorbarHandler.Ruler.Exponent = 0;
                        elseif strcmp('millimeter', app.ReportUnits)
                            format = '%.1f';
                            app.ColorbarHandler.Ruler.Exponent = -5;
                        else
                            format = '%.2f';
                            app.ColorbarHandler.Ruler.Exponent = 0;
                        end
                        app.ColorbarHandler.TicksMode = 'auto';
                        
                        app.ColorbarHandler.Label.String = ['Cone area (', unitString, '^2)'];
                        app.ColorbarHandler.Ruler.TickLabelFormat = format;

                        % setup limits
                        if app.ImageMagnificationFactor == 0 && ~strcmp('pixel', app.ReportUnits)
                            minArea = -Inf;
                            maxArea = Inf;
                            ticks = [];
                        else
                            minArea = app.VoronoiMinConeArea .* scaleCoefficient^2;
                            maxArea = app.VoronoiMaxConeArea .* scaleCoefficient^2;
                            ticks = [minArea, maxArea];
                        end
                        app.AxisForColorbarHandler.CLim = [minArea, maxArea];
                        app.ColorbarHandler.Ticks = ticks;
                    end
                end

                % if bar for density
                if app.ShowDMCheckBox.Value
                    % setup colormap
                    app.AxisForColorbarHandler.Colormap = app.DensColorMap;

                    if app.zScoremapCheckBox.Value
                        scaleCoefficient = 1;
                    else
                        scaleCoefficient = GetScaleCoefficient(app.ReportUnits, app.ImageMagnificationFactor, app.RetinalMagnificationFactor);
                    end
                    unitString = GetUnitsToPrint(app.ReportUnits);

                    % setup options
                    if strcmp('degree', app.ReportUnits) || strcmp('millimeter', app.ReportUnits) || app.zScoremapCheckBox.Value
                        format = '%d';
                        app.ColorbarHandler.Ruler.Exponent = 0;
                    elseif strcmp('arcsec', app.ReportUnits)
                        format = '%0.1f';
                        app.ColorbarHandler.Ruler.Exponent = -4;
                    elseif strcmp('pixel', app.ReportUnits)
                        format = '%0.1f';
                        app.ColorbarHandler.Ruler.Exponent = -3;
                    else
                        format = '%.2f';
                        app.ColorbarHandler.Ruler.Exponent = 0;
                    end
                    app.ColorbarHandler.TicksMode = 'auto';
                    app.ColorbarHandler.Ruler.TickLabelFormat = format;

                    % setup limits
                    minDens = app.DensityMinimumLimit ./ scaleCoefficient^2;
                    maxDens = app.DensityMaximumLimit ./ scaleCoefficient^2;
                    app.AxisForColorbarHandler.CLim = [minDens, maxDens];
                    app.ColorbarHandler.Ticks = [minDens, maxDens];

                    if app.zScoremapCheckBox.Value
                        app.ColorbarHandler.Label.String = 'Z-Score (SD units)';
                    else
                        app.ColorbarHandler.Label.String = ['Cone density (cones/', unitString, '^2)'];
                    end
                end

                if app.ColorbarHandler.Ruler.Exponent ~= 0
                    app.ColorbarHandler.Label.String = [app.ColorbarHandler.Label.String, ' ', app.ColorbarHandler.Ruler.SecondaryLabel.String];
                    app.ColorbarHandler.Ruler.SecondaryLabel.Visible = 'off';
                end
                app.ColorbarHandler.Ruler.TickLabelRotation = 0;
            else
                % turn color bar off
                app.ColorbarHandler.Visible = 'off';
                app.AxisForColorbarHandler.Visible = 'off';
            end
        end
        
        function UpdateLegacy(app)
            app.FigureHandler.Name = [app.Fname,'  ', ThousandSep(app.ConeCount),' cones; ', num2str(app.TotalRodCount), ' rods'];
            app.InfoLabel.Text = 'Info';

            if ~isempty(app.StatisticsWindowUIFigureHandler) && isvalid(app.StatisticsWindowUIFigureHandler)
                app.StatisticsWindowUIFigureHandler.UpdateReceptorsCount();
            end
        end
        % Updates Image ------------------------------------------------------------
        
        % Updates auto limits for density
        function UpdateDensityLimits(app)
            if app.AutoLimCheckBox.Value
                densityHandler = GetDensityHandlerFromRadioButtons(app);
                if ~isempty(densityHandler) && isvalid(densityHandler)
                    if app.zScoremapCheckBox.Value
                        maxValue = ceil(min([ ...
                            max(abs([min(densityHandler.DensityMatrix(~isinf(densityHandler.DensityMatrix)), [], 'all'), ...
                                max(densityHandler.DensityMatrix(~isinf(densityHandler.DensityMatrix)), [], 'all')])), 5]));
                        app.DensityMaximumLimit = maxValue;
                        app.DensityMinimumLimit = -maxValue;
                    else
                        app.DensityMaximumLimit = densityHandler.PCD_cppa;
                        app.DensityMinimumLimit = min(densityHandler.DensityMatrix, [], 'all');
                    end
                else
                    app.DensityMinimumLimit = 0;
                    app.DensityMaximumLimit = 0;
                end
            end
        end

        % Updates User Interface
        function UpdateUI(app, varargin)
            if isempty(varargin)
                offAll = 0;
            else
                offAll = varargin{1};
            end

            isDisabled = ~offAll && ~isempty(app.Image) && ~isempty(app.FigureHandler);
            isDisabledString = BoolToOnOff(app, isDisabled);
            set(findall(app.MarksPropertiesPanel, '-property', 'Enable'), 'Enable', isDisabledString);
            set(findall(app.ImagePropertiesPanel, '-property', 'Enable'), 'Enable', isDisabledString);
            set(findall(app.OtherPanel, '-property', 'Enable'), 'Enable', isDisabledString);
            set(findall(app.ImproveCNNResultsPanel, '-property', 'Enable'), 'Enable', isDisabledString);
            set(findall(app.ReceptorsTypeDropDown, '-property', 'Enable'), 'Enable', isDisabledString);
            set(findall(app.VoronoidiagramButtonGroup, '-property', 'Enable'), 'Enable', isDisabledString);
            set(findall(app.ImportExportMenu, '-property', 'Enable'), 'Enable', isDisabledString);
            set(findall(app.DensitycalculationparametersPanel, '-property', 'Enable'), 'Enable', isDisabledString);
            set(findall(app.UnitssettingsPanel, '-property', 'Enable'), 'Enable', isDisabledString);
            set(findall(app.EyetypeButtonGroup, '-property', 'Enable'), 'Enable', isDisabledString);
            app.NoneButton.Enable = "off";
            
            isDisabled = isDisabled && isvalid(app.FigureHandler);
            isDisabledString = BoolToOnOff(app, isDisabled);

            set(findall(app.MetricsMenu, '-property', 'Enable'), 'Enable', isDisabledString);

            anyDensityHandlerValid = (~isempty(app.EuclideanNConesHandler) && isvalid(app.EuclideanNConesHandler))...
                || (~isempty(app.NNDmeanHandler) && isvalid(app.NNDmeanHandler))...
                || (~isempty(app.YellotsRingsHandler) && isvalid(app.YellotsRingsHandler));

            set(findall(app.DensitycontourPanel, '-property', 'Enable'), 'Enable', BoolToOnOff(app, isDisabled && anyDensityHandlerValid));
            set(findall(app.DensitymapButtonGroup, '-property', 'Enable'), 'Enable', BoolToOnOff(app, isDisabled && anyDensityHandlerValid));
            set(app.EuclideanNConesButton, 'Enable', BoolToOnOff(app, isDisabled && ~isempty(app.EuclideanNConesHandler) && isvalid(app.EuclideanNConesHandler)));
            set(app.NNDmeanButton, 'Enable', BoolToOnOff(app, isDisabled && ~isempty(app.NNDmeanHandler) && isvalid(app.NNDmeanHandler)));
            set(app.YellotsRingsButton, 'Enable', BoolToOnOff(app, isDisabled && ~isempty(app.YellotsRingsHandler) && isvalid(app.YellotsRingsHandler)));
            if ~isempty(app.EuclideanNConesHandler) && isvalid(app.EuclideanNConesHandler)
                app.EuclideanNconesEditField.Value = app.EuclideanNConesHandler.NumOfNearestCones;
            end
            if ~isempty(app.YellotsRingsHandler) && isvalid(app.YellotsRingsHandler)
                app.YellotsROIsizeEditField.Value = app.YellotsRingsHandler.ROI_size;
            end

            if app.EuclideanNConesButton.Enable + app.NNDmeanButton.Enable + app.YellotsRingsButton.Enable == 1
                enabledRadioButton = findall([app.EuclideanNConesButton, app.NNDmeanButton, app.YellotsRingsButton], 'Enable', 1);
                app.DensitymapButtonGroup.SelectedObject = enabledRadioButton;
            end
            if ~anyDensityHandlerValid
                app.PCDCheckBox.Value = 0;
                app.CDCCheckBox.Value = 0;
                app.ContoursCheckBox.Value = 0;
                app.ShowDMCheckBox.Value = 0;
            end
            
            UpdateDensityLimits(app);

%             app.ReportunitsDropDown.Enable = ~app.zScoremapCheckBox.Value;
            
            app.MaxLimEditField.Enable = BoolToOnOff(app, ~app.AutoLimCheckBox.Value & isDisabled);
            app.MinLimEditField.Enable = BoolToOnOff(app, ~app.AutoLimCheckBox.Value & isDisabled);

            set(findall(app.HelpMenu, '-property', 'Enable'), 'Enable', 'on');
            app.SaverecognitionMenu.Enable = isDisabledString;
            app.InfoLabel.Enable = BoolToOnOff(app, ~isempty(app.FigureHandler));
            app.ReceptorLabel.Enable = BoolToOnOff(app, ~isempty(app.FigureHandler));

            app.IMFEditField.Value = app.ImageMagnificationFactor;
            app.RMFEditField.Value = app.RetinalMagnificationFactor;
            
            app.SaveIMFButton.Enable = isDisabled && app.IMFEditField.Editable;
            app.SaveRMFButton.Enable = isDisabled && app.RMFEditField.Editable;
        end

        function UpdateReportUnits(app)
            if ~strcmp(app.ReportunitsDropDown.Value, 'pixel') && app.ImageMagnificationFactor == 0
                app.ReportunitsDropDown.Value = 'pixel';
                msgbox("Please, Enter the IMF value.",...
                      "Error","error");
                return;
            end

            if (strcmp(app.ReportunitsDropDown.Value, 'millimeter') || ...
                strcmp(app.ReportunitsDropDown.Value, 'micrometer')) && app.RetinalMagnificationFactor == 0
                app.ReportunitsDropDown.Value = 'pixel';
                msgbox("Please, Enter the RMF value.",...
                      "Error","error");
                return;
            end

            oldUnits = app.ReportUnits;
            app.ReportUnits = app.ReportunitsDropDown.Value;

            IsOldMeterUnits = any(strcmp({'millimeter', 'mm', 'micrometer', 'µm'}, oldUnits));
            IsNewMeterUnits = any(strcmp({'millimeter', 'mm', 'micrometer', 'µm'}, app.ReportUnits));

            % if report Units has changed to metric or back
            if IsOldMeterUnits ~= IsNewMeterUnits
                % recreate zScore
                PreloadAverageMapData(app);

                if ~isempty(app.ZScoreMapHandler)
                    delete(app.ZScoreMapHandler);
    
                    switch app.DensitymapButtonGroup.SelectedObject.Tag
                        case 'EuclideanNCones'
                            densityHandler = app.EuclideanNConesHandler;
                        case 'NNDmean'
                            densityHandler = app.NNDmeanHandler;
                        case 'YellotsRungs'
                            densityHandler = app.YellotsRingsHandler;
                        otherwise
                            densityHandler = [];
                    end
            
                    GetZScore(app, densityHandler);
                end
            end

            if ~isempty(app.StatisticsWindowUIFigureHandler) && isvalid(app.StatisticsWindowUIFigureHandler)
                app.StatisticsWindowUIFigureHandler.RefreshAll();
            end

            handler.Name = 'DensityMinimumLimit';
            UpdateMinMaxFields(app, handler, []);
            handler.Name = 'DensityMaximumLimit';
            UpdateMinMaxFields(app, handler, []) ;
            
            app.DensityReportUnitsLabel.Text = app.ReportUnits;
        end

        function UpdateMinMaxFields(app, handler, ~) 
            if app.zScoremapCheckBox.Value
                scaleCoefficient = 1;
            else
                scaleCoefficient = GetScaleCoefficient(app.ReportUnits, app.ImageMagnificationFactor, app.RetinalMagnificationFactor);
            end

            % setup options
            if strcmp('degree', app.ReportUnits) || strcmp('millimeter', app.ReportUnits)
                roundFraction = 0;
            elseif strcmp('arcsec', app.ReportUnits)
                roundFraction = 5;
            elseif strcmp('pixel', app.ReportUnits)
                roundFraction = 4;
            else
                roundFraction = 2;
            end

            if strcmp(handler.Name, 'DensityMinimumLimit')
                app.MinLimEditField.Value = round(app.DensityMinimumLimit / (scaleCoefficient^2), roundFraction);
            elseif strcmp(handler.Name, 'DensityMaximumLimit')
                app.MaxLimEditField.Value = round(app.DensityMaximumLimit / (scaleCoefficient^2), roundFraction);
            end
        end
        
        % Cast logical value to strings 'on'/'off'. It is necessary for UI
        % components for Matlab vesions before Matlab R2022b
        function result = BoolToOnOff(~, value)
            if value
                result = 'on';
            else
                result = 'off';
            end
        end
        
        % ----------------------- Voronoi Diagram -------------------------------------       
        % Print Voronoi diagram
        function PrintVoronoiByType(app, voronoiType, conelocs)
            % get cones for presentation
            vorocones = conelocs(conelocs(:,3)~=0, 1:2);
            vorocones = unique(vorocones, 'rows', 'stable');
            
            % refresh data, if Vornoi is turned off or Empty option
            % selected
            if ~app.VoronoiDiagramCheckBox.Value || strcmp(voronoiType, 'Empty')
                app.VoronoiAlphaShape = [];
                app.VoronoiVerticies = [];
                
                app.VoronoiAreaColors = [];
                app.VoronoiMaxConeArea = [];
                app.VoronoiConeAreaColorScale = [];
                app.VoronoiAreaColorIndexes = [];
                app.VoronoiNNColors = [];
                app.VoronoiNNColorScale = [];
            end
            
            % if there is enough cones
            if size(vorocones, 1) > 3 && app.VoronoiDiagramCheckBox.Value
                %%%%%%%%%%%%%% PREPARE THE DATA %%%%%%%%%%%%%%
                % if there is no DelaunayTriangulation
                if isempty(app.VoronoiDelaunayTriang)
                    % create it
                    app.VoronoiDelaunayTriang = delaunayTriangulation(vorocones);
                else
                    % find the difference and updated existing DelaunayTriangulation
                    oldLength = size(app.VoronoiDelaunayTriang.Points, 1);
                    newLength = size(vorocones, 1);
                    coneWasDeleted = 0;
                    coneWasAdded = 0;
                    if newLength < oldLength
                        memberList = ismember(app.VoronoiDelaunayTriang.Points, vorocones, 'rows');
                        changeIndexes = find(memberList == 0);
                        coneWasDeleted = 1;
                    elseif newLength > oldLength
                        newCones = vorocones(end - (newLength - oldLength) + 1 : end, :);
                        coneWasAdded = 1;
                    end
                    app.VoronoiDelaunayTriang.Points = vorocones;
                end
                
                % if delaunay triangulation was not created
                if isempty(app.VoronoiDelaunayTriang)
                    % exit the function
                    return;
                end
                
                % V - verticies of polygons, C - the polygons constructed by V
                [V,C] = voronoiDiagram(app.VoronoiDelaunayTriang);
                numberOfClosedPolygons = length(C);
                
                % if we want present not Empty voroni
                if ~strcmp(voronoiType, 'Empty')
                    % Remove 'peek' coordinates of voronoi
                    if isempty(app.VoronoiAlphaShape)
                        % create an alphaShape
                        app.VoronoiAlphaShape = alphaShape(vorocones(:, 1), vorocones(:, 2));
                        app.VoronoiAlphaShape.Alpha = 2 * app.VoronoiAlphaShape.Alpha;
                        V(V(:, 1) == Inf | V(:, 2) == Inf, :) = -1;
                        % fill with NaN all verticies which is out of
                        % AlphaShape
                        tf = inShape(app.VoronoiAlphaShape, V(:,1), V(:, 2));
                        V(~tf, 1) = NaN;
                        V(~tf, 2) = NaN;
                        differentPointsIndexes = [];
                    else
                        % if new point was added
                        if newLength > oldLength
                            % check whether new point is inside the shape
                            tf = inShape(app.VoronoiAlphaShape, newCones(:, 1), newCones(:, 2));
                            % if not
                            if ~all(tf)
                                % update shape
                                app.VoronoiAlphaShape.Points = vorocones;
                            end
                        end
                        % remove peaks from new voronoi and find the
                        % difference to the previous one
                        V(V(:, 1) == Inf | V(:, 2) == Inf, :) = -1;
                        app.VoronoiVerticies(isnan(app.VoronoiVerticies)) = -1;
                        listOfMemberLogic = ismember(V, app.VoronoiVerticies, 'rows');
                        indexes = find(listOfMemberLogic == 0);
                        different = V(indexes, :);
                        tf = inShape(app.VoronoiAlphaShape, different(:, 1), different(:, 2));
                        differentPointsIndexes = indexes(tf);
                        V(V == -1) = NaN;
                        V(indexes(~tf), :) = NaN;
                    end

                    colorsNNisCreated = 0;
                    colorsCAisCreated = 0;
                    % get color scale
                    if strcmp(voronoiType, 'Number of Neighbors') && isempty(app.VoronoiNNColorScale)
                        
                        NumNeighbors = arrayfun(@(a) size(C{a},2), 1 : numberOfClosedPolygons);
                        
                        numcolorsNN = app.VoronoiMaxNN - app.VoronoiMinNN + 1;  % ********* change from 15
                        
                        idxNN = round(linspace(1, length(app.VColorMap), numcolorsNN));
                        app.VoronoiNNColorScale = flipud(app.VColorMap(idxNN,:));
                        colorsNNisCreated = 1;
                        
                    elseif strcmp(voronoiType, 'Cone Area') && isempty(app.VoronoiConeAreaColorScale)
                        
                        ConeArea = arrayfun(@(a) polyarea(V(C{a},1), V(C{a},2)), 1 : numberOfClosedPolygons);
                         
                        app.VoronoiMinConeArea = min(ConeArea);
                        app.VoronoiMaxConeArea = max(ConeArea);

                        numcolors = 25;  % ********* change from 15
                        app.VoronoiMaxConeArea = app.VoronoiMaxConeArea;        % basis for dividing cone Area through numcolors
    
                        idx = round(linspace(1, length(app.VColorMap), numcolors));
                        app.VoronoiConeAreaColorScale = flipud(app.VColorMap(idx,:));
    
                        app.VoronoiAreaColorIndexes = round(linspace(app.VoronoiMinConeArea, app.VoronoiMaxConeArea, numcolors));
                        colorsCAisCreated = 1;
                        
                    end

                    %%%%%%%%%%%%%% PRINT THE DIAGRAM %%%%%%%%%%%%%%
                    % Find colors for closed polygones by voronoiType
                    % VARIANTE 1: Falschfarben sind Anzahl Nachbarn
                    if strcmp(voronoiType, 'Number of Neighbors') && isempty(app.VoronoiNNColors)
                        app.VoronoiNNColors = zeros(numberOfClosedPolygons, 3);
                        logicMinNN = NumNeighbors <= app.VoronoiMinNN;
                        logicMaxNN = NumNeighbors > app.VoronoiMaxNN;
                        
                        app.VoronoiNNColors(logicMinNN, 1) = app.VoronoiNNColorScale(1, 1);
                        app.VoronoiNNColors(logicMinNN, 2) = app.VoronoiNNColorScale(1, 2);
                        app.VoronoiNNColors(logicMinNN, 3) = app.VoronoiNNColorScale(1, 3);
                        
                        app.VoronoiNNColors(logicMaxNN, 1) = app.VoronoiNNColorScale(end, 1);
                        app.VoronoiNNColors(logicMaxNN, 2) = app.VoronoiNNColorScale(end, 2);
                        app.VoronoiNNColors(logicMaxNN, 3) = app.VoronoiNNColorScale(end, 3);
                        
                        app.VoronoiNNColors(~logicMaxNN & ~logicMinNN, :) = app.VoronoiNNColorScale(NumNeighbors(~logicMaxNN & ~logicMinNN) - app.VoronoiMinNN + 1, :);
                        
                    % VARIANTE 2: Falschfarben sind PatchgrцЯe
                    elseif strcmp(voronoiType, 'Cone Area') && isempty(app.VoronoiAreaColors)
                        app.VoronoiAreaColors = zeros(numberOfClosedPolygons, 3);
                        [~, colorInd] = min(abs(app.VoronoiAreaColorIndexes - ConeArea'), [], 2);
                        logicCA = ConeArea <= app.VoronoiMaxConeArea;
                        app.VoronoiAreaColors(logicCA, :) = app.VoronoiConeAreaColorScale(colorInd(logicCA), :);
                        app.VoronoiAreaColors(~logicCA, 1) = app.VoronoiConeAreaColorScale(end, 1);
                        app.VoronoiAreaColors(~logicCA, 2) = app.VoronoiConeAreaColorScale(end, 2);
                        app.VoronoiAreaColors(~logicCA, 3) = app.VoronoiConeAreaColorScale(end, 3);
                    end
                end
                
                app.VoronoiVerticies = V;
                
                % Create a matrix from list of Verice inedexes (empty values filled by NaN)
                lengthesC = cellfun('length',C);
                maxLengthesC = max(lengthesC);
                % concat all points in one array from cell array of arrays
                allPolyConcateneted = cat(2, C{:});
                % preallocate memory for matrix
                matrixC = zeros([maxLengthesC + 1, numberOfClosedPolygons]);
                % find indexes where polygons definition ends
                indexes = sub2ind(size(matrixC), lengthesC(:).' + 1, 1:numberOfClosedPolygons);
                % fill it with NaN
                matrixC(indexes) =  1;
                matrixC = cumsum(matrixC(1:end - 1,:), 1);
                matrixC(matrixC == 1) = NaN;
                % fill polygons in the rest places of matrix
                matrixC(matrixC == 0) = allPolyConcateneted;
                matrixC = matrixC';                
                
                % Get plot properties
                if strcmp(voronoiType, 'Empty')
                    edgeColor = 'green';
                    faceColor = 'none';
                    colors = [];
                else
                    % Update colors. Values will be calculated only for
                    % changed polygons
                    rowsToUpdate = ismember(matrixC', differentPointsIndexes);
                    rowsToUpdate = any(rowsToUpdate);
                    rowsToUpdate = find(rowsToUpdate == 1);
                    
                    if ~isempty(app.VoronoiNNColors) && ~colorsNNisCreated
                        if coneWasDeleted
                            app.VoronoiNNColors(changeIndexes, :) = [];
                        elseif coneWasAdded
                            app.VoronoiNNColors(end + oldLength - newLength, :) = [0 0 0];
                        end
                        
                        conePolysToUpdate = C(rowsToUpdate);
                        NumNeighbors = cellfun('length', conePolysToUpdate);
                        
                        logicMinNN = NumNeighbors <= app.VoronoiMinNN;
                        logicMaxNN = NumNeighbors > app.VoronoiMaxNN;
                        colorInd = NumNeighbors(~logicMaxNN & ~logicMinNN) - app.VoronoiMinNN + 1;
                        
                        app.VoronoiNNColors(rowsToUpdate(~logicMaxNN & ~logicMinNN), :) = app.VoronoiNNColorScale(colorInd, :);
                        
                        app.VoronoiNNColors(rowsToUpdate(logicMinNN), 1) = app.VoronoiNNColorScale(1, 1);
                        app.VoronoiNNColors(rowsToUpdate(logicMinNN), 2) = app.VoronoiNNColorScale(1, 2);
                        app.VoronoiNNColors(rowsToUpdate(logicMinNN), 3) = app.VoronoiNNColorScale(1, 3);
                        
                        app.VoronoiNNColors(rowsToUpdate(logicMaxNN), 1) = app.VoronoiNNColorScale(end, 1);
                        app.VoronoiNNColors(rowsToUpdate(logicMaxNN), 2) = app.VoronoiNNColorScale(end, 2);
                        app.VoronoiNNColors(rowsToUpdate(logicMaxNN), 3) = app.VoronoiNNColorScale(end, 3);
                    end
                    
                    if ~isempty(app.VoronoiAreaColors) && ~colorsCAisCreated
                        if coneWasDeleted
                            app.VoronoiAreaColors(changeIndexes, :) = [];
                        elseif coneWasAdded
                            app.VoronoiAreaColors(end + newLength - oldLength, :) = [0 0 0];
                        end
                        
                        conePolysToUpdate = C(rowsToUpdate);
                        Areas = cellfun(@(a) polyarea(V(a, 1), V(a, 2)), conePolysToUpdate);
                        [~, colorInd] = min(abs(app.VoronoiAreaColorIndexes - Areas), [], 2);
                        logicCA = Areas <= app.VoronoiMaxConeArea;
                        
                        app.VoronoiAreaColors(rowsToUpdate(logicCA), :) = app.VoronoiConeAreaColorScale(colorInd(logicCA), :);
                        app.VoronoiAreaColors(rowsToUpdate(~logicCA), 1) = app.VoronoiConeAreaColorScale(end, 1);
                        app.VoronoiAreaColors(rowsToUpdate(~logicCA), 2) = app.VoronoiConeAreaColorScale(end, 2);
                        app.VoronoiAreaColors(rowsToUpdate(~logicCA), 3) = app.VoronoiConeAreaColorScale(end, 3);
                    end
                    
                    if strcmp(voronoiType, 'Number of Neighbors')
                        colors = app.VoronoiNNColors;
                    elseif strcmp(voronoiType, 'Cone Area')
                        colors = app.VoronoiAreaColors;
                    end
                    edgeColor = 'black';
                    faceColor = 'flat';
                end
                
                % Plot the diagram
                if isempty(app.VoronoiPatchHandler) || ~isvalid(app.VoronoiPatchHandler)
                    app.VoronoiPatchHandler = patch(app.AxisHandler, 'Faces', [],'Vertices', []);
                end
                
                set(app.VoronoiPatchHandler, 'Faces', matrixC, 'Vertices', app.VoronoiVerticies, ...
                    'FaceVertexCData', colors, 'FaceColor', faceColor, ...
                    'EdgeColor', edgeColor, 'Visible', 'on');
            else
                if ~isempty(app.VoronoiPatchHandler) && isvalid(app.VoronoiPatchHandler)
                    set(app.VoronoiPatchHandler, 'Visible', 'off');
                end
            end
        end
        
        % ------------------------- Find Cones ------------------------------

        function box_addCones = DrawBox(app)
            fPFBox = drawrectangle(app.AxisHandler); % imrect(gca);
            wait(fPFBox);
            
            box_addCones = fPFBox.Position;
            delete(fPFBox);

            if ~isempty(box_addCones)
                [rows, cols] = size(app.Image);
                
                minaddx = floor(box_addCones(1));
                minaddy = floor(box_addCones(2));
                if minaddx < 1
                    minaddx = 1;
                end
                if minaddy < 1
                    minaddy = 1;
                end
                
                maxaddx = minaddx + ceil(box_addCones(3));
                maxaddy = minaddy + ceil(box_addCones(4));
                if maxaddx > cols
                    maxaddx = cols;
                end
                if maxaddy > rows
                    maxaddy = rows;
                end
                
                box_addCones = [minaddx, minaddy, maxaddx, maxaddy];
            end
        end

        function add_conelocs = FindCones(~, image, box_addCones, methodName, dataSetName, imagePath, cnnFloderName)
        % Finds Cones in given box
            add_conelocs = [];

            if ~isempty(box_addCones)
                
                minaddx = box_addCones(1);
                minaddy = box_addCones(2);
                
                maxaddx = box_addCones(3);
                maxaddy = box_addCones(4);

                if strcmp(methodName, 'FastPeakFind')
                    p = FastPeakFind(image(minaddy:maxaddy, minaddx:maxaddx));
                    add_conelocs(:,1) = p(1:2:end)+minaddx-1;
                    add_conelocs(:,2) = p(2:2:end)+minaddy-1;
                end

                if strcmp(methodName, 'CNN')
                    [p, success] = RunCNNnewSet_automatic(dataSetName, imagePath,...
                        image(minaddy:maxaddy, minaddx:maxaddx), cnnFloderName);

                    if ~success
                        return;
                    end

                    add_conelocs(:,1) = p(:, 1)+minaddx-1;
                    add_conelocs(:,2) = p(:, 2)+minaddy-1;
                end
                
                add_conelocs(:,3) = 1;
            end
        end

        % Finds cone locations by FastPeakFind algorithm
        function FastPeakFinder(app)
            p = FastPeakFind(app.Image);
            app.Conelocs(:,1) = p(1:2:end);
            app.Conelocs(:,2) = p(2:2:end);
            app.Conelocs(:,3) = 1;
            app.Rodlocs = zeros(0, 3);
            
            app.Conelocs = unique(app.Conelocs, 'rows', 'stable');
            
            app.ConeCount = size(app.Conelocs(app.Conelocs(:,3)~=0, 1),1);
            app.TotalConeCount = size(app.Conelocs,1);
        end
        
        % Find cone locations by FCN
        function success = PythonFCN(app, image1, path)
            success = 0;
            try
                [data, ~, success] = DetectConesPythonFCN(image1, path);
            catch ME
                disp(ME);
                msgbox("Recognitions was not successful. Please, check that all FCN requirements has been installed. Please check that your image is grayscale",...
                      "Error","error");
                return;
            end

            if success
                app.Conelocs(:,1) = data(:, 1);
                app.Conelocs(:,2) = data(:, 2);
                app.Conelocs(:,3) = 1;
                app.Rodlocs = zeros(0, 3);
                
                app.Conelocs = unique(app.Conelocs, 'rows', 'stable');
                
                app.ConeCount = size(app.Conelocs(app.Conelocs(:,3)~=0, 1),1);
                app.TotalConeCount = size(app.Conelocs,1);
            end
        end

        % Find cone locations by CNN
        function success = CnnFinder(app, cnnFloderName)
            % DataSet = '14111';  % dataSet specifies trained CNN (grader 1 trained network - 14(x16) training images and 1(x16) validation image)
            % DataSet = '42';  % dataSet specifies trained CNN (grader 1 14(x16)training + 100 cunefare val images and 1(x16) validation g1 + 16 cunefare training images)
           
            fn = {'g1+cunefare', 'alex training set', '41 eye jlr set (black cut)', 'julius montages', 'julius montages2', '49 montages (2024)'};
            [indx, tf] = listdlg('Name', 'Cone Finder Data Sets', 'PromptString', {'Select a cone finder data set.'},...
                'SelectionMode','single','ListString', fn);
            
            % if user clicked 'Cancel'
            if ~tf
                DataSet = 'hamwoodset';
            else
                switch indx
                    case 1 % 'g1+cunefare'
                        DataSet = 'g1+cunefare';
                        
                    case 2 % 'alex training set'
                        DataSet = 'alex training set';
                       
                    case 3
                        DataSet = '41 eye jlr set (black cut)';
                        
                    case 4
                        DataSet = 'julius montages';
                    
                    case 5
                        DataSet = 'julius montages2';
                    
                    case 6
                        DataSet = 'hamwoodset';
                end % switch
            end
            
            ImageDir = [app.Pname app.Fname];
            startTimeCNN = datetime("now");
            fprintf('startTimeCNN: %s \n', string(startTimeCNN));
            [app.Conelocs, success] = RunCNNnewSet_automatic(DataSet, ImageDir, app.Image, cnnFloderName);
            endTimeCNN = datetime("now");
            fprintf('endTimeCNN: %s \n', string(endTimeCNN));
            disp(' ');
            disp(' ');
            

            if ~success
%                 uialert(app.CMUIFigure,...
%                     "Recognitions was not successful. Please, check that your CNN has been compiled. Please check that your image is grayscale", ...
%                     "Error...",...
%                     'Icon', 'error');
                  msgbox("Recognitions was not successful. Please, check that your CNN has been compiled. Please check that your image is grayscale",...
                      "Error","error");
            end

            app.Conelocs = unique(app.Conelocs,'rows', 'stable');
            app.Conelocs(:,3) = 1;
            
            app.Conelocs = unique(app.Conelocs, 'rows', 'stable');
            
            app.Rodlocs = zeros(0, 3);
            app.ConeCount = size(app.Conelocs(app.Conelocs(:,3)~=0, 1),1);
            app.TotalConeCount = size(app.Conelocs,1);
        end
        
        % Loads Data
        function LoadData(app)
            load([app.Pname app.Fname], 'I', 'conelocs');

            % if we open old format and there was several images
            if iscell(I)
                % take first image
                app.Image = I{1};
            else
                app.Image = I;
            end
            app.Image = app.Image(:,:,1);
            
            % if image was 'double' format (0 - 1)
            if isa(app.Image, 'double')
                % conver it to int8 format (0-255)
                app.Image = im2uint8(app.Image);
            end
            
            app.Conelocs = conelocs;
            
            app.Conelocs = unique(app.Conelocs, 'rows', 'stable');
            % delete cone locations outside image
            app.Conelocs(app.Conelocs(:, 1) < 1 | app.Conelocs(:, 2) < 1, :) = [];
            
            % if cone locations has not "type"
            if size(app.Conelocs, 2) < 3
                % set type as "autodetect"
                app.Conelocs(:, 3) = 1;
            end
            
            
            % if cone locations has old format
            if any(app.Conelocs(:, 3) == 3 | app.Conelocs(:, 3) == 0)
                app.OldFormatLoaded = 1;
                
                % set to all user-found-cones which is "outside the box" type "2" (just user-found)
                app.Conelocs(app.Conelocs(:, 3) == 3, 3) = 2;
            
                % ask user to keep autodetect cones "outside the box"
                loadConesChoise = questdlg('You load data of old format. Choose, which data should be loaded.', 'Old format',...
                    'All cones', 'Cones "inside the box"', 'Cones "inside the box"');
                % if keep all cones
                if strcmp(loadConesChoise, 'All cones')
                    % keep it
                    app.Conelocs(app.Conelocs(:, 3) == 0, 3) = 1;
                else
                    % delete them
                    app.Conelocs(app.Conelocs(:, 3) == 0, :) = [];
                end
            else
                variableInfo = who('-file', [app.Pname app.Fname]);
                
                if ismember('euclideanNCones', variableInfo)
                    load([app.Pname app.Fname], 'euclideanNCones');
                    if isstruct(euclideanNCones)
                        % load class EuclideanNCones from struct
                        app.EuclideanNConesHandler = EuclideanNCones.loadobj(euclideanNCones);
                    end
                % support an old "misspelled" version
                elseif ismember('euclidianNCones', variableInfo)
                    load([app.Pname app.Fname], 'euclidianNCones');
                    if isstruct(euclidianNCones)
                        % load class EuclideanNCones from struct
                        app.EuclideanNConesHandler = EuclideanNCones.loadobj(euclidianNCones);
                    end
                end
                
                if ismember('nnd_mean', variableInfo)
                    load([app.Pname app.Fname], 'nnd_mean');
                    if isstruct(nnd_mean)
                        % load class NNDmean from struct
                        app.NNDmeanHandler = NNDmean.loadobj(nnd_mean);
                    end
                end
                
                if ismember('yellotsRings', variableInfo)
                    load([app.Pname app.Fname], 'yellotsRings');
                    if isstruct(yellotsRings)
                        % load class YellotsRings from struct
                        app.YellotsRingsHandler = YellotsRings.loadobj(yellotsRings);
                    end
                end

                if ismember('pixelPerDegree', variableInfo)
                    load([app.Pname app.Fname], 'pixelPerDegree');
                    app.ImageMagnificationFactor = pixelPerDegree;
                end

                if ismember('RMF', variableInfo)
                    load([app.Pname app.Fname], 'RMF');
                    app.RetinalMagnificationFactor = RMF;
                end

                if ismember('rodlocs', variableInfo)
                    load([app.Pname app.Fname], 'rodlocs');
                    app.Rodlocs = rodlocs;
                end

                if ismember('eyeType', variableInfo)
                    load([app.Pname app.Fname], 'eyeType');
                    h = findobj(app.EyetypeButtonGroup, 'Tag', eyeType);
                    if ~isempty(h) && isvalid(h)
                        app.EyetypeButtonGroup.SelectedObject = h;
                    end
                end
            end
            
            if isempty(app.Rodlocs)
                app.Rodlocs = zeros(0, 3);
            end

            app.ConeCount = size(app.Conelocs(app.Conelocs(:,3)~=0, 1),1);
            app.TotalConeCount = size(app.Conelocs,1);
            
            app.MarkerSize = 5;
            
            CreateFigure(app);

            UpdateLegacy(app);
        end
        
        % Removes Cones outside fovea image (on black boards)
        function RemoveBlackCones(app)
            app.Conelocs = RemoveConesOusideImage(app.Image, app.Conelocs);
            app.ConeCount = size(app.Conelocs(app.Conelocs(:,3)~=0,1),1);
            app.TotalConeCount = size(app.Conelocs,1);
            UpdateLegacy(app);
        end
        
        % Deletes all duplicated cones
        function RemoveDoubleCones(app)
            % the minimum distance between cones among 41 eye, annotated by
            % JLR is 2.6874. Distance 2.5 is choosen in order to have "safe
            % zone".
            distDifference = 2.5;
            
            app.Conelocs = RemoveNeighborConesInRadius(app.Image, app.Conelocs, distDifference);
            app.ConeCount = size(app.Conelocs(app.Conelocs(:,3)~=0,1),1);
            app.TotalConeCount = size(app.Conelocs,1);
        end

        function IsCanceled = NewFileOpening(app, fileTypes)
            IsCanceled = 0;
            
            if ~isempty(app.Image)
                answer = questdlg('Do you want to save your current recognition?', ...
    	            'Save before open', ...
    	            'Yes', 'No', 'Cancel', 'Cancel');
                
                % Handle response
                switch answer
                    case 'Yes'
                        % Select box to export
                        SaveCurrentLocations(app);
                        
                    case 'Cancel'
                        IsCanceled = 1;
                        return;
                        
                end
            end
            
            format shortg
            app.Conelocs = [];
            app.Image = []; 
           
            AddAllPathesCM(app);
            
            try 
                close(app.FigureHandler);
                app.FigureHandler = matlab.ui.Figure.empty;
                delete(app.StatisticsWindowUIFigureHandler);
            catch 
            end
            
            % User input - Select files to analyse
            % Select tiff to analyze, or mat file in case revisiting already counted tiff
            if isequal(app.Pname, 0) || isempty(app.Pname)
                start_path = app.StartFolder;
            else
                start_path = app.Pname;
            end
            
            % file selection GUI
            [fnames, pname] = uigetfile(fileTypes, 'Select file(s) to mark', start_path, 'MultiSelect', 'off');
            
            % checks if user cancelled
            if isequal(fnames,0) || isequal(pname,0)
                % display a message in the commmand window
                disp(' ');disp(' ');disp(' ');disp(' ----> User cancelled script');disp(' ');disp(' ');
                % if cancelled exit script
                IsCanceled = 1;
            end
            
            app.Pname = pname;
            app.Fname = fnames;
        end
                
        % Finds the colormap by its name
        function cmap = GetColorMapBuyName(~, cname)
            cmap = [];
            try
                colormaps_science;
                switch cname
                    case 'bone'
                        cmap = bone;
                        
                    case 'cividis'
                        cmap = cividis; 
                        
                    case 'coolwarm'
                        cmap = coolwarm; 
                        
                    case 'inferno'
                        cmap = inferno; 
                        
                    case 'magma'
                        cmap = magma; 
                        
                    case 'plasma'
                        cmap = plasma; 
                        
                    case 'parula'
                        cmap = parula();
                        
                    case 'viridis'
                        cmap = viridis; 
                end
            catch
                disp('colormap is not found. Default colormap is used.');
            end
        end

        function SetupColorMap(app)
            % Colormap for Voronoi patches
            try
                colormaps_science;
                app.VColorMap = viridis;
            catch
                disp('colormap is not found. Default colormap is used.');
                app.VColorMap = parula();
            end

            app.DensColorMap = parula();
%             close(gcf);
        end
        
        % Cuts image on tiles and export it to pairs of tiff image and csv
        % cone locations.
        % selectionsBox - 4 elements array, representing a rectangle [x, y, width, height]
        % squareSize - size of square tile
        % folder - folder to write an export
        function CutAndSaveExport(app, selectionBox, squareSize, folder)
            % get sizes of the box
            originalSizeX = selectionBox(3);
            originalSizeY = selectionBox(4);
            
            % get sqare tiles positions
            cutoutsINrowX = ceil(originalSizeX / squareSize);
            cutoutsINrowY = ceil(originalSizeY / squareSize);
            
            numCutouts = cutoutsINrowX * cutoutsINrowY;
            
            offsetX = floor(cutoutsINrowX*squareSize-originalSizeX) / (cutoutsINrowX-1); 
            offsetY = floor(cutoutsINrowY*squareSize-originalSizeY) / (cutoutsINrowY-1);
            
            positionsStartX = [1, squareSize-offsetX : squareSize-1-offsetX : ((squareSize-1)*cutoutsINrowX-offsetX*cutoutsINrowX)];
            positionsStartY = [1, squareSize-offsetY : squareSize-1-offsetY : ((squareSize-1)*cutoutsINrowY-offsetY*cutoutsINrowY)];
            positionsStartX = positionsStartX + selectionBox(1) - 1;
            positionsStartY = positionsStartY + selectionBox(2) - 1;

            % get waitbar
            h = waitbar(0, 'Exporting...');
            
            % get original name of image
            [~, nm, ~] = fileparts([app.Pname app.Fname]);
            
            % for each tile
            for cutoutY = 1:cutoutsINrowY
                for cutoutX = 1:cutoutsINrowX
                    
                    % create patches of 150x150 pixel
                    startX = positionsStartX(cutoutX);
                    startY = positionsStartY(cutoutY);
                    endX = startX + squareSize - 1;
                    endY = startY + squareSize - 1;
                    
                    positions = [...
                        startX, startY; ...
                        endX, endY ...
                        ];
                    
                    GetConeLocationAndWriteWithImage(app, nm, folder, positions)

                    % update waitbar
                    waitbar(((cutoutY - 1) * cutoutsINrowX + cutoutX) / (numCutouts))
                end
            end
            
            % close waitbar
            close(h)
        end
        
        % Get cone locations and wites CSV and TIFF
        function GetConeLocationAndWriteWithImage(app, nm, folder, positions)
            startX = round(positions(1, 1));
            endX = round(positions(2, 1));
            startY = round(positions(1, 2));
            endY = round(positions(2, 2));
            
            % get image to export
            imageToExport = app.Image(startY:endY,startX:endX);
                
            % get cone locations to export
            conelocsToExport = app.Conelocs((...
                app.Conelocs(:,1) >= startX &...
                app.Conelocs(:,1) <= endX &...
                app.Conelocs(:,2) >= startY &...
                app.Conelocs(:,2) <= endY), 1:2);
            % move cone locations to tile origin
            conelocsToExport(:, 1) = conelocsToExport(:, 1) - startX + 1;
            conelocsToExport(:, 2) = conelocsToExport(:, 2) - startY + 1;
            
            % get name of exporting file
            name = [nm, '_', num2str(startX), '_', num2str(startY), '_', num2str(endX), '_', num2str(endY)];
            
            % save locations to csv
            writematrix(conelocsToExport, [folder, filesep, name, '.csv']);
            imwrite(imageToExport, [folder, filesep, name,  '.tiff']);
        end
        
        % Imports files from CSV file
        function success = ImportFromCSV(app)
            [~, nm, ~] = fileparts(app.Fname);
            start_path = [app.Pname, filesep, nm, '.csv'];
            % file selection GUI
            [filename, pname] = uigetfile('*.csv;', 'Select file(s) to mark', start_path, 'MultiSelect', 'off');
            
            if all(filename == 0) && all(pname == 0)
                success = false;
                return;
            end

            conelocs = readmatrix([pname, filesep, filename]);
            
            app.Conelocs(:, 1:2) = conelocs;
            app.Conelocs(:, 3) = 2;
            
            app.Conelocs = unique(app.Conelocs, 'rows', 'stable');

            app.Rodlocs = zeros(0, 3);
            
            app.ConeCount = size(app.Conelocs(app.Conelocs(:,3)~=0, 1),1);
            app.TotalConeCount = size(app.Conelocs,1);
            success = true;
        end
    end
    
    methods (Access = private)        
        function CreateFigure(app)
            app.FigureHandler = figure('MenuBar', 'none', ...
                                      'ToolBar', 'none', ...
                                      'DockControls', 'off', ...
                                      'NumberTitle', 'off');
            app.AxisHandler = axes(app.FigureHandler);
            app.ImageShowHandler = imshow(app.Image, 'Parent', app.AxisHandler);
            hold on;
        end
        
        function CreateScrollMinimap(app)
            if isempty(app.ScrollPanelHandler)
                app.ScrollPanelHandler = imscrollpanel(app.ImageShowHandler.Parent.Parent, app.ImageShowHandler); % Handle to scroll panel.
                set(app.ScrollPanelHandler,'Units','normalized',...
                   'Position',[0 0 1 1])
            
                % Add a Magnification Box and an Overview tool.
                app.MagBoxHandler = immagbox(app.ImageShowHandler.Parent.Parent, app.ImageShowHandler);
                pos = get(app.MagBoxHandler,'Position');
                set(app.MagBoxHandler,'Position',[0 0 pos(3) pos(4)]);

                % TODO: decide what to do with minimap
%                app.MiniMapHandler = imoverview(app.ImageShowHandler);
%                set(app.MiniMapHandler, 'MenuBar', 'none');
%                set(app.MiniMapHandler, 'ToolBar', 'none');
%                app.MiniMapHandler.Resize = 'off';
               
            
                % Get the scroll panel API to programmatically control the view.
                app.ScrollPanelApi = iptgetapi(app.ScrollPanelHandler); 
                mag = app.ScrollPanelApi.findFitMag();
                app.ScrollPanelApi.setMagnification(mag);
                figure(app.FigureHandler);
             end
        end
        
        function SubscribeEvents(app)
            [h, w, ~] = size(app.Image);
            app.ImgWidth = w;
            app.ImgHeight = h;
            
            
            set(app.FigureHandler, 'WindowScrollWheelFcn', @app.ZoomMouseWheelCallback);
            set(app.FigureHandler, 'WindowButtonDownFcn', @app.MouseButtonDownCallback);
            set(app.FigureHandler, 'WindowButtonUpFcn', @app.MouseButtonUpCallback);
            set(app.FigureHandler, 'WindowKeyPressFcn', @app.KeyPressCallback);
            set(app.FigureHandler, 'WindowKeyReleaseFcn', @app.KeyReleaseCallback);
            set(app.FigureHandler, 'WindowButtonMotionFcn', @app.FigureWindowButtonMotionFcn);
            set(app.FigureHandler, 'SizeChangedFcn', @app.FigureSizeChangedCallback);
            set(app.FigureHandler, 'CloseRequestFcn', @app.FigCloseRequestFcn);
            
            app.MagnificationAndMoveCallbackIds{1} = app.ScrollPanelApi.addNewMagnificationCallback(@app.UpdateScalebar);
            app.MagnificationAndMoveCallbackIds{2} = app.ScrollPanelApi.addNewLocationCallback(@app.UpdateScalebar);
            app.MagnificationAndMoveCallbackIds{3} = app.ScrollPanelApi.addNewMagnificationCallback(@app.UpdateGridAndScaleRingsText);
            app.ImageMagnificationFactorListener = addlistener(app, 'ImageMagnificationFactor', 'PostSet', @app.UpdateScalebarUnits);
            app.RetinalMagnificationFactorListener = addlistener(app, 'RetinalMagnificationFactor', 'PostSet', @app.UpdateScalebarUnits);
            app.DensityMinimumLimitListener = addlistener(app, 'DensityMinimumLimit', 'PostSet', @app.UpdateMinMaxFields);
            app.DensityMaximumLimitListener = addlistener(app, 'DensityMaximumLimit', 'PostSet', @app.UpdateMinMaxFields);
        end
        
        function UnsubscribeEvents(app, hFig)
            set(hFig, 'WindowScrollWheelFcn', []);
            set(hFig, 'WindowButtonDownFcn', []);
            set(hFig, 'WindowButtonUpFcn', []);
            set(hFig, 'WindowKeyPressFcn', []);
            set(hFig, 'WindowKeyReleaseFcn', []);
            set(hFig, 'WindowButtonMotionFcn', []);
            set(hFig, 'SizeChangedFcn', []);
            
            if isvalid(app)
                if ~isempty(app.ScrollPanelApi)
                    app.ScrollPanelApi.removeNewMagnificationCallback(app.MagnificationAndMoveCallbackIds{1});
                    app.ScrollPanelApi.removeNewLocationCallback(app.MagnificationAndMoveCallbackIds{2});
                end
    
                delete(app.ImageMagnificationFactorListener);
                delete(app.RetinalMagnificationFactorListener);
                delete(app.DensityMinimumLimitListener);
                delete(app.DensityMaximumLimitListener);
            end
        end
        
        function FigureWindowButtonMotionFcn(app, ~, ~)
            if ~isempty(app) && isvalid(app) && ~isempty(app.ImageShowHandler) && isvalid(app.ImageShowHandler)
                seedPt = get(app.ImageShowHandler.Parent, 'CurrentPoint'); 
                seedPt = seedPt(1, :);
                x = seedPt(1);
                y = seedPt(2);
                [height, width] = size(app.Image);
                indexY = round(y);
                indexX = round(x);
                if indexX > 0 && indexX < width && indexY > 0 && indexY < height
                    app.InfoLabel.Text = sprintf('X: %8.2f; Y: %8.2f; Pixel: %d', x, y, app.Image(indexY, indexX));
                else
                    app.InfoLabel.Text = 'Info';
                end

                if ~isempty(app.StatisticsWindowUIFigureHandler) && isvalid(app.StatisticsWindowUIFigureHandler)
                    app.StatisticsWindowUIFigureHandler.UpdateDynamicData(x, y);
                end
            end
        end
        
        % Applies zoom
        function ZoomMouseWheelCallback(app, ~, cbdata)
            % get current point
            seedPt = get(app.ImageShowHandler.Parent, 'CurrentPoint');
            % get the direction of magnification
            scrollChange = cbdata.VerticalScrollCount; % -1: zoomIn, 1: zoomOut
            % get current magnification
            oldMag = app.ScrollPanelApi.getMagnification();
            
            % get mew magnification
            steps = round(logspace(1,4,40));
            magnification = [10:10:100, steps(11:end)];
            magnification = magnification ./ 100;
            if scrollChange > 0
                index = find(magnification < oldMag, 1, 'last');
            else
                index = find(magnification > oldMag, 1, 'first');
            end
            
            if ~isempty(index)
                % get old top left location
                oldLoc = app.ScrollPanelApi.getVisibleLocation();
                
                % change magnification
                newMag = magnification(index);
                app.ScrollPanelApi.setMagnification(newMag);
                
                % get new top left location
                newY = seedPt(1, 2) - (oldMag/newMag) * (seedPt(1, 2) - oldLoc(2));
                newX = seedPt(1, 1) - (oldMag/newMag) * (seedPt(1, 1) - oldLoc(1));
                
                % change top left location
                app.ScrollPanelApi.setVisibleLocation(newX, newY);
            end
        end
        
        
        function MouseButtonDownCallback(app, hObj, event)
            if app.IsBusy
                return;
            end
            
            clickType = event.Source.SelectionType;
            isAltPressed = any(ismember(get(hObj,'CurrentModifier'), 'alt'));

            % If 'Alt' is pressed
            if isAltPressed && ~isempty(app.ImageShowHandler) && isvalid(app.ImageShowHandler)
                seedPt = get(app.ImageShowHandler.Parent, 'CurrentPoint'); 
                seedPt = seedPt(1, :);
                x = seedPt(1);
                y = seedPt(2);
            
                % Mouse Left Click
                if strcmp(clickType, 'normal')
                    MarkCone(app, x, y);
                end
                
                % Mouse Right Click (or Ctrl + Left Click)
                if strcmp(clickType, 'alt')
                    RemoveMark(app, x, y);
                end
            end
            
            % if clicked mouse button 3 (wheel)
            if (strcmp(clickType, 'extend'))

                guiArea = hittest(hObj);
                parentAxes = ancestor(guiArea, 'axes');

                % if the mouse is over the desired axis, trigger the pan fcn
                if ~isempty(parentAxes)
                    app.StartPan(hObj)
                else
                    setptr(event.Source, 'forbidden')
                end
            end
        end
                
        function MouseButtonUpCallback(app, ~, ~)
            if app.IsBusy
                return;
            end
            
            app.StopPan();
        end
        
            
        function KeyPressCallback(app, ~, event)
            if app.IsBusy
                return;
            end
            
            if any(ismember(event.Modifier, 'alt'))
                setptr(app.FigureHandler, 'cross');
            end
            modifierControlOn = any(ismember(event.Modifier, 'control'));
            button = event.Key;
        
            switch button
                case 'uparrow'
                    MoveImage(app, [0, -1]);
                case 'downarrow'
                    MoveImage(app, [0, 1]);
                case 'rightarrow'
                    MoveImage(app, [1 0]);
                case 'leftarrow'
                    MoveImage(app, [-1 0]);
                
                % Darken image "D"
                case 'd'
                    if (app.BrightnessSlider.Value < 2.0)
                        if (app.BrightnessSlider.Value + 0.1 <= 2.0)
                            app.BrightnessSlider.Value = app.BrightnessSlider.Value + 0.1;
                        else
                            app.BrightnessSlider.Value = 2.0;
                        end
                    end
                    newEvent.Value = app.BrightnessSlider.Value;
                    BrightnessSliderValueChanging(app, newEvent);
                    
                % Brighten image "B"
                case 'b'
                    if (app.BrightnessSlider.Value > 0.0)
                        if (app.BrightnessSlider.Value - 0.1 >= 0.0)
                            app.BrightnessSlider.Value = app.BrightnessSlider.Value - 0.1;
                        else
                            app.BrightnessSlider.Value = 0.0;
                        end
                    end
                    newEvent.Value = app.BrightnessSlider.Value;
                    BrightnessSliderValueChanging(app, newEvent);
                    
                % Reset image brightness "R"
                case 'r'
                    app.BrightnessSlider.Value = 1.0;
                    ResetButtonValueChanged(app, event);
                                        
                % Log filter image "L"
                case 'l'
                    app.LogfilterCheckBox.Value = ~app.LogfilterCheckBox.Value;
                    LogImageLCheckBoxValueChanged(app, event);
                    
                % Laplassian of Gaussian filter image "G"
                case 'g'
                    app.LaplacianofGaussianfilterCheckBox.Value = ~app.LaplacianofGaussianfilterCheckBox.Value;
                    LapOfGausCheckBoxValueChanged(app, event);
                    
                % Show image "I"
                case 'i'
                    app.ShowImageICheckBox.Value = ~app.ShowImageICheckBox.Value;
                    ShowImageICheckBoxValueChanged(app, event);
                    
                % Show Marks "T"
                case 't'
                    app.ShowMarkedLocationsTCheckBox.Value = ~app.ShowMarkedLocationsTCheckBox.Value;
                    ShowMarkedLocationsTSwitchValueChanged(app, event);
                    
                % Circle marks "C"
                case 'c'
                    app.CircleButton.Value = ~app.CircleButton.Value;
                    ConeMarksTypeButtonGroupSelectionChanged(app, event);
                    
                % Cross marks "X"
                case 'x'
                    app.CrossButton.Value = ~app.CrossButton.Value;
                    ConeMarksTypeButtonGroupSelectionChanged(app, event);
                    
                % Dot marks "Z"
                case 'z'
                    app.DotButton.Value = ~app.DotButton.Value;
                    ConeMarksTypeButtonGroupSelectionChanged(app, event);
                    
                % Autodetected marks "1"
                case '1'
                    if modifierControlOn
                        app.Autodetect1Button.Value = ~app.Autodetect1Button.Value;
                        ShowMarkedOptionsButtonGroupSelectionChanged(app, event);
                    end
                    
                % Userdetected marks "2"
                case '2'
                    if modifierControlOn
                        app.Userdetect2Button.Value = ~app.Userdetect2Button.Value;
                        ShowMarkedOptionsButtonGroupSelectionChanged(app, event);
                    end
                    
                % Auto + User detected marks "3"
                case '3'
                    if modifierControlOn
                        app.AutoUserButton.Value = ~app.AutoUserButton.Value;
                        ShowMarkedOptionsButtonGroupSelectionChanged(app, event);
                    end
                    
                % Highlight autodetected marks "0"
                case '0'
                    if modifierControlOn
                        app.AutovsUserdetectCheckBox.Value = ...
                            ~app.AutovsUserdetectCheckBox.Value;
                        AutovsUserdetectCheckBoxValueChanged(app, event);
                    end
                    
                % Show grid "Q"
                case 'q'
                    app.GridCheckBox.Value = ~app.GridCheckBox.Value;
                    GridCheckBoxValueChanged(app, event);
                    
                % Show grid numbers "Y"
                case 'y'
                    app.GridNumbersCheckBox.Value = ~app.GridNumbersCheckBox.Value;
                    ShowGirdNumbersECheckBoxValueChanged(app, event);
                    
                % Save current locations "SPACE"
                case 'space'
                    SaveCurrentLocationsSPACEButtonPushed(app, event);
                    
                % Delete selected locations "Del" ("entf")
                case 'delete'
                    DeletedSelectedConesButtonPushed(app, event);
                    
                % Plot voronoi digram for 'Cone Area', "V"
                case 'v'
                    if strcmp(app.AreaButton.Tag, app.VoronoiType) && app.VoronoiDiagramCheckBox.Value 
                        app.VoronoiDiagramCheckBox.Value = 0;
                        VoronoiDiagramCheckBoxValueChanged(app, event);
                    else
                        app.VoronoidiagramButtonGroup.SelectedObject = app.AreaButton;
                        if ~app.VoronoiDiagramCheckBox.Value 
                            app.VoronoiDiagramCheckBox.Value = 1;
                        end
                        VoronoidiagramButtonGroupSelectionChanged(app, event);
                    end
                    
                % Plot voronoi digram for 'Number of Neighbours', "N"
                case 'n'
                    if strcmp(app.NNeighbourButton.Tag, app.VoronoiType) && app.VoronoiDiagramCheckBox.Value 
                        app.VoronoiDiagramCheckBox.Value = 0;
                        VoronoiDiagramCheckBoxValueChanged(app, event);
                    else
                        app.VoronoidiagramButtonGroup.SelectedObject = app.NNeighbourButton;
                        if ~app.VoronoiDiagramCheckBox.Value 
                            app.VoronoiDiagramCheckBox.Value = 1;
                        end
                        VoronoidiagramButtonGroupSelectionChanged(app, event);
                    end
                    
                % Plot voronoi digram for 'Empty', "E"
                case 'e'
                    if strcmp(app.VoidButton.Tag, app.VoronoiType) && app.VoronoiDiagramCheckBox.Value 
                        app.VoronoiDiagramCheckBox.Value = 0;
                        VoronoiDiagramCheckBoxValueChanged(app, event);
                    else
                        app.VoronoidiagramButtonGroup.SelectedObject = app.VoidButton;
                        if ~app.VoronoiDiagramCheckBox.Value 
                            app.VoronoiDiagramCheckBox.Value = 1;
                        end
                        VoronoidiagramButtonGroupSelectionChanged(app, event);
                    end

                % Switch between rods/cones
                case 's'
                    if strcmp(app.ReceptorsTypeDropDown.Value, 'Cones')
                        app.ReceptorsTypeDropDown.Value = 'Rods';
                    else
                        app.ReceptorsTypeDropDown.Value = 'Cones';
                    end
                    ReceptorsTypeDropDownValueChanged(app, event);
            end
               
            UpdateUI(app);
        end
    
        function KeyReleaseCallback(app, ~, event)
            if app.IsBusy
                return;
            end
            
            if ~any(ismember(event.Modifier, 'alt'))
                setptr(app.FigureHandler, 'arrow');
            end
        end

        function FigureSizeChangedCallback(app, ~, ~)
            if isempty(app.AxisForColorbarHandler) ...
                    || ~isvalid(app.AxisForColorbarHandler)...
                    || strcmp(app.AxisForColorbarHandler.Visible, 'off')
                return;
            end
            SetColorbarSize(app);
        end

        function SetColorbarSize(app)
            app.FigureHandler.Units = "pixels"';
            app.AxisForColorbarHandler.Units = 'pixels';
            app.AxisForColorbarHandler.Position = [app.FigureHandler.Position(3) - 235, 20, 221, 60];
            app.ColorbarHandler.Units = 'pixels';
            app.ColorbarHandler.Position = [app.FigureHandler.Position(3) - 206, 45, 157, 8];
        end
        
        function FigCloseRequestFcn(app, hFig, ~)
            if isvalid(app)
                if ~isempty(app.Conelocs) && ~isempty(app.Image)
                    close_dialog = questdlg('Do you want to save before quitting?',...
                        'Close analysis window', 'Yes', 'No', 'Cancel', 'Cancel');
                    
                    if strcmp(close_dialog, 'Yes')
                        SaveCurrentLocations(app);
                    elseif strcmp(close_dialog, 'Cancel')
                        app.IsClosing = 0;
                        return;
                    end
                end


                UnsubscribeEvents(app, hFig);
                delete(hFig);
    
                app.FigureHandler = matlab.ui.Figure.empty;
                app.AxisHandler = matlab.graphics.axis.Axes.empty;
                app.AxisForColorbarHandler = matlab.graphics.axis.Axes.empty;
                app.ColorbarHandler = [];
                app.ScrollPanelApi = [];
                app.ImageShowHandler = matlab.graphics.primitive.Image.empty;
                app.DensityMapImageHandler = [];
                app.DensityContoursHandler = {[], []; [], []};
                app.MagBoxHandler = [];
                app.MiniMapHandler = [];
                app.DensityTextHandlers = [];
                app.ScrollPanelHandler = [];
                app.GridHorLinesHandler = [];
                app.GridVertLinesHandler = [];
                app.GridTextHandler = [];
                app.ScaleRingsHandler = [];
                app.ScaleRingsTextHandler = [];
                app.ScaleRingsHorAxHandler = [];
                app.ScaleRingsVertAxHandler = [];
                delete(app.EuclideanNConesHandler);
                app.EuclideanNConesHandler = [];
                app.EuclideanNConesMenu.Checked = 0;
                delete(app.NNDmeanHandler);
                app.NNDmeanHandler = [];
                app.NNDmeanMenu.Checked = 0;
                delete(app.YellotsRingsHandler);
                app.YellotsRingsHandler = [];
                app.YellotsRingsMenu.Checked = 0;
                delete(app.ZScoreMapHandler);
                app.ZScoreMapHandler = [];
                app.zScoremapCheckBox.Value = 0;
                SetDensitySavedInterfaceParams(app);
                delete(app.ScaleBar);
                app.ScaleBar = [];
                app.Image = [];
                app.Conelocs = [];
                app.ImageMagnificationFactor = 0;
                app.RetinalMagnificationFactor = 0;
                app.EyetypeButtonGroup.SelectedObject = app.NoneButton;
                app.AvgDensityRadialDataStruct = [];

                app.ReportunitsDropDown.Value = app.ReportunitsDropDown.Items{1};
                app.ScaleBarUnitsDropDown.Value = app.ScaleBarUnitsDropDown.Items{1};

                app.VoronoiDiagramCheckBox.Value = 0;
                app.ShowDMCheckBox.Value = 0;
                app.ContoursCheckBox.Value = 0;
                UpdateUI(app);

                delete(app.StatisticsWindowUIFigureHandler);
                delete(app.DensityContoursUISettingsHandler);
                
                app.AutoSaveTimer.stop();
            else
                UnsubscribeEvents(app, hFig);
                delete(hFig);
            end
        end
        
        %% AXIS PANNING FUNCTIONS
    
        % Call this Fcn in your 'WindowButtonDownFcn'
        % Take in desired Axis to pan
        % Get seed points & assign the Panning Fcn to top level Fig
        function StartPan(app, hFig)
            % Parent Fig
            %hFig = ancestor(hAx, 'Figure', 'toplevel');   
    
            % Get init mouse position
            seedPt = get(hFig, 'CurrentPoint'); 
            % Keep only 1st point
            seedPt = seedPt(1, :); 
    
            
            start_pos_im = app.ScrollPanelApi.getVisibleImageRect();
            start_pos_im(3:4) = [];

            screenPerImagePixels = app.ScrollPanelApi.getMagnification();

            set(hFig,'WindowButtonMotionFcn', {@app.PanningCallback, screenPerImagePixels, seedPt, start_pos_im});
            % Assign 'Panning' cursor
            setptr(hFig, 'hand');
        end
    
    
        % Call this Fcn in your 'WindowButtonUpFcn'
        function StopPan(app)
            set(gcbf,'WindowButtonMotionFcn', @app.FigureWindowButtonMotionFcn);
            setptr(gcbf,'arrow');
        end
    
    
        % Controls the real-time panning on the desired axis
        function PanningCallback(app, ~, ~, screenPerImagePixels, seedPt, start_pos_im)            
            % The closedhand pointer is not provided by the HG figure window. Since we
            % are using setptr, disable the pointer manager to prevent manager
            % from caching hand cursors.
            iptPointerManager(app.FigureHandler,'Disable');
            setptr(app.FigureHandler,'closedhand')

            % Note: we must use the figure CurrentPoint property to make sure
            % the calculation of the deltas is invariant to the image being dragged.
            new_point = get(app.FigureHandler, 'CurrentPoint');
            cp = new_point(1,1:2);
            delta_x_screen = cp(1) - seedPt(1);
            delta_y_screen = cp(2) - seedPt(2);
        
            new_pos_im =  start_pos_im - ...
                [delta_x_screen -delta_y_screen]/screenPerImagePixels;

            new_pos_im = app.ConstrainDrag(new_pos_im);
            app.ScrollPanelApi.setVisibleLocation(new_pos_im(1),new_pos_im(2));
        end
        
        function pos = ConstrainDrag(app, proposedPos)
    
            x = proposedPos(1);
            y = proposedPos(2);
    
            r = app.ScrollPanelApi.getVisibleImageRect();
            w = r(3);
            h = r(4);
            
            % get image model and cdata sizes
            imModel = getimagemodel(app.ImageShowHandler);
            imW = getImageWidth(imModel);
            imH = getImageHeight(imModel);
            
            % get spatial span of data
            xdata = app.getXData(app.ImageShowHandler);
            ydata = app.getYData(app.ImageShowHandler);
            
            % compute pixel span in each direction
            dx = images.internal.getDeltaOnePixel(xdata,imW);
            dy = images.internal.getDeltaOnePixel(ydata,imH);
            
            left_edge   = xdata(1) - dx/2;
            right_edge  = xdata(2) + dx/2;
            top_edge    = ydata(1) - dy/2;
            bottom_edge = ydata(2) + dy/2;
            
            x = min(right_edge  - w, max(x,left_edge) );
            y = min(bottom_edge - h, max(y,top_edge) );
     
            pos = [x y];
    
        end
            
        function xdata = getXData(~, handle)
            
            xdata = get(handle,'XData');
            
            if isscalar(xdata)
                xdata = [xdata xdata];
            end
        end
        
        function ydata = getYData(~, handle)
            
            ydata = get(handle,'YData');
            
            if isscalar(ydata)
                ydata = [ydata ydata];
            end
        end
        
        % Moves image.
        % directionVector - vector which contains two values [x y].
        % x and y can be +1, 0, -1
        function MoveImage(app, directionVector)
            directionVector = 10 * directionVector ./ app.ScrollPanelApi.getMagnification();
            location = app.ScrollPanelApi.getVisibleLocation();
            app.ScrollPanelApi.setVisibleLocation([location(1) + directionVector(1), location(2) + directionVector(2)]);
        end
        
        %%
        % Saves data by timer
        function AutoSaveFuncion(app, ~, ~)
            if isempty(app.Image) || isempty(app.Conelocs)
                return;
            end
            
            app.InfoLabel.Text = 'autosaving';
            
            [pstr, nm, ext] = fileparts([app.Pname app.Fname]);
            
            if strcmp(ext,'.tiff')==1 || strcmp(ext,'.tif')==1
                name2 = [pstr filesep nm '_annotated_autosave.mat'];
            elseif strcmp(ext,'.mat')==1
                name2 = [pstr filesep nm '_autosave.mat'];
            end
                        
            % names of variables must be the same to names in 'save' function
            conecount = app.ConeCount;
            I = app.Image;
            conelocs = app.Conelocs;
            rodlocs = app.Rodlocs;
%             pixelPerDegree = app.ImageMagnificationFactor;

            euclideanNCones = [];
            if ~isempty(app.EuclideanNConesHandler) && isvalid(app.EuclideanNConesHandler)
                euclideanNCones = app.EuclideanNConesHandler.saveobj();
            end
            
            nnd_mean = [];
            if ~isempty(app.NNDmeanHandler) && isvalid(app.NNDmeanHandler)
                nnd_mean = app.NNDmeanHandler.saveobj();
            end
            
            yellotsRings = [];
            if ~isempty(app.YellotsRingsHandler) && isvalid(app.YellotsRingsHandler)
                yellotsRings = app.YellotsRingsHandler.saveobj();
            end

            if isfile(name2)
                save(name2, 'conecount', 'I', 'conelocs', 'rodlocs', 'euclideanNCones', 'nnd_mean', 'yellotsRings', '-append');%'pixelPerDegree', 
            else
                save(name2, 'conecount', 'I', 'conelocs', 'rodlocs', 'euclideanNCones', 'nnd_mean', 'yellotsRings'); %'pixelPerDegree'
            end
            
            if ~app.IsClosing
                app.InfoLabel.Text = 'Saved successfully';
            end
        end
        
        % Loads parameters from settigns file
        function LoadParameters(app, folder)
            keySet = {'IsAutosaveOn', 'StartFolder'};
            defaults = {1, folder};
            settingsLog = containers.Map(keySet, zeros(1, length(keySet)));
            loadedSettings = [];
            
            settingsFilePath = [folder, filesep(), 'settings.mat'];
            % if we have file with settings
            if exist(settingsFilePath, 'file')
                % check which settings is available 
                variableInfo = who('-file', 'settings.mat');
                for ind = 1:length(variableInfo)
                    if ismember(variableInfo{ind}, keySet)
                        settingsLog(variableInfo{ind}) = 1;
                    end
                end
                
                loadedSettings = load(settingsFilePath);
            end
            
            % for each settings
            for ind = 1:length(keySet)
                % if settings were found in file
                if settingsLog(keySet{ind})
                    % set them
                    value = loadedSettings.(keySet{ind});
                    app.(keySet{ind}) = value;
                else
                    % else set default
                    app.(keySet{ind}) = defaults{ind};
                end
            end 
        end
        
        % Saves parameters to settings file
        function SaveParameters(app)
            tempS.IsAutosaveOn = app.IsAutosaveOn;
            tempS.StartFolder = app.StartFolder;
            
            filePath = [mfilename('fullpath'), '.mlapp'];
            [folder, ~, ~] = fileparts(filePath);
            settingsFilePath = [folder, filesep(), 'settings.mat'];
            save(settingsFilePath, '-struct', 'tempS');
        end
        
        % Calculates the density map
        function CalculateDensity(app, type, answer)
            app.IsBusy = 1;
            
            app.UpdateUI(1);
        
            % calculate metric
            if strcmp(answer, 'Yes')
                sizeIm = size(app.Image);

                switch type
                    case 'euclideanNCones'
                        if ~isempty(app.EuclideanNConesHandler) && isvalid(app.EuclideanNConesHandler)
                            app.EuclideanNConesHandler.Vorocones = unique(app.Conelocs(:, 1:2), 'rows');
                            app.EuclideanNConesHandler.Recalculate(app.Image);
                        else
                            app.EuclideanNConesHandler = EuclideanNCones(unique(app.Conelocs(:, 1:2), 'rows'), sizeIm, app.EuclideanNconesEditField.Value, app.Image);
                        end

                    case 'NNDmean'
                        if ~isempty(app.NNDmeanHandler) && isvalid(app.NNDmeanHandler)
                            app.NNDmeanHandler.Vorocones = unique(app.Conelocs(:, 1:2), 'rows');
                            app.NNDmeanHandler.Recalculate();
                        else
                            app.NNDmeanHandler = NNDmean(unique(app.Conelocs(:, 1:2), 'rows'), sizeIm);
                        end

                    case 'YellotsRings'
                        if ~isempty(app.YellotsRingsHandler) && isvalid(app.YellotsRingsHandler)
                            app.YellotsRingsHandler.Recalculate();
                        else
                            app.YellotsRingsHandler = YellotsRings(app.Image, app.YellotsROIsizeEditField.Value);
                        end

                end
            end
            
            app.IsBusy = 0;
            app.UpdateUI();
        end
        
        % Sets color to preview axes
        function SetColor(~, axesHandler, color)
            axesHandler.XAxis.Visible = 'off';
            axesHandler.YAxis.Visible = 'off';
            axesHandler.Color = color;
        end

        % Checks the matlab version
        function result = CheckVersion(app)
            result = false;
            if app.CurrnetMatlabVersion(1) > 2022 || ...
                app.CurrnetMatlabVersion(1) == 2022 && app.CurrnetMatlabVersion(2) >= 2
                result = true;
            end
        end
        
        function AddAllPathesCM(~)
            % add folder with Cone Mapper itself
            addpath(fileparts(mfilename('fullpath')));
            % add folder with FCN_Code to matlab search for current session
            addpath(genpath(fullfile(fileparts(mfilename('fullpath')), 'FCN_Code')));
            % add folder with CNN_Code to matlab search for current session
            addpath(genpath(fullfile(fileparts(mfilename('fullpath')), 'CNN_Code')));
            % add folder with Utilities to matlab search for current session
            addpath(genpath(fullfile(fileparts(mfilename('fullpath')), 'Utilities')));
            % add folder with Windows to matlab search for current session
            addpath(genpath(fullfile(fileparts(mfilename('fullpath')), 'Windows')));
            % add folder with Icons to matlab search for current session
            addpath(genpath(fullfile(fileparts(mfilename('fullpath')), 'Icons')));
        end
        
        function SetZScoreSavedInterfaceParams(app)
            if ~app.AutoLimCheckBox.Value
                app.DensityMinimumLimitManual = app.DensityMinimumLimit;
                app.DensityMaximumLimitManual = app.DensityMaximumLimit;
            end

            if ~app.AutoLimitOnOffZScore
                app.DensityMinimumLimit = app.DensityMinimumLimitManualZScore;
                app.DensityMaximumLimit = app.DensityMaximumLimitManualZScore;
            end
            
            app.AutoLimitOnOffDensity = app.AutoLimCheckBox.Value;
            app.DensityColorMapPrevious = app.CMapDensityDropDown.Value;

            app.AutoLimCheckBox.Value = app.AutoLimitOnOffZScore;
            app.CMapDensityDropDown.Value = 'coolwarm';

            app.DensColorMap = GetColorMapBuyName(app, app.CMapDensityDropDown.Value);
        end
        
        function SetDensitySavedInterfaceParams(app)
            if ~app.AutoLimCheckBox.Value
                app.DensityMinimumLimitManualZScore = app.DensityMinimumLimit;
                app.DensityMaximumLimitManualZScore = app.DensityMaximumLimit;
            end

            if ~app.AutoLimitOnOffDensity
                app.DensityMinimumLimit = app.DensityMinimumLimitManual;
                app.DensityMaximumLimit = app.DensityMaximumLimitManual;
            end

            app.AutoLimitOnOffZScore = app.AutoLimCheckBox.Value;
            app.AutoLimCheckBox.Value = app.AutoLimitOnOffDensity;
            app.CMapDensityDropDown.Value = app.DensityColorMapPrevious;

            app.DensColorMap = GetColorMapBuyName(app, app.CMapDensityDropDown.Value);
        end
        
        function PreloadAverageMapData(app)
            pathUtilities = fullfile(fileparts(mfilename('fullpath')), 'Utilities');
            switch app.ReportUnits
                case {'micrometer', 'µm', 'millimeter', 'mm'}
                    avgFilename = 'AverageDensityEuclideanNConesMap_RMF.mat';
                otherwise
                    avgFilename = 'AverageDensityEuclideanNConesMap.mat';
            end
            
            if strcmp(app.EyetypeButtonGroup.SelectedObject.Tag, 'right')
                load(fullfile(pathUtilities, 'Metrics', avgFilename), 'densityRadialDataStructRight',...
                    'imageMagnificationFactorRight', 'retinalMagnificationFactorRight');
                app.AvgDensityRadialDataStruct = densityRadialDataStructRight;
                app.AvgDensityRadialDataStruct.ImageMagnificationFactor = imageMagnificationFactorRight;
                app.AvgDensityRadialDataStruct.RetinalMagnificationFactor = retinalMagnificationFactorRight;
            elseif strcmp(app.EyetypeButtonGroup.SelectedObject.Tag, 'left')
                load(fullfile(pathUtilities, 'Metrics', avgFilename), 'densityRadialDataStructLeft', ...
                    'imageMagnificationFactorLeft', 'retinalMagnificationFactorLeft');
                app.AvgDensityRadialDataStruct = densityRadialDataStructLeft;
                app.AvgDensityRadialDataStruct.ImageMagnificationFactor = imageMagnificationFactorLeft;
                app.AvgDensityRadialDataStruct.RetinalMagnificationFactor = retinalMagnificationFactorLeft;
            else
                app.AvgDensityRadialDataStruct = [];
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            filePath = [mfilename('fullpath'), '.mlapp'];
            [folder, ~, ~] = fileparts(filePath);
            LoadParameters(app, folder);
            
            AddAllPathesCM(app);
            
            set(findall(app.CMUIFigure, '-property', 'Enable'), 'Enable', 'off');
            set(findall(app.FileMenu, '-property', 'Enable'), 'Enable', 'on');
            set(findall(app.HelpMenu, '-property', 'Enable'), 'Enable', 'on');
            app.SaverecognitionMenu.Enable = 'off';
            
            SetupColorMap(app);
            
            SetColor(app, app.MainColorPreviewAxes, app.DensityContoursMainLinesColor);
            SetColor(app, app.SingleLineColorPreviewAxes, app.DensityContoursSingleLinesColor);
            SetColor(app, app.TextLabelColorPreviewAxes, app.DensityTextLabelColor);
            SetColor(app, app.ScaleBarColorPreviewAxes, app.ScaleBarColor);

            app.AutoSaveTimer = timer('StartDelay', 300, ...
                'TimerFcn', @app.AutoSaveFuncion, ...
                'Period', 300, ...
                'ExecutionMode', 'fixedSpacing', ...
                'Tag', 'AutosaveConeFinder', ...
                'Name','AutosaveConeFinder');

            v = version('-release');
            subRelease = 1;
            if strcmp(v(5), 'b')
                subRelease = 2;
            end
            app.CurrnetMatlabVersion = [str2double(v(1:4)), subRelease];
        end

        % Close request function: CMUIFigure
        function CMUIFigureCloseRequest(app, event)
            app.IsClosing = 1;
            
            try
                close(app.FigureHandler);
                if ~app.IsClosing
                    return;
                end
            catch
            end

            if ~isempty(app.AutoSaveTimer)
                app.AutoSaveTimer.stop();
            end
            
            delete(app.StatisticsWindowUIFigureHandler);
            delete(app.DensityContoursUISettingsHandler);
            delete(app.SettingUIFigureHandler);
            delete(app.AutoSaveTimer);
            delete(app);
        end

        % Menu selected function: NewrecognitionMenu
        function NewrecognitionMenuSelected(app, event)
            IsCanceled = NewFileOpening(app, '*.tiff;*.tif;');
            
            % Prepare file to start/continue cone marking
            if ~IsCanceled
                app.Image = imread([app.Pname app.Fname]);
                CreateFigure(app);
                                
                PrintImage(app, app.Image);
                
                % QUESTDLG for use of the FastPeakFinder
                fn = {'Fast Peak Finder', 'FCN', 'CNN v25', 'Import from CSV'};
                [indx,tf] = listdlg('Name', 'Cone Finder Algroithms', 'PromptString', {'Select a cone finder algorithm.'},...
                    'SelectionMode','single','ListString', fn);
                
                % if user clicked 'Ok'
                if tf
                    success = true;
                    switch indx
                        case 1 % 'Fast Peak Finder'
                            FastPeakFinder(app);
                            
                        case 2 % FCN python Patrick
                            success = PythonFCN(app, app.Image, app.Pname);

                        case 3 % 'CNN v25'
                            success = CnnFinder(app, 'matconvnet-1.0-beta25');
                            
                        case 4 % 'Import'
                            success = ImportFromCSV(app);
                    end % switch
                end

                % if recognition was successfull
                if tf && success
                    UpdateImage(app);
                    UpdateLegacy(app);
                    
                    SubscribeEvents(app);
                
                    if ~isempty(app.AutoSaveTimer) && strcmp(app.AutoSaveTimer.Running, 'off') && app.IsAutosaveOn
                        app.AutoSaveTimer.start();
                    end

                    pathToIcon = [fullfile(fileparts(mfilename('fullpath'))), filesep(), 'Icons', filesep(), 'CF_icon_about.png'];
                    [icondata,iconcmap] = imread(pathToIcon);
                    textArray = "Please, fill the IMF, RMF fields and specify the type of the eye (Left or Right) in Metrics tab.";
                    msgFig = msgbox(textArray, ...
                        "Warning...",...
                        "custom", icondata, iconcmap,...
                        struct('Interpreter', 'tex', 'WindowStyle', 'non-modal'));
                    movegui(msgFig, 'onscreen');
                    movegui(msgFig, 'center');
                % if user clicked 'Cancel' or recognition was not successfull
                else
                    % close everything
                    app.ConeCount = 0;

                    try
                        app.Image = [];
                        FigCloseRequestFcn(app, app.FigureHandler, [])
                        close(app.FigureHandler);
                    catch
                    end
                end
            end

            UpdateUI(app);
        end

        % Menu selected function: OpenrecognitionMenu
        function OpenrecognitionMenuSelected(app, event)
            IsCanceled = NewFileOpening(app, '*.mat;');
            
            if ~IsCanceled
                % load data
                LoadData(app); 
                
                UpdateImage(app);
                UpdateLegacy(app);
                
                SubscribeEvents(app);
            end
            
            if ~isempty(app.AutoSaveTimer) && strcmp(app.AutoSaveTimer.Running, 'off') && app.IsAutosaveOn
                app.AutoSaveTimer.start();
            end
            
            UpdateUI(app);
        end

        % Menu selected function: SaverecognitionMenu
        function SaveCurrentLocationsSPACEButtonPushed(app, event)
            SaveCurrentLocations(app);
        end

        % Menu selected function: SettingsMenu
        function SettingsMenuSelected(app, event)
            if isempty(app.SettingUIFigureHandler ) || ~isvalid(app.SettingUIFigureHandler )
                app.SettingUIFigureHandler = Settings(app);
            end
        end

        % Menu selected function: ExitMenu
        function ExitButtonPushed(app, event)
            close(app.CMUIFigure);
        end

        % Menu selected function: ImportConeLocationsMenu
        function ImportConeLocationsMenuSelected(app, event)
            app.IsBusy = 1;
            
            [~, nm, ~] = fileparts(app.Fname);
            start_path = [app.Pname, filesep, nm, '.csv'];
            % file selection GUI
            [filename, pname] = uigetfile('*.csv;', 'Select file(s) to mark', start_path, 'MultiSelect', 'off');
            
            if all(filename == 0) && all(pname == 0)
                app.IsBusy = 0;
                return;
            end

            % get conelocs from file
            conelocs = readmatrix([pname, filesep, filename]);
            
            % print Image
            PrintImage(app, app.Image);
                     
            % turn off all other things
            if ~isempty(app.VoronoiPatchHandler) && isvalid(app.VoronoiPatchHandler)
                set(app.VoronoiPatchHandler, 'Visible', 'off');
            end
            if ~isempty(app.ConeUserPlotHandler) && isvalid(app.ConeUserPlotHandler)
                set(app.ConeUserPlotHandler, 'Visible', 'off');
            end
            if ~isempty(app.ConeAutoPlotHandler) && isvalid(app.ConeAutoPlotHandler)
                set(app.ConeAutoPlotHandler, 'Visible', 'off');
            end
            
            importedConesHandler = plot(app.AxisHandler, conelocs(:, 1), conelocs(:, 2), '.y');
            
            width = max(conelocs(:, 1));
            height = max(conelocs(:, 2));
            boxOverCones = drawrectangle(app.AxisHandler, 'Position',[1, 1, width, height]);
            addlistener(boxOverCones, 'MovingROI', @RectEvent);
            
            function RectEvent(~, event)
                diffX = event.CurrentPosition(1) - event.PreviousPosition(1);
                diffY = event.CurrentPosition(2) - event.PreviousPosition(2);
                conelocs(:, 1) = conelocs(:, 1) + diffX;
                conelocs(:, 2) = conelocs(:, 2) + diffY;
                
                importedConesHandler.XData = conelocs(:, 1);
                importedConesHandler.YData = conelocs(:, 2);
            end
            
            wait(boxOverCones);
            
            delete(importedConesHandler);
            delete(boxOverCones);
            
            % save conelocs
            conelocs(:,3) = 2;
            app.Conelocs = [app.Conelocs; conelocs];
            app.Conelocs = unique(app.Conelocs, 'rows', 'stable');
            
            app.ConeCount = size(app.Conelocs(app.Conelocs(:,3)~=0, 1),1);
            app.TotalConeCount = size(app.Conelocs,1);
            
            app.IsBusy = 0;
            % update image
            UpdateImage(app);
        end

        % Menu selected function: ExportConeLocationsMenu
        function ExportConeLocationsMenuSelected(app, event)
            app.IsBusy = 1;
            selectionRect = [];
            [height, width, ~] = size(app.Image);
            
            answer = questdlg('Do you want to select a box to export?', ...
	            'Selection Box', ...
	            'Select a Box', 'Whole image', 'Cancel', 'Cancel');
            
            % Handle response
            switch answer
                case 'Select a Box'
                    % Select box to export
                    selectionBox = drawrectangle(app.AxisHandler);
                    wait(selectionBox);
                    selectionRect = selectionBox.Position;
                    delete(selectionBox);
                    
                case 'Whole image'
                    selectionRect = [1 1 width height];
                    
                case 'Cancel'
                    disp('Export canceled ...')
                    app.InfoLabel.Text = 'Export canceled...';
                    
            end
            
            % if selection is not empty
            if ~isempty(selectionRect)
                
                % ask about tile size
                prompt = {sprintf('Enter a tile size (in pixels).\n0 - whole selection.\n150 - default for Cunefare CNN.')};
                dlgtitle = 'Tile size';
                definput = {'150'};
                dims = [1 40];
                tileSize = inputdlg(prompt, dlgtitle, dims, definput);
                
                % if user cancel tile size entering
                if isempty(tileSize)
                    app.InfoLabel.Text = 'Export canceled...';
                    app.IsBusy = 0;
                    return;
                end

                tileSize = ceil(str2double(tileSize{1}));
                
                % if user enter nonsense as tile size
                if isnan(tileSize)
                    tileSize = 0;
                    disp('Input size is empty');
                end
                
                folder = uigetdir(app.Pname, 'Select folder to export...');
                
                % if user cancel folder choosing
                if folder == 0
                    app.InfoLabel.Text = 'Export canceled...';
                    app.IsBusy = 0;
                    return;
                end

                % question about tiles or whole selection
                if tileSize ~= 0
                    CutAndSaveExport(app, selectionRect, tileSize, folder);
                else
                    % get coords for export
                    startX = floor(selectionRect(1));
                    if startX < 1
                        startX = 1;
                    end
                    
                    startY = floor(selectionRect(2));
                    if startY < 1
                        startY = 1;
                    end
                    
                    endX = startX + floor(selectionRect(3));
                    if endX > width
                        endX = width;
                    end
                    
                    endY = startY + floor(selectionRect(4));
                    if endY > height
                        endY = height;
                    end
                    
                    [~, nm, ~] = fileparts([app.Pname app.Fname]);
                
                    positions = [...
                        startX, startY; ...
                        endX, endY ...
                        ];
                    
                    GetConeLocationAndWriteWithImage(app, nm, folder, positions)
                end
                
                app.InfoLabel.Text = 'Exported successfully...';
            end
            
            app.IsBusy = 0;
            
        end

        % Menu selected function: ShowStatsWindowMenu
        function ShowStatsWindowMenuSelected(app, event)
            PreloadAverageMapData(app);
            
            if isempty(app.SettingUIFigureHandler ) || ~isvalid(app.SettingUIFigureHandler)
                app.StatisticsWindowUIFigureHandler = StatisticsWindow(app);
            end
        end

        % Menu selected function: EuclideanNConesMenu
        function EuclideanNConesMenuSelected(app, event)
            app.UpdateUI(1);

            answer = 'Yes';
            % if metric was calculated
            if ~isempty(app.EuclideanNConesHandler) && isvalid(app.EuclideanNConesHandler)
                % ask: do we need to recaulculate?
                answer = questdlg("Do you want to recalculate Euclidean N Cones Density?", ...
    	            "Euclidean N Cones", ...
    	            'Yes', 'No', 'No');
            end
            
            CalculateDensity(app, 'euclideanNCones', answer);
                
            app.UpdateUI();
            app.UpdateImage();
            if ~isempty(app.StatisticsWindowUIFigureHandler) && isvalid(app.StatisticsWindowUIFigureHandler)
                app.StatisticsWindowUIFigureHandler.RefreshAll();
            end
        end

        % Menu selected function: NNDmeanMenu
        function NNDmeanMenuSelected(app, event)
            app.UpdateUI(1);

            answer = 'Yes';
            % if metric was calculated
            if ~isempty(app.NNDmeanHandler) && isvalid(app.NNDmeanHandler)
                % ask: do we need to recaulculate?
                answer = questdlg("Do you want to recalculate NND mean Density?", ...
    	            "NND mean Density", ...
    	            'Yes', 'No', 'No');
            end

            CalculateDensity(app, 'NNDmean', answer);

            app.UpdateUI();
            app.UpdateImage();
            if ~isempty(app.StatisticsWindowUIFigureHandler) && isvalid(app.StatisticsWindowUIFigureHandler)
                app.StatisticsWindowUIFigureHandler.RefreshAll();
            end
        end

        % Menu selected function: YellotsRingsMenu
        function YellotsRingsMenuSelected(app, event)
            app.UpdateUI(1);

            answer = 'Yes';
            % if metric was calculated
            if ~isempty(app.YellotsRingsHandler) && isvalid(app.YellotsRingsHandler)
                % ask: do we need to recaulculate?
                answer = questdlg("Do you want to recalculate Yellots rings Density?", ...
    	            "Yellots rings Density", ...
    	            'Yes', 'No', 'No');
            end
            
            CalculateDensity(app, 'YellotsRings', answer);
                
            app.UpdateUI();
            app.UpdateImage();
            if ~isempty(app.StatisticsWindowUIFigureHandler) && isvalid(app.StatisticsWindowUIFigureHandler)
                app.StatisticsWindowUIFigureHandler.RefreshAll();
            end
        end

        % Menu selected function: ShowdocumentationMenu
        function ShowdocumentationMenuSelected(app, event)
            filePath = [mfilename('fullpath'), '.mlapp'];
            [folder, ~, ~] = fileparts(filePath); 
            addpath([folder, '\Documentation']);
            addpath(genpath([folder, '\Documentation\ConeMapperDocumentation']));
            web([folder, '\Documentation\ConeMapperDocumentation\Welcome to Cone Mapper.html'])
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            pathToIcon = [fullfile(fileparts(mfilename('fullpath'))), filesep(), 'Icons', filesep(), 'CF_icon_about.png'];
            [icondata,iconcmap] = imread(pathToIcon);
            textArray = ["Cone Mapper";...
                "Version 1.2";...
                'AOVisionLab Page: https://ao.ukbonn.de/index.html';...
                'GitHub Page: https://github.com/ukb-aoslo'];
            msgbox(textArray, ...
                "About...",...
                "custom", icondata, iconcmap,...
                struct('Interpreter', 'tex', 'WindowStyle', 'non-modal'));
        end

        % Value changed function: ShowImageICheckBox
        function ShowImageICheckBoxValueChanged(app, event)
            UpdateImage(app);
        end

        % Value changed function: LaplacianofGaussianfilterCheckBox
        function LapOfGausCheckBoxValueChanged(app, event)
            UpdateImage(app);
        end

        % Value changed function: LogfilterCheckBox
        function LogImageLCheckBoxValueChanged(app, event)
            UpdateImage(app);
        end

        % Value changing function: BrightnessSlider
        function BrightnessSliderValueChanging(app, event)
            app.Brightness = event.Value;
            UpdateImage(app);
        end

        % Button pushed function: ResetButton
        function ResetButtonValueChanged(app, event)
            app.BrightnessSlider.Value = 1.0;
            app.Brightness = 1.0;
            UpdateImage(app);
        end

        % Value changed function: ShowMarkedLocationsTCheckBox
        function ShowMarkedLocationsTSwitchValueChanged(app, event)
            UpdateImage(app);
        end

        % Selection changed function: ConeMarksTypeButtonGroup
        function ConeMarksTypeButtonGroupSelectionChanged(app, event)
            if app.CircleButton.Value
                app.Marker = 'o';
                
                % yellow
                app.Color = [1 1 0];
                app.MarkerSize = 5;
            elseif app.CrossButton.Value
                app.Marker = 'x';
                % yellow
                app.Color = [1 1 0];
                app.MarkerSize = 5;
            else
                app.Marker = '.';
                % yellow
                app.Color = [1 1 0];
                app.MarkerSize = 5;
            end
            
            UpdateImage(app);
        end

        % Value changed function: AutovsUserdetectCheckBox
        function AutovsUserdetectCheckBoxValueChanged(app, event)
            UpdateImage(app);
        end

        % Selection changed function: ShowMarkedOptionsButtonGroup
        function ShowMarkedOptionsButtonGroupSelectionChanged(app, event)
            if app.Userdetect2Button.Value
                app.ShowConesByDetectType = 1;
            elseif app.Autodetect1Button.Value
                app.ShowConesByDetectType = 2;
            else
                app.ShowConesByDetectType = 0;
            end
            
            UpdateImage(app);
        end

        % Value changed function: VoronoiDiagramCheckBox
        function VoronoiDiagramCheckBoxValueChanged(app, event)
            UpdateUI(app);
            UpdateImage(app);
        end

        % Selection changed function: VoronoidiagramButtonGroup
        function VoronoidiagramButtonGroupSelectionChanged(app, event)
            app.VoronoiType = app.VoronoidiagramButtonGroup.SelectedObject.Tag;
            if app.VoronoiDiagramCheckBox.Value
                VoronoiDiagramCheckBoxValueChanged(app, event)
            end
        end

        % Value changed function: CMapVoronoiDensityDropDown
        function CMapVoronoiDensityDropDownValueChanged(app, event)
            app.VColorMap = GetColorMapBuyName(app, app.CMapVoronoiDensityDropDown.Value); 
            
            app.VoronoiNNColors = [];
            app.VoronoiAreaColors = [];
            app.VoronoiNNColorScale = [];
            app.VoronoiConeAreaColorScale = [];
            
            UpdateImage(app);
        end

        % Value changed function: ShowDMCheckBox
        function ShowDMCheckBoxValueChanged(app, event)
            UpdateImage(app);
        end

        % Value changing function: AlphaSlider
        function AlphaSliderValueChanging(app, event)
            app.DensityAlphaValue = event.Value;
            app.AlphaEditField.Value = app.DensityAlphaValue;
            UpdateImage(app);
        end

        % Value changed function: AlphaEditField
        function AlphaEditFieldValueChanged(app, event)
            app.DensityAlphaValue = app.AlphaEditField.Value;
            app.AlphaSlider.Value = app.DensityAlphaValue;
            UpdateImage(app);
        end

        % Selection changed function: DensitymapButtonGroup
        function DensitymapButtonGroupSelectionChanged(app, event)
            UpdateDensityLimits(app);

            UpdateImage(app);

            if ~isempty(app.StatisticsWindowUIFigureHandler) && isvalid(app.StatisticsWindowUIFigureHandler)
                app.StatisticsWindowUIFigureHandler.RefreshAll();
            end
        end

        % Value changed function: PCDCheckBox
        function PCDCheckBoxValueChanged(app, event)
            UpdateImage(app);
        end

        % Value changed function: CDCCheckBox
        function CDCCheckBoxValueChanged(app, event)
            UpdateImage(app);
        end

        % Value changed function: ApproximationEdgeCheckBox
        function ApproximationEdgeCheckBoxValueChanged(app, event)
            UpdateImage(app);
        end

        % Value changed function: CMapDensityDropDown
        function CMapDensityDropDownValueChanged(app, event)
            app.DensColorMap = GetColorMapBuyName(app, app.CMapDensityDropDown.Value); 
            
            UpdateImage(app);
        end

        % Value changed function: ContoursCheckBox
        function ContoursCheckBoxValueChanged(app, event)
            UpdateImage(app);
        end

        % Value changed function: ShowMainPercentageLinesCheckBox
        function ShowMainPercentageLinesCheckBoxValueChanged(app, event)
            UpdateImage(app);
        end

        % Value changed function: MainLinesPercentageDifferenceEditField
        function MainLinesPercentageDifferenceEditFieldValueChanged(app, event)
            app.DensityContoursMainLinesPercentageDistance = app.MainLinesPercentageDifferenceEditField.Value / 100;
            UpdateImage(app);
        end

        % Value changed function: ShowSinglePercentageLineCheckBox
        function ShowSinglePercentageLineCheckBoxValueChanged(app, event)
            UpdateImage(app);
        end

        % Value changed function: SingleLinePercentageEditField
        function SingleLinePercentageEditFieldValueChanged(app, event)
            app.DensityContoursSingleLinePercentage = app.SingleLinePercentageEditField.Value / 100; 
            UpdateImage(app);
        end

        % Value changed function: ShowPercentTextCheckBox
        function ShowPercentTextCheckBoxValueChanged(app, event)
            UpdateImage(app);
        end

        % Button down function: MainColorPreviewAxes
        function PickMainColorButtonPushed(app, event)
            color = uisetcolor(app.DensityContoursMainLinesColor, 'Select color');
            SetColor(app, app.MainColorPreviewAxes, color);
            app.DensityContoursMainLinesColor = color;
            UpdateImage(app);
        end

        % Button down function: SingleLineColorPreviewAxes
        function PickSingleLineColorButtonPushed(app, event)
            color = uisetcolor(app.DensityContoursSingleLinesColor, 'Select color');
            SetColor(app, app.SingleLineColorPreviewAxes, color);
            app.DensityContoursSingleLinesColor = color;
            UpdateImage(app);
        end

        % Button down function: TextLabelColorPreviewAxes
        function TextLabelColorPreviewAxesButtonDown(app, event)
            color = uisetcolor(app.DensityTextLabelColor, 'Select color');
            SetColor(app, app.TextLabelColorPreviewAxes, color);
            app.DensityTextLabelColor = color;
            UpdateImage(app);
        end

        % Value changed function: GridCheckBox
        function GridCheckBoxValueChanged(app, event)
            UpdateImage(app);
        end

        % Value changed function: GridNumbersCheckBox
        function ShowGirdNumbersECheckBoxValueChanged(app, event)
            UpdateImage(app);
        end

        % Value changed function: ScaleBarCheckBox
        function ScaleBarCheckBoxValueChanged(app, event)
            UpdateImage(app);
        end

        % Value changed function: ScaleBarUnitsDropDown
        function ScaleBarUnitsDropDownValueChanged(app, event)
            UpdateScalebarUnits(app, [], []);
            UpdateImage(app);
        end

        % Button down function: ScaleBarColorPreviewAxes
        function ScaleBarColorPreviewAxesButtonDown(app, event)
            color = uisetcolor(app.ScaleBarColor, 'Select color');
            SetColor(app, app.ScaleBarColorPreviewAxes, color);
            app.ScaleBarColor = color;
            app.ScaleBar.Color = color;

            UpdateImage(app);
        end

        % Button pushed function: DeletedSelectedConesButton
        function DeletedSelectedConesButtonPushed(app, event)
            app.IsBusy = 1;
            
            delBox = drawpolygon(app.AxisHandler); % imrect(gca);
            wait(delBox);
            
            poly = delBox.Position;
            if ~isempty(poly)
                toDeleteLogical =  inpolygon(app.Conelocs(:, 1), app.Conelocs(:, 2),  poly(:, 1), poly(:, 2));
                
                app.Conelocs(toDeleteLogical, :)= [];
                
                delete(delBox);
                
                UpdateImage(app);
                UpdateLegacy(app);
            end
            
            app.IsBusy = 0;
        end

        % Button pushed function: ApplySelectedButton
        function ApplySelectedButtonPushed(app, event)
            app.IsBusy = 1;
            UpdateUI(app, 1);

            if app.FindConesCheckBox.Value

                box_addCones = DrawBox(app);
                add_conelocs = FindCones(app, app.Image, box_addCones, ...
                    app.AlgorithmDropDown.Value, app.TrainingDropDown.Value, ...
                    [app.Pname app.Fname], 'matconvnet-1.0-beta25');

                app.Conelocs = [app.Conelocs; add_conelocs];
                app.Conelocs = unique(app.Conelocs, 'rows', 'stable');
            end

            if app.DelOutsideImageCheckBox.Value
                app.RemoveBlackCones();
            end

            if app.DelDoublesCheckBox.Value
                app.RemoveDoubleCones();
            end

            UpdateUI(app);
            UpdateLegacy(app);
            UpdateImage(app);
            app.IsBusy = 0;
        end

        % Value changed function: EuclideanNconesEditField
        function EuclideanNconesEditFieldValueChanged(app, event)
            if ~isempty(app.EuclideanNConesHandler) && isvalid(app.EuclideanNConesHandler)
                app.EuclideanNConesHandler.NumOfNearestCones = app.EuclideanNconesEditField.Value;
            end
        end

        % Value changed function: YellotsROIsizeEditField
        function YellotsROIsizeEditFieldValueChanged(app, event)
            if ~isempty(app.YellotsRingsHandler) && isvalid(app.YellotsRingsHandler)
                app.YellotsRingsHandler.ROI_size = app.YellotsROIsizeEditField.Value;
            end
        end

        % Value changed function: IMFEditField
        function IMFEditFieldValueChanged(app, event)
            app.ImageMagnificationFactor = app.IMFEditField.Value;
            if ~isempty(app.StatisticsWindowUIFigureHandler) && isvalid(app.StatisticsWindowUIFigureHandler)
                app.StatisticsWindowUIFigureHandler.RefreshAll();
            end
            UpdateImage(app);
        end

        % Value changed function: ReportunitsDropDown
        function ReportunitsDropDownValueChanged(app, event)
            UpdateReportUnits(app);

            UpdateImage(app);
        end

        % Value changed function: ColorBarCheckBox
        function ColorBarCheckBoxValueChanged(app, event)
            UpdateImage(app);
        end

        % Value changed function: RMFEditField
        function RMFEditFieldValueChanged(app, event)
            app.RetinalMagnificationFactor = app.RMFEditField.Value;
        end

        % Button pushed function: LockPPDButton
        function LockPPDButtonPushed(app, event)
            app.IMFEditField.Editable = ~app.IMFEditField.Editable;
            app.SaveIMFButton.Enable = app.IMFEditField.Editable;

            if app.IMFEditField.Editable
                pathToIcon = [fullfile(fileparts(mfilename('fullpath'))), filesep(), 'Icons', filesep(), 'unlock.png'];
                labelText = 'Unlocked';

                % Save the lock value to temp var
                app.IMFpreviousValue = app.ImageMagnificationFactor;
            else
                pathToIcon = [fullfile(fileparts(mfilename('fullpath'))), filesep(), 'Icons', filesep(), 'lock.png'];
                labelText = 'Locked';

                % Return the lock value to the field
                app.ImageMagnificationFactor = app.IMFpreviousValue;
                app.IMFEditField.Value = app.ImageMagnificationFactor;
                app.IMFpreviousValue = [];
            end

            app.LockPPDButton.Icon = pathToIcon;
            app.LockPPDButton.Text = labelText;
        end

        % Button pushed function: SaveIMFButton
        function SaveIMFButtonPushed(app, event)
            structToSave.pixelPerDegree = app.ImageMagnificationFactor;
            app.IMFpreviousValue = app.ImageMagnificationFactor;
            
            SaveOnlyGivenField(app, structToSave);
            app.InfoLabel.Text = 'PPD saved successfully';
            LockPPDButtonPushed(app, event);
        end

        % Button pushed function: LockRMFButton
        function LockRMFButtonPushed(app, event)
            app.RMFEditField.Editable = ~app.RMFEditField.Editable;
            app.SaveRMFButton.Enable = app.RMFEditField.Editable;

            if app.RMFEditField.Editable
                pathToIcon = [fullfile(fileparts(mfilename('fullpath'))), filesep(), 'Icons', filesep(), 'unlock.png'];
                labelText = 'Unlocked';

                % Save the lock value to temp var
                app.RMFpreviousValue = app.RetinalMagnificationFactor;
            else
                pathToIcon = [fullfile(fileparts(mfilename('fullpath'))), filesep(), 'Icons', filesep(), 'lock.png'];
                labelText = 'Locked';

                % Return the lock value to the field
                app.RetinalMagnificationFactor = app.RMFpreviousValue;
                app.RMFEditField.Value = app.RetinalMagnificationFactor;
                app.RMFpreviousValue = [];
            end

            app.LockRMFButton.Icon = pathToIcon;
            app.LockRMFButton.Text = labelText;
        end

        % Button pushed function: SaveRMFButton
        function SaveRMFButtonPushed(app, event)
            structToSave.RMF = app.RetinalMagnificationFactor;
            app.RMFpreviousValue = app.RetinalMagnificationFactor;

            SaveOnlyGivenField(app, structToSave);
            app.InfoLabel.Text = 'RMF saved successfully';
            LockRMFButtonPushed(app, event);
        end

        % Value changed function: ReceptorsTypeDropDown
        function ReceptorsTypeDropDownValueChanged(app, event)
            if strcmp(app.ReceptorsTypeDropDown.Value, 'Rods')
                app.ReceptorLabel.Text = 'Rods';
            else
                app.ReceptorLabel.Text = 'Cones';
            end
        end

        % Value changed function: AutoLimCheckBox
        function AutoLimCheckBoxValueChanged(app, event)
            % if we turn on auto lim
            if app.AutoLimCheckBox.Value
                % save manual lim
                if app.zScoremapCheckBox.Value
                    app.DensityMaximumLimitManualZScore = app.DensityMaximumLimit;
                    app.DensityMinimumLimitManualZScore = app.DensityMinimumLimit;
                else
                    app.DensityMaximumLimitManual = app.DensityMaximumLimit;
                    app.DensityMinimumLimitManual = app.DensityMinimumLimit;
                end

                % calc auto lim
                UpdateDensityLimits(app);
            else
                % set manual lim
                if app.zScoremapCheckBox.Value
                    app.DensityMaximumLimit = app.DensityMaximumLimitManualZScore;
                    app.DensityMinimumLimit = app.DensityMinimumLimitManualZScore;
                else
                    app.DensityMaximumLimit = app.DensityMaximumLimitManual;
                    app.DensityMinimumLimit = app.DensityMinimumLimitManual;
                end
            end

            UpdateImage(app);
            UpdateUI(app);

            if ~isempty(app.StatisticsWindowUIFigureHandler) && isvalid(app.StatisticsWindowUIFigureHandler)
                app.StatisticsWindowUIFigureHandler.RefreshAll();
            end
        end

        % Value changed function: MinLimEditField
        function MinLimEditFieldValueChanged(app, event)
            if app.zScoremapCheckBox.Value
                scaleCoefficient = 1;
            else
                scaleCoefficient = GetScaleCoefficient(app.ReportUnits, app.ImageMagnificationFactor, app.RetinalMagnificationFactor);
            end

            app.DensityMinimumLimit = app.MinLimEditField.Value * (scaleCoefficient^2);

            if ~isempty(app.StatisticsWindowUIFigureHandler) && isvalid(app.StatisticsWindowUIFigureHandler)
                app.StatisticsWindowUIFigureHandler.RefreshAll();
            end
            UpdateImage(app);
        end

        % Value changed function: MaxLimEditField
        function MaxLimEditFieldValueChanged(app, event)
            if app.MinLimEditField.Value > event.Value
                msgbox("Maximum limit cannot be less than minimum limit.", "Error", "error");
                app.MaxLimEditField.Value = event.PreviousValue;
                return;
            end

            if app.zScoremapCheckBox.Value
                scaleCoefficient = 1;
            else
                scaleCoefficient = GetScaleCoefficient(app.ReportUnits, app.ImageMagnificationFactor, app.RetinalMagnificationFactor);
            end

            app.DensityMaximumLimit = app.MaxLimEditField.Value * (scaleCoefficient^2);

            if ~isempty(app.StatisticsWindowUIFigureHandler) && isvalid(app.StatisticsWindowUIFigureHandler)
                app.StatisticsWindowUIFigureHandler.RefreshAll();
            end
            UpdateImage(app);
        end

        % Value changed function: ScaleRingsCheckBox
        function ScaleRingsCheckBoxValueChanged(app, event)
            UpdateImage(app);
        end

        % Value changed function: DensContGaussFilterCheckBox
        function DensContGaussFilterCheckBoxValueChanged(app, event)
            UpdateImage(app);
        end

        % Value changed function: DensContGaussFilterSigmaEditField
        function DensContGaussFilterSigmaEditFieldValueChanged(app, event)
            UpdateImage(app);
        end

        % Value changed function: zScoremapCheckBox
        function zScoremapCheckBoxValueChanged(app, event)
            if app.zScoremapCheckBox.Value
                switch app.DensitymapButtonGroup.SelectedObject.Tag
                    case 'EuclideanNCones'
                        densityHandler = app.EuclideanNConesHandler;
                    case 'NNDmean'
                        densityHandler = app.NNDmeanHandler;
                    case 'YellotsRungs'
                        densityHandler = app.YellotsRingsHandler;
                    otherwise
                        densityHandler = [];
                end
        
                zScoreIsCreated = GetZScore(app, densityHandler);
                if ~zScoreIsCreated
                    app.zScoremapCheckBox.Value = 0;
                    return;
                end
                
                SetZScoreSavedInterfaceParams(app);
            else
                SetDensitySavedInterfaceParams(app);
            end

            UpdateUI(app);
            UpdateImage(app);
            if ~isempty(app.StatisticsWindowUIFigureHandler) && isvalid(app.StatisticsWindowUIFigureHandler)
                app.StatisticsWindowUIFigureHandler.RefreshAll();
            end
        end

        % Selection changed function: EyetypeButtonGroup
        function EyetypeButtonGroupSelectionChanged(app, event)
            if app.zScoremapCheckBox.Value
                switch app.DensitymapButtonGroup.SelectedObject.Tag
                    case 'EuclideanNCones'
                        densityHandler = app.EuclideanNConesHandler;
                    case 'NNDmean'
                        densityHandler = app.NNDmeanHandler;
                    case 'YellotsRungs'
                        densityHandler = app.YellotsRingsHandler;
                    otherwise
                        densityHandler = [];
                end
        
                GetZScore(app, densityHandler);

                UpdateUI(app);
                UpdateImage(app);
            end

            if ~isempty(app.StatisticsWindowUIFigureHandler) && isvalid(app.StatisticsWindowUIFigureHandler)
                app.StatisticsWindowUIFigureHandler.RefreshAll();
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.CMUIFigure = uifigure('Visible', 'off');
            app.CMUIFigure.Name = 'CM';
            app.CMUIFigure.Icon = fullfile(pathToMLAPP, 'Icons', 'CF_icon.png');
            app.CMUIFigure.CloseRequestFcn = createCallbackFcn(app, @CMUIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.CMUIFigure);
            app.FileMenu.Text = 'File';
            app.NewrecognitionMenu = uimenu(app.FileMenu);
            app.NewrecognitionMenu.MenuSelectedFcn = createCallbackFcn(app, @NewrecognitionMenuSelected, true);
            app.NewrecognitionMenu.Text = 'New recognition';
            app.OpenrecognitionMenu = uimenu(app.FileMenu);
            app.OpenrecognitionMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenrecognitionMenuSelected, true);
            app.OpenrecognitionMenu.Text = 'Open recognition';
            app.SaverecognitionMenu = uimenu(app.FileMenu);
            app.SaverecognitionMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveCurrentLocationsSPACEButtonPushed, true);
            app.SaverecognitionMenu.Text = 'Save recognition';
            app.SettingsMenu = uimenu(app.FileMenu);
            app.SettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @SettingsMenuSelected, true);
            app.SettingsMenu.Separator = 'on';
            app.SettingsMenu.Text = 'Settings';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitMenu.Separator = 'on';
            app.ExitMenu.Text = 'Exit';
            app.ImportExportMenu = uimenu(app.CMUIFigure);
            app.ImportExportMenu.Text = 'Import/Export';
            app.ImportConeLocationsMenu = uimenu(app.ImportExportMenu);
            app.ImportConeLocationsMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportConeLocationsMenuSelected, true);
            app.ImportConeLocationsMenu.Text = 'Import Cone Locations';
            app.ExportConeLocationsMenu = uimenu(app.ImportExportMenu);
            app.ExportConeLocationsMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportConeLocationsMenuSelected, true);
            app.ExportConeLocationsMenu.Text = 'Export Cone Locations';
            app.MetricsMenu = uimenu(app.CMUIFigure);
            app.MetricsMenu.Text = 'Metrics';
            app.ShowStatsWindowMenu = uimenu(app.MetricsMenu);
            app.ShowStatsWindowMenu.MenuSelectedFcn = createCallbackFcn(app, @ShowStatsWindowMenuSelected, true);
            app.ShowStatsWindowMenu.Text = 'Show Stats Window';
            app.EuclideanNConesMenu = uimenu(app.MetricsMenu);
            app.EuclideanNConesMenu.MenuSelectedFcn = createCallbackFcn(app, @EuclideanNConesMenuSelected, true);
            app.EuclideanNConesMenu.Separator = 'on';
            app.EuclideanNConesMenu.Text = 'EuclideanNCones';
            app.NNDmeanMenu = uimenu(app.MetricsMenu);
            app.NNDmeanMenu.MenuSelectedFcn = createCallbackFcn(app, @NNDmeanMenuSelected, true);
            app.NNDmeanMenu.Text = 'NNDmean';
            app.YellotsRingsMenu = uimenu(app.MetricsMenu);
            app.YellotsRingsMenu.MenuSelectedFcn = createCallbackFcn(app, @YellotsRingsMenuSelected, true);
            app.YellotsRingsMenu.Text = 'YellotsRings';
            app.HelpMenu = uimenu(app.CMUIFigure);
            app.HelpMenu.Text = 'Help';
            app.ShowdocumentationMenu = uimenu(app.HelpMenu);
            app.ShowdocumentationMenu.MenuSelectedFcn = createCallbackFcn(app, @ShowdocumentationMenuSelected, true);
            app.ShowdocumentationMenu.Text = 'Show documentation';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Separator = 'on';
            app.AboutMenu.Text = 'About....';
            app.InfoLabel = uilabel(app.CMUIFigure);
            app.InfoLabel.Text = {'Info'; ''};
            app.TabGroup = uitabgroup(app.CMUIFigure);
            app.ViewTab = uitab(app.TabGroup);
            app.ViewTab.Title = 'View';
            app.ImagePropertiesPanel = uipanel(app.ViewTab);
            app.ImagePropertiesPanel.Title = 'AOSLO Image';
            app.LogfilterCheckBox = uicheckbox(app.ImagePropertiesPanel);
            app.LogfilterCheckBox.ValueChangedFcn = createCallbackFcn(app, @LogImageLCheckBoxValueChanged, true);
            app.LogfilterCheckBox.Text = 'Log filter';
            app.LaplacianofGaussianfilterCheckBox = uicheckbox(app.ImagePropertiesPanel);
            app.LaplacianofGaussianfilterCheckBox.ValueChangedFcn = createCallbackFcn(app, @LapOfGausCheckBoxValueChanged, true);
            app.LaplacianofGaussianfilterCheckBox.Text = 'Laplacian of Gaussian filter';
            app.ShowImageICheckBox = uicheckbox(app.ImagePropertiesPanel);
            app.ShowImageICheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowImageICheckBoxValueChanged, true);
            app.ShowImageICheckBox.Text = '';
            app.ShowImageICheckBox.Value = true;
            app.BrightnessSliderLabel = uilabel(app.ImagePropertiesPanel);
            app.BrightnessSliderLabel.Text = 'Brightness';
            app.BrightnessSlider = uislider(app.ImagePropertiesPanel);
            app.BrightnessSlider.Limits = [0 2];
            app.BrightnessSlider.MajorTicks = [];
            app.BrightnessSlider.ValueChangingFcn = createCallbackFcn(app, @BrightnessSliderValueChanging, true);
            app.BrightnessSlider.MinorTicks = [];
            app.BrightnessSlider.Value = 1;
            app.ResetButton = uibutton(app.ImagePropertiesPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonValueChanged, true);
            app.ResetButton.Text = 'Reset';
            app.MarksPropertiesPanel = uipanel(app.ViewTab);
            app.MarksPropertiesPanel.Title = 'Cone center locations';
            app.ConeMarksTypeButtonGroup = uibuttongroup(app.MarksPropertiesPanel);
            app.ConeMarksTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ConeMarksTypeButtonGroupSelectionChanged, true);
            app.DotButton = uiradiobutton(app.ConeMarksTypeButtonGroup);
            app.DotButton.Text = 'Dot';
            app.DotButton.Value = true;
            app.CircleButton = uiradiobutton(app.ConeMarksTypeButtonGroup);
            app.CircleButton.Text = 'Circle';
            app.CrossButton = uiradiobutton(app.ConeMarksTypeButtonGroup);
            app.CrossButton.Text = 'Cross';
            app.ShowMarkedOptionsButtonGroup = uibuttongroup(app.MarksPropertiesPanel);
            app.ShowMarkedOptionsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ShowMarkedOptionsButtonGroupSelectionChanged, true);
            app.AutoUserButton = uiradiobutton(app.ShowMarkedOptionsButtonGroup);
            app.AutoUserButton.Text = 'Auto+User';
            app.AutoUserButton.Value = true;
            app.Userdetect2Button = uiradiobutton(app.ShowMarkedOptionsButtonGroup);
            app.Userdetect2Button.Text = 'User';
            app.Autodetect1Button = uiradiobutton(app.ShowMarkedOptionsButtonGroup);
            app.Autodetect1Button.Text = 'Auto';
            app.AutovsUserdetectCheckBox = uicheckbox(app.MarksPropertiesPanel);
            app.AutovsUserdetectCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutovsUserdetectCheckBoxValueChanged, true);
            app.AutovsUserdetectCheckBox.Text = 'Auto vs User detect';
            app.ShowMarkedLocationsTCheckBox = uicheckbox(app.MarksPropertiesPanel);
            app.ShowMarkedLocationsTCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowMarkedLocationsTSwitchValueChanged, true);
            app.ShowMarkedLocationsTCheckBox.Text = '';
            app.ShowMarkedLocationsTCheckBox.Value = true;
            app.VoronoidiagramButtonGroup = uibuttongroup(app.ViewTab);
            app.VoronoidiagramButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @VoronoidiagramButtonGroupSelectionChanged, true);
            app.VoronoidiagramButtonGroup.Title = 'Voronoi diagram';
            app.VoidButton = uiradiobutton(app.VoronoidiagramButtonGroup);
            app.VoidButton.Tag = 'Empty';
            app.VoidButton.Text = 'Void';
            app.VoidButton.Value = true;
            app.AreaButton = uiradiobutton(app.VoronoidiagramButtonGroup);
            app.AreaButton.Tag = 'Cone Area';
            app.AreaButton.Text = 'Area';
            app.NNeighbourButton = uiradiobutton(app.VoronoidiagramButtonGroup);
            app.NNeighbourButton.Tag = 'Number of Neighbors';
            app.NNeighbourButton.Text = 'NNeighbour';
            app.VoronoiDiagramCheckBox = uicheckbox(app.VoronoidiagramButtonGroup);
            app.VoronoiDiagramCheckBox.ValueChangedFcn = createCallbackFcn(app, @VoronoiDiagramCheckBoxValueChanged, true);
            app.VoronoiDiagramCheckBox.Text = '';
            app.CMapDropDownLabel_2 = uilabel(app.VoronoidiagramButtonGroup);
            app.CMapDropDownLabel_2.Text = 'CMap';
            app.CMapVoronoiDensityDropDown = uidropdown(app.VoronoidiagramButtonGroup);
            app.CMapVoronoiDensityDropDown.Items = {'Bone', 'Cividis', 'CoolWarm', 'Inferno', 'Magma', 'Plasma', 'Parula', 'Viridis'};
            app.CMapVoronoiDensityDropDown.ItemsData = {'bone', 'cividis', 'coolwarm', 'inferno', 'magma', 'plasma', 'parula', 'viridis'};
            app.CMapVoronoiDensityDropDown.ValueChangedFcn = createCallbackFcn(app, @CMapVoronoiDensityDropDownValueChanged, true);
            app.CMapVoronoiDensityDropDown.Value = 'viridis';
            app.DensitymapButtonGroup = uibuttongroup(app.ViewTab);
            app.DensitymapButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DensitymapButtonGroupSelectionChanged, true);
            app.DensitymapButtonGroup.Title = 'Density map';
            app.EuclideanNConesButton = uiradiobutton(app.DensitymapButtonGroup);
            app.EuclideanNConesButton.Tag = 'EuclideanNCones';
            app.EuclideanNConesButton.Text = 'Euclid';
            app.EuclideanNConesButton.Value = true;
            app.NNDmeanButton = uiradiobutton(app.DensitymapButtonGroup);
            app.NNDmeanButton.Tag = 'NNDmean';
            app.NNDmeanButton.Text = 'NND';
            app.YellotsRingsButton = uiradiobutton(app.DensitymapButtonGroup);
            app.YellotsRingsButton.Tag = 'YellotsRungs';
            app.YellotsRingsButton.Text = 'Yellots';
            app.ShowDMCheckBox = uicheckbox(app.DensitymapButtonGroup);
            app.ShowDMCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowDMCheckBoxValueChanged, true);
            app.ShowDMCheckBox.Text = '';
            app.PCDCheckBox = uicheckbox(app.DensitymapButtonGroup);
            app.PCDCheckBox.ValueChangedFcn = createCallbackFcn(app, @PCDCheckBoxValueChanged, true);
            app.PCDCheckBox.Text = 'PCD';
            app.CDCCheckBox = uicheckbox(app.DensitymapButtonGroup);
            app.CDCCheckBox.ValueChangedFcn = createCallbackFcn(app, @CDCCheckBoxValueChanged, true);
            app.CDCCheckBox.Text = 'CDC';
            app.AlphaSliderLabel = uilabel(app.DensitymapButtonGroup);
            app.AlphaSliderLabel.Text = 'Alpha';
            app.AlphaSlider = uislider(app.DensitymapButtonGroup);
            app.AlphaSlider.Limits = [0 1];
            app.AlphaSlider.MajorTicks = [];
            app.AlphaSlider.MajorTickLabels = {''};
            app.AlphaSlider.ValueChangingFcn = createCallbackFcn(app, @AlphaSliderValueChanging, true);
            app.AlphaSlider.MinorTicks = [];
            app.AlphaSlider.Value = 0.4;
            app.AlphaEditField = uieditfield(app.DensitymapButtonGroup, 'numeric');
            app.AlphaEditField.Limits = [0 1];
            app.AlphaEditField.ValueDisplayFormat = '%11.2g';
            app.AlphaEditField.ValueChangedFcn = createCallbackFcn(app, @AlphaEditFieldValueChanged, true);
            app.AlphaEditField.Value = 0.4;
            app.CMapDropDownLabel = uilabel(app.DensitymapButtonGroup);
            app.CMapDropDownLabel.Text = 'CMap';
            app.CMapDensityDropDown = uidropdown(app.DensitymapButtonGroup);
            app.CMapDensityDropDown.Items = {'Bone', 'Cividis', 'CoolWarm', 'Inferno', 'Magma', 'Plasma', 'Parula', 'Viridis'};
            app.CMapDensityDropDown.ItemsData = {'bone', 'cividis', 'coolwarm', 'inferno', 'magma', 'plasma', 'parula', 'viridis'};
            app.CMapDensityDropDown.ValueChangedFcn = createCallbackFcn(app, @CMapDensityDropDownValueChanged, true);
            app.CMapDensityDropDown.Value = 'parula';
            app.ApproximationEdgeCheckBox = uicheckbox(app.DensitymapButtonGroup);
            app.ApproximationEdgeCheckBox.ValueChangedFcn = createCallbackFcn(app, @ApproximationEdgeCheckBoxValueChanged, true);
            app.ApproximationEdgeCheckBox.Text = 'Approximation Edge';
            app.AutoLimCheckBox = uicheckbox(app.DensitymapButtonGroup);
            app.AutoLimCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutoLimCheckBoxValueChanged, true);
            app.AutoLimCheckBox.Text = 'AutoLim';
            app.AutoLimCheckBox.Value = true;
            app.minEditFieldLabel = uilabel(app.DensitymapButtonGroup);
            app.minEditFieldLabel.Text = 'min';
            app.MinLimEditField = uieditfield(app.DensitymapButtonGroup, 'numeric');
            app.MinLimEditField.ValueDisplayFormat = '%g';
            app.MinLimEditField.ValueChangedFcn = createCallbackFcn(app, @MinLimEditFieldValueChanged, true);
            app.maxEditFieldLabel = uilabel(app.DensitymapButtonGroup);
            app.maxEditFieldLabel.Text = 'max';
            app.MaxLimEditField = uieditfield(app.DensitymapButtonGroup, 'numeric');
            app.MaxLimEditField.ValueDisplayFormat = '%g';
            app.MaxLimEditField.ValueChangedFcn = createCallbackFcn(app, @MaxLimEditFieldValueChanged, true);
            app.DensityReportUnitsLabel = uilabel(app.DensitymapButtonGroup);
            app.DensityReportUnitsLabel.Text = 'pixel';
            app.zScoremapCheckBox = uicheckbox(app.DensitymapButtonGroup);
            app.zScoremapCheckBox.ValueChangedFcn = createCallbackFcn(app, @zScoremapCheckBoxValueChanged, true);
            app.zScoremapCheckBox.Text = 'z-Score map';
            app.OtherPanel = uipanel(app.ViewTab);
            app.OtherPanel.Title = 'Other';
            app.ScaleBarColorPreviewAxes = uiaxes(app.OtherPanel);
            app.ScaleBarColorPreviewAxes.XTick = [];
            app.ScaleBarColorPreviewAxes.YTick = [];
            app.ScaleBarColorPreviewAxes.ButtonDownFcn = createCallbackFcn(app, @ScaleBarColorPreviewAxesButtonDown, true);
            app.ScaleBarCheckBox = uicheckbox(app.OtherPanel);
            app.ScaleBarCheckBox.ValueChangedFcn = createCallbackFcn(app, @ScaleBarCheckBoxValueChanged, true);
            app.ScaleBarCheckBox.Text = 'Scale Bar';
            app.GridCheckBox = uicheckbox(app.OtherPanel);
            app.GridCheckBox.ValueChangedFcn = createCallbackFcn(app, @GridCheckBoxValueChanged, true);
            app.GridCheckBox.Text = 'Grid';
            app.GridNumbersCheckBox = uicheckbox(app.OtherPanel);
            app.GridNumbersCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowGirdNumbersECheckBoxValueChanged, true);
            app.GridNumbersCheckBox.Text = 'Grid Numbers';
            app.ScaleBarUnitsDropDown = uidropdown(app.OtherPanel);
            app.ScaleBarUnitsDropDown.Items = {'pixel', 'arcsec', 'arcmin', 'degree', 'millimeter', 'micrometer'};
            app.ScaleBarUnitsDropDown.ValueChangedFcn = createCallbackFcn(app, @ScaleBarUnitsDropDownValueChanged, true);
            app.ScaleBarUnitsDropDown.Value = 'pixel';
            app.ColorBarCheckBox = uicheckbox(app.OtherPanel);
            app.ColorBarCheckBox.ValueChangedFcn = createCallbackFcn(app, @ColorBarCheckBoxValueChanged, true);
            app.ColorBarCheckBox.Text = 'Color Bar';
            app.ScaleRingsCheckBox = uicheckbox(app.OtherPanel);
            app.ScaleRingsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ScaleRingsCheckBoxValueChanged, true);
            app.ScaleRingsCheckBox.Text = 'Scale Rings';
            app.DensitycontourPanel = uipanel(app.ViewTab);
            app.DensitycontourPanel.Title = 'Density contour';
            app.MainColorPreviewAxes = uiaxes(app.DensitycontourPanel);
            app.MainColorPreviewAxes.XTick = [];
            app.MainColorPreviewAxes.YTick = [];
            app.MainColorPreviewAxes.ButtonDownFcn = createCallbackFcn(app, @PickMainColorButtonPushed, true);
            app.SingleLineColorPreviewAxes = uiaxes(app.DensitycontourPanel);
            app.SingleLineColorPreviewAxes.XTick = [];
            app.SingleLineColorPreviewAxes.YTick = [];
            app.SingleLineColorPreviewAxes.ButtonDownFcn = createCallbackFcn(app, @PickSingleLineColorButtonPushed, true);
            app.TextLabelColorPreviewAxes = uiaxes(app.DensitycontourPanel);
            app.TextLabelColorPreviewAxes.XTick = [];
            app.TextLabelColorPreviewAxes.YTick = [];
            app.TextLabelColorPreviewAxes.ButtonDownFcn = createCallbackFcn(app, @TextLabelColorPreviewAxesButtonDown, true);
            app.ContoursCheckBox = uicheckbox(app.DensitycontourPanel);
            app.ContoursCheckBox.ValueChangedFcn = createCallbackFcn(app, @ContoursCheckBoxValueChanged, true);
            app.ContoursCheckBox.Text = '';
            app.ShowSinglePercentageLineCheckBox = uicheckbox(app.DensitycontourPanel);
            app.ShowSinglePercentageLineCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowSinglePercentageLineCheckBoxValueChanged, true);
            app.ShowSinglePercentageLineCheckBox.Text = 'Single line @';
            app.ShowSinglePercentageLineCheckBox.Value = true;
            app.ShowMainPercentageLinesCheckBox = uicheckbox(app.DensitycontourPanel);
            app.ShowMainPercentageLinesCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowMainPercentageLinesCheckBoxValueChanged, true);
            app.ShowMainPercentageLinesCheckBox.Text = 'Increment @';
            app.ShowMainPercentageLinesCheckBox.Value = true;
            app.ShowPercentTextCheckBox = uicheckbox(app.DensitycontourPanel);
            app.ShowPercentTextCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowPercentTextCheckBoxValueChanged, true);
            app.ShowPercentTextCheckBox.Text = 'Text labels';
            app.Label = uilabel(app.DensitycontourPanel);
            app.Label.Text = '%';
            app.SingleLinePercentageEditField = uieditfield(app.DensitycontourPanel, 'numeric');
            app.SingleLinePercentageEditField.Limits = [1 100];
            app.SingleLinePercentageEditField.ValueDisplayFormat = '%11g';
            app.SingleLinePercentageEditField.ValueChangedFcn = createCallbackFcn(app, @SingleLinePercentageEditFieldValueChanged, true);
            app.SingleLinePercentageEditField.Value = 20;
            app.Label_2 = uilabel(app.DensitycontourPanel);
            app.Label_2.Text = '%';
            app.MainLinesPercentageDifferenceEditField = uieditfield(app.DensitycontourPanel, 'numeric');
            app.MainLinesPercentageDifferenceEditField.Limits = [1 100];
            app.MainLinesPercentageDifferenceEditField.ValueDisplayFormat = '%11g';
            app.MainLinesPercentageDifferenceEditField.ValueChangedFcn = createCallbackFcn(app, @MainLinesPercentageDifferenceEditFieldValueChanged, true);
            app.MainLinesPercentageDifferenceEditField.Value = 10;
            app.DensContGaussFilterCheckBox = uicheckbox(app.DensitycontourPanel);
            app.DensContGaussFilterCheckBox.ValueChangedFcn = createCallbackFcn(app, @DensContGaussFilterCheckBoxValueChanged, true);
            app.DensContGaussFilterCheckBox.Text = 'Gauss filter';
            app.Label_3 = uilabel(app.DensitycontourPanel);
            app.Label_3.Text = 'sigma';
            app.DensContGaussFilterSigmaEditField = uieditfield(app.DensitycontourPanel, 'numeric');
            app.DensContGaussFilterSigmaEditField.Limits = [0 100];
            app.DensContGaussFilterSigmaEditField.ValueDisplayFormat = '%11g';
            app.DensContGaussFilterSigmaEditField.ValueChangedFcn = createCallbackFcn(app, @DensContGaussFilterSigmaEditFieldValueChanged, true);
            app.DensContGaussFilterSigmaEditField.Value = 1;
            app.EditTab = uitab(app.TabGroup);
            app.EditTab.Title = 'Edit';
            app.ImproveCNNResultsPanel = uipanel(app.EditTab);
            app.ImproveCNNResultsPanel.Title = 'Improve CNN Results';
            app.DelOutsideImageCheckBox = uicheckbox(app.ImproveCNNResultsPanel);
            app.DelOutsideImageCheckBox.Text = 'Del outside image';
            app.DelDoublesCheckBox = uicheckbox(app.ImproveCNNResultsPanel);
            app.DelDoublesCheckBox.Text = 'Del doubles';
            app.ApplySelectedButton = uibutton(app.ImproveCNNResultsPanel, 'push');
            app.ApplySelectedButton.ButtonPushedFcn = createCallbackFcn(app, @ApplySelectedButtonPushed, true);
            app.ApplySelectedButton.Text = 'Apply Selected';
            app.AlgorithmDropDownLabel = uilabel(app.ImproveCNNResultsPanel);
            app.AlgorithmDropDownLabel.Text = 'Algorithm';
            app.AlgorithmDropDown = uidropdown(app.ImproveCNNResultsPanel);
            app.AlgorithmDropDown.Items = {'FastPeakFind', 'CNN'};
            app.AlgorithmDropDown.Value = 'CNN';
            app.TrainingDropDownLabel = uilabel(app.ImproveCNNResultsPanel);
            app.TrainingDropDownLabel.Text = 'Training';
            app.TrainingDropDown = uidropdown(app.ImproveCNNResultsPanel);
            app.TrainingDropDown.Items = {'g1+cunefare', 'alex training set', '41 eye jlr set (black cut)', 'julius montages', 'julius montages2'};
            app.TrainingDropDown.Value = 'julius montages';
            app.FindConesCheckBox = uicheckbox(app.ImproveCNNResultsPanel);
            app.FindConesCheckBox.Text = 'Find cones';
            app.DeletedSelectedConesButton = uibutton(app.ImproveCNNResultsPanel, 'push');
            app.DeletedSelectedConesButton.ButtonPushedFcn = createCallbackFcn(app, @DeletedSelectedConesButtonPushed, true);
            app.DeletedSelectedConesButton.Text = 'Deleted Selected Cones';
            app.HelpTextAreaLabel = uilabel(app.EditTab);
            app.HelpTextAreaLabel.Text = 'Help';
            app.HelpTextArea = uitextarea(app.EditTab);
            app.HelpTextArea.Editable = 'off';
            app.HelpTextArea.Value = {'Alt + Mouse Left Click - place Mark'; 'Alt + Mouse Right Click - remove Mark'; ''};
            app.ReceptorstypeDropDownLabel = uilabel(app.EditTab);
            app.ReceptorstypeDropDownLabel.Text = 'Receptors type';
            app.ReceptorsTypeDropDown = uidropdown(app.EditTab);
            app.ReceptorsTypeDropDown.Items = {'Cones', 'Rods'};
            app.ReceptorsTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ReceptorsTypeDropDownValueChanged, true);
            app.ReceptorsTypeDropDown.Value = 'Cones';
            app.MetricsTab = uitab(app.TabGroup);
            app.MetricsTab.Title = 'Metrics';
            app.DensitycalculationparametersPanel = uipanel(app.MetricsTab);
            app.DensitycalculationparametersPanel.Title = 'Density calculation parameters';
            app.EuclideanNconesLabel = uilabel(app.DensitycalculationparametersPanel);
            app.EuclideanNconesLabel.Text = 'Euclidean N cones';
            app.EuclideanNconesEditField = uieditfield(app.DensitycalculationparametersPanel, 'numeric');
            app.EuclideanNconesEditField.Limits = [0 1000];
            app.EuclideanNconesEditField.RoundFractionalValues = 'on';
            app.EuclideanNconesEditField.ValueDisplayFormat = '%11g';
            app.EuclideanNconesEditField.ValueChangedFcn = createCallbackFcn(app, @EuclideanNconesEditFieldValueChanged, true);
            app.EuclideanNconesEditField.Value = 150;
            app.YellotsROIsizeEditFieldLabel = uilabel(app.DensitycalculationparametersPanel);
            app.YellotsROIsizeEditFieldLabel.Text = 'Yellots ROI size';
            app.YellotsROIsizeEditField = uieditfield(app.DensitycalculationparametersPanel, 'numeric');
            app.YellotsROIsizeEditField.Limits = [50 500];
            app.YellotsROIsizeEditField.RoundFractionalValues = 'on';
            app.YellotsROIsizeEditField.ValueDisplayFormat = '%11g';
            app.YellotsROIsizeEditField.ValueChangedFcn = createCallbackFcn(app, @YellotsROIsizeEditFieldValueChanged, true);
            app.YellotsROIsizeEditField.Value = 150;
            app.UnitssettingsPanel = uipanel(app.MetricsTab);
            app.UnitssettingsPanel.Title = 'Units settings';
            app.ReportunitsDropDownLabel = uilabel(app.UnitssettingsPanel);
            app.ReportunitsDropDownLabel.Text = 'Report units';
            app.ReportunitsDropDown = uidropdown(app.UnitssettingsPanel);
            app.ReportunitsDropDown.Items = {'pixel', 'arcsec', 'arcmin', 'degree', 'millimeter', 'micrometer'};
            app.ReportunitsDropDown.ValueChangedFcn = createCallbackFcn(app, @ReportunitsDropDownValueChanged, true);
            app.ReportunitsDropDown.Value = 'pixel';
            app.IMFEditFieldLabel = uilabel(app.UnitssettingsPanel);
            app.IMFEditFieldLabel.Text = 'IMF';
            app.IMFEditField = uieditfield(app.UnitssettingsPanel, 'numeric');
            app.IMFEditField.Limits = [0 Inf];
            app.IMFEditField.ValueChangedFcn = createCallbackFcn(app, @IMFEditFieldValueChanged, true);
            app.IMFEditField.Editable = 'off';
            app.RMFEditFieldLabel = uilabel(app.UnitssettingsPanel);
            app.RMFEditFieldLabel.Text = 'RMF';
            app.RMFEditField = uieditfield(app.UnitssettingsPanel, 'numeric');
            app.RMFEditField.Limits = [0 Inf];
            app.RMFEditField.ValueChangedFcn = createCallbackFcn(app, @RMFEditFieldValueChanged, true);
            app.RMFEditField.Editable = 'off';
            app.LockPPDButton = uibutton(app.UnitssettingsPanel, 'push');
            app.LockPPDButton.ButtonPushedFcn = createCallbackFcn(app, @LockPPDButtonPushed, true);
            app.LockPPDButton.Icon = fullfile(pathToMLAPP, 'Icons', 'lock.png');
            app.LockPPDButton.Text = 'Locked';
            app.SaveIMFButton = uibutton(app.UnitssettingsPanel, 'push');
            app.SaveIMFButton.ButtonPushedFcn = createCallbackFcn(app, @SaveIMFButtonPushed, true);
            app.SaveIMFButton.Enable = 'off';
            app.SaveIMFButton.Text = 'Save IMF';
            app.LockRMFButton = uibutton(app.UnitssettingsPanel, 'push');
            app.LockRMFButton.ButtonPushedFcn = createCallbackFcn(app, @LockRMFButtonPushed, true);
            app.LockRMFButton.Icon = fullfile(pathToMLAPP, 'Icons', 'lock.png');
            app.LockRMFButton.Text = 'Locked';
            app.SaveRMFButton = uibutton(app.UnitssettingsPanel, 'push');
            app.SaveRMFButton.ButtonPushedFcn = createCallbackFcn(app, @SaveRMFButtonPushed, true);
            app.SaveRMFButton.Enable = 'off';
            app.SaveRMFButton.Text = 'Save RMF';
            app.IMFUnitsLabel = uilabel(app.UnitssettingsPanel);
            app.IMFUnitsLabel.Text = 'pixel/deg';
            app.RMFUnitsLabel = uilabel(app.UnitssettingsPanel);
            app.RMFUnitsLabel.Text = 'µm/deg';
            app.EyetypeButtonGroup = uibuttongroup(app.MetricsTab);
            app.EyetypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @EyetypeButtonGroupSelectionChanged, true);
            app.EyetypeButtonGroup.Title = 'Eye type';
            app.LeftButton = uiradiobutton(app.EyetypeButtonGroup);
            app.LeftButton.Tag = 'left';
            app.LeftButton.Text = 'Left';
            app.RightButton = uiradiobutton(app.EyetypeButtonGroup);
            app.RightButton.Tag = 'right';
            app.RightButton.Text = 'Right';
            app.NoneButton = uiradiobutton(app.EyetypeButtonGroup);
            app.NoneButton.Tag = 'none';
            app.NoneButton.Enable = 'off';
            app.NoneButton.Text = 'None';
            app.NoneButton.Value = true;
            app.ReceptorLabel = uilabel(app.CMUIFigure);
            app.ReceptorLabel.Text = 'Cones';
            % Show the figure after all components are created
            app.CMUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ConeMapperApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CMUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CMUIFigure)
        end
    end
end