classdef DensityCalculationSettingsWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DensityCalculationSettingsUIFigure  matlab.ui.Figure
        OKButton               matlab.ui.control.Button
        CancelButton           matlab.ui.control.Button
        YRROIsizeSpinner       matlab.ui.control.Spinner
        YRROIsizeSpinnerLabel  matlab.ui.control.Label
        ENCnearestneighbornumberSpinner  matlab.ui.control.Spinner
        ENCnearestneighbornumberSpinnerLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        MainAppHandler % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainAppHanlder)
            app.MainAppHandler = mainAppHanlder;
            if ~isempty(app.MainAppHandler.EuclidianNConesHandler) && isvalid(app.MainAppHandler.EuclidianNConesHandler)
                app.ENCnearestneighbornumberSpinner.Value = app.MainAppHandler.EuclidianNConesHandler.NumOfNearestCones;
            end
            if ~isempty(app.MainAppHandler.YellotsRingsHandler) && isvalid(app.MainAppHandler.YellotsRingsHandler)
                app.YRROIsizeSpinner.Value = app.MainAppHandler.YellotsRingsHandler.ROI_size;
            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app);
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.MainAppHandler.EuclidianNConesHandler.NumOfNearestCones = app.ENCnearestneighbornumberSpinner.Value;
            app.MainAppHandler.YellotsRingsHandler.ROI_size = app.YRROIsizeSpinner.Value;

            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.DensityCalculationSettingsUIFigure = uifigure('Visible', 'off');
            app.DensityCalculationSettingsUIFigure.Name = 'Density Calculation Settings';
            app.DensityCalculationSettingsUIFigure.Icon = fullfile(pathToMLAPP, 'Icons', 'CF_icon.png');
            app.ENCnearestneighbornumberSpinnerLabel = uilabel(app.DensityCalculationSettingsUIFigure);
            app.ENCnearestneighbornumberSpinnerLabel.Text = 'ENC nearest neighbor number';
            app.ENCnearestneighbornumberSpinner = uispinner(app.DensityCalculationSettingsUIFigure);
            app.ENCnearestneighbornumberSpinner.Limits = [0 1000];
            app.ENCnearestneighbornumberSpinner.Value = 150;
            app.YRROIsizeSpinnerLabel = uilabel(app.DensityCalculationSettingsUIFigure);
            app.YRROIsizeSpinnerLabel.Text = 'YR ROI size';
            app.YRROIsizeSpinner = uispinner(app.DensityCalculationSettingsUIFigure);
            app.YRROIsizeSpinner.Step = 10;
            app.YRROIsizeSpinner.Limits = [50 500];
            app.YRROIsizeSpinner.Value = 150;
            app.CancelButton = uibutton(app.DensityCalculationSettingsUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.OKButton = uibutton(app.DensityCalculationSettingsUIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            % Show the figure after all components are created
            app.DensityCalculationSettingsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DensityCalculationSettingsWindow(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.DensityCalculationSettingsUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.DensityCalculationSettingsUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DensityCalculationSettingsUIFigure)
        end
    end
end