classdef DensityContourSettings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DensityContourSettignsUIFigure  matlab.ui.Figure
        CancelButton                    matlab.ui.control.Button
        OKButton                        matlab.ui.control.Button
        MetrictouseButtonGroup          matlab.ui.container.ButtonGroup
        YellotsRingsButton              matlab.ui.control.RadioButton
        NNDmeanButton                   matlab.ui.control.RadioButton
        EuclidianNConesButton           matlab.ui.control.RadioButton
        ShowparametersPanel             matlab.ui.container.Panel
        MainLinesPercentageDifferenceEditField  matlab.ui.control.NumericEditField
        MainlinespercentagedifferenceEditFieldLabel  matlab.ui.control.Label
        SingleLinePercentageEditField   matlab.ui.control.NumericEditField
        SinglelinepercentageEditFieldLabel  matlab.ui.control.Label
        ShowPercentTextCheckBox         matlab.ui.control.CheckBox
        ShowMainPercentageLinesCheckBox  matlab.ui.control.CheckBox
        ShowSinglePercentageLineCheckBox  matlab.ui.control.CheckBox
        ColorsPanel                     matlab.ui.container.Panel
        PickSingleLineColorButton       matlab.ui.control.Button
        PickMainColorButton             matlab.ui.control.Button
        SingleLineColorPreviewAxes      matlab.ui.control.UIAxes
        MainColorPreviewAxes            matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        MainAppHandler = [];
        MainColor = [0 0 1];
        SingleLineColor = [0 0 1];
    end
    
    methods (Access = private)
        % Sets color to preview axes
        function SetColor(~, axesHandler, color)
            previewColor = zeros(50, 50, 3);
            previewColor(:, :, 1) = color(1);
            previewColor(:, :, 2) = color(2);
            previewColor(:, :, 3) = color(3);
            imshow(previewColor, 'Parent',  axesHandler);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.MainAppHandler = mainapp;
            app.ShowMainPercentageLinesCheckBox.Value = app.MainAppHandler.DensityContoursShowMainLines;
            app.MainLinesPercentageDifferenceEditField.Value = app.MainAppHandler.DensityContoursMainLinesPercentageDistance;
            app.MainColor = app.MainAppHandler.DensityContoursMainLinesColor;
            SetColor(app, app.MainColorPreviewAxes, app.MainColor);
            app.ShowSinglePercentageLineCheckBox.Value = app.MainAppHandler.DensityContoursShowSingleLine;
            app.SingleLinePercentageEditField.Value = app.MainAppHandler.DensityContoursSingleLinePercentage;
            app.SingleLineColor = app.MainAppHandler.DensityContoursSingleLinesColor;
            SetColor(app, app.SingleLineColorPreviewAxes, app.SingleLineColor);
            app.ShowPercentTextCheckBox.Value = app.MainAppHandler.DensityContoursShowText;
            
            switch app.MainAppHandler.DensityContoursMetricToUse
                case 'euclidnc'
                    app.EuclidianNConesButton.Value = 1;
                    
                case 'nnd_mean'
                    app.NNDmeanButton.Value = 1;
                    
                case 'yellots_rings'
                    app.YellotsRingsButton.Value = 1;
                    
                otherwise
                    disp(app.MainAppHandler.DensityContoursMetricToUse);
                    error("Invalid metric has been chosen.")
            end
        end

        % Button pushed function: PickMainColorButton
        function PickMainColorButtonPushed(app, event)
            color = uisetcolor();
            SetColor(app, app.MainColorPreviewAxes, color);
            app.MainColor = color;
        end

        % Button pushed function: PickSingleLineColorButton
        function PickSingleLineColorButtonPushed(app, event)
            color = uisetcolor();
            SetColor(app, app.SingleLineColorPreviewAxes, color);
            app.SingleLineColor = color;
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.MainAppHandler.DensityContoursShowMainLines = app.ShowMainPercentageLinesCheckBox.Value;
            app.MainAppHandler.DensityContoursMainLinesPercentageDistance = app.MainLinesPercentageDifferenceEditField.Value;
            app.MainAppHandler.DensityContoursMainLinesColor = app.MainColor;
            app.MainAppHandler.DensityContoursShowSingleLine = app.ShowSinglePercentageLineCheckBox.Value;
            app.MainAppHandler.DensityContoursSingleLinePercentage = app.SingleLinePercentageEditField.Value;
            app.MainAppHandler.DensityContoursSingleLinesColor = app.SingleLineColor;
            app.MainAppHandler.DensityContoursShowText = app.ShowPercentTextCheckBox.Value;
            app.MainAppHandler.DensityContoursMetricToUse = app.MetrictouseButtonGroup.SelectedObject.Tag;
            
            app.MainAppHandler.UpdateImage();
            
            delete(app);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DensityContourSettignsUIFigure = uifigure('Visible', 'off');
            app.DensityContourSettignsUIFigure.Name = 'Density Contour Settigns';
            app.ColorsPanel = uipanel(app.DensityContourSettignsUIFigure);
            app.ColorsPanel.Title = 'Colors';
            app.MainColorPreviewAxes = uiaxes(app.ColorsPanel);
            app.MainColorPreviewAxes.XTick = [];
            app.MainColorPreviewAxes.YTick = [];
            app.SingleLineColorPreviewAxes = uiaxes(app.ColorsPanel);
            app.SingleLineColorPreviewAxes.XTick = [];
            app.SingleLineColorPreviewAxes.YTick = [];
            app.PickMainColorButton = uibutton(app.ColorsPanel, 'push');
            app.PickMainColorButton.ButtonPushedFcn = createCallbackFcn(app, @PickMainColorButtonPushed, true);
            app.PickMainColorButton.Text = 'Pick Main Color';
            app.PickSingleLineColorButton = uibutton(app.ColorsPanel, 'push');
            app.PickSingleLineColorButton.ButtonPushedFcn = createCallbackFcn(app, @PickSingleLineColorButtonPushed, true);
            app.PickSingleLineColorButton.Text = 'Pick Single Line Color';
            app.ShowparametersPanel = uipanel(app.DensityContourSettignsUIFigure);
            app.ShowparametersPanel.Title = 'Show parameters';
            app.ShowSinglePercentageLineCheckBox = uicheckbox(app.ShowparametersPanel);
            app.ShowSinglePercentageLineCheckBox.Text = 'Show single percentage line';
            app.ShowMainPercentageLinesCheckBox = uicheckbox(app.ShowparametersPanel);
            app.ShowMainPercentageLinesCheckBox.Text = 'Show main percentage lines';
            app.ShowMainPercentageLinesCheckBox.Value = true;
            app.ShowPercentTextCheckBox = uicheckbox(app.ShowparametersPanel);
            app.ShowPercentTextCheckBox.Text = 'Show percent text';
            app.SinglelinepercentageEditFieldLabel = uilabel(app.ShowparametersPanel);
            app.SinglelinepercentageEditFieldLabel.Text = 'Single line percentage';
            app.SingleLinePercentageEditField = uieditfield(app.ShowparametersPanel, 'numeric');
            app.SingleLinePercentageEditField.Limits = [0 1];
            app.SingleLinePercentageEditField.Value = 0.2;
            app.MainlinespercentagedifferenceEditFieldLabel = uilabel(app.ShowparametersPanel);
            app.MainlinespercentagedifferenceEditFieldLabel.Text = 'Main lines percentage difference';
            app.MainLinesPercentageDifferenceEditField = uieditfield(app.ShowparametersPanel, 'numeric');
            app.MainLinesPercentageDifferenceEditField.Limits = [0 1];
            app.MainLinesPercentageDifferenceEditField.Value = 0.1;
            app.MetrictouseButtonGroup = uibuttongroup(app.DensityContourSettignsUIFigure);
            app.MetrictouseButtonGroup.Title = 'Metric to use';
            app.EuclidianNConesButton = uiradiobutton(app.MetrictouseButtonGroup);
            app.EuclidianNConesButton.Tag = 'euclidnc';
            app.EuclidianNConesButton.Text = 'EuclidianNCones';
            app.EuclidianNConesButton.Value = true;
            app.NNDmeanButton = uiradiobutton(app.MetrictouseButtonGroup);
            app.NNDmeanButton.Tag = 'nnd_mean';
            app.NNDmeanButton.Text = 'NNDmean';
            app.YellotsRingsButton = uiradiobutton(app.MetrictouseButtonGroup);
            app.YellotsRingsButton.Tag = 'yellots_rings';
            app.YellotsRingsButton.Text = 'YellotsRings';
            app.OKButton = uibutton(app.DensityContourSettignsUIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.DensityContourSettignsUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.DensityContourSettignsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DensityContourSettings(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DensityContourSettignsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DensityContourSettignsUIFigure)
        end
    end
end