classdef Settings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ConeFinderSettingsUIFigure  matlab.ui.Figure
        AutosaveCheckBox            matlab.ui.control.CheckBox
        OKButton                    matlab.ui.control.Button
        CancelButton                matlab.ui.control.Button
        BrowseButton                matlab.ui.control.Button
        StartFolderEditField        matlab.ui.control.EditField
        StartFolderEditFieldLabel   matlab.ui.control.Label
    end

    
    properties (Access = private)
        MainAppHandler = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.MainAppHandler = mainapp;
            app.AutosaveCheckBox.Value = app.MainAppHandler.IsAutosaveOn;
            app.StartFolderEditField.Value = app.MainAppHandler.StartFolder;
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            folder = uigetdir(pwd, 'Select start folder...');
            app.StartFolderEditField.Value = folder;
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app);
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.MainAppHandler.StartFolder = app.StartFolderEditField.Value;
            app.MainAppHandler.IsAutosaveOn = app.AutosaveCheckBox.Value;
            app.MainAppHandler.UpdateParameters();
            
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.ConeFinderSettingsUIFigure = uifigure('Visible', 'off');
            app.ConeFinderSettingsUIFigure.Name = 'ConeFinder Settings';
            app.ConeFinderSettingsUIFigure.Icon = fullfile(pathToMLAPP, 'Icons', 'CF_icon.png');
            app.StartFolderEditFieldLabel = uilabel(app.ConeFinderSettingsUIFigure);
            app.StartFolderEditFieldLabel.Text = 'Start Folder';
            app.StartFolderEditField = uieditfield(app.ConeFinderSettingsUIFigure, 'text');
            app.BrowseButton = uibutton(app.ConeFinderSettingsUIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.CancelButton = uibutton(app.ConeFinderSettingsUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.OKButton = uibutton(app.ConeFinderSettingsUIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.AutosaveCheckBox = uicheckbox(app.ConeFinderSettingsUIFigure);
            app.AutosaveCheckBox.Text = 'Autosave';
            % Show the figure after all components are created
            app.ConeFinderSettingsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Settings(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ConeFinderSettingsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ConeFinderSettingsUIFigure)
        end
    end
end