classdef TestApp1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        StartConeFinderButton           matlab.ui.control.StateButton
        VoronoiiButton                  matlab.ui.control.Button
        ResetBrightnessButton           matlab.ui.control.StateButton
        EditModeSwitchLabel             matlab.ui.control.Label
        EditModeSwitch                  matlab.ui.control.Switch
        BeightnessSliderLabel           matlab.ui.control.Label
        BeightnessSlider                matlab.ui.control.Slider
        LogImageSwitchLabel             matlab.ui.control.Label
        LogImageSwitch                  matlab.ui.control.Switch
        ShowmarkedlocationsSwitchLabel  matlab.ui.control.Label
        ShowmarkedlocationsSwitch       matlab.ui.control.Switch
        ShowimageSwitchLabel            matlab.ui.control.Label
        ShowimageSwitch                 matlab.ui.control.Switch
        ConeMarksTypeButtonGroup        matlab.ui.container.ButtonGroup
        DotButton                       matlab.ui.control.RadioButton
        CircleButton                    matlab.ui.control.RadioButton
        CrossButton                     matlab.ui.control.RadioButton
        ShowMarksOptionsButtonGroup     matlab.ui.container.ButtonGroup
        AutoUserButton                  matlab.ui.control.RadioButton
        UserdetectButton                matlab.ui.control.RadioButton
        AutodetectButton                matlab.ui.control.RadioButton
        HighlightautodetectconesSwitchLabel  matlab.ui.control.Label
        HighlightautodetectconesSwitch  matlab.ui.control.Switch
        SaveCurrentLocationsButton      matlab.ui.control.Button
        ExitButton                      matlab.ui.control.Button
    end

    
    properties (Access = private)
        
    end
    
    properties (Access = public)
        Map; % Description
        Conelocs = [];
        VoronoiPlot = 1;
        I;
        MarkerSize = 2;
        Boxposition;
        Totalconecount = 0;
        Conecount = 0;
        Ttl;
        A1;
        Minlimx = 0;
        Minlimy = 0;
        Maxlimx = 0;
        Maxlimy = 0;
        ChangeMosaic;
        Mosaic = 0;
        MultipleMosaic = 0;
        Fname;
        Pname;
        Imageon = 'On';
        MarkType = '.y';
        ShowConesByDetectType = 0;
        Brightness = 1.0;
        Handler;
        LogImage = 'On';
    end
    
    methods (Access = public)
        
        function MarkCone(app, x, y)
            %         x = round(x); y = round(y);       % rounded until 10.09.2019
            %         x = x; y = y;                     % exact values from 11.09.2019 (also changes in next analysis steps)
            % CNN generates more exact cone
            % locations anyway
            
            if x < app.Minlimx || x > app.Maxlimx || y < app.Minlimy || y > app.Maxlimy
                app.Conelocs(app.Totalconecount+1,1) = x; app.Conelocs(app.Totalconecount+1,2) = y; app.Conelocs(app.Totalconecount+1,3) = 3;
            else
                app.Conelocs(app.Totalconecount+1,1) = x; app.Conelocs(app.Totalconecount+1,2) = y; app.Conelocs(app.Totalconecount+1,3) = 2;
            end
            
            app.Conelocs = unique(app.Conelocs, 'rows', 'stable');
            app.Conecount = size(app.Conelocs(app.Conelocs(:,3)~=0,1),1);
            app.Totalconecount = size(app.Conelocs,1);
            
            plot(app.Conelocs(app.Conelocs(:,3)~=0,1), app.Conelocs(app.Conelocs(:,3)~=0,2), app.MarkType, 'MarkerSize', app.MarkerSize)
            % app.Conecount = app.Conecount+1;
            [Inr,  Num_mosaics] = size(app.ChangeMosaic);
            app.Ttl.BackgroundColor = [1 0 0];
            title([app.Fname,'  ',num2str(size(app.Conelocs(app.Conelocs(:,3)~=0,1),1)),' cones', '  mosaic: ', num2str(app.Mosaic), '/', num2str(Num_mosaics)],'Interpreter','none')
        end
        
        function RemoveMark(app, x, y)
            % Clear marked cone nearest to click location
            if (size(app.Conelocs,1)>0)
                %         x = round(x); y = round(y);       % rounded until 10.09.2019 --> see button 1
                currentloc = [x y];
                %compute Euclidean distances:
                distances = sqrt(sum(bsxfun(@minus, app.Conelocs(:,1:2), currentloc).^2,2));
                %find the smallest distance and delete that index in app.Conelocs:
                app.Conelocs(distances==min(distances),:)=[];
                
                app.Conelocs = unique(app.Conelocs, 'rows', 'stable');
                app.Conecount = size(app.Conelocs(app.Conelocs(:,3)~=0,1),1);
                app.Totalconecount = size(app.Conelocs,1);
                
                % After deletion, plot current set of cones
                [Inr, Num_mosaics] = size(app.ChangeMosaic);
                cla; app.A1 = imshow(app.I);
                title([app.Fname,'  ',num2str(size(app.Conelocs(app.Conelocs(:,3)~=0,1),1)),' cones', '  mosaic: ', num2str(app.Mosaic), '/', num2str(Num_mosaics)],'Interpreter','none')
                hold on;
                plot(app.Conelocs(app.Conelocs(:,3)~=0,1), app.Conelocs(app.Conelocs(:,3)~=0,2), app.MarkType, 'MarkerSize', app.MarkerSize)
                rectangle('Position', app.Boxposition,'EdgeColor', [1 0 1]);
                
            end
        end
        
        function SaveCurrentLocations(app)
            if app.MultipleMosaic
                app.I = app.ChangeMosaic;
            end
            
            [pstr, nm, ext] = fileparts([app.Pname app.Fname]);
            
            if strcmp(ext,'.tiff')==1 || strcmp(ext,'.tif')==1
                name1 = [pstr filesep nm '_annotated.png'];
                name2 = [pstr filesep nm '_annotated.mat'];
            elseif strcmp(ext,'.mat')==1
                name1 = [pstr filesep nm '.png'];
                name2 = [app.Pname app.Fname];
            end
            app.Handler.figprint = gcf; print(app.Handler.figprint, name1, '-dpng');
            
            conecount = app.Conecount;
            I = app.I;
            conelocs = app.Conelocs;
            boxposition = app.Boxposition;
            multipleMosaic = app.MultipleMosaic;
            
            save(name2, 'conecount', 'I', 'conelocs','boxposition','multipleMosaic');
            app.Ttl.BackgroundColor = [0 1 0];
            
            if app.MultipleMosaic
                app.I = app.ChangeMosaic;
            end
        end
        
        function ZoomIn(app)
            ax = axis;
            width=ax(2)-ax(1);
            height=ax(4)-ax(3);
            axis([ax(1)-width/3 ax(2)+width/3 ax(3)-height/3 ax(4)+height/3]);
            zoom(1/2);
        end
        
        function ZoomOut(app, x, y)
            ax = axis;
            width=ax(2)-ax(1);
            height=ax(4)-ax(3);
            axis([x-width/2 x+width/2 y-height/2 y+height/2]);
            zoom(2);
        end
        
        function PanLeft(app)
            ax = axis;
            width=ax(2)-ax(1);
            height=ax(4)-ax(3);
            increment = round(width/20);
            axis([ax(1)-increment ax(2)-increment ax(3) ax(4)]);
        end
        
        function PanRight(app)
            ax = axis;
            width=ax(2)-ax(1);
            height=ax(4)-ax(3);
            increment = round(width/20);
            axis([ax(1)+increment ax(2)+increment ax(3) ax(4)]);
        end
        
        function PanUp(app)
            ax = axis;
            width=ax(2)-ax(1);
            height=ax(4)-ax(3);
            increment = round(height/20);
            axis([ax(1) ax(2) ax(3)-increment ax(4)-increment]);
        end
        
        function PanDown(app)
            ax = axis;
            width=ax(2)-ax(1);
            height=ax(4)-ax(3);
            increment = round(height/20);
            axis([ax(1) ax(2) ax(3)+increment ax(4)+increment]);
        end
        
        function PlotConelocs(app)
            rectangle('Position', app.Boxposition,'EdgeColor',[1 0 1]);
            
            if strcmp(app.ShowmarkedlocationsSwitch.Value, 'On')
                switch app.ShowConesByDetectType
                    case 1
                        usercones = app.Conelocs(app.Conelocs(:,3)==2,1:2);
                        plot(usercones(:,1),usercones(:,2), app.MarkType, 'MarkerSize', app.MarkerSize);
                        
                    case 2
                        autocones = app.Conelocs(app.Conelocs(:,3)==1,1:2);
                        plot(autocones(:,1),autocones(:,2),'.m')
                        
                    otherwise
                        if strcmp(app.HighlightautodetectconesSwitch.Value, 'Off')
                            plot(app.Conelocs(app.Conelocs(:,3)~=0,1), app.Conelocs(app.Conelocs(:,3)~=0,2), app.MarkType, 'MarkerSize', app.MarkerSize);
                        else
                            usercones = app.Conelocs(app.Conelocs(:,3)==2,1:2);
                            autocones = app.Conelocs(app.Conelocs(:,3)==1,1:2);
                            plot(autocones(:,1),autocones(:,2),'.m');
                            plot(usercones(:,1),usercones(:,2), app.MarkType, 'MarkerSize', app.MarkerSize);
                        end
                end
            end
        end
        
        function UpdateImage(app)
            blank = zeros(size(app.I));
            if strcmp(app.Imageon, 'Off')
                cla; app.A1 = imshow(blank); hold on
            else
                J = imadjust(app.I,[],[],app.Brightness);
                
                if strcmp(app.LogImage, 'Off')
                   J = GetLogImage(app, J);
                end
                
                cla; app.A1 = imshow(J); hold on;
            end
            PlotConelocs(app);
        end
        
        function image = GetLogImage(app, Image)
            I_INbox = log(double(Image(app.Minlimy+20:app.Maxlimy-20, app.Minlimx+20:app.Maxlimx-20)));
            
            I_log = log(double(Image));
            I_log = I_log - min(I_INbox(:));
            I_log = I_log./max(I_log(:));
            
            image = I_log;
        end
    end

    methods (Access = private)

        % Value changed function: StartConeFinderButton
        function StartConeFinderButtonValueChanged(app, event)
            %clear all, close all, clc;
            
            format shortg
            markson = 1;
            imageon = 1;
            %voronoiplot = 1;
            app.Conelocs = [];
            % Colormap for Voronoi patches
            % map = colormap('colorcube'); close(gcf);
            % numcolors = 15;  % ********* change from 15
            % idx = round(linspace(1,15,numcolors));
            % neighcolors = map(idx,:);
            % neighcolors = flipud(neighcolors);
            
            colormaps_mpl2019;
            % map = colormap(inferno); close(gcf);
            app.Map = colormap(viridis); close(gcf);
            % adjust settings for colormap in VoronoiPatch function ~ line 660
            
            %% User input - Select files to analyse
            % Select tiff to analyze, or mat file in case revisiting already counted tiff
            
            start_path = ([cd filesep]);
            % start_path = (['C:\Users\Jenny\Documents\Projekte\foveal_structure_and_function\Probandendaten_STUDIE\foveal9_mosaics\MARKED_files (g1+cunefare CNN Training)' filesep]);
            % start_path = (['E:\ausgelagerte_Studiendaten\788_gradedImages' filesep]);
            
            % file selection GUI
            [fnames, app.Pname] = uigetfile('*.mat;*.tiff;*.tif;', 'Select file(s) to mark', start_path, 'MultiSelect', 'on');
            
            % checks if user cancelled
            if isequal(fnames,0) || isequal(app.Pname,0)
                % display a message in the commmand window
                disp(' ');disp(' ');disp(' ');disp(' ----> User cancelled script');disp(' ');disp(' ');
                % if cancelled exit script
                return
            end
            
            % determine number of selected filess
            sumnorms = cellstr(fnames);
            nfiles = size(fnames(:),1);
            
            if nfiles > 10
                nfiles = 1;
            end
            
            if nfiles > 1
                app.MultipleMosaic = 1;
                app.Fname = fnames{1};
                [pstr, nm, ext] = fileparts([app.Pname app.Fname]);
            else
                app.MultipleMosaic = 0;
                app.Fname = fnames;
                [pstr, nm, ext] = fileparts([app.Pname app.Fname]);
            end
            
            %% In case of Multiselect - prepare for changing the app.Mosaic image
            if app.MultipleMosaic
                
                ref_image=fnames{1};
                refframe = imread([app.Pname ref_image]);
                
                pad = 100;
                
                %     rect1 = [206,206,301,301];
                %     rect2 = [306,306,201,201];
                rect1 = [200,200,201,201];
                rect2 = [312,200,201,201];
                rect3 = [200,312,201,201];
                rect4 = [312,312,201,201];
                %     rect5 = [308,164,79,183];
                
                [fheight, fwidth] = size(refframe);
                % rect is [start x coordinate, y coordinate, width, height]
                refcrop1 = imcrop(refframe,rect1);
                refcrop2 = imcrop(refframe,rect2);
                refcrop3 = imcrop(refframe,rect3);
                refcrop4 = imcrop(refframe,rect4);
                %     refcrop5 = imcrop(refframe,rect5);
                
                %     figure, imshow(refframe)
                %     title('Reference Frame')
                
                sumframe=zeros(fheight+(pad*2),fwidth+(pad*2));
                sumframebinary=ones(fheight+(pad*2),fwidth+(pad*2));
                
                for nf = 1:nfiles
                    frame = imread([app.Pname fnames{nf}]);
                    % rect is [start x coordinate, y coordinate, width, height]
                    crop1 = imcrop(frame,rect1);
                    crop2 = imcrop(frame,rect2);
                    crop3 = imcrop(frame,rect3);
                    crop4 = imcrop(frame,rect4);
                    %         crop5 = imcrop(frame,rect5);
                    
                    [shift1, dump] = dftregistration(fft2(refcrop1),fft2(crop1),10);
                    [shift2, dump] = dftregistration(fft2(refcrop2),fft2(crop2),10);
                    [shift3, dump] = dftregistration(fft2(refcrop3),fft2(crop3),10);
                    [shift4, dump] = dftregistration(fft2(refcrop4),fft2(crop4),10);
                    %         [shift5, dump] = dftregistration(fft2(refcrop5),fft2(crop5),10);
                    
                    
                    shifts(:,:,nf) = [shift1(3:4);shift2(3:4);shift3(3:4);shift4(3:4);];
                    %         shifts(:,:,nf) = [shift1(3:4);shift2(3:4);];
                    meanshift(nf,:) = mean(shifts(:,:,nf));
                    deviation(nf) = mean(std(shifts(:,:,nf)));
                    
                    f = double(zeros(fheight+(pad*2),fwidth+(pad*2)));
                    f(pad+1:(fheight+pad),pad+1:(fwidth+pad))=frame;
                    
                    deltar = -meanshift(nf,1);
                    deltac = -meanshift(nf,2);
                    phase = 2;
                    [nr,nc]=size(f);
                    Nr = ifftshift((-fix(nr/2):ceil(nr/2)-1));
                    Nc = ifftshift((-fix(nc/2):ceil(nc/2)-1));
                    [Nc,Nr] = meshgrid(Nc,Nr);
                    g = abs(ifft2(fft2(f).*exp(1i*2*pi*(deltar*Nr/nr+deltac*Nc/nc))).*exp(-1i*phase));
                    g = g./max(g(:));
                    
                    app.ChangeMosaic{nf} = g(101:812,101:812);
                    
                    %         figure, imshow(g)
                    %         title(['Frame ', num2str(nf)])
                    
                    clear f deltar deltac Nc Nr nc nr g
                end
                
                %     figure, imshow(app.ChangeMosaic(:,:,1));
                %     imwrite(app.ChangeMosaic(:,:,1),'frame1.tif','tif','Compression','none');
                %     figure, imshow(app.ChangeMosaic(:,:,2));
                %     imwrite(app.ChangeMosaic(:,:,2),'frame2.tif','tif','Compression','none');
                %     figure, imshow(app.ChangeMosaic(:,:,3));
                %     imwrite(app.ChangeMosaic(:,:,3),'frame3.tif','tif','Compression','none');
                
            end
            
            %% Prepare file to start/continue cone marking
            if strcmp(ext,'.tiff')==1 || strcmp(ext,'.tif')==1
                
                if app.MultipleMosaic
                    app.Mosaic = 1;
                    app.I = app.ChangeMosaic{app.Mosaic};
                elseif nfiles == 1
                    app.I = imread([app.Pname app.Fname]);
                    app.ChangeMosaic{1} = app.I;
                end
                
                app.Handler.fig = figure;
                imshow(app.I), hold on
                
                box_size = questdlg('Select box size', 'Define window size for analysis', '200x200 pixel', ...
                    '512x512  pixel', '1024x1024  pixel', '1024x1024  pixel');
                if strcmp(box_size, '200x200 pixel')
                    hi = imrect(gca,[0 0 200 200]);
                    app.MarkerSize = 2;
                elseif strcmp(box_size, '512x512  pixel')
                    hi = imrect(gca,[0 0 512 512]);
                    app.MarkerSize = 2;
                elseif strcmp(box_size, '1024x1024  pixel')
                    hi = imrect(gca,[0 0 1024 1024]);
                    app.MarkerSize = 2;
                end
                
                app.Boxposition = wait(hi);
                %     app.Boxposition = [295.8507 239.8978 200 200];    % defined, preselected positions for Box can be entered here, before starting the script
                %     app.Boxposition = [107.2877 97.5342 512 512];    % defined, preselected positions for Box can be entered here, before starting the script
                app.Minlimx = app.Boxposition(1);
                app.Minlimy = app.Boxposition(2);
                app.Maxlimx = app.Minlimx + app.Boxposition(3);
                app.Maxlimy = app.Minlimy + app.Boxposition(4);
                
                cla; app.A1 = imshow(app.I);title(app.Fname,'Interpreter','none'), hold on;
                ax = gca; app.Ttl = ax.Title; app.Ttl.FontSize = 14;
                app.Ttl.BackgroundColor = [1 0 0];
                rectangle('Position', app.Boxposition,'EdgeColor',[1 0 1]);
                
                % QUESTDLG for use of the FastPeakFinder
                ButtonName = questdlg(' Use automatic cone finding algorithm? ', ...
                    'Startup condition', ...
                    'Fast Peak Finder', 'CNN','No','No');
                switch ButtonName
                    case 'Fast Peak Finder'
                        p = FastPeakFind(app.I);
                        app.Conelocs(:,1) = p(1:2:end);
                        app.Conelocs(:,2) = p(2:2:end);
                        app.Conelocs(:,3) = 1;
                        
                        app.Conelocs(app.Conelocs(:,1)<=app.Minlimx,3)= 0;
                        app.Conelocs(app.Conelocs(:,1)>=app.Maxlimx,3)= 0;
                        app.Conelocs(app.Conelocs(:,2)<=app.Minlimy,3)= 0;
                        app.Conelocs(app.Conelocs(:,2)>=app.Maxlimy,3)= 0;
                        
                        app.Conelocs = unique(app.Conelocs, 'rows', 'stable');
                        
                        plot(app.Conelocs(app.Conelocs(:,3)~=0,1), app.Conelocs(app.Conelocs(:,3)~=0,2), app.MarkType, 'MarkerSize', app.MarkerSize)
                        app.Conecount = size(app.Conelocs(app.Conelocs(:,3)~=0,1),1);
                        app.Totalconecount = size(app.Conelocs,1);
                        
                    case 'CNN'
                        p = FastPeakFind(app.I);
                        
                        %             DataSet = '14111';  % dataSet specifies trained CNN (grader 1 trained network - 14(x16) training images and 1(x16) validation image)
                        DataSet = 'g1+cunefare';  % dataSet specifies trained CNN (grader 1 14(x16)training + 100 cunefare val images and 1(x16) validation g1 + 16 cunefare training images)
                        %             DataSet = '42';  % dataSet specifies trained CNN (grader 1 14(x16)training + 100 cunefare val images and 1(x16) validation g1 + 16 cunefare training images)
                        
                        ImageDir = [app.Pname app.Fname];
                        tic;
                        startTimeCNN = clock;
                        fprintf('startTimeCNN: %s \n', sprintf('%g ', startTimeCNN));
                        [app.Conelocs] = RunCNNnewSet_automatic(DataSet, ImageDir, app.Boxposition, app.I);
                        endTimeCNN = clock;
                        fprintf('endTimeCNN: %s \n', sprintf('%g ', endTimeCNN));
                        toc;
                        disp(' ');
                        disp(' ');
                        
                        app.Conelocs = unique(app.Conelocs,'rows', 'stable');
                        
                        %             app.Conelocs(:,1) = p(1:2:end);
                        %             app.Conelocs(:,2) = p(2:2:end);
                        app.Conelocs(:,3) = 1;
                        
                        app.Conelocs(app.Conelocs(:,1)<=app.Minlimx,3)= 0;
                        app.Conelocs(app.Conelocs(:,1)>=app.Maxlimx,3)= 0;
                        app.Conelocs(app.Conelocs(:,2)<=app.Minlimy,3)= 0;
                        app.Conelocs(app.Conelocs(:,2)>=app.Maxlimy,3)= 0;
                        
                        app.Conelocs = unique(app.Conelocs, 'rows', 'stable');
                        
                        plot(app.Conelocs(app.Conelocs(:,3)~=0,1), app.Conelocs(app.Conelocs(:,3)~=0,2), app.MarkType, 'MarkerSize', app.MarkerSize)
                        app.Conecount = size(app.Conelocs(app.Conelocs(:,3)~=0,1),1);
                        app.Totalconecount = size(app.Conelocs,1);
                        
                        
                    case 'No'
                        app.Conecount = 0;
                end % switch
                title([app.Fname,'  ',num2str(app.Conecount),' cones'],'Interpreter','none')
                
                if app.MultipleMosaic == 1
                    app.ChangeMosaic{1} = app.I;
                    app.Mosaic = 1;
                    %         app.I = app.I(:,:, app.Mosaic);
                else
                    app.ChangeMosaic{1} = app.I;
                    app.Mosaic = 1;
                end
                
            elseif strcmp(ext,'.mat')==1
                load([app.Pname app.Fname]);
                app.Conelocs = unique(app.Conelocs, 'rows', 'stable');
                app.Conecount = size(app.Conelocs(app.Conelocs(:,3)~=0,1),1);
                app.Totalconecount = size(app.Conelocs,1);
                
                app.Minlimx = app.Boxposition(1);
                app.Minlimy = app.Boxposition(2);
                app.Maxlimx = app.Minlimx + app.Boxposition(3);
                app.Maxlimy = app.Minlimy + app.Boxposition(4);
                
                if app.MultipleMosaic == 1
                    app.ChangeMosaic = app.I;
                    app.Mosaic = 1;
                    app.I = app.ChangeMosaic{1};
                else
                    app.ChangeMosaic = app.I;
                    app.Mosaic = 1;
                end
                
                app.MarkerSize = 4;
                
                app.Handler.fig = figure; imshow(app.I), title([app.Fname,'  ',num2str(app.Conecount),' cones'],'Interpreter','none'), hold on
                ax = gca;                    % get the current axis
                app.Ttl = ax.Title;              % get the title text object
                app.Ttl.FontSize = 14;
                app.Ttl.BackgroundColor = [1 0 0];
                
                cla; app.A1 = imshow(app.I);title([app.Fname,'  ',num2str(app.Conecount),' cones'],'Interpreter','none'), hold on;
                rectangle('Position', app.Boxposition,'EdgeColor',[1 0 1]);
                plot(app.Conelocs(app.Conelocs(:,3)~=0,1), app.Conelocs(app.Conelocs(:,3)~=0,2), app.MarkType, 'MarkerSize', app.MarkerSize)
            end
        end

        % Button pushed function: VoronoiiButton
        function VoronoiiButtonPushed(app, event)
            
            map = app.Map;
            
            numcolors = 25;  % ********* change from 15
            %         max_area_pixel = 150;        % basis for dividing cone Area through numcolors
            max_area_pixel = 80;        % basis for dividing cone Area through numcolors
            corr_factor = max_area_pixel/numcolors; % to represent whole colormap over values
            idx = round(linspace(1,256,numcolors));
            neighcolors = map(idx,:);
            neighcolors = flipud(neighcolors);
            
            min_neighbors = 4;
            max_neighbors = 9;
            numcolorsNN = max_neighbors-min_neighbors;  % ********* change from 15
            %         corr_factorNN = numcolorsNN/max_neighbours; % to represent whole colormap over values
            idxNN = round(linspace(1,256,numcolorsNN));
            neighcolorsNN = map(idxNN,:);
            neighcolorsNN = flipud(neighcolorsNN);
            
            if app.VoronoiPlot
                cla; app.A1 = imshow(app.I); hold on;
                voronoi_type = questdlg('Select coloring for Voronoi patches', 'Coloring Voronois', 'Cone Area', ...
                    'Number of Neighbors', 'Cone Area');
                % Calculate num neighbours from Voronoi diagram
                if size(app.Conelocs,1)>3
                    
                    vorocones = app.Conelocs(app.Conelocs(:,3)~=0,1:2);
                    vorocones = unique(vorocones, 'rows', 'stable');
                    
                    dt = delaunayTriangulation(vorocones(:,1),vorocones(:,2));
                    [V,C] = voronoiDiagram(dt);
                    [dump,G] = voronoin(vorocones(:,1:2));
                    for j = 1 : length ( G )
                        NumNeighbors(j,1) = size(G{j},2);
                        ConeArea(j,1) = polyarea(V(C{j},1),V(C{j},2));
                        idxMinMax = round(linspace(min(ConeArea),max_area_pixel,numcolors));
                    end
                end
                for j = 1:length(C)
                    if all(C{j}~=1)
                        
                        if strcmp(voronoi_type, 'Number of Neighbors')  % VARIANTE 1: Falschfarben sind Anzahl Nachbarn
                            if NumNeighbors(j)<= min_neighbors
                                h_p = patch(V(C{j},1),V(C{j},2),neighcolorsNN(1,:));
                            elseif NumNeighbors(j)> max_neighbors
                                h_p = patch(V(C{j},1),V(C{j},2),neighcolorsNN(end,:));
                            elseif NumNeighbors(j)> min_neighbors
                                %                             h_p = patch(V(C{j},1),V(C{j},2),neighcolors(NumNeighbors(j),:),'FaceAlpha',0.1);
                                h_p = patch(V(C{j},1),V(C{j},2),neighcolorsNN(NumNeighbors(j)-min_neighbors,:));
                            end
                            
                        elseif strcmp(voronoi_type, 'Cone Area')        % VARIANTE 2: Falschfarben sind PatchgrцЯe
                            if ConeArea(j)<=max_area_pixel
                                [val,pos]=min(abs(idxMinMax-ConeArea(j)));
                                %                             h_p = patch(V(C{j},1),V(C{j},2),neighcolors(round(ConeArea(j)/corr_factor),:));
                                h_p = patch(V(C{j},1),V(C{j},2),neighcolors(pos,:));
                                %                         if ConeArea(j)<=64
                                %                             h_p = patch(V(C{j},1),V(C{j},2),neighcolors(round(ConeArea(j)),:));
                            else
                                h_p = patch(V(C{j},1),V(C{j},2),neighcolors(end,:));
                            end
                        end                                             % VARIANTEN Ende
                    end
                end
                app.VoronoiPlot = 0;
            else
                cla; app.A1 = imshow(app.I); hold on;
                plot(app.Conelocs(app.Conelocs(:,3)~=0,1), app.Conelocs(app.Conelocs(:,3)~=0,2), app.MarkType, 'MarkerSize', app.MarkerSize)
                
                rectangle('Position', app.Boxposition,'EdgeColor',[1 0 1]);
                app.VoronoiPlot = 1;
            end
        end

        % Value changed function: EditModeSwitch
        function EditModeSwitchValueChanged(app, event)
            
            while strcmp(app.EditModeSwitch.Value,'On')
                [x,y,button] = ginput(1);
                
                % Mark cone center by left clicking
                if button == 1
                    MarkCone(app, x, y)
                end
                
                % Clear marked cone nearest to click location
                if button == 3
                    RemoveMark(app, x, y)
                end
                
                % Zoom in and out with two keys "+", "-" on numpad
                if button == 45
                    ZoomIn(app)
                end
                if button == 43
                    ZoomOut(app, x, y)
                end
                
                % Pan image laterally with arrow keys
                if button == 28 % LEFT arrow
                    PanLeft(app)
                elseif button == 29 % RIGHT arrow
                    PanRight(app)
                elseif button == 30 % UP arrow
                    PanUp(app)
                elseif button == 31 % DOWN arrow
                    PanDown(app)
                end
                
                % Escape key to quit count routine
                if button == 27
                    app.EditModeSwitch.Value = 'Off';
                end
            end
        end

        % Value changed function: ResetBrightnessButton
        function ResetBrightnessButtonValueChanged(app, event)
            app.BeightnessSlider.Value = 1.0;
            app.Brightness = 1.0;
            UpdateImage(app);
        end

        % Value changed function: LogImageSwitch
        function LogImageSwitchValueChanged(app, event)
            % log_image = app.LogImageSwitch.Value
            % default = On
            app.LogImage = app.LogImageSwitch.Value;
            UpdateImage(app);
%             
%             if strcmp(app.LogImageSwitch.Value, 'Off')
%                 I_INbox = log(double(app.I(app.Minlimy+20:app.Maxlimy-20, app.Minlimx+20:app.Maxlimx-20)));
%                 
%                 I_log = log(double(app.I));
%                 I_log = I_log - min(I_INbox(:));
%                 I_log = I_log./max(I_log(:));
% 
%                 cla; app.A1 = imshow(I_log); hold on;
%                 PlotConelocs(app);
%             else
%                 UpdateImage(app);
%             end
        end

        % Value changed function: ShowmarkedlocationsSwitch
        function ShowmarkedlocationsSwitchValueChanged(app, event)
            % markson = app.ShowmarkedlocationsSwitch.Value
            % default = On
            UpdateImage(app);
        end

        % Value changed function: ShowimageSwitch
        function ShowimageSwitchValueChanged(app, event)
            % imageon = app.ShowimageSwitch.Value
            % default = On
            app.Imageon = app.ShowimageSwitch.Value;
            UpdateImage(app);
        end

        % Value changing function: BeightnessSlider
        function BeightnessSliderValueChanging(app, event)
            app.Brightness = event.Value;
            UpdateImage(app);
        end

        % Selection changed function: ConeMarksTypeButtonGroup
        function ConeMarksTypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.ConeMarksTypeButtonGroup.SelectedObject;
            
            switch selectedButton.Text
                case 'Circle'
                    app.MarkType = 'oy';
                    app.MarkerSize = 5;
                    
                case 'Cross'
                    app.MarkType = 'xy';
                    app.MarkerSize = 5;
                    
                otherwise
                    app.MarkType = '.y';
                    app.MarkerSize = 2;
            end
            
            UpdateImage(app);
        end

        % Selection changed function: ShowMarksOptionsButtonGroup
        function ShowMarksOptionsButtonGroupSelectionChanged(app, event)
            selectedButton = app.ShowMarksOptionsButtonGroup.SelectedObject;
            
            switch selectedButton.Text
                case 'Userdetect'
                    app.ShowConesByDetectType = 1;
                    
                case 'Autodetect'
                    app.ShowConesByDetectType = 2;
                    
                otherwise
                    app.ShowConesByDetectType = 0;
            end
            UpdateImage(app);
        end

        % Value changed function: HighlightautodetectconesSwitch
        function HighlightautodetectconesSwitchValueChanged(app, event)
            UpdateImage(app);
        end

        % Button pushed function: SaveCurrentLocationsButton
        function SaveCurrentLocationsButtonPushed(app, event)
            SaveCurrentLocations(app);
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            close_dialog = questdlg('Do you want to save before quitting?',...
                'Close analysis window', 'Yes', 'No', 'Yes');
            
            if strcmp(close_dialog, 'Yes')
                SaveCurrentLocations(app);
            end
            
            close(app.Handler.fig);
            close(app.UIFigure);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.StartConeFinderButton = uibutton(app.UIFigure, 'state');
            app.StartConeFinderButton.ValueChangedFcn = createCallbackFcn(app, @StartConeFinderButtonValueChanged, true);
            app.StartConeFinderButton.Text = 'Start ConeFinder';
            app.VoronoiiButton = uibutton(app.UIFigure, 'push');
            app.VoronoiiButton.ButtonPushedFcn = createCallbackFcn(app, @VoronoiiButtonPushed, true);
            app.VoronoiiButton.Text = 'Voronoii';
            app.ResetBrightnessButton = uibutton(app.UIFigure, 'state');
            app.ResetBrightnessButton.ValueChangedFcn = createCallbackFcn(app, @ResetBrightnessButtonValueChanged, true);
            app.ResetBrightnessButton.Text = 'Reset Brightness';
            app.EditModeSwitchLabel = uilabel(app.UIFigure);
            app.EditModeSwitchLabel.Text = 'Edit Mode';
            app.EditModeSwitch = uiswitch(app.UIFigure, 'slider');
            app.EditModeSwitch.ValueChangedFcn = createCallbackFcn(app, @EditModeSwitchValueChanged, true);
            app.BeightnessSliderLabel = uilabel(app.UIFigure);
            app.BeightnessSliderLabel.Text = 'Beightness';
            app.BeightnessSlider = uislider(app.UIFigure);
            app.BeightnessSlider.Limits = [0 2];
            app.BeightnessSlider.MajorTicks = [0 0.5 1 1.5 2];
            app.BeightnessSlider.ValueChangingFcn = createCallbackFcn(app, @BeightnessSliderValueChanging, true);
            app.BeightnessSlider.Value = 1;
            app.LogImageSwitchLabel = uilabel(app.UIFigure);
            app.LogImageSwitchLabel.Text = 'Log Image';
            app.LogImageSwitch = uiswitch(app.UIFigure, 'slider');
            app.LogImageSwitch.ValueChangedFcn = createCallbackFcn(app, @LogImageSwitchValueChanged, true);
            app.LogImageSwitch.Value = 'On';
            app.ShowmarkedlocationsSwitchLabel = uilabel(app.UIFigure);
            app.ShowmarkedlocationsSwitchLabel.Text = 'Show marked locations';
            app.ShowmarkedlocationsSwitch = uiswitch(app.UIFigure, 'slider');
            app.ShowmarkedlocationsSwitch.ValueChangedFcn = createCallbackFcn(app, @ShowmarkedlocationsSwitchValueChanged, true);
            app.ShowmarkedlocationsSwitch.Value = 'On';
            app.ShowimageSwitchLabel = uilabel(app.UIFigure);
            app.ShowimageSwitchLabel.Text = 'Show image';
            app.ShowimageSwitch = uiswitch(app.UIFigure, 'slider');
            app.ShowimageSwitch.ValueChangedFcn = createCallbackFcn(app, @ShowimageSwitchValueChanged, true);
            app.ShowimageSwitch.Value = 'On';
            app.ConeMarksTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.ConeMarksTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ConeMarksTypeButtonGroupSelectionChanged, true);
            app.ConeMarksTypeButtonGroup.Title = 'Cone Marks Type';
            app.DotButton = uiradiobutton(app.ConeMarksTypeButtonGroup);
            app.DotButton.Text = 'Dot';
            app.DotButton.Value = true;
            app.CircleButton = uiradiobutton(app.ConeMarksTypeButtonGroup);
            app.CircleButton.Text = 'Circle';
            app.CrossButton = uiradiobutton(app.ConeMarksTypeButtonGroup);
            app.CrossButton.Text = 'Cross';
            app.ShowMarksOptionsButtonGroup = uibuttongroup(app.UIFigure);
            app.ShowMarksOptionsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ShowMarksOptionsButtonGroupSelectionChanged, true);
            app.ShowMarksOptionsButtonGroup.Title = 'Show Marks Options';
            app.AutoUserButton = uiradiobutton(app.ShowMarksOptionsButtonGroup);
            app.AutoUserButton.Text = 'Auto + User';
            app.AutoUserButton.Value = true;
            app.UserdetectButton = uiradiobutton(app.ShowMarksOptionsButtonGroup);
            app.UserdetectButton.Text = 'Userdetect';
            app.AutodetectButton = uiradiobutton(app.ShowMarksOptionsButtonGroup);
            app.AutodetectButton.Text = 'Autodetect';
            app.HighlightautodetectconesSwitchLabel = uilabel(app.UIFigure);
            app.HighlightautodetectconesSwitchLabel.Text = 'Highlight autodetect cones';
            app.HighlightautodetectconesSwitch = uiswitch(app.UIFigure, 'slider');
            app.HighlightautodetectconesSwitch.ValueChangedFcn = createCallbackFcn(app, @HighlightautodetectconesSwitchValueChanged, true);
            app.SaveCurrentLocationsButton = uibutton(app.UIFigure, 'push');
            app.SaveCurrentLocationsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveCurrentLocationsButtonPushed, true);
            app.SaveCurrentLocationsButton.Text = 'Save Current Locations';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
        end
    end

    methods (Access = public)

        % Construct app
        function app = TestApp1()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end