classdef UnitsWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UnitsUIFigure                  matlab.ui.Figure
        CancelButton                   matlab.ui.control.Button
        OKButton                       matlab.ui.control.Button
        PixelsperdegreeEditField       matlab.ui.control.NumericEditField
        PixelsperdegreeEditFieldLabel  matlab.ui.control.Label
        UnitsDropDown                  matlab.ui.control.DropDown
        UnitsDropDownLabel             matlab.ui.control.Label
    end

    
    properties (Access = private)
        MainAppHandler = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            app.MainAppHandler = mainApp;
            app.PixelsperdegreeEditField.Value = app.MainAppHandler.PixelsPerDergree;
            app.UnitsDropDown.Value = app.MainAppHandler.Units;
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            if app.PixelsperdegreeEditField.Value == 0 && ~strcmp(app.UnitsDropDown.Value, 'pixel')
                msgbox('Please, enter the PPD value!', 'Error', "error");
                return;
            end
            
            app.MainAppHandler.PixelsPerDergree = app.PixelsperdegreeEditField.Value;
            app.MainAppHandler.Units = app.UnitsDropDown.Value;

            if ~isempty(app.MainAppHandler.StatisticsWindowUIFigureHandler) && isvalid(app.MainAppHandler.StatisticsWindowUIFigureHandler)
                app.MainAppHandler.StatisticsWindowUIFigureHandler.RefreshAll();
            end
            delete(app);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UnitsUIFigure = uifigure('Visible', 'off');
            app.UnitsUIFigure.Name = 'Units';
            app.UnitsUIFigure.Icon = fullfile(pathToMLAPP, 'Icons', 'CF_icon.png');
            app.UnitsDropDownLabel = uilabel(app.UnitsUIFigure);
            app.UnitsDropDownLabel.Text = 'Units';
            app.UnitsDropDown = uidropdown(app.UnitsUIFigure);
            app.UnitsDropDown.Items = {'pixel', 'arcsec', 'arcmin', 'degree'};
            app.UnitsDropDown.Value = 'pixel';
            app.PixelsperdegreeEditFieldLabel = uilabel(app.UnitsUIFigure);
            app.PixelsperdegreeEditFieldLabel.Text = 'Pixels per degree';
            app.PixelsperdegreeEditField = uieditfield(app.UnitsUIFigure, 'numeric');
            app.PixelsperdegreeEditField.Limits = [0 Inf];
            app.OKButton = uibutton(app.UnitsUIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.UnitsUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.UnitsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UnitsWindow(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UnitsUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UnitsUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UnitsUIFigure)
        end
    end
end