classdef StatisticsWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ConeMapperStatisticsUIFigure  matlab.ui.Figure
        StatisticsPanel               matlab.ui.container.Panel
        NumOfRodsVLabel               matlab.ui.control.Label
        NumberofrodsLabel             matlab.ui.control.Label
        CDCDensValVLabel              matlab.ui.control.Label
        CDCdensityvalueLabel          matlab.ui.control.Label
        PCDDensValVLabel              matlab.ui.control.Label
        PCDdensityvalueLabel          matlab.ui.control.Label
        PCDcoordsVLabel               matlab.ui.control.Label
        PCDcoordinatesLabel           matlab.ui.control.Label
        CDCcoordsVLabel               matlab.ui.control.Label
        CDCcoordinatesLabel           matlab.ui.control.Label
        BrightnessVLabel              matlab.ui.control.Label
        DensRadVLabel                 matlab.ui.control.Label
        DensVLabel                    matlab.ui.control.Label
        XYCoordVLabel                 matlab.ui.control.Label
        XYcoordinatesLabel            matlab.ui.control.Label
        BrightnessvalueLabel          matlab.ui.control.Label
        DensityradialvalueLabel       matlab.ui.control.Label
        DensityvalueLabel             matlab.ui.control.Label
        NumOfConesVLabel              matlab.ui.control.Label
        NumberofconesLabel            matlab.ui.control.Label
        radialAvgUIAxes               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        MainAppHandler           matlab.apps.AppBase;
        DensityHandler = [];

        AvgDensityRadialDataStruct = [];
        DensityRadialDataStruct = [];
        RadialValuesToPlot;
        CurrentPointPlotHandler = [];
        CurrentPointVertLineHandler = [];
        TopPercentile = 90;
        BotPercentile = 10;
    end
    
    methods (Access = public)
        
        % Updates Data, which depends on current coordinates:
        % current coordinates, brightness, density value
        function UpdateDynamicData(app, x, y)
            [height, width] = size(app.MainAppHandler.Image);
            indexY = round(y);
            indexX = round(x);

            if indexX > 0 && indexX < width && indexY > 0 && indexY < height
                % set position
                app.XYCoordVLabel.Text = sprintf('X: %8.2f; Y: %8.2f', x, y);
                % set brightness
                app.BrightnessVLabel.Text = num2str(app.MainAppHandler.Image(indexY, indexX));

                if ~isempty(app.DensityHandler) && isvalid(app.DensityHandler)
                    if isa(app.DensityHandler, 'ZScoreMap')
                        scaleCoefficient = 1;
                    else
                        scaleCoefficient = GetScaleCoefficient(app.MainAppHandler.ReportUnits, app.MainAppHandler.ImageMagnificationFactor, app.MainAppHandler.RetinalMagnificationFactor);
                    end
                    
                    if isa(app.DensityHandler, 'ZScoreMap')
                        unitString = ' SD unit';
                    else
                        unitString = GetUnitsToPrint(app.MainAppHandler.ReportUnits);
                        unitString = [' cones/', unitString, '^2'];
                    end

                    % set density value
%                     densityValue = sprintf(app.GetFormatStringByUnit(app.MainAppHandler.ReportUnits), ...
%                         app.DensityHandler.DensityMatrix(indexY, indexX) / (scaleCoefficient^2));

                    if isnan(app.DensityHandler.DensityMatrix(indexY, indexX))
                        densityValue = 'NaN';
                    else
                        densityValue = ThousandSep(round(app.DensityHandler.DensityMatrix(indexY, indexX) / (scaleCoefficient^2), ...
                            app.GetRoundNumberByUnit(app.MainAppHandler.ReportUnits)));
                    end
%                     app.DensVLabel.Text = ['$ {', densityValue, '} \ \frac{cones}{{', unitString, '}^{2}} $'];
                    app.DensVLabel.Text = [densityValue, unitString];

                    % set current point
                    distance = round(pdist2([indexX, indexY], round(app.DensityHandler.CDC20_loc)));
                    radIndex = find(app.DensityRadialDataStruct.radiusesAll == distance, 1, 'first');
                    app.CurrentPointPlotHandler.XData = app.RadialValuesToPlot.radiusesAll(radIndex);
                    app.CurrentPointPlotHandler.YData = app.DensityRadialDataStruct.densityRadialAverageAll(radIndex);

                    app.CurrentPointVertLineHandler.XData = [app.RadialValuesToPlot.radiusesAll(radIndex), app.RadialValuesToPlot.radiusesAll(radIndex)];
                    app.CurrentPointVertLineHandler.YData = [app.DensityRadialDataStruct.densityRadialPercentiles(1, radIndex), app.DensityRadialDataStruct.densityRadialPercentiles(2, radIndex)];

                    % set density radial value
                    if isempty(radIndex)
%                         densityValue = sprintf('%6.4f', NaN);
                        densityValue = 'NaN';
                    else
%                         densityValue = sprintf(app.GetFormatStringByUnit(app.MainAppHandler.ReportUnits), ...
%                             app.DensityRadialDataStruct.densityRadialAverageAll(radIndex));
                        densityValue = ThousandSep(round(app.DensityRadialDataStruct.densityRadialAverageAll(radIndex), ...
                            app.GetRoundNumberByUnit(app.MainAppHandler.ReportUnits)));
                    end
%                     app.DensRadVLabel.Text = ['$ {', densityValue, '} \ \frac{cones}{{', unitString, '}^{2}} $'];
                    app.DensRadVLabel.Text = [densityValue, unitString];
                        
                else
                    app.DensVLabel.Text = "-";
                    app.DensRadVLabel.Text = "-";
                end
            else

            end
        end

        % Updates Data, which not depends on current coordinates:
        % number of cones, CDC, PCD
        function UpdateStaticData(app)
            app.ConeMapperStatisticsUIFigure.Name = app.MainAppHandler.Fname;

            if ~isempty(app.DensityHandler) && isvalid(app.DensityHandler)
                if isa(app.DensityHandler, 'ZScoreMap')
                    scaleCoefficient = 1;
                else
                    scaleCoefficient = GetScaleCoefficient(app.MainAppHandler.ReportUnits, app.MainAppHandler.ImageMagnificationFactor, app.MainAppHandler.RetinalMagnificationFactor);
                end
                
                if isa(app.DensityHandler, 'ZScoreMap')
                    unitString = ' SD unit';
                else
                    unitString = GetUnitsToPrint(app.MainAppHandler.ReportUnits);
                    unitString = [' cones/', unitString, '^2'];
                end

                % set CDC data
                app.CDCcoordsVLabel.Text = sprintf('X: %6d; Y: %6d', round(app.DensityHandler.CDC20_loc(1)), round(app.DensityHandler.CDC20_loc(2)));
                densityValue = ThousandSep(round(app.DensityHandler.DensityMatrix(round(app.DensityHandler.CDC20_loc(2)), round(app.DensityHandler.CDC20_loc(1))) ...
                    / (scaleCoefficient^2), ...
                    app.GetRoundNumberByUnit(app.MainAppHandler.ReportUnits)));

%                 densityValue = sprintf(app.GetFormatStringByUnit(app.MainAppHandler.ReportUnits), ...
%                     app.DensityHandler.DensityMatrix(round(app.DensityHandler.CDC20_loc(2)), round(app.DensityHandler.CDC20_loc(1))) / (scaleCoefficient^2));
%                 app.CDCDensValVLabel.Text = ['$ {', densityValue,'} \ \frac{cones}{{', unitString, '}^{2}} $'];
                app.CDCDensValVLabel.Text = [densityValue, unitString];
                
                % set PCD data
                app.PCDcoordsVLabel.Text = sprintf('X: %6d; Y: %6d', round(app.DensityHandler.PCD_loc(1)), round(app.DensityHandler.PCD_loc(2)));
                densityValue = ThousandSep(round(app.DensityHandler.DensityMatrix(round(app.DensityHandler.PCD_loc(2)), round(app.DensityHandler.PCD_loc(1))) ...
                    / (scaleCoefficient^2), ...
                    app.GetRoundNumberByUnit(app.MainAppHandler.ReportUnits)));
%                 densityValue = sprintf(app.GetFormatStringByUnit(app.MainAppHandler.ReportUnits), ...
%                     app.DensityHandler.DensityMatrix(round(app.DensityHandler.PCD_loc(2)), round(app.DensityHandler.PCD_loc(1))) / (scaleCoefficient^2));
%                 app.PCDDensValVLabel.Text = ['$ {', densityValue,'} \ \frac{cones}{{', unitString, '}^{2}} $'];
                app.PCDDensValVLabel.Text = [densityValue, unitString];
                    
            else
                app.CDCcoordsVLabel.Text = "No data";
                app.CDCDensValVLabel.Text = "No data";
                app.PCDcoordsVLabel.Text = "No data";
                app.PCDDensValVLabel.Text = "No data";
            end
            
            UpdateReceptorsCount(app);

            if ~isa(app.DensityHandler, 'ZScoreMap')
                PlotRadAvgOfAvgMap(app);
            end
            
            PlotRadialAverage(app);
        end

        function UpdateReceptorsCount(app)
            app.NumOfConesVLabel.Text = ThousandSep(app.MainAppHandler.TotalConeCount);
            app.NumOfRodsVLabel.Text = ThousandSep(app.MainAppHandler.TotalRodCount);
        end

        % Refreshes all data and graphs
        function RefreshAll(app)
            app.DensityHandler = app.MainAppHandler.GetDensityHandlerFromRadioButtons();
            UpdateStaticData(app);
            app.XYCoordVLabel.Text = "-";
            app.BrightnessVLabel.Text = "-";
            app.DensVLabel.Text = "-";
            app.DensRadVLabel.Text = "-";
        end

        % Returns specific format
        function formatString = GetFormatStringByUnit(app, unit)
            if isa(app.DensityHandler, 'ZScoreMap')
                formatString = '%3.2f';
                return;
            end

            switch unit
                case 'arcsec'
                    formatString = '%6.5f';
        
                case 'arcmin'
                    formatString = '%6.2f';
        
                case {'degree', 'deg'}
                    formatString = '%6.0f';

                case {'micrometer', 'µm'}
                    formatString = '%6.4f';
                
                case {'millimeter', 'mm'}
                    formatString = '%7.0f';
        
                otherwise
                    formatString = '%6.4f';
            end
        end

        % Returns specific format
        function roundNumber = GetRoundNumberByUnit(app, unit)
            if isa(app.DensityHandler, 'ZScoreMap')
                roundNumber = 2;
                return;
            end

            switch unit
                case 'arcsec'
                    roundNumber = 5;
        
                case 'arcmin'
                    roundNumber = 2;
        
                case {'degree', 'deg'}
                    roundNumber = 0;

                case {'micrometer', 'µm'}
                    roundNumber = 4;
                
                case {'millimeter', 'mm'}
                    roundNumber = 0;
        
                otherwise
                    roundNumber = 4;
            end
        end
    end


    methods (Access = private)
        % Creates and plots radial average graphs
        function PlotRadialAverage(app)
            if ~isempty(app.DensityHandler) && isvalid(app.DensityHandler)
                scaleCoefficient = GetScaleCoefficient(app.MainAppHandler.ReportUnits, app.MainAppHandler.ImageMagnificationFactor, app.MainAppHandler.RetinalMagnificationFactor);
                if isa(app.DensityHandler, 'ZScoreMap')
                    scaleCoefficientSquare = 1;
                else
                    scaleCoefficientSquare = scaleCoefficient^2;
                end


                unitString = GetUnitsToPrint(app.MainAppHandler.ReportUnits);
                if isa(app.DensityHandler, 'ZScoreMap')
                    unitStringFull = 'SD unit';
                else
                    unitStringFull = ['cones/', unitString, '^2'];
                end
                

                % get radial average
                app.DensityRadialDataStruct = GetDensityRadialAverage(app.DensityHandler.DensityMatrix, app.DensityHandler.CDC20_loc, [app.BotPercentile, app.TopPercentile]);
                
                % convert everithing to proper units
                % convert radial average
                app.DensityRadialDataStruct.densityRadialAverageAll = app.DensityRadialDataStruct.densityRadialAverageAll ./ scaleCoefficientSquare;
                app.DensityRadialDataStruct.densityRadialAverageHorizontal = app.DensityRadialDataStruct.densityRadialAverageHorizontal ./ scaleCoefficientSquare;
                app.DensityRadialDataStruct.densityRadialAverageVertical = app.DensityRadialDataStruct.densityRadialAverageVertical ./ scaleCoefficientSquare;
                
                % convert stds
                app.DensityRadialDataStruct.densityRadialStdAll = app.DensityRadialDataStruct.densityRadialStdAll ./ scaleCoefficientSquare;
                app.DensityRadialDataStruct.densityRadialStdHorizontal = app.DensityRadialDataStruct.densityRadialStdHorizontal ./ scaleCoefficientSquare;
                app.DensityRadialDataStruct.densityRadialStdVertical = app.DensityRadialDataStruct.densityRadialStdVertical ./ scaleCoefficientSquare;

                % convert percentiles
                app.DensityRadialDataStruct.densityRadialPercentiles = app.DensityRadialDataStruct.densityRadialPercentiles ./ scaleCoefficientSquare;

                % convert radiuses
                app.RadialValuesToPlot.radiusesAll = app.DensityRadialDataStruct.radiusesAll .* (scaleCoefficient);
                app.RadialValuesToPlot.radiusesHorizontal = app.DensityRadialDataStruct.radiusesHorizontal .* (scaleCoefficient);
                app.RadialValuesToPlot.radiusesVertical = app.DensityRadialDataStruct.radiusesVertical .* (scaleCoefficient);


                % plot everything
                % fill percentile area
                xValues = [app.RadialValuesToPlot.radiusesAll; flip(app.RadialValuesToPlot.radiusesAll)];
                inBetween = [app.DensityRadialDataStruct.densityRadialPercentiles(1, :), flip(app.DensityRadialDataStruct.densityRadialPercentiles(end, :))]';
                fill(app.radialAvgUIAxes, xValues, inBetween, [151/255,187/255,187/255], 'FaceAlpha', 0.7, 'EdgeColor','none');

                % plot radial avgs
                hold(app.radialAvgUIAxes, "on");
                radialPlot = plot(app.radialAvgUIAxes, app.RadialValuesToPlot.radiusesAll, app.DensityRadialDataStruct.densityRadialAverageAll, 'LineWidth', 1.5, 'Color', "#330000");
                horRadialPlot = plot(app.radialAvgUIAxes, app.RadialValuesToPlot.radiusesHorizontal, app.DensityRadialDataStruct.densityRadialAverageHorizontal, 'LineWidth', 1.5, 'Color', [0 1 1]);
                vertRadialPlot = plot(app.radialAvgUIAxes, app.RadialValuesToPlot.radiusesVertical, app.DensityRadialDataStruct.densityRadialAverageVertical, 'LineWidth', 1.5, 'Color', [1 0 1]);

                % plot approximate line
                indexOfApproximate = find(app.DensityRadialDataStruct.isApproximated, 1, 'first');
                xline(app.radialAvgUIAxes, app.RadialValuesToPlot.radiusesAll(indexOfApproximate));

                % plot current position
                app.CurrentPointPlotHandler = plot(app.radialAvgUIAxes, app.RadialValuesToPlot.radiusesAll(1), app.DensityRadialDataStruct.densityRadialAverageAll(1), ...
                    'Marker', 'x', ...
                    'MarkerEdgeColor', [201/255 60/255 56/255], ...
                    'MarkerSize', 6, ...
                    'LineWidth', 2,...
                    'LineStyle', 'none');
%                 datatip(app.CurrentPointPlotHandler);

                % plot percentiles
                % [numOfPercentiles, percLength, ~] = size(app.DensityRadialDataStruct.densityRadialPercentiles);
                % percRadials = zeros(percLength*numOfPercentiles + numOfPercentiles, 1);
                % percDens = zeros(percLength*numOfPercentiles + numOfPercentiles, 1);
                % for ind = 1:numOfPercentiles
                %     percRadials((ind-1)* percLength + ind: ind* percLength + ind) = [app.RadialValuesToPlot.radiusesAll; NaN];
                %     percDens((ind-1)* percLength + ind: ind* percLength + ind) = [app.DensityRadialDataStruct.densityRadialPercentiles(ind, :), NaN];
                % end
                % 
                % percPlot = plot(app.radialAvgUIAxes, ...
                %     percRadials', ...
                %     percDens, ...
                %     'Color', [0.5 0 0.8], ...
                %     'LineWidth', 1,'LineStyle','--');

                % plot current position line
                app.CurrentPointVertLineHandler = line(app.radialAvgUIAxes, ...
                    [app.RadialValuesToPlot.radiusesAll(1), app.RadialValuesToPlot.radiusesAll(1)], ...
                    [app.DensityRadialDataStruct.densityRadialPercentiles(1, 1), app.DensityRadialDataStruct.densityRadialPercentiles(2, 1)], ...
                    'LineWidth', 1,'LineStyle','-', 'Color', [0 0 0]);

                % show labels
                app.radialAvgUIAxes.XLabel.String = ['Distance from CDC (', unitString, ')'];
                app.radialAvgUIAxes.YLabel.String = ['Average Density (', unitStringFull, ')'];

                    % percPlot, ...
                    % ['Radial ', num2str(app.BotPercentile), '-', num2str(app.TopPercentile), ' percentiles'], ...
                legend(app.radialAvgUIAxes, [radialPlot, horRadialPlot, vertRadialPlot, ...
                    app.CurrentPointPlotHandler],...
                    {'Radial average', 'Horizontal sector average', 'Vertical sector average',...
                    'Current radial point'});


%                 if app.MainAppHandler.AutoLimCheckBox.Value
%                     app.radialAvgUIAxes.YLimMode = "auto";
%                 else
                    app.radialAvgUIAxes.YLim = [app.MainAppHandler.DensityMinimumLimit / scaleCoefficientSquare, app.MainAppHandler.DensityMaximumLimit / scaleCoefficientSquare];    
%                 end
                hold(app.radialAvgUIAxes, "off");
            end
        end

        function PlotRadAvgOfAvgMap(app)
            if ~isempty(app.AvgDensityRadialDataStruct)

                % convert average density radial data struct if possible to match
                % image magnification factor
                % TODO: check is it correct to do so?
%                 if app.MainAppHandler.ImageMagnificationFactor ~= 0 && app.MainAppHandler.RetinalMagnificationFactor ~= 0
%                     oldScaleCoefficient = GetScaleCoefficient('deg', app.AvgDensityRadialDataStruct.ImageMagnificationFactor, app.AvgDensityRadialDataStruct.RetinalMagnificationFactor);
%                     newScaleCoefficient = GetScaleCoefficient('deg', app.MainAppHandler.ImageMagnificationFactor, app.MainAppHandler.RetinalMagnificationFactor);
%     
%                     avgDensityRadialDataStruct.densityRadialAverageAll = app.AvgDensityRadialDataStruct.densityRadialAverageAll ./ (oldScaleCoefficient^2);
%                     avgDensityRadialDataStruct.densityRadialAverageAll = avgDensityRadialDataStruct.densityRadialAverageAll .* (newScaleCoefficient^2);
%     
%                     avgDensityRadialDataStruct.densityRadialStdAll = app.AvgDensityRadialDataStruct.densityRadialStdAll ./ (oldScaleCoefficient^2);
%                     avgDensityRadialDataStruct.densityRadialStdAll = avgDensityRadialDataStruct.densityRadialStdAll .* (newScaleCoefficient^2);
%     
%                     radialValuesToPlot.radiusesAll = app.AvgDensityRadialDataStruct.radiusesAll .* (oldScaleCoefficient);
%                     radialValuesToPlot.radiusesAll = radialValuesToPlot.radiusesAll ./ (newScaleCoefficient);
%                 else
%                     avgDensityRadialDataStruct.densityRadialAverageAll = app.AvgDensityRadialDataStruct.densityRadialAverageAll;
%                     avgDensityRadialDataStruct.densityRadialStdAll = app.AvgDensityRadialDataStruct.densityRadialStdAll;
%                     radialValuesToPlot.radiusesAll = app.AvgDensityRadialDataStruct.radiusesAll;
% 
%                     if app.MainAppHandler.ImageMagnificationFactor == 0
%                         msgbox("Please, setup the IMF and the RMF in Metrics tab. Presentation of average radial plot may be not correct.",...
%                               "Warning", "warn");
%                     end
%                 end

                avgDensityRadialDataStruct.densityRadialAverageAll = app.AvgDensityRadialDataStruct.densityRadialAverageAll;
                avgDensityRadialDataStruct.densityRadialStdAll = app.AvgDensityRadialDataStruct.densityRadialStdAll;
                radialValuesToPlot.radiusesAll = app.AvgDensityRadialDataStruct.radiusesAll;

                scaleCoefficient = GetScaleCoefficient(app.MainAppHandler.ReportUnits, app.AvgDensityRadialDataStruct.ImageMagnificationFactor, app.AvgDensityRadialDataStruct.RetinalMagnificationFactor);
                % scaleCoefficient = GetScaleCoefficient(app.MainAppHandler.ReportUnits, app.MainAppHandler.ImageMagnificationFactor, app.MainAppHandler.RetinalMagnificationFactor);
                % unitString = GetUnitsToPrint(app.MainAppHandler.ReportUnits);
                if isa(app.DensityHandler, 'ZScoreMap')
                    scaleCoefficientSquare = 1;
                else
                    scaleCoefficientSquare = scaleCoefficient^2;
                end


                % convert everithing to proper units
                % convert radial average
                avgDensityRadialDataStruct.densityRadialAverageAll = avgDensityRadialDataStruct.densityRadialAverageAll ./ scaleCoefficientSquare;
                
                % convert percentiles
                avgDensityRadialDataStruct.densityRadialStdAll = avgDensityRadialDataStruct.densityRadialStdAll ./ scaleCoefficientSquare;

                % convert radiuses
                radialValuesToPlot.radiusesAll = radialValuesToPlot.radiusesAll .* (scaleCoefficient);

                % plot everything
                % fill percentile area
                [h, ~, ~] = size(radialValuesToPlot.radiusesAll);
                if h ~= 1
                    radialValuesToPlot.radiusesAll = radialValuesToPlot.radiusesAll';
                    avgDensityRadialDataStruct.densityRadialAverageAll = avgDensityRadialDataStruct.densityRadialAverageAll';
                    avgDensityRadialDataStruct.densityRadialStdAll = avgDensityRadialDataStruct.densityRadialStdAll';
                end

                xValues = [radialValuesToPlot.radiusesAll, flip(radialValuesToPlot.radiusesAll)];
                inBetween = [avgDensityRadialDataStruct.densityRadialAverageAll-avgDensityRadialDataStruct.densityRadialStdAll, ...
                    flip(avgDensityRadialDataStruct.densityRadialAverageAll+avgDensityRadialDataStruct.densityRadialStdAll)];
                fill(app.radialAvgUIAxes, xValues, inBetween, [230/255 230/255 230/255], 'FaceAlpha', 1, 'EdgeColor','none');

                % plot radial avgs
                hold(app.radialAvgUIAxes, "on");
                plot(app.radialAvgUIAxes, radialValuesToPlot.radiusesAll, avgDensityRadialDataStruct.densityRadialAverageAll, ...
                    'LineWidth', 1.5, ...
                    'LineStyle','--', ...
                    'Color', [1 1 1]);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainAppHandler)
            app.MainAppHandler = mainAppHandler;
            app.DensityHandler = mainAppHandler.GetDensityHandlerFromRadioButtons();
            app.AvgDensityRadialDataStruct = mainAppHandler.AvgDensityRadialDataStruct;
            RefreshAll(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ConeMapperStatisticsUIFigure = uifigure('Visible', 'off');
            app.ConeMapperStatisticsUIFigure.Name = 'Cone Mapper Statistics';
            app.ConeMapperStatisticsUIFigure.Icon = 'CF_icon.png';
            app.radialAvgUIAxes = uiaxes(app.ConeMapperStatisticsUIFigure);
            title(app.radialAvgUIAxes, 'Density Radial Average')
            xlabel(app.radialAvgUIAxes, 'Distance from CDC')
            ylabel(app.radialAvgUIAxes, 'Average Density')
            zlabel(app.radialAvgUIAxes, 'Z')
            app.StatisticsPanel = uipanel(app.ConeMapperStatisticsUIFigure);
            app.StatisticsPanel.Title = 'Statistics';
            app.NumberofconesLabel = uilabel(app.StatisticsPanel);
            app.NumberofconesLabel.Text = 'Number of cones';
            app.NumOfConesVLabel = uilabel(app.StatisticsPanel);
            app.DensityvalueLabel = uilabel(app.StatisticsPanel);
            app.DensityvalueLabel.Text = 'Density value';
            app.DensityradialvalueLabel = uilabel(app.StatisticsPanel);
            app.DensityradialvalueLabel.Text = 'Density radial value';
            app.BrightnessvalueLabel = uilabel(app.StatisticsPanel);
            app.BrightnessvalueLabel.Text = 'Brightness value';
            app.XYcoordinatesLabel = uilabel(app.StatisticsPanel);
            app.XYcoordinatesLabel.Text = 'X, Y coordinates';
            app.XYCoordVLabel = uilabel(app.StatisticsPanel);
            app.DensVLabel = uilabel(app.StatisticsPanel);
            app.DensRadVLabel = uilabel(app.StatisticsPanel);
            app.BrightnessVLabel = uilabel(app.StatisticsPanel);
            app.CDCcoordinatesLabel = uilabel(app.StatisticsPanel);
            app.CDCcoordinatesLabel.Text = 'CDC coordinates';
            app.CDCcoordsVLabel = uilabel(app.StatisticsPanel);
            app.PCDcoordinatesLabel = uilabel(app.StatisticsPanel);
            app.PCDcoordinatesLabel.Text = 'PCD coordinates';
            app.PCDcoordsVLabel = uilabel(app.StatisticsPanel);
            app.PCDdensityvalueLabel = uilabel(app.StatisticsPanel);
            app.PCDdensityvalueLabel.Text = 'PCD density value';
            app.PCDDensValVLabel = uilabel(app.StatisticsPanel);
            app.CDCdensityvalueLabel = uilabel(app.StatisticsPanel);
            app.CDCdensityvalueLabel.Text = 'CDC density value';
            app.CDCDensValVLabel = uilabel(app.StatisticsPanel);
            app.NumberofrodsLabel = uilabel(app.StatisticsPanel);
            app.NumberofrodsLabel.Text = 'Number of rods';
            app.NumOfRodsVLabel = uilabel(app.StatisticsPanel);
            % Show the figure after all components are created
            app.ConeMapperStatisticsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = StatisticsWindow(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ConeMapperStatisticsUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.ConeMapperStatisticsUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ConeMapperStatisticsUIFigure)
        end
    end
end